classdef morphContinuumGen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SavelastButton           matlab.ui.control.Button
        SavecontinuumButton      matlab.ui.control.Button
        PlaycontinuumButton      matlab.ui.control.Button
        StepsDropDown            matlab.ui.control.DropDown
        StepsDropDownLabel       matlab.ui.control.Label
        morphngRateLabel         matlab.ui.control.Label
        SynthesizeButton         matlab.ui.control.Button
        morphingrateSlider       matlab.ui.control.Slider
        morphingrateSliderLabel  matlab.ui.control.Label
        Play_BButton             matlab.ui.control.Button
        Play_AButton             matlab.ui.control.Button
        FullPathOf_BLabel        matlab.ui.control.Label
        FullPathOf_ALabel        matlab.ui.control.Label
        FileName_BLabel          matlab.ui.control.Label
        FileName_ALabel          matlab.ui.control.Label
        Load_BButton             matlab.ui.control.Button
        Load_AButton             matlab.ui.control.Button
        UIControl3D              matlab.ui.control.UIAxes
    end


    properties (Access = private)
        CommonData % Description
    end

    methods (Access = private)

        function results = loadMorphingStructures(app)
            if isfield(app.CommonData,"fileAinfo")
                switch app.CommonData.fileAinfo.Status
                    case "updated"
                        fileAinfo = app.CommonData.fileAinfo;
                        delLoc = strfind(fileAinfo.Artist,',');
                        delLoc = [delLoc,length(fileAinfo.Artist)+1];
                        nBase = length(delLoc);
                        headLoc = 1;
                        mObjA = struct;
                        for ii = 1:nBase
                            fullPath = fileAinfo.Artist(headLoc:delLoc(ii)-1);
                            mObjA.morphStr(ii) = load(fullPath);
                            headLoc = delLoc(ii)+1;
                        end
                        app.CommonData.mObjA = mObjA;
                        comment = fileAinfo.Comment;
                        delLoc = strfind(comment,',');
                        delLoc = [delLoc,length(comment)+1];
                        nWeight = length(delLoc);
                        if nWeight ~= 5
                            disp("file does not have proper weight information");
                            return;
                        else
                            weight = zeros(5,nBase);
                            headLoc = 1;
                            for ii = 1:5
                                weight(ii,:) = ...
                                    eval(comment(headLoc:delLoc(ii)-1));
                                headLoc = delLoc(ii)+1;
                            end
                        end
                        %disp(weight)
                        app.CommonData.weightA = weight;
                        %disp(app.CommonData.mObjA);
                        app.CommonData.fileAinfo.Status = "current";
                        return;
                end
            end
            if isfield(app.CommonData,"fileBinfo")
                switch app.CommonData.fileBinfo.Status
                    case "updated"
                        fileBinfo = app.CommonData.fileBinfo;
                        delLoc = strfind(fileBinfo.Artist,',');
                        delLoc = [delLoc,length(fileBinfo.Artist)+1];
                        nBase = length(delLoc);
                        headLoc = 1;
                        mObjB = struct;
                        for ii = 1:nBase
                            fullPath = fileBinfo.Artist(headLoc:delLoc(ii)-1);
                            mObjB.morphStr(ii) = load(fullPath);
                            headLoc = delLoc(ii)+1;
                        end
                        mObjA = app.CommonData.mObjA;
                        anchorIsOk = 1;
                        if size(mObjA.morphStr(1).timeAnchor) ~= size(mObjB.morphStr(1).timeAnchor)
                            anchorIsOk = 0;
                        end
                        if size(mObjA.morphStr(1).timeFreqAnchor) ~= size(mObjB.morphStr(1).timeFreqAnchor)
                            anchorIsOk = 0;
                        end
                        if anchorIsOk
                            app.CommonData.mObjB = mObjB;
                        else
                            disp("file does not have matching morphing base");
                            return;
                        end
                        comment = fileBinfo.Comment;
                        delLoc = strfind(comment,',');
                        delLoc = [delLoc,length(comment)+1];
                        nWeight = length(delLoc);
                        if nWeight ~= 5
                            disp("file does not have proper weight information");
                            return;
                        else
                            weight = zeros(5,nBase);
                            headLoc = 1;
                            for ii = 1:5
                                weight(ii,:) = ...
                                    eval(comment(headLoc:delLoc(ii)-1));
                                headLoc = delLoc(ii)+1;
                            end
                        end
                        %disp(weight)
                        app.CommonData.weightB = weight;
                        app.CommonData.fileBinfo.Status = "current";
                end
            end
            results =  1;
        end

        function results = design3DweightViewer(app)
            %axes(app.UIControl3D);
            axis(app.UIControl3D,[-2 2 -2 2 -2 2]);
            [az,al] = view(app.UIControl3D);
            [XX,YY] = meshgrid(-2:0.01:2,-2:0.01:2);
            ndiv = size(XX,1);
            np=21;
            [sx,sy,sz] = sphere(np);
            app.CommonData.sphereBase = {sx,sy,sz};
            app.CommonData.cursorRad = 0.2;
            curorRad = app.CommonData.cursorRad;
            app.CommonData.cursorBase = ...
                {curorRad*sx,curorRad*sy,curorRad*sz};
            cursorBase = app.CommonData.cursorBase;
            scolor = ones(np,np,3);
            app.CommonData.curcorCenter = [0 0];
            %cursorCenter = app.CommonData.curcorCenter;
            %---- define a half-transparent cursor for weight manipulation
            %---- Sample B
            app.CommonData.sampleB_Handle = ...
                surface(app.UIControl3D,...
                cursorBase{1}+1,cursorBase{2},cursorBase{3}+2, ...
                scolor,"FaceLighting","gouraud","FaceAlpha",0,"AmbientStrength",0.6);
            shading(app.UIControl3D,"flat");
            light(app.UIControl3D,'Position',[1/3 1 0.55]);
            hold(app.UIControl3D,"on");
            app.CommonData.sampleBtx_Text = ...
                text(app.UIControl3D,1,0,2.5,"Btx","FontSize",20,"Color",[0 0 0], ...
                "HorizontalAlignment","center");
            app.CommonData.sampleBfx_Text = ...
                text(app.UIControl3D,1,0,2.5,"Bfx","FontSize",20,"Color",[0 0 0], ...
                "HorizontalAlignment","center");
            app.CommonData.sampleBfo_Text = ...
                text(app.UIControl3D,1,0,2.5,"Bfo","FontSize",20,"Color",[0 0 0], ...
                "HorizontalAlignment","center");
            app.CommonData.sampleBsl_Text = ...
                text(app.UIControl3D,1,0,2.5,"Bsl","FontSize",20,"Color",[0 0 0], ...
                "HorizontalAlignment","center");
            app.CommonData.sampleBap_Text = ...
                text(app.UIControl3D,1,0,2.5,"Bap","FontSize",20,"Color",[0 0 0], ...
                "HorizontalAlignment","center");
            %---- Sample A
            app.CommonData.sampleA_Handle = ...
                surface(app.UIControl3D,...
                cursorBase{1}-1,cursorBase{2},cursorBase{3}+2, ...
                scolor,"FaceLighting","gouraud","FaceAlpha",0,"AmbientStrength",0.6);
            app.CommonData.sampleAtx_Text = ...
                text(app.UIControl3D,-1,0,2.5,"Atx","FontSize",20,"Color",[0 0 0], ...
                "HorizontalAlignment","center");
            app.CommonData.sampleAfx_Text = ...
                text(app.UIControl3D,-1,0,2.5,"Afx","FontSize",20,"Color",[0 0 0], ...
                "HorizontalAlignment","center");
            app.CommonData.sampleAfo_Text = ...
                text(app.UIControl3D,-1,0,2.5,"Afo","FontSize",20,"Color",[0 0 0], ...
                "HorizontalAlignment","center");
            app.CommonData.sampleAsl_Text = ...
                text(app.UIControl3D,-1,0,2.5,"Asl","FontSize",20,"Color",[0 0 0], ...
                "HorizontalAlignment","center");
            app.CommonData.sampleAap_Text = ...
                text(app.UIControl3D,-1,0,2.5,"Aap","FontSize",20,"Color",[0 0 0], ...
                "HorizontalAlignment","center");
            %---- define three reference items, red, blue, and green
            redColor = scolor*0+0.2;
            redColor(:,:,1) = 1;
            greenColor = scolor*0+0.2;
            greenColor(:,:,2) = 1;
            blueColor = scolor*0+0.2;
            blueColor(:,:,3) = 1;
            redCenter = [0 1];
            greenCenter = [cos(7/6*pi) sin(7/6*pi)];
            blueCenter = [cos(11/6*pi) sin(11/6*pi)];
            app.CommonData.redCenter = redCenter;
            app.CommonData.greenCenter = greenCenter;
            app.CommonData.blueCenter = blueCenter;
            app.CommonData.redItem = ...
                surface(app.UIControl3D,...
                curorRad*sx+redCenter(1),curorRad*sy+redCenter(2),curorRad*sz+1, ...
                redColor,"FaceLighting","gouraud","AmbientStrength",0.8,"FaceAlpha",0.5);
            app.CommonData.greenItem = ...
                surface(app.UIControl3D,...
                curorRad*sx+greenCenter(1),curorRad*sy+greenCenter(2),curorRad*sz+1, ...
                greenColor,"FaceLighting","gouraud","AmbientStrength",0.8,"FaceAlpha",0.5);
            app.CommonData.blueItem = ...
                surface(app.UIControl3D,...
                curorRad*sx+blueCenter(1),curorRad*sy+blueCenter(2),curorRad*sz+1, ...
                blueColor,"FaceLighting","gouraud","AmbientStrength",0.8,"FaceAlpha",0.5);
            shading(app.UIControl3D,"flat");
            %---- draw backgground image
            r = sqrt(XX.^2+YY.^2);
            %backImgBase = 1-0.7 ./(1+exp(10*(r-1.3)));
            backImg = zeros(ndiv,ndiv,3);
            backImg(:,:,1) = 1-0.7 ./(1+exp(10*(r-1.25)));
            backImg(:,:,2) = 1-0.3 ./(1+exp(10*(r-1.25)));
            backImg(:,:,3) = 1-0.7 ./(1+exp(10*(r-1.25)));
            image(app.UIControl3D,-2:0.01:2,-2:0.01:2,backImg)
            %---- draw triangular edges connecting three colored spheres
            plot(app.UIControl3D,[redCenter(1) greenCenter(1)],...
                [redCenter(2) greenCenter(2)],"k","LineWidth",3);
            plot(app.UIControl3D,[blueCenter(1) greenCenter(1)],...
                [blueCenter(2) greenCenter(2)],"k","LineWidth",3);
            plot(app.UIControl3D,[blueCenter(1) redCenter(1)],...
                [blueCenter(2) redCenter(2)],"k","LineWidth",3);
            %plot3(app.UIControl3D,sx,sy,sz)
            %---- plot morphed point indicator
            indicatorRad = 0.08;
            app.CommonData.indicatorRad = indicatorRad;
            %--- tx
            txColor = scolor;%[0 0.4470 0.7410];
            txColor(:,:,1) = 0;
            txColor(:,:,2) = 0.4470;
            txColor(:,:,3) = 0.7410;
            app.CommonData.txDisp = ...
                surface(app.UIControl3D,...
                indicatorRad*sx-0.4,indicatorRad*sy,indicatorRad*sz+2.5, ...
                txColor,"FaceLighting","gouraud","AmbientStrength",0.8,"FaceAlpha",0.5);
            %--- tx
            txColor = scolor;%[0.8500 0.3250 0.0980];
            txColor(:,:,1) = 0.8500;
            txColor(:,:,2) = 0.3250;
            txColor(:,:,3) = 0.0980;
            app.CommonData.fxDisp = ...
                surface(app.UIControl3D,...
                indicatorRad*sx-0.2,indicatorRad*sy,indicatorRad*sz+2.5, ...
                txColor,"FaceLighting","gouraud","AmbientStrength",0.8,"FaceAlpha",0.5);
            %--- fo
            txColor = scolor;%[0.9290 0.6940 0.1250];
            txColor(:,:,1) = 0.9290;
            txColor(:,:,2) = 0.6940;
            txColor(:,:,3) = 0.1250;
            app.CommonData.foDisp = ...
                surface(app.UIControl3D,...
                indicatorRad*sx-0.0 ...
                ,indicatorRad*sy,indicatorRad*sz+2.5, ...
                txColor,"FaceLighting","gouraud","AmbientStrength",0.8,"FaceAlpha",0.5);
            %--- sl
            txColor = scolor;%[0.4940 0.1840 0.5560];
            txColor(:,:,1) = 0.4940;
            txColor(:,:,2) = 0.1840;
            txColor(:,:,3) = 0.5560;
            app.CommonData.slDisp = ...
                surface(app.UIControl3D,...
                indicatorRad*sx+0.2 ...
                ,indicatorRad*sy,indicatorRad*sz+2.5, ...
                txColor,"FaceLighting","gouraud","AmbientStrength",0.8,"FaceAlpha",0.5);
            %--- ap
            txColor = scolor;%[0.3010 0.7450 0.9330]];
            txColor(:,:,1) = 0.9;
            txColor(:,:,2) = 0;
            txColor(:,:,3) = 0.9;
            app.CommonData.apDisp = ...
                surface(app.UIControl3D,...
                indicatorRad*sx+0.4 ...
                ,indicatorRad*sy,indicatorRad*sz+2.5, ...
                txColor,"FaceLighting","gouraud","AmbientStrength",0.8,"FaceAlpha",0.5);
            shading(app.UIControl3D,"flat");
            light(app.UIControl3D,'Position',[1/3 1 0.55]);
            %----- attribute indicator
            app.CommonData.morphtx_Text = ...
                text(app.UIControl3D,-1.5,1.0,2.5,"Time axis","FontSize",24,"Color",[0 0 0], ...
                "HorizontalAlignment","r");
            app.CommonData.morphtx_Link = ...
                plot3(app.UIControl3D,[-0.4 -1.5],[0 1.0],[2.5 2.5],"Color",[0 0.4470 0.7410],...
                "LineWidth",2);
            app.CommonData.morphfx_Text = ...
                text(app.UIControl3D,-1.5,1.3,2.5,"Frequency axis","FontSize",24,"Color",[0 0 0], ...
                "HorizontalAlignment","r");
            app.CommonData.morphfx_Link = ...
                plot3(app.UIControl3D,[-0.2 -1.5],[0 1.3],[2.5 2.5],"Color",[0.8500 0.3250 0.0980],...
                "LineWidth",2);
            app.CommonData.morphfo_Text = ...
                text(app.UIControl3D,1.5,1.6,2.5,"Fudamental freq.","FontSize",24,"Color",[0 0 0], ...
                "HorizontalAlignment","l");
            app.CommonData.morphfo_Link = ...
                plot3(app.UIControl3D,[-0.0 1.5],[0 1.6],[2.5 2.5],"Color",[0.9290 0.6940 0.1250],...
                "LineWidth",2);
            app.CommonData.morphsl_Text = ...
                text(app.UIControl3D,1.5,1.3,2.5,"Spectrum level","FontSize",24,"Color",[0 0 0], ...
                "HorizontalAlignment","l");
            app.CommonData.morphsl_Link = ...
                plot3(app.UIControl3D,[0.2 1.5],[0 1.3],[2.5 2.5],"Color",[0.4940 0.1840 0.5560],...
                "LineWidth",2);
            app.CommonData.morphap_Text = ...
                text(app.UIControl3D,1.5,1.0,2.5,"Aperiodicity","FontSize",24,"Color",[0 0 0], ...
                "HorizontalAlignment","l");
            app.CommonData.morphap_Link = ...
                plot3(app.UIControl3D,[0.4 1.5],[0 1.0],[2.5 2.5],"Color",[0.9 0.00 0.9],...
                "LineWidth",2);
            %----- finalize
            grid(app.UIControl3D,"off");
            %axis(app.UIControl3D,"off");
            axis(app.UIControl3D,"equal");
            axis(app.UIControl3D,"image");
            axis(app.UIControl3D,1.5*[-2 2 -1.1 1.1 -2 3]);
            view(app.UIControl3D,az,al);
            hold(app.UIControl3D,"off");
            axes(app.UIControl3D);
            axis(app.UIControl3D,"off");
            app.CommonData.cursor = "off";
            results =  1;
        end

        function results = setSampleA(app)
            weight = app.CommonData.weightA;
            tx = weight(1,:)';
            app.CommonData.tmpWeightd = tx;weight2xy(app);
            tmpLoc = app.CommonData.tmpXY;
            app.CommonData.sampleAtx_Text.Position = [tmpLoc,0];
            fx = weight(2,:)';
            app.CommonData.tmpWeightd = fx;weight2xy(app);
            tmpLoc = app.CommonData.tmpXY;
            app.CommonData.sampleAfx_Text.Position = [tmpLoc,0];
            fo = weight(3,:)';
            app.CommonData.tmpWeightd = fo;weight2xy(app);
            tmpLoc = app.CommonData.tmpXY;
            app.CommonData.sampleAfo_Text.Position = [tmpLoc,0];
            sl = weight(4,:)';
            app.CommonData.tmpWeightd = sl;weight2xy(app);
            tmpLoc = app.CommonData.tmpXY;
            app.CommonData.sampleAsl_Text.Position = [tmpLoc,0];
            ap = weight(5,:)';
            app.CommonData.tmpWeightd = ap;weight2xy(app);
            tmpLoc = app.CommonData.tmpXY;
            app.CommonData.sampleAap_Text.Position = [tmpLoc,0];
            results =  1;
        end

        function results = weight2xy(app)
            areaCrd = app.CommonData.tmpWeightd;
            %y1 = areaCrd(1);
            %x1 = -1+areaCrd(2);
            %y2 = y1/sqrt(1.25);
            %x2 = x1+y1/2;
            %y3 = y2-0.5/sqrt(1.25);
            %x3 = x2+0.5;
            %app.CommonData.tmpXY = [x3 y3]*2*sqrt(1.25);
            h = 1.5*areaCrd(1);
            y = h-0.5;
            delX = (areaCrd(2)-areaCrd(3))/(areaCrd(2)+areaCrd(3));
            x = delX*(1-y)/sqrt(3);
            app.CommonData.tmpXY = [x y];
            results =  1;
        end

        function results = setSampleB(app)
            weight = app.CommonData.weightB;
            tx = weight(1,:)';
            app.CommonData.tmpWeightd = tx;weight2xy(app);
            tmpLoc = app.CommonData.tmpXY;
            app.CommonData.sampleBtx_Text.Position = [tmpLoc,0];
            fx = weight(2,:)';
            app.CommonData.tmpWeightd = fx;weight2xy(app);
            tmpLoc = app.CommonData.tmpXY;
            app.CommonData.sampleBfx_Text.Position = [tmpLoc,0];
            fo = weight(3,:)';
            app.CommonData.tmpWeightd = fo;weight2xy(app);
            tmpLoc = app.CommonData.tmpXY;
            app.CommonData.sampleBfo_Text.Position = [tmpLoc,0];
            sl = weight(4,:)';
            app.CommonData.tmpWeightd = sl;weight2xy(app);
            tmpLoc = app.CommonData.tmpXY;
            app.CommonData.sampleBsl_Text.Position = [tmpLoc,0];
            ap = weight(5,:)';
            app.CommonData.tmpWeightd = ap;weight2xy(app);
            tmpLoc = app.CommonData.tmpXY;
            app.CommonData.sampleBap_Text.Position = [tmpLoc,0];
            results =  1;
        end

        function results = updateMrateDisplay(app)
            rad = app.CommonData.indicatorRad;
            sx = app.CommonData.sphereBase{1}*rad;
            sy = app.CommonData.sphereBase{2}*rad;
            %sz = app.CommonData.sphereBase{3}*rad;
            weight = app.CommonData.morphedWeight;
            %---- tx
            tx = weight(1,:)';
            app.CommonData.tmpWeightd = tx;weight2xy(app);
            tmpLoc = app.CommonData.tmpXY;
            app.CommonData.morphtx_Link.XData = [tmpLoc(1) -1.5];
            app.CommonData.morphtx_Link.YData = [tmpLoc(2) 1];
            app.CommonData.txDisp.XData = tmpLoc(1)+sx;
            app.CommonData.txDisp.YData = tmpLoc(2)+sy;
            %---- fx
            fx = weight(2,:)';
            app.CommonData.tmpWeightd = fx;weight2xy(app);
            tmpLoc = app.CommonData.tmpXY;
            app.CommonData.morphfx_Link.XData = [tmpLoc(1) -1.5];
            app.CommonData.morphfx_Link.YData = [tmpLoc(2) 1.3];
            app.CommonData.fxDisp.XData = tmpLoc(1)+sx;
            app.CommonData.fxDisp.YData = tmpLoc(2)+sy;
            %---- fo
            fo = weight(3,:)';
            app.CommonData.tmpWeightd = fo;weight2xy(app);
            tmpLoc = app.CommonData.tmpXY;
            app.CommonData.morphfo_Link.XData = [tmpLoc(1) 1.5];
            app.CommonData.morphfo_Link.YData = [tmpLoc(2) 1.6];
            app.CommonData.foDisp.XData = tmpLoc(1)+sx;
            app.CommonData.foDisp.YData = tmpLoc(2)+sy;
            %---- sl
            sl = weight(4,:)';
            app.CommonData.tmpWeightd = sl;weight2xy(app);
            tmpLoc = app.CommonData.tmpXY;
            app.CommonData.morphsl_Link.XData = [tmpLoc(1) 1.5];
            app.CommonData.morphsl_Link.YData = [tmpLoc(2) 1.3];
            app.CommonData.slDisp.XData = tmpLoc(1)+sx;
            app.CommonData.slDisp.YData = tmpLoc(2)+sy;
            %---- ap
            ap = weight(5,:)';
            app.CommonData.tmpWeightd = ap;weight2xy(app);
            tmpLoc = app.CommonData.tmpXY;
            app.CommonData.morphap_Link.XData = [tmpLoc(1) 1.5];
            app.CommonData.morphap_Link.YData = [tmpLoc(2) 1.3];
            app.CommonData.apDisp.XData = tmpLoc(1)+sx;
            app.CommonData.apDisp.YData = tmpLoc(2)+sy;
            results =  1;
        end

        function results = saveSignal(app)
            morphdBase = app.CommonData.mObjA;
            %morphStr = app.CommonData.morphStr;
            weightStr = app.CommonData.weightStr;
            xSynth = app.CommonData.synthesizedSignal;
            fs = app.CommonData.fsA;
            author = "source:";
            nItems = length(morphdBase.morphStr);
            %artist = string(morphdBase.morphStr(1).fullpath);
            author = author + "," ...
                + string(morphdBase.morphStr(1).worldParameter.fileName);
            for ii = 2:nItems
                %    artist = artist + "," ...
                %        + string(morphdBase.morphStr(ii).fullpath);
                author = author + "," ...
                    + string(morphdBase.morphStr(ii).worldParameter.fileName);
            end
            author = author+","+ " with: morphContinuumGen";
            artist = app.CommonData.fileAinfo.Artist;
            comment = string([ '[' num2str(weightStr.tx(:)','%11.6f') ']' ]);
            comment = comment + "," ...
                + string([ '[' num2str(weightStr.fx(:)','%11.6f') ']' ]);
            comment = comment + "," ...
                + string([ '[' num2str(weightStr.fo(:)','%11.6f') ']' ]);
            comment = comment + "," ...
                + string([ '[' num2str(weightStr.sl(:)','%11.6f') ']' ]);
            comment = comment + "," ...
                + string([ '[' num2str(weightStr.ap(:)','%11.6f') ']' ]);
            t = datetime(datetime,'format','yyyyMMddHHmmss');
            mRate = num2str(round(app.CommonData.morphingRatePct));
            app.CommonData.outFileName = ...
                "morphSyn" + string(t) +"mpc"+ mRate + ".wav";
            if ~isfield(app.CommonData,"outDirectory")
                outDirectory = uigetdir(pwd,'Output file directory');
                %disp(outDirectory);
                app.CommonData.outDirectory = outDirectory;
            end
            outFileName = app.CommonData.outFileName;
            outDirectory = app.CommonData.outDirectory;
            audiowrite(outDirectory + "/" + outFileName, ...
                xSynth/max(abs(xSynth))*0.8,fs,"BitsPerSample",24, ...
                "Title",author,"Artist",artist,"Comment",comment);
            results = 1;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            design3DweightViewer(app);
        end

        % Button pushed function: Load_AButton
        function Load_AButtonPushed(app, event)
            successInd = 1;
            try
                [fileName, filePath] = ...
                    uigetfile({'*.wav'},"Select morphed sound file:");
                if isnumeric(fileName) && isnumeric(filePath)
                    if fileName*filePath == 0;successInd = 0;end
                end
            catch
                successInd = 0;
            end
            if successInd == 1
                app.FileName_ALabel.Text = fileName;
                app.FullPathOf_ALabel.Text = filePath;
                [xA,fsA] = audioread([filePath  fileName]);
                app.CommonData.signal_A = xA;
                app.CommonData.fsA = fsA;
                app.Play_AButton.Enable = "on";
                app.CommonData.fileAinfo = audioinfo([filePath  fileName]);
                app.CommonData.fileAinfo.Status = "updated";
                app.Load_BButton.Enable = "on";
                loadMorphingStructures(app);
                setSampleA(app);
            end
        end

        % Button pushed function: Load_BButton
        function Load_BButtonPushed(app, event)
            successInd = 1;
            try
                [fileName, filePath] = ...
                    uigetfile({'*.wav'},"Select morphed sound file:");
                if isnumeric(fileName) && isnumeric(filePath)
                    if fileName*filePath == 0;successInd = 0;end
                end
            catch
                successInd = 0;
            end
            if successInd == 1
                app.FileName_BLabel.Text = fileName;
                app.FullPathOf_BLabel.Text = filePath;
                [xB,fsB] = audioread([filePath  fileName]);
                app.CommonData.signal_B = xB;
                app.CommonData.fsB = fsB;
                app.Play_BButton.Enable = "on";
                app.CommonData.fileBinfo = audioinfo([filePath  fileName]);
                app.CommonData.fileBinfo.Status = "updated";
                loadMorphingStructures(app);
                setSampleB(app);
                app.SynthesizeButton.Enable = "on";
                app.morphingrateSlider.Enable = "on";
                app.StepsDropDown.Visible = "on";
                app.StepsDropDownLabel.Visible = "on";
                app.PlaycontinuumButton.Visible = "on";
                app.SavecontinuumButton.Visible = "on";
            end
        end

        % Button pushed function: Play_AButton
        function Play_AButtonPushed(app, event)
            fs = app.CommonData.fsA;
            xA = app.CommonData.signal_A;
            soundsc(xA,fs);
        end

        % Button pushed function: Play_BButton
        function Play_BButtonPushed(app, event)
            fs = app.CommonData.fsB;
            xB = app.CommonData.signal_B;
            soundsc(xB,fs);
        end

        % Button pushed function: SynthesizeButton
        function SynthesizeButtonPushed(app, event)
            if isempty(event)
                value = app.CommonData.changingValue;
            else
                value = app.morphingrateSlider.Value;
            end
            morphingRate = value/100;
            morphdBase = app.CommonData.mObjA;
            weightStr = struct;
            weightA = app.CommonData.weightA;
            weightB = app.CommonData.weightB;
            morphedWeight = (1-morphingRate)*weightA+morphingRate*weightB;
            weightStr.tx = morphedWeight(1,:)';
            weightStr.fx = morphedWeight(2,:)';
            weightStr.fo = morphedWeight(3,:)';
            weightStr.sl = morphedWeight(4,:)';
            weightStr.ap = morphedWeight(5,:)';
            app.CommonData.weightStr = weightStr;
            app.CommonData.morphedWeight = morphedWeight;
            app.CommonData.morphingRatePct = value;
            if ~isempty(event)
                morphStr = ...
                    generalizedTCmorphing(morphdBase,weightStr,0);
                app.CommonData.morphStr = morphStr;
                fs = app.CommonData.fsA;
                xSynth = Synthesis(morphStr.source_parameter, ...
                    morphStr.spectrum_parameter);
                app.CommonData.signalDuration = length(xSynth)/fs;
                soundsc(xSynth,fs);
                app.CommonData.synthesizedSignal = ...
                    xSynth/max(abs(xSynth))*0.8;
                app.SavelastButton.Visible = "on";
                app.SavelastButton.Enable = "on";
            end
        end

        % Value changed function: morphingrateSlider
        function morphingrateSliderValueChanged(app, event)
            value = app.morphingrateSlider.Value;
            app.morphngRateLabel.Text = [num2str(round(value)) ' %'];
            SynthesizeButtonPushed(app,event);
            if isfield(app.CommonData,"outFileName")
                app.CommonData = rmfield(app.CommonData,"outFileName");
            end
        end

        % Value changing function: morphingrateSlider
        function morphingrateSliderValueChanging(app, event)
            changingValue = event.Value;
            app.CommonData.changingValue = changingValue;
            app.morphngRateLabel.Text = [num2str(round(changingValue)) ' %'];
            SynthesizeButtonPushed(app,[]);
            updateMrateDisplay(app);
        end

        % Button pushed function: PlaycontinuumButton
        function PlaycontinuumButtonPushed(app, event)
            nStep = str2double(app.StepsDropDown.Value);
            %disp(app.CommonData.nStep);
            app.CommonData.nStep = nStep;
            mRatePercept = 100/(nStep-1);
            for ii = 1:nStep
                app.morphingrateSlider.Value = mRatePercept*(ii-1);
                app.CommonData.changingValue = mRatePercept*(ii-1);
                app.morphngRateLabel.Text = ...
                    [num2str(round(mRatePercept*(ii-1))) ' %'];
                SynthesizeButtonPushed(app,[]);
                updateMrateDisplay(app);
                SynthesizeButtonPushed(app,event);
                pause(app.CommonData.signalDuration+0.3);
            end
        end

        % Button pushed function: SavelastButton
        function SavelastButtonPushed(app, event)
            saveSignal(app);
        end

        % Button pushed function: SavecontinuumButton
        function SavecontinuumButtonPushed(app, event)
            nStep = str2double(app.StepsDropDown.Value);
            outDirectory = uigetdir(pwd,'Output continuum directory');
            %disp(outDirectory);
            app.CommonData.outDirectory = outDirectory;
            %disp(app.CommonData.nStep);
            app.CommonData.nStep = nStep;
            mRatePercept = 100/(nStep-1);
            for ii = 1:nStep
                app.morphingrateSlider.Value = mRatePercept*(ii-1);
                app.CommonData.changingValue = mRatePercept*(ii-1);
                app.morphngRateLabel.Text = ...
                    [num2str(round(mRatePercept*(ii-1))) ' %'];
                SynthesizeButtonPushed(app,[]);
                updateMrateDisplay(app);
                SynthesizeButtonPushed(app,event);
                pause(app.CommonData.signalDuration+0.3);
                saveSignal(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIControl3D = uiaxes(app.UIFigure);
            app.UIControl3D.XTick = [];
            app.UIControl3D.YTick = [];
            app.UIControl3D.ZTick = [];
            app.Load_AButton = uibutton(app.UIFigure, 'push');
            app.Load_AButton.ButtonPushedFcn = createCallbackFcn(app, @Load_AButtonPushed, true);
            app.Load_AButton.Text = 'Load_A';
            app.Load_BButton = uibutton(app.UIFigure, 'push');
            app.Load_BButton.ButtonPushedFcn = createCallbackFcn(app, @Load_BButtonPushed, true);
            app.Load_BButton.Enable = 'off';
            app.Load_BButton.Text = 'Load_B';
            app.FileName_ALabel = uilabel(app.UIFigure);
            app.FileName_ALabel.Text = 'FileName_A';
            app.FileName_BLabel = uilabel(app.UIFigure);
            app.FileName_BLabel.Text = 'FileName_B';
            app.FullPathOf_ALabel = uilabel(app.UIFigure);
            app.FullPathOf_ALabel.Text = 'FullPathOf_A';
            app.FullPathOf_BLabel = uilabel(app.UIFigure);
            app.FullPathOf_BLabel.Text = 'FullPathOf_B';
            app.Play_AButton = uibutton(app.UIFigure, 'push');
            app.Play_AButton.ButtonPushedFcn = createCallbackFcn(app, @Play_AButtonPushed, true);
            app.Play_AButton.Enable = 'off';
            app.Play_AButton.Text = 'Play_A';
            app.Play_BButton = uibutton(app.UIFigure, 'push');
            app.Play_BButton.ButtonPushedFcn = createCallbackFcn(app, @Play_BButtonPushed, true);
            app.Play_BButton.Enable = 'off';
            app.Play_BButton.Text = 'Play_B';
            app.morphingrateSliderLabel = uilabel(app.UIFigure);
            app.morphingrateSliderLabel.Text = 'morphing rate';
            app.morphingrateSlider = uislider(app.UIFigure);
            app.morphingrateSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.morphingrateSlider.MajorTickLabels = {'0', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.morphingrateSlider.ValueChangedFcn = createCallbackFcn(app, @morphingrateSliderValueChanged, true);
            app.morphingrateSlider.ValueChangingFcn = createCallbackFcn(app, @morphingrateSliderValueChanging, true);
            app.morphingrateSlider.MinorTicks = [];
            app.morphingrateSlider.Enable = 'off';
            app.SynthesizeButton = uibutton(app.UIFigure, 'push');
            app.SynthesizeButton.ButtonPushedFcn = createCallbackFcn(app, @SynthesizeButtonPushed, true);
            app.SynthesizeButton.Enable = 'off';
            app.SynthesizeButton.Text = 'Synthesize';
            app.morphngRateLabel = uilabel(app.UIFigure);
            app.morphngRateLabel.Text = '0';
            app.StepsDropDownLabel = uilabel(app.UIFigure);
            app.StepsDropDownLabel.Visible = 'off';
            app.StepsDropDownLabel.Text = 'Steps';
            app.StepsDropDown = uidropdown(app.UIFigure);
            app.StepsDropDown.Items = {'3', '4', '5', '7', '11'};
            app.StepsDropDown.ItemsData = {'3', '4', '5', '7', '11'};
            app.StepsDropDown.Visible = 'off';
            app.StepsDropDown.Value = '5';
            app.PlaycontinuumButton = uibutton(app.UIFigure, 'push');
            app.PlaycontinuumButton.ButtonPushedFcn = createCallbackFcn(app, @PlaycontinuumButtonPushed, true);
            app.PlaycontinuumButton.Visible = 'off';
            app.PlaycontinuumButton.Text = 'Play continuum';
            app.SavecontinuumButton = uibutton(app.UIFigure, 'push');
            app.SavecontinuumButton.ButtonPushedFcn = createCallbackFcn(app, @SavecontinuumButtonPushed, true);
            app.SavecontinuumButton.Visible = 'off';
            app.SavecontinuumButton.Text = 'Save continuum';
            app.SavelastButton = uibutton(app.UIFigure, 'push');
            app.SavelastButton.ButtonPushedFcn = createCallbackFcn(app, @SavelastButtonPushed, true);
            app.SavelastButton.Enable = 'off';
            app.SavelastButton.Visible = 'off';
            app.SavelastButton.Text = 'Save last';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = morphContinuumGen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end