classdef morphingAligner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        SAVEmObjectButton         matlab.ui.control.Button
        PLAYNLxButton             matlab.ui.control.Button
        PLAYCxButton              matlab.ui.control.Button
        mRate_indicator_3         matlab.ui.control.Label
        mRate_indicator_2         matlab.ui.control.Label
        RestoreEDITButton         matlab.ui.control.Button
        mRate_indicator           matlab.ui.control.Label
        VTL_indicator             matlab.ui.control.Label
        VTLSlider                 matlab.ui.control.Slider
        VTLLabel                  matlab.ui.control.Label
        RESETButton               matlab.ui.control.Button
        SynthesisButton           matlab.ui.control.Button
        mRateSlider               matlab.ui.control.Slider
        mRateSliderLabel          matlab.ui.control.Label
        SAVEEDITButton            matlab.ui.control.Button
        READonNonlinearAxButton   matlab.ui.control.Button
        READonCannonicalAxButton  matlab.ui.control.Button
        MorphingalignerLabel      matlab.ui.control.Label
        SCOREAxes                 matlab.ui.control.UIAxes
        RightPanel                matlab.ui.container.Panel
        leftposSlider             matlab.ui.control.Slider
        leftposSliderLabel        matlab.ui.control.Label
        TxmagSlider               matlab.ui.control.Slider
        TxmagSliderLabel          matlab.ui.control.Label
        FreqlimitButtonGroup      matlab.ui.container.ButtonGroup
        kHzButton_5               matlab.ui.control.RadioButton
        kHzButton                 matlab.ui.control.RadioButton
        kHzButton_2               matlab.ui.control.RadioButton
        kHzButton_3               matlab.ui.control.RadioButton
        kHzButton_4               matlab.ui.control.RadioButton
        FullButton                matlab.ui.control.RadioButton
        TimeanchorPanel           matlab.ui.container.Panel
        ClearButton_2             matlab.ui.control.Button
        UndoButton_2              matlab.ui.control.Button
        TFanchorPanel             matlab.ui.container.Panel
        ClearButton               matlab.ui.control.Button
        UndoButton                matlab.ui.control.Button
        mRate_indicator_5         matlab.ui.control.Label
        mRate_indicator_4         matlab.ui.control.Label
        DebugButton               matlab.ui.control.Button
        ViewcontrolButtonGroup    matlab.ui.container.ButtonGroup
        NLTFaxisButton            matlab.ui.control.RadioButton
        VariableButton            matlab.ui.control.RadioButton
        CannonicalButton          matlab.ui.control.RadioButton
        rateSlider                matlab.ui.control.Slider
        rateSliderLabel           matlab.ui.control.Label
        FlushoffButton            matlab.ui.control.Button
        FlushonButton             matlab.ui.control.Button
        AlphaSlider               matlab.ui.control.Slider
        WaveAxesMorph             matlab.ui.control.UIAxes
        SpecAxesMorph             matlab.ui.control.UIAxes
        WaveAxesRef               matlab.ui.control.UIAxes
        SpecAxesRef               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        CommonData % Shared data within this app
    end

    properties (Access = public)
        MyTimer = 0; % Description
    end

    methods (Access = private)

        function morphingStr = makeMorphingStr(app)
            makeMorphingObject(app);
            morphingStr = struct;
            worldPRef = app.CommonData.worldParamsRef;
            worldPTgt = app.CommonData.worldParamsTarget;
            nId = app.CommonData.anchorCount;
            anchStr = struct;
            anchStr.tAnchRef = app.CommonData.handleLocation(1:nId);
            %anchStr.tAnchTgt = ...
            %    app.CommonData.modTFAnchorTgt(1:nId);
            %anchStr.tAnchTgt = ...
            %    app.CommonData.referenceLocation(1:nId);
            anchStr.referenceLocation = ...
                app.CommonData.referenceLocation(1:nId);
            anchStr.tAnchTgt = ...
                app.CommonData.tAnchTgt(1:nId);
            anchStr.tfAnchor = app.CommonData.ftAnchor;
            anchStr.ftAnchorTgt = app.CommonData.ftAnchorTgt;
            anchStr.modTFAnchorTgt = app.CommonData.modTFAnchorTgt;
            anchStr.erbFxTgt = app.CommonData.erbFxTgt;
            morphingStr.creator = "morphingAligner";
            morphingStr.creationDate = datetime;%datestr(now);
            morphingStr.targetFileName = app.CommonData.targetFileName;
            morphingStr.referenceFileName = app.CommonData.referenceFileName;
            morphingStr.worldPRef = worldPRef;
            morphingStr.worldPTgt = worldPTgt;
            morphingStr.anchStr = anchStr;
            morphingStr.testMrate = app.mRateSlider.Value;
            morphingStr.vtl_ratio = app.VTLSlider.Value;
            %morphingStr.timeAnchorReference = app.CommonData.timeAnchorReference;
            %morphingStr.timeAnchorTarget = app.CommonData.timeAnchorTarget;
            morphingStr.timeAnchorReference = sort(anchStr.tAnchRef)';
            morphingStr.timeAnchorTarget = sort(anchStr.tAnchTgt)';
            morphingStr.timeFreqAnchorReference = app.CommonData.timeFreqAnchorReference;
            morphingStr.timeFreqAnchorTarget = app.CommonData.timeFreqAnchorTarget;
        end

        function results = linear2erbSgram(app)
            sgram = app.CommonData.currentSpectrogram;
            fs = app.CommonData.samplingFrequency;
            fx = (0:size(sgram,1)-1)/size(sgram,1)*fs/2;
            erbL = hz2kHzWH(1);
            erbH = hz2kHzWH(fs/2);
            %erbFx = (erbL:0.2:erbH)';
            erbFx = (0:0.001:1)'*(erbH-erbL)+erbL;
            erbFxHz = kHz2hzWH(erbFx);
            sgramErb = interp1(fx,diag(fx)*sgram,kHz2hzWH(erbFx),"linear","extrap");
            sgramErbDb = 10*log10(sgramErb);
            hzTic = [100 300 500 700 1000 2000 4000 8000 10000 20000]';
            yTkckFqErb = hz2kHzWH(hzTic);
            yTickLabel = {'.1', '.3', '.5', '.7', '1', '2', '4', '8', '10', '20'};
            sgramDb = max(-80, sgramErbDb - max(sgramErbDb(:)));
            app.CommonData.erbSgramDb = sgramDb;
            app.CommonData.erbSgramLN = (sgramErb/max(abs(sgramErb(:)))).^(1/3);
            app.CommonData.erbFx = erbFx;
            app.CommonData.sgramGrid = diag(erbFxHz)*(sgramDb*0+1); 
            app.CommonData.sgramTimeGrid = ...
                (sgramDb*0+1)*diag(app.CommonData.currentSpectrogramTime); 
            app.CommonData.erbFxHz = erbFxHz;
            app.CommonData.erbyTickLoc = yTkckFqErb;
            app.CommonData.erbyTickLabel = yTickLabel;
            app.CommonData.erbyLim = [erbFx(1) erbFx(end)];
            results = 1;
        end

        function results = morphingAndSynthesize(app)
            worldPRef = app.CommonData.worldParamsRef;
            worldPTgt = app.CommonData.worldParamsTarget;
            morphingStr = makeMorphingStr(app);
            anchStr = struct;
            anchStr.timeAnchorReference = morphingStr.timeAnchorReference;
            anchStr.timeAnchorTarget = morphingStr.timeAnchorTarget;
            anchStr.timeFreqAnchorReference = morphingStr.timeFreqAnchorReference;
            anchStr.timeFreqAnchorTarget = morphingStr.timeFreqAnchorTarget;
            mRate = app.CommonData.mRate;
            vtl_rtio = app.VTLSlider.Value;
            morphStr = wordTV2WmorphingEngineRev(worldPRef, worldPTgt, anchStr, ...
                mRate, vtl_rtio, 0);
            xSynth = Synthesis(morphStr.source_parameter, ...
                morphStr.spectrum_parameter);
            soundsc(xSynth,worldPRef.samplingFrequency);
            %---- wave 
            if isfield(app.CommonData, "mhwHandle")
                delete(app.CommonData.mhwHandle);
            end
            fs = worldPRef.samplingFrequency;
            ttMorph = (0:length(xSynth)-1)/fs;
            axes(app.WaveAxesMorph)
            axis(app.WaveAxesMorph,[0 ttMorph(end) -1 1]);
            xlabel(app.WaveAxesMorph,'time (s)');
            ylabel(app.WaveAxesMorph,'level (rel)');
            grid(app.WaveAxesMorph,"on");
            hold(app.WaveAxesMorph,"on");
            app.CommonData.mhwHandle = plot(app.WaveAxesMorph,ttMorph,xSynth/max(abs(xSynth)), ...
                "k", "linewidth",3,"HitTest","off");
            hold(app.WaveAxesMorph,"off");
            drawnow
            %--- sgram
            if isfield(app.CommonData, "mhsHandle")
                delete(app.CommonData.mhsHandle);
            end
            app.CommonData.currentSpectrogram = ...
                morphStr.spectrum_parameter.spectrogram;
            app.CommonData.currentSpectrogramTime = ...
                morphStr.spectrum_parameter.temporal_positions;
            linear2erbSgramSynth(app);
            erbSgramDbMorph = app.CommonData.erbSgramDb;
            tFrame = morphStr.source_parameter.temporal_positions;
            axes(app.SpecAxesMorph)
            axis(app.SpecAxesMorph,'xy');
            %axis(app.SpecAxesMorph,[tFrame([1 end]) app.CommonData.erbyLim]);
            yLimRef = get(app.SpecAxesRef,"Ylim");
            set(app.SpecAxesMorph,"XLim",tFrame([1 end]));
            set(app.SpecAxesMorph,"YLim",yLimRef);
            %set(app.SpecAxesMorph,"YTick",app.CommonData.erbyTickLoc, ...
            %    "YTickLabel",app.CommonData.erbyTickLabel);
            hold(app.SpecAxesMorph,"on");
            app.CommonData.mhsHandle = imagesc(app.SpecAxesMorph,tFrame([1 end]),app.CommonData.erbyLim, ...
                erbSgramDbMorph);
            hold(app.SpecAxesMorph,"off");
            drawnow
            app.WaveAxesMorph.Visible = "on";
            app.SpecAxesMorph.Visible = "on";
            set(app.WaveAxesRef, "XColor", [1 1 1]);
            set(app.SpecAxesRef, "XColor", [1 1 1]);
            results = 1;
        end

        function results = updateSgramDisplay(app)
            results = 1;
            app.VTL_indicator.Text = num2str(app.CommonData.vtl_ratio,3);
            nId = app.CommonData.anchorCount;
            xx = app.CommonData.xx(1,:);
            [sortedHandLoc, sortIdx] = sort(app.CommonData.handleLocation(1:nId));
            sortedReferenceLoc = sort(app.CommonData.referenceLocation(1:nId));
            xdataOrg = app.CommonData.timeOnTgt/app.CommonData.timeOnTgt(end)*xx(end);
            xdata = app.CommonData.timeOnRef/app.CommonData.timeOnRef(end) ...
                *xx(end);
            xModSgram = interp1([xx(1), sortedHandLoc, xx(end)],[xx(1) ...
                sortedReferenceLoc xx(end)], xdata,"linear","extrap");
            yy = app.CommonData.erbFx;
            nErbBins = length(yy);
            idx = app.CommonData.currentTFidx;
            nfC = sum(app.CommonData.ftAnchorTgt(:,idx) > 0);
            erbFxTgt =app.CommonData.erbFxTgt(:, idx);
            if nfC > 0
                fAncTgt = app.CommonData.ftAnchorTgt(1:nfC,idx);
                fAnc = app.CommonData.ftAnchor(1:nfC,idx);
                yyTgtOnRef = interp1([0 fAncTgt' yy(end)], [0 fAnc' yy(end)], ...
                    yy,"linear","extrap");
                if app.CommonData.FTinitialize == 0
                    app.CommonData.erbFxTgt(:, idx) = yyTgtOnRef;
                else
                    yOnTgt = interp1(yy,erbFxTgt,app.CommonData.ftAnchorTgt(nfC,idx),"linear","extrap");
                    fAnc(nfC) = yOnTgt;
                    app.CommonData.ftAnchor(nfC,idx) = yOnTgt;
                    yyTgtOnRef = interp1([0 fAncTgt' yy(end)], [0 fAnc' yy(end)], ...
                    yy,"linear","extrap");
                    app.CommonData.erbFxTgt(:, idx) = yyTgtOnRef;
                end
            else
                app.CommonData.erbFxTgt(:, idx) = yy;
            end
            xxSg = ones(nErbBins,length(xModSgram))*diag(xModSgram);
            vtl_ratio = app.CommonData.vtl_ratio;
            yySg = interp2([xx(1) sortedReferenceLoc xx(end)], hz2kHzWH(kHz2hzWH(yy)*vtl_ratio), ...
                hz2kHzWH(kHz2hzWH([yy app.CommonData.erbFxTgt(:, sortIdx(1:nId)) yy])), ...
                xModSgram, yy,"linear");
            modSgram = interp2(xdataOrg,app.CommonData.erbFx,...
                app.CommonData.erbSgramDbTgt,xxSg,yySg,"linear");
            modSgramGrid = interp2(xdataOrg,app.CommonData.erbFx,...
                app.CommonData.sgramGrid,xxSg,yySg,"linear");
            modSgramTime = interp2(xdataOrg,app.CommonData.erbFx,...
                app.CommonData.sgramTimeGrid,xxSg,yySg,"linear");
            set(app.CommonData.tgtSgramHandle,"CData",modSgram, ...
                "AlphaData",app.CommonData.currentAlpha);
            modTFAnchorTgt = app.CommonData.ftAnchorTgt * 0;
            for ii = 1:nId
                nfC = sum(app.CommonData.ftAnchor(:, ii) > 0);
                [~, xInd] = min(abs(modSgramTime(2,:) - app.CommonData.referenceLocation(ii)));
                if nfC > 0
                    for jj = 1:nfC
                    [~, yInd] = min(abs(yy-app.CommonData.ftAnchor(jj,ii)));
                    modTFAnchorTgt(jj,ii) = modSgramGrid(yInd, xInd);
                    end
                end
            end
            
            %-------
            for id = 1:nId
                nfC = sum(app.CommonData.ftAnchor(:, id) > 0);
                if nfC > 0
                    for jj = 1:nfC
                        yy = app.CommonData.ftAnchorTgt(jj,id);
                        yyMod = hz2kHzWH(kHz2hzWH(yy)*vtl_ratio);
                        set(app.CommonData.anchBase.handleSet(id).freqHandle(jj), ...
                            "xdata",app.CommonData.handleLocation(id), ...
                            "ydata", yyMod);
                        [~, yInd] = min(abs(yy-yyMod));
                        modTFAnchorTgt(jj,ii) = modSgramGrid(yInd, xInd);
                    end
                end
            end
            app.CommonData.modTFAnchorTgt = modTFAnchorTgt;
            app.CommonData.modSgramGrid = modSgramGrid;
            app.CommonData.modSgramTimeGrid = modSgramTime;
            %-------
            erbSgramNLTgt = 10.0.^((modSgram - max(modSgram(:)))/30);
            erbSgramLNRef = app.CommonData.erbSgramLNRef;
            tmpp = erbSgramNLTgt(:)-erbSgramLNRef(:);
            tmpp(isnan(tmpp)) = 0;
            diffDb = 20*log10(std(tmpp));
            app.CommonData.differenceHistory = ...
                [app.CommonData.differenceHistory(2:end);diffDb];
            set(app.CommonData.ScoreHandle, "YData",app.CommonData.differenceHistory)
            drawnow %limitrate;
        end

        function results = updateWaveDisplay(app)
            results = 1;
            nId = app.CommonData.anchorCount;
            xx = app.CommonData.xx(1,:);
            sortedHandLoc = sort(app.CommonData.handleLocation(1:nId));
            sortedReferenceLoc = sort(app.CommonData.referenceLocation(1:nId));
            xMod = interp1([xx(1), sortedHandLoc, xx(end)],[xx(1) ...
                sortedReferenceLoc xx(end)], xx,"linear","extrap");
            %yData = get(app.CommonData.targetWaveRHandle,"YData");
            %xxi = (1:length(yData))/length(yData)*xx(end);
            %xModi = interp1([xx(1) sortedReferenceLoc xx(end)], ...
            %    [xx(1), sortedHandLoc, xx(end)], xxi,"linear","extrap");
            app.CommonData.lastTimeOnTarget = xMod;
            originalTxRef = app.CommonData.originalTxRef;
            tgtTimeAxis = app.CommonData.tgtTimeAxis;
            sortedTAnchorTgt = sort(app.CommonData.tAnchTgt(1:nId));
            targetTimeOnRefAxis = interp1([0 sortedHandLoc originalTxRef(end)], ...
                [0 sortedTAnchorTgt tgtTimeAxis(end)],originalTxRef,"linear","extrap");
            app.CommonData.targetTimeOnRefAxis = targetTimeOnRefAxis;
            xModi = interp1(targetTimeOnRefAxis,originalTxRef,tgtTimeAxis,"linear","extrap");
            set(app.CommonData.targetWaveRHandle,"XData",xModi);
        end

        function results = setupReferenceData(app)
            results = 1;
            tmp = app.CommonData.tmp;
            app.CommonData.referenceFileName = tmp.paramFile;
            app.CommonData.worldParamsRef = tmp.worldParameter;
            %vuv = tmp.worldParameter.source_parameter.vuv;
            app.CommonData.samplingFrequency = tmp.worldParameter.samplingFrequency;
            app.CommonData.xRef = tmp.worldParameter.signal;
            x = app.CommonData.xRef;
            fs = tmp.worldParameter.samplingFrequency;
            ttRef = (1:length(x))'/fs;
            tFrame = tmp.worldParameter.source_parameter.temporal_positions;
            app.CommonData.timeOnRef = tFrame;
            app.CommonData.originalTxRef = ttRef;
            %onLocations = tFrame(vuv > vuv([1 1:end-1]));
            %offLocations = tFrame(vuv > vuv([2:end end]));
            x = x/max(abs(x));
            app.CommonData.refWaveHandle = plot(app.WaveAxesRef,ttRef,x, ...
                "linewidth",3,"HitTest","off","Color",[0 0 0 1]);
            %xline(app.WaveAxesRef,onLocations,"LineWidth",4,"Color",[0 1 0], ...
            %    "HitTest","off");
            %xline(app.WaveAxesRef,offLocations,"LineWidth",4,"Color",[1 0 0], ...
            %    "HitTest","off");
            app.CommonData.refTextHandle = ...
                text(app.WaveAxesRef, 0, 0.8, " " + app.CommonData.referenceFileName, ...
                "color", 'black', 'FontSize', 20);
            axis(app.WaveAxesRef,[0 ttRef(end) -1 1]);
            xlabel(app.WaveAxesRef,'time (s)');
            ylabel(app.WaveAxesRef,'level (rel)');
            grid(app.WaveAxesRef,"on");
            set(app.WaveAxesRef,"YTick",[0 1],"YTickLabel",{'0','1'})
            %------
            app.CommonData.currentSpectrogram = ...
                tmp.worldParameter.spectrum_parameter.spectrogram;
            app.CommonData.currentSpectrogramTime = ...
                tmp.worldParameter.spectrum_parameter.temporal_positions;
            linear2erbSgram(app);
            erbSgramDbRef = app.CommonData.erbSgramDb;
            app.CommonData.erbSgramDbRef = erbSgramDbRef;
            app.CommonData.erbSgramLNRef = app.CommonData.erbSgramLN;
            app.CommonData.refSgramHandle = ...
                imagesc(app.SpecAxesRef,tFrame([1 end]),app.CommonData.erbyLim, ...
                erbSgramDbRef);
            axis(app.SpecAxesRef,'xy');
            axis(app.SpecAxesRef,[tFrame([1 end]) 0 6]);
            %set(app.SpecAxesRef,"YTick",app.CommonData.erbyTickLoc, ...
            %    "YTickLabel",app.CommonData.erbyTickLabel);
            axis(app.SpecAxesMorph,[tFrame([1 end]) 0 6]);
            app.PLAYCxButton.Enable = "on";
            linkaxes([app.WaveAxesRef,app.WaveAxesRef,app.SpecAxesMorph, app.SpecAxesRef],'x');
        end
        
        function results = setupTargetData(app)
            results = 1;
            tmp = app.CommonData.tmp;
            app.CommonData.targetFileName = tmp.paramFile;
            app.CommonData.worldParamsTarget = tmp.worldParameter;
            app.CommonData.xTarget = tmp.worldParameter.signal;
            x = app.CommonData.xTarget;
            x = -x/max(abs(x));
            fs = tmp.worldParameter.samplingFrequency;
            ttTarget = (1:length(x))'/fs;
            %--- paint target waveform
            nXbins = 1000;
            xlimT = get(app.SpecAxesRef,"xlim");
            ttTarget = ttTarget/ttTarget(end)*xlimT(2);
            xxBase = (0:nXbins-1)/(nXbins-1)*ttTarget(end);
            yyBase = (-1:0.02:1);
            [xx, yy] = meshgrid(xxBase, yyBase);
            app.CommonData.xx = xx;
            app.CommonData.yy = yy;
            %cc = zeros(length(yyBase),length(xxBase),3);
            %cc(:,:,2) = 0.7;
            xtmp = round(max(1, min(length(yyBase) -2 ,(x+1)/2 * length(yyBase))));
            baseData = zeros(length(yyBase),length(xxBase));
            for ii = 1:length(x)-1
                xIdx = round(max(1, min(length(xxBase), [0 1]+ii/length(x)*length(xxBase))));
                if xtmp(ii+1) > xtmp(ii)
                    for jj = xtmp(ii):xtmp(ii+1)
                        baseData(jj+[0 1],xIdx) = 1;
                    end
                else
                    for jj = xtmp(ii+1):xtmp(ii)
                        baseData(jj+[0 1],xIdx) = 1;
                    end
                end
            end
            vuv = tmp.worldParameter.source_parameter.vuv;
            tFrame = tmp.worldParameter.source_parameter.temporal_positions;
            app.CommonData.timeOnTgt = tFrame;
            onLocations = tFrame(vuv > vuv([1 1:end-1]))/tFrame(end)*xlimT(2);
            offLocations = tFrame(vuv > vuv([2:end end]))/tFrame(end)*xlimT(2);
            for ii = 1:length(onLocations)
                [~, idxx] = min(abs(onLocations(ii) - xxBase));
                tmpIdx = max(1, min(nXbins, idxx + (-1:1)));
                baseData(:,tmpIdx) = 1;
            end
            for ii = 1:length(offLocations)
                [~, idxx] = min(abs(offLocations(ii) - xxBase));
                tmpIdx = max(1, min(nXbins, idxx + (-1:1)));
                baseData(:,tmpIdx) = 1;
            end
            hold(app.WaveAxesRef,"on");
            app.CommonData.baseData = baseData;
            xlimT = get(app.SpecAxesRef,"xlim");
            xNormal = app.CommonData.xTarget/max(abs(app.CommonData.xTarget));
            refTimeOnTgtAxis = ttTarget;
            app.CommonData.tgtTimeAxis = (1:length(x))'/fs;
            app.CommonData.refTimeOnTgtAxis = refTimeOnTgtAxis;
            app.CommonData.targetTimeOnRefAxis = ...
                app.CommonData.originalTxRef/app.CommonData.originalTxRef(end) ...
                *app.CommonData.tgtTimeAxis(end);
            app.CommonData.targetWaveRHandle = ...
                plot(app.WaveAxesRef,refTimeOnTgtAxis,xNormal,"HitTest","off","Color", ...
                [0 0.7 0 1],"LineWidth",2);
            app.CommonData.tgtTextHandle = ...
                text(app.WaveAxesRef, 0, -0.8, " " + app.CommonData.targetFileName, ...
                "color", '#77AC30','FontSize',20);
            axis(app.WaveAxesRef,[0 xlimT(end) -1 1]);
            app.CommonData.lastTimeOnTarget = (0:nXbins-1)/(nXbins-1)*app.CommonData.timeOnRef(end);
            xlabel(app.WaveAxesRef,'time (s)');
            ylabel(app.WaveAxesRef,'level (rel)');
            set(app.WaveAxesRef,"xlim",xlimT);%[ttTarget(1) ttTarget(end)])
            app.WaveAxesRef.Visible = "on";
            app.READonNonlinearAxButton.Enable = "off";
            %--- prepare time handle ----
            nHandle = app.CommonData.maxTimeAnchor;
            app.CommonData.referenceLocation = zeros(1, nHandle);
            app.CommonData.tAnchTgt = zeros(1, nHandle); % This is for waveform anchor
            app.CommonData.handleLocation = zeros(1, nHandle);
            handlePosition = (1:nHandle)'/(nHandle+1)*ttTarget(end);
            app.CommonData.timeHandleWave = xline(app.WaveAxesRef, handlePosition, ...
                "LineWidth",2,"Color",[1 1 1] * 0.2, "HitTest","on", ...
                        "ButtonDownFcn", @app.pickTimeWaveCursor);
            set(app.CommonData.timeHandleWave, "Visible","off");
            %----- prepare time-frequency handle
            app.CommonData.timeHandleSpec = xline(app.SpecAxesRef, handlePosition, ...
                "LineWidth",2,"Color",[1 1 1],"HitTest","on", ...
                "ButtonDownFcn", @app.pickTimeSpecCursor);
            %-----
            hold(app.SpecAxesRef,"on");
            xlim = get(app.SpecAxesRef,"xlim");
            app.CommonData.currentSpectrogram = ...
                tmp.worldParameter.spectrum_parameter.spectrogram;
            app.CommonData.currentSpectrogramTime = ...
                tmp.worldParameter.spectrum_parameter.temporal_positions;
            linear2erbSgram(app);
            erbSgramDbTgt = app.CommonData.erbSgramDb;
            app.CommonData.erbSgramDbTgt = erbSgramDbTgt;
            app.CommonData.tgtSgramHandle = ...
                imagesc(app.SpecAxesRef,xlim,app.CommonData.erbyLim, ...
                erbSgramDbTgt);
            ftAnchor = zeros(app.CommonData.maxFreqAnchor, nHandle);
            ftAnchorTgt = zeros(app.CommonData.maxFreqAnchor, nHandle);
            app.CommonData.erbFxTgt = zeros(length(app.CommonData.erbFx), nHandle);
            anchBase = struct;
            for ii = 1:nHandle
                app.CommonData.erbFxTgt(:, ii) = app.CommonData.erbFx;
                for jj = 1:app.CommonData.maxFreqAnchor
                    anchBase.handleSet(ii).freqHandle(jj) = ...
                        plot(app.SpecAxesRef, handlePosition(ii), hz2kHzWH(ftAnchor(jj,ii)), ...
                        'o',"LineWidth",3,"Color",[1 1 1],"Visible","off","HitTest","off");%, ...
                end
            end
            drawnow
            set(app.CommonData.timeHandleSpec, "Visible","off","HitTest","off");
            app.CommonData.anchBase = anchBase;
            app.CommonData.ftAnchor = ftAnchor;
            app.CommonData.ftAnchorTgt = ftAnchorTgt;
            axis(app.SpecAxesRef,'xy');
            hold(app.SpecAxesRef,"off");
            app.CommonData.axisOnTarget = struct;
            %----- for morphing object prepare for alignment with
            %cannonical sample
            app.CommonData.nltfAxis = "new";
            if isfield(tmp, "timeAnchor")
                app.CommonData.nltfAxis = "given";
                nTimeAnchor = length(tmp.timeAnchor);
                timeFreqAnchor = hz2kHzWH(tmp.timeFreqAnchor);
                app.CommonData.ftAnchor = timeFreqAnchor;
                app.CommonData.ftAnchorTgt = timeFreqAnchor;
                %disp(nTimeAnchor);
                app.CommonData.totalTFanchors = 0;
                for id = 1:nTimeAnchor
                    currentPointTmp = tmp.timeAnchor(id)/app.CommonData.tgtTimeAxis(end) ...
                            * app.CommonData.originalTxRef(end);
                    if id < 2
                        app.CommonData.referenceLocation(id) = currentPointTmp;
                        [~, idx] = min(abs(currentPointTmp - app.CommonData.xx(1,:)));
                        app.CommonData.referenceIdx = idx;
                        [~,idTxRef] = min(abs(currentPointTmp - app.CommonData.originalTxRef));
                        app.CommonData.tAnchTgt(id) = app.CommonData.targetTimeOnRefAxis(idTxRef);
                    else
                        %[~, idx] = min(abs(currentPointTmp - app.CommonData.xx(1,:)));
                        %timeOnTarget = app.CommonData.axisOnTarget;
                        app.CommonData.referenceLocation(id) = currentPointTmp;%timeOnTarget(idx);
                        [~, idTxRef] = min(abs(currentPointTmp - app.CommonData.originalTxRef));
                        app.CommonData.tAnchTgt(id) = app.CommonData.targetTimeOnRefAxis(idTxRef);
                    end
                    set(app.UIFigure,"pointer","left");
                    %assignWaveMarker(app);
                    %nId = id;%app.CommonData.anchorCount;
                    set(app.CommonData.timeHandleWave(id),"Visible","on", ...
                    "Value", currentPointTmp,"HitTest","on", ...
                        "ButtonDownFcn", @app.pickTimeWaveCursor, "userdata", id);
                set(app.CommonData.timeHandleSpec(id),"Visible","on", ...
                    "Value", currentPointTmp, "userdata", id, "HitTest", "on");
                app.CommonData.handleLocation(id) = currentPointTmp;
                %sortedHandLoc = sort(app.CommonData.handleLocation(1:nId));
                %sortedReferenceLoc = sort(app.CommonData.referenceLocation(1:nId));
                %sortedTgtLoc = sort(app.CommonData.tAnchTgt(1:nId));
                %if (nId > 1 && (sum(diff(sortedHandLoc) == 0) + ...
                %        sum(diff(sortedReferenceLoc) == 0)) == 0) ...
                %        || nId == 1
                app.CommonData.anchorCount = id;
                    updateWaveDisplay(app);
                    %----- sgram
                    nfC = sum(timeFreqAnchor(:, id) > 0);
                    if nfC > 0
                    for jj = 1:nfC
                        app.CommonData.ftAnchor(jj,id) = timeFreqAnchor(jj, id);
                        set(app.CommonData.anchBase.handleSet(id).freqHandle(jj), ...
                            "xdata",app.CommonData.handleLocation(id), ...
                            "ydata", app.CommonData.ftAnchor(jj,id), ...
                            "Visible", "on");
                        app.CommonData.totalTFanchors = app.CommonData.totalTFanchors + 1;
                        nTFcount = app.CommonData.totalTFanchors;
                        ftAnchorV = app.CommonData.ftAnchor(nfC, id);
                        ftAnchorTgtV = app.CommonData.ftAnchorTgt(nfC, id);
                        app.CommonData.TFimeAnchorHistory(nTFcount,:) = ...
                            [id, jj, ftAnchorV,  ftAnchorTgtV, app.CommonData.vtl_ratio];
                        app.CommonData.FTinitialize = 0;
                        updateSgramDisplay(app);
                        app.CommonData.FTinitialize = 0;
                    end
                    end
                    %if 
                    %app.CommonData.vtl_ratio = app.VTLSlider.Value;
                    %updateSgramDisplay(app);
                %end
                    %updateSgramDisplay(app);
                    drawnow
                    app.CommonData.currentId = id;
                    app.CommonData.currentTFidx = id;
                    app.ClearButton_2.Enable = "on";
                    app.UndoButton_2.Enable = "on";
                    app.ClearButton.Enable = "on";
                    app.UndoButton.Enable = "on";
                end
            end
            app.CommonData.pen = "up";
            set(app.UIFigure,"pointer","arrow");
            %-----
            drawnow;
            %axisOnTarget = struct;
            app.PLAYNLxButton.Enable = "on";
            %app.CommonData.axisOnTarget = axisOnTarget;
            app.FlushonButton.Enable = "on";
            app.SynthesisButton.Enable = "on";
            app.RESETButton.Enable = "on";
            hold(app.WaveAxesRef,"off");        
        end

        function results = pickTimeWaveCursor(app,src,~)
            results = 1;
            app.CommonData.pen = "pick";
            idx = get(src, "userdata");
            app.CommonData.currentId = idx;
            set(app.UIFigure,"pointer","left")
            set(app.CommonData.timeHandleWave(idx),"Visible","on", ...
                "color", [0.7 0 0.7],"linewidth",4);
            set(app.CommonData.timeHandleSpec(idx),"Visible","on", ...
                "color", [1 1 0.5],"linewidth",4);
        end

        function results = pickTimeSpecCursor(app,src,~)
            results = 1;
            currentSpecLoc = get(app.SpecAxesRef, "CurrentPoint");
            idx = get(src, "userdata");
            nfC = sum(app.CommonData.ftAnchorTgt(:, idx) > 0);
            minFreqDist = 100;
            if nfC > 0
                yy = app.CommonData.ftAnchorTgt(1:nfC,idx);
                yyMod = hz2kHzWH(kHz2hzWH(yy)*app.CommonData.vtl_ratio);
                [minFreqDist, idF] = min(abs(currentSpecLoc(1,2)-yyMod));
            end
            if minFreqDist > 0.06%10%0.8
                nfC = nfC + 1;
                app.UndoButton.Enable = "on";
                app.ClearButton.Enable = "on";
                app.CommonData.totalTFanchors = app.CommonData.totalTFanchors + 1;
                nTFcount = app.CommonData.totalTFanchors;
                app.CommonData.ftAnchor(nfC, idx) = currentSpecLoc(1,2);
                app.CommonData.TFimeAnchorHistory(nTFcount, 1:3) = ...
                    [idx, nfC, currentSpecLoc(1,2)];
                app.CommonData.ftAnchorTgt(nfC, idx) = ...
                    hz2kHzWH(kHz2hzWH(currentSpecLoc(1,2))/app.VTLSlider.Value);
                xloc = get(app.CommonData.timeHandleSpec(idx),"Value");
                yDisp = app.CommonData.ftAnchorTgt(nfC, idx);%currentSpecLoc(1,2)
                set(app.CommonData.anchBase.handleSet(idx).freqHandle(nfC), "Visible", "on", ...
                    "markersize", 24,"xdata",xloc, "ydata", yDisp, "HitTest","off", ...
                    "userdata", [idx, nfC]);%, ...
                app.CommonData.currentTFidx = idx;
                app.CommonData.currentTFnfc = nfC;
                app.CommonData.currentId = idx;
                app.CommonData.FTinitialize = 1;
                updateSgramDisplay(app);
                app.CommonData.FTinitialize = 0;
            else
                set(app.CommonData.anchBase.handleSet(idx).freqHandle(idF), "Visible", "on", ...
                    "markersize", 24,"marker","x", "HitTest","off");
                app.CommonData.currentTFidx = idx;
                app.CommonData.currentTFnfc = idF;
                app.CommonData.currentId = idx;
            end
            set(app.CommonData.timeHandleWave(idx),"Visible","on", ...
                "color", [0.7 0 0.7],"linewidth",4);
            set(app.CommonData.timeHandleSpec(idx),"Visible","on", ...
                "color", [1 1 0.5],"linewidth",4);
            set(app.UIFigure, "Pointer","top");
            app.CommonData.pen = "pickTFmk";
        end
        
        function results = pickTFmarker(app,src,~)
            results = 1;
            app.CommonData.pen = "pickTFmk";
            set(app.UIFigure, "Pointer","circle");
            [idx, nfC] = get(src, "userdata");
            app.CommonData.currentTFidx = idx;
            app.CommonData.currentTFnfc = nfC;
            disp(['TFpick ' num2str([idx nfC])])
        end
        
        
        function results = makeMorphingObject(app)
           results = 1;
           nId = app.CommonData.anchorCount;
           app.CommonData.timeAnchorReference = zeros(nId, 1);
           app.CommonData.timeAnchorTarget = zeros(nId, 1);
           app.CommonData.timeFreqAnchorReference = ...
               zeros(app.CommonData.maxFreqAnchor,nId);
           app.CommonData.timeFreqAnchorTarget = ...
               zeros(app.CommonData.maxFreqAnchor,nId);
           [~, sortIdx] = sort(app.CommonData.handleLocation(1:nId));
           erbFx = app.CommonData.erbFx;
           tFrameRef = app.CommonData.worldParamsRef.spectrum_parameter.temporal_positions;
           for ii = 1:nId
               id = sortIdx(ii);
               currentTimeOnRef = app.CommonData.handleLocation(id);
               app.CommonData.timeAnchorReference(ii) = currentTimeOnRef;
               [~, idxx] = min(abs(currentTimeOnRef - tFrameRef));
               modSgramTimeTgt = app.CommonData.modSgramTimeGrid(50,idxx);
               app.CommonData.timeAnchorTarget(ii) = modSgramTimeTgt;
               nfC = sum(app.CommonData.ftAnchor(:, id) > 0);
               for jj = 1:nfC
                   yData = app.CommonData.anchBase.handleSet(id).freqHandle(jj).YData;
                   app.CommonData.timeFreqAnchorReference(jj, ii) = ...
                       kHz2hzWH(yData);
                   [~, iiErb] = min(abs(erbFx - yData));
                   app.CommonData.timeFreqAnchorTarget(jj, ii) = ...
                       app.CommonData.modSgramGrid(iiErb,idxx);
               end
           end
           %app.CommonData.timeAnchorTarget(1:nId) = sort(app.CommonData.referenceLocation(1:nId))';
        end
        
        function results = linear2erbSgramSynth(app)
            sgram = app.CommonData.currentSpectrogram;
            fs = app.CommonData.samplingFrequency;
            fx = (0:size(sgram,1)-1)/size(sgram,1)*fs/2;
            erbL = hz2kHzWH(1);
            erbH = hz2kHzWH(fs/2);
            %erbFx = (erbL:0.2:erbH)';
            %erbFx = (erbL:0.2:erbH)';
            erbFx = (0:0.001:1)'*(erbH-erbL)+erbL;
            sgramErb = interp1(fx,diag(fx)*sgram,kHz2hzWH(erbFx),"linear","extrap");
            sgramErbDb = 10*log10(sgramErb);
            sgramDb = max(-80, sgramErbDb - max(sgramErbDb(:)));
            app.CommonData.erbSgramDb = sgramDb;
            results = 1;
        end
        
        function results = initializeGUIposition(app)
            results = 1;
            rootInfo = groot;
            screenSize = get(rootInfo, "ScreenSize");
            worldManipulatorBaseFile = "morphingAlignerBase.txt";
            checkPath = string(app.CommonData.appPath) + "/" + worldManipulatorBaseFile;
            if exist(checkPath,"file")
                fid = fopen(checkPath,"r");
                tline = fgetl(fid);
                while tline ~= -1
                    ss = strsplit(tline);
                    if "Position" == string(ss{1})
                        if length(ss) == 5
                            xBase = str2double(ss{2});
                            yBase = str2double(ss{3});
                            width = str2double(ss{4});
                            hight = str2double(ss{5});
                        end
                    end
                    tline = fgetl(fid);
                end
                fclose(fid);
                if ~exist("xBase","var")
                width = max(1021, screenSize(3) - 101);
                hight = max(604, screenSize(4) - 2 - 30);
                xBase = 100;
                yBase = 1;
                end
            else
                width = max(1021, screenSize(3) - 101);
                hight = max(604, screenSize(4) - 2 - 30);
                xBase = 100;
                yBase = 1;
            end
            set(app.UIFigure, "Position", [xBase yBase width hight]);
            app.CommonData.figurePosition = [xBase yBase width hight];
        end
        
        function results = saveBaseFile(app)
            results = 1;
            worldManipulatorBaseFile = "morphingAlignerBase.txt";
            checkPath = string(app.CommonData.appPath) + "/" + worldManipulatorBaseFile;
            fid = fopen(checkPath,"w");
            position = get(app.UIFigure, 'Position');
            outText = ['Position ' num2str(position)];
            fprintf(fid, "%s\n", outText);
            fclose(fid);
        end
        
        function results = assignWaveMarker(app)
            results = 1;
            currentLoc = get(app.WaveAxesRef, "CurrentPoint");
            nId = app.CommonData.anchorCount;
            xlimit = get(app.WaveAxesRef,"xlim");
            if (abs(currentLoc(1,2))-1) < 0 && ...
                    (currentLoc(1,1)-xlimit(1)) * (currentLoc(1,1) - xlimit(2)) < 0
                set(app.CommonData.timeHandleWave(nId),"Visible","on", ...
                    "Value", currentLoc(1,1), "color", [0.7 0 0.7],"linewidth",4);
                set(app.CommonData.timeHandleSpec(nId),"Visible","on", ...
                    "Value", currentLoc(1,1), "color", [1 1 0.5],"linewidth",4);
                app.CommonData.handleLocation(nId) = currentLoc(1,1);
                sortedHandLoc = sort(app.CommonData.handleLocation(1:nId));
                sortedReferenceLoc = sort(app.CommonData.referenceLocation(1:nId));
                %sortedTgtLoc = sort(app.CommonData.tAnchTgt(1:nId));
                if (nId > 1 && (sum(diff(sortedHandLoc) == 0) + ...
                        sum(diff(sortedReferenceLoc) == 0)) == 0) ...
                        || nId == 1
                    updateWaveDisplay(app);
                    %----- sgram
                    %app.CommonData.vtl_ratio = app.VTLSlider.Value;
                    %updateSgramDisplay(app);
                end
            end
        end
    end

    methods (Access = public)

        function TimerCallback(app,~,~)
            app.CommonData.timeCount = app.CommonData.timeCount ...
                + app.rateSlider.Value;
            alphV = 0.5 + 0.499 * ...
                atan(20*sin(2*pi*app.CommonData.timerPeriod*app.CommonData.timeCount))/pi*2;
            app.AlphaSlider.Value = alphV;
            app.CommonData.currentAlpha = alphV;
            set(app.CommonData.tgtSgramHandle,"AlphaData",alphV);
            set(app.CommonData.refWaveHandle,"Color",[0 0 0 1-alphV]);
            set(app.CommonData.targetWaveRHandle,"Color",[0 0.7 0 alphV]);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CommonData.appfullpath = mfilename("fullpath");
            [appPath, ~, ~] = fileparts(app.CommonData.appfullpath);
            app.CommonData.appPath = appPath;
            rootInfo = groot;
            screenSize = get(rootInfo, "ScreenSize");
            width = min(1021, screenSize(3) - 21);
            hight = min(604, screenSize(4) - 2 - 30);
            set(app.UIFigure, "Position", [20 1 width hight]);
            %initializeGUIposition(app);
            app.READonNonlinearAxButton.Enable = "off";
            app.SynthesisButton.Enable = "off";
            app.WaveAxesMorph.Visible = "off";
            app.SpecAxesMorph.Visible = "off";
            app.UndoButton_2.Enable = "off";
            app.ClearButton_2.Enable = "off";
            app.UndoButton.Enable = "off";
            app.ClearButton.Enable = "off";
            app.CommonData.FTinitialize = 0;
            app.CommonData.maxTimeAnchor = 100;
            app.CommonData.maxFreqAnchor = 7;
            app.CommonData.timeAnchorHistory = zeros(app.CommonData.maxTimeAnchor,1);
            app.CommonData.TFimeAnchorHistory = ...
                zeros(app.CommonData.maxTimeAnchor*app.CommonData.maxFreqAnchor,5);
            app.CommonData.totalTFanchors = 0;
            disableDefaultInteractivity(app.WaveAxesMorph);
            disableDefaultInteractivity(app.SpecAxesMorph);
            app.VariableButton.Value = 1;
            app.CommonData.currentAlpha = 0.5;
            app.CommonData.vtl_ratio = 1;
            app.CommonData.timeCount = 0;
            app.CommonData.timeProxyMargin = 0.015; % 15 ms
            app.CommonData.pen = "up";
            app.CommonData.anchorCount = 0;
            app.CommonData.currentTFidx = 0;
            app.CommonData.currentTFnfc = 0;
            app.CommonData.differenceHistory = zeros(100,1) - 20;
            app.CommonData.ScoreHandle = ...
                plot(app.SCOREAxes, app.CommonData.differenceHistory,"LineWidth",2);
            grid(app.SCOREAxes, "on");
            axis(app.SCOREAxes, [1 100 -40 -16])
            set(app.SpecAxesRef,"YLim",[0 6]);
            set(app.SpecAxesMorph,"YLim",[0 6]);
            drawnow;
            app.CommonData.timerPeriod = 0.2;
            app.MyTimer = timer("Period",app.CommonData.timerPeriod, ...
                "ExecutionMode","fixedRate","TasksToExecute", Inf);
            app.MyTimer.TimerFcn = @app.TimerCallback;
            initializeGUIposition(app);
        end

        % Button pushed function: READonCannonicalAxButton
        function READonCannonicalAxButtonPushed(app, event)
            [paramFile, paramPath] = uigetfile({'*.mat'},"Select parameter file:");
            app.CommonData.tmp = load([paramPath '/' paramFile]);
            app.CommonData.tmp.paramFile = paramFile;
            app.WaveAxesMorph.Visible = "off";
            app.SpecAxesMorph.Visible = "off";
            if isfield(app.CommonData.tmp, "worldParameter")
                setupReferenceData(app);
                %-------
                app.READonNonlinearAxButton.Enable = "on";
                app.READonCannonicalAxButton.Enable = "off";
            end
            app.WaveAxesMorph.Visible = "off";
            app.SpecAxesMorph.Visible = "off";
        end

        % Button pushed function: READonNonlinearAxButton
        function READonNonlinearAxButtonPushed(app, event)
            [paramFile, paramPath] = uigetfile({'*.mat'},"Select parameter file:");
            app.CommonData.tmp = load([paramPath '/' paramFile]);
            app.CommonData.tmp.paramFile = paramFile;
            app.WaveAxesMorph.Visible = "off";
            app.SpecAxesMorph.Visible = "off";
            if isfield(app.CommonData.tmp, "worldParameter")
                setupTargetData(app);
            end
            app.WaveAxesMorph.Visible = "off";
            app.SpecAxesMorph.Visible = "off";
        end

        % Value changing function: AlphaSlider
        function AlphaSliderValueChanging(app, event)
            if isfield(app.CommonData, "mhwHandle")
                delete(app.CommonData.mhwHandle);
                app.WaveAxesMorph.Visible = "off";
            end
            if isfield(app.CommonData, "mhsHandle")
                delete(app.CommonData.mhsHandle);
                app.SpecAxesMorph.Visible = "off";
            end
            changingValue = event.Value;
            app.CommonData.currentAlpha = changingValue;
            set(app.CommonData.refWaveHandle,"Color",[0 0 0 1-changingValue]);
            set(app.CommonData.targetWaveRHandle,"Color",[0 0.7 0 changingValue]);
            set(app.CommonData.tgtSgramHandle,"AlphaData",changingValue);
            app.WaveAxesMorph.Visible = "off";
            app.SpecAxesMorph.Visible = "off";
            drawnow %limitrate
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            position = get(app.UIFigure, 'Position');
            if isfield(app.CommonData, "figurePosition") ...
                    && sum(position ~= app.CommonData.figurePosition) > 0
                saveBaseFile(app);
            end
            switch app.CommonData.pen
                case "down"
                    app.CommonData.pen = "up";
                    id = app.CommonData.anchorCount;
                    set(app.CommonData.timeHandleWave(id),"color",[1 1 1] * 0.2,"linewidth",1);
                    set(app.CommonData.timeHandleSpec(id),"color",[1 1 1],"linewidth",1);
                    app.CommonData.axisOnTarget = app.CommonData.lastTimeOnTarget;
                case "pick"
                    app.CommonData.pen = "up";
                    id = app.CommonData.currentId;
                    set(app.CommonData.timeHandleWave(id),"color",[1 1 1] * 0.2,"linewidth",1);
                    set(app.CommonData.timeHandleSpec(id),"color",[1 1 1],"linewidth",1);
                case "pickTFmk"
                    id = app.CommonData.currentId;
                    set(app.CommonData.timeHandleWave(id),"color",[1 1 1] * 0.2,"linewidth",1);
                    set(app.CommonData.timeHandleSpec(id),"color",[1 1 1],"linewidth",1);
                    idx = app.CommonData.currentTFidx;
                    nfC = app.CommonData.currentTFnfc;
                    set(app.CommonData.anchBase.handleSet(idx).freqHandle(nfC), ...
                        "markersize", 6, "marker","o");
            end
            app.CommonData.pen = "up";
            set(app.UIFigure,"pointer","arrow")
        end

        % Button down function: WaveAxesRef
        function WaveAxesRefButtonDown(app, event)
            if app.CommonData.pen == "up"
                currentPoint = get(app.WaveAxesRef, "CurrentPoint");
                if app.CommonData.anchorCount == 0 ...
                        || min(abs(app.CommonData.handleLocation(1:app.CommonData.anchorCount) ...
                        - currentPoint(1,1))) > app.CommonData.timeProxyMargin
                    app.CommonData.pen = "down";
                    app.CommonData.anchorCount = app.CommonData.anchorCount + 1;
                    id = app.CommonData.anchorCount;
                    app.CommonData.handleLocation(id) = currentPoint(1,1);
                    set(app.CommonData.timeHandleWave(id),"Visible","on", ...
                        "Value", currentPoint(1,1),"HitTest","on", ...
                        "ButtonDownFcn", @app.pickTimeWaveCursor, "userdata", id);
                    set(app.CommonData.timeHandleSpec(id),"Visible","on", ...
                        "Value", currentPoint(1,1), "userdata", id, ...
                        "HitTest", "on");
                    if id < 2
                        app.CommonData.referenceLocation(id) = currentPoint(1,1);
                        [~, idx] = min(abs(currentPoint(1,1) - app.CommonData.xx(1,:)));
                        app.CommonData.referenceIdx = idx;
                        [~,idTxRef] = min(abs(currentPoint(1,1) - app.CommonData.originalTxRef));
                        app.CommonData.tAnchTgt(id) = app.CommonData.targetTimeOnRefAxis(idTxRef);
                    else
                        [~, idx] = min(abs(currentPoint(1,1) - app.CommonData.xx(1,:)));
                        timeOnTarget = app.CommonData.axisOnTarget;
                        app.CommonData.referenceLocation(id) = timeOnTarget(idx);
                        [~, idTxRef] = min(abs(currentPoint(1,1) - app.CommonData.originalTxRef));
                        app.CommonData.tAnchTgt(id) = app.CommonData.targetTimeOnRefAxis(idTxRef);
                    end
                    set(app.UIFigure,"pointer","left");
                    assignWaveMarker(app);
                    drawnow
                    app.CommonData.currentId = id;
                    app.CommonData.currentTFidx = id;
                    app.UndoButton_2.Enable = "on";
                    app.ClearButton_2.Enable = "on";
                end
            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            if isfield(app.CommonData, "currentId")
                switch app.CommonData.pen
                    case "up"
                        currentLoc = get(app.WaveAxesRef, "CurrentPoint");
                        currentSpecLoc = get(app.SpecAxesRef, "CurrentPoint");
                        %tFrame = app.CommonData.timeOnRef;
                        xlimit = get(app.WaveAxesRef,"xlim");
                        ylimit = get(app.SpecAxesRef,"ylim");
                        if (currentLoc(1,1)-xlimit(1)) * (currentLoc(1,1) - xlimit(2)) < 0
                            if isfield(app.CommonData, "mhwHandle")
                                delete(app.CommonData.mhwHandle);
                                set(app.WaveAxesRef, "XColor", [0 0 0]);
                            end
                            if isfield(app.CommonData, "mhsHandle")
                                delete(app.CommonData.mhsHandle);
                                set(app.SpecAxesRef, "XColor", [0 0 0]);
                            end
                            switch app.SpecAxesMorph.Visible
                                case "on"
                            %set(app.SpecAxesRef,"xlim",tFrame([1 end]));
                            %set(app.WaveAxesRef,"xlim",tFrame([1 end]));
                            changingValue = app.leftposSlider.Value;
            width = app.CommonData.originalTxRef(end) - app.CommonData.originalTxRef(1);
            currentXlim = [0 width / app.TxmagSlider.Value] + width*changingValue/100;
            set(app.WaveAxesRef,"XLim",currentXlim);
                            app.WaveAxesRef.Visible = "on";
                            app.SpecAxesRef.Visible = "on";
                            app.WaveAxesMorph.Visible = "off";
                            app.SpecAxesMorph.Visible = "off";
                            end
                        end
                        if (abs(currentLoc(1,2))-1) < 0 && ...
                                (currentLoc(1,1)-xlimit(1)) * (currentLoc(1,1) - xlimit(2)) < 0
                            if app.CommonData.anchorCount > 0 ...
                                    && min(abs(app.CommonData.handleLocation(1:app.CommonData.anchorCount) ...
                                    - currentLoc(1,1))) < 0.005
                                set(app.UIFigure,"pointer","hand")
                            else
                                set(app.UIFigure,"pointer","crosshair")
                            end
                        elseif (currentSpecLoc(1,2)-ylimit(1)) * (currentSpecLoc(1,2) - ylimit(2)) < 0 ...
                                && (currentLoc(1,1)-xlimit(1)) * (currentLoc(1,1) - xlimit(2)) < 0
                            [mindist, idx] = min(abs(app.CommonData.handleLocation(1:app.CommonData.anchorCount) ...
                                - currentSpecLoc(1,1)));
                                if mindist < 0.005
                                    nfC = sum(app.CommonData.ftAnchorTgt(:, idx)>0);
                                    yy = app.CommonData.ftAnchorTgt(1:nfC,idx);
                                    yyMod = hz2kHzWH(kHz2hzWH(yy)*app.CommonData.vtl_ratio);
                                    [minFreqDist, ~] = min(abs(currentSpecLoc(1,2)-yyMod));
                                    if minFreqDist < 0.02%10%0.8
                                    set(app.UIFigure,"pointer","hand")
                                    else
                                        set(app.UIFigure,"pointer","cross")
                                    end
                                else
                                    set(app.UIFigure,"pointer","crosshair")
                                end
                        else
                            set(app.UIFigure,"pointer","arrow")
                        end
                    case "down"
                        %assignWaveMarker(app);
                        %if 1 == 2
                        currentLoc = get(app.WaveAxesRef, "CurrentPoint");
                        nId = app.CommonData.anchorCount;
                        xlimit = get(app.WaveAxesRef,"xlim");
                        if (abs(currentLoc(1,2))-1) < 0 && ...
                                (currentLoc(1,1)-xlimit(1)) * (currentLoc(1,1) - xlimit(2)) < 0
                            set(app.CommonData.timeHandleWave(nId),"Visible","on", ...
                                "Value", currentLoc(1,1), "color", [0.7 0 0.7],"linewidth",4);
                            set(app.CommonData.timeHandleSpec(nId),"Visible","on", ...
                                "Value", currentLoc(1,1), "color", [1 1 0.5],"linewidth",4);
                            app.CommonData.handleLocation(nId) = currentLoc(1,1);
                            sortedHandLoc = sort(app.CommonData.handleLocation(1:nId));
                            sortedReferenceLoc = sort(app.CommonData.referenceLocation(1:nId));
                            if (nId > 1 && (sum(diff(sortedHandLoc) == 0) + ...
                                    sum(diff(sortedReferenceLoc) == 0)) == 0) ...
                                    || nId == 1
                                updateWaveDisplay(app);
                                %----- sgram
                                app.CommonData.vtl_ratio = app.VTLSlider.Value;
                                updateSgramDisplay(app);
                            end
                        end
                        %end
                    case "pick"
                        currentLoc = get(app.WaveAxesRef, "CurrentPoint");
                        id = app.CommonData.currentId;
                        if id > 0
                        nId = app.CommonData.anchorCount;
                        set(app.CommonData.timeHandleWave(id),"color",[0.7 0 0.7], ...
                            "Value", currentLoc(1,1),"linewidth",4);
                        set(app.CommonData.timeHandleSpec(id),"Visible","on", ...
                            "Value", currentLoc(1,1), "color", [1 1 0.5],"linewidth",4);
                        app.CommonData.handleLocation(id) = currentLoc(1,1);
                        nfC = sum(app.CommonData.ftAnchor(:, id) > 0);
                        if nfC > 0
                            for jj = 1:nfC
                                set(app.CommonData.anchBase.handleSet(id).freqHandle(jj), ...
                                    "xdata",currentLoc(1,1));
                            end
                        end
                        sortedHandLoc = sort(app.CommonData.handleLocation(1:nId));
                        sortedReferenceLoc = sort(app.CommonData.referenceLocation(1:nId));
                        if (nId > 1 && (sum(diff(sortedHandLoc) == 0) + ...
                                sum(diff(sortedReferenceLoc) == 0)) == 0) ...
                                || nId == 1
                            updateWaveDisplay(app);
                            %----- sgram
                            app.CommonData.vtl_ratio = app.VTLSlider.Value;
                            updateSgramDisplay(app);
                        end
                        end
                    case "pickTFmk"
                        currentLoc = get(app.SpecAxesRef, "CurrentPoint");
                        idx = app.CommonData.currentTFidx;
                        nfC = app.CommonData.currentTFnfc;
                        nTFcount = app.CommonData.totalTFanchors;
                        if idx*nfC > 0
                        set(app.CommonData.anchBase.handleSet(idx).freqHandle(nfC), ...
                            "ydata", currentLoc(1,2),"markersize",20, "marker","x");
                        app.CommonData.TFimeAnchorHistory(nTFcount, 4:5) = ...
                            [currentLoc(1,2), app.VTLSlider.Value];
                        app.CommonData.ftAnchorTgt(nfC, idx) = ...
                            hz2kHzWH(kHz2hzWH(currentLoc(1,2))/app.VTLSlider.Value);
                        app.CommonData.vtl_ratio = app.VTLSlider.Value;
                        updateSgramDisplay(app);
                        end
                end
            end
        end

        % Button pushed function: SAVEEDITButton
        function SAVEEDITButtonPushed(app, event)
            morphingStr = makeMorphingStr(app);
            t = char(datetime('now','Format','yMMdHHmmss'));
            datestrnow30 = [t(1:8) 'T' t(9:end)];
            outFileName = "worldMorphParamSet" + datestrnow30 + "d.mat";
            if isfield(app.CommonData,'outFilePath')
                oldPath = cd(app.CommonData.outFilePath);
            end
            [fileName,filePath] = uiputfile('','save parameter',outFileName);
            if isequal(filePath, 0) || isequal(fileName, 0)
                if isfield(app.CommonData,'outFilePath')
                    cd(oldPath);
                    disp('save cancelled!');
                end
            else
                if isfield(app.CommonData,'outFilePath')
                    cd(oldPath);
                end
                app.CommonData.outFileName = string(fileName);
                app.CommonData.outFilePath = string(filePath);
                app.CommonData.fileFullPath = app.CommonData.outFilePath;
                save(app.CommonData.outFilePath + fileName,...
                    "morphingStr");
                app.SAVEEDITButton.Enable = "off";
            end
        end

        % Button pushed function: SynthesisButton
        function SynthesisButtonPushed(app, event)
            mRate = app.mRateSlider.Value;
            app.CommonData.mRate = mRate;
            morphingAndSynthesize(app);
            app.SAVEEDITButton.Enable = "on";
            app.SAVEmObjectButton.Enable = "on";
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.MyTimer);
            delete(app)
        end

        % Button pushed function: FlushonButton
        function FlushonButtonPushed(app, event)
            if isfield(app.CommonData, "mhwHandle")
                delete(app.CommonData.mhwHandle);
                app.WaveAxesMorph.Visible = "off";
                set(app.WaveAxesRef, "XColor", [0 0 0]);
            end
            if isfield(app.CommonData, "mhsHandle")
                delete(app.CommonData.mhsHandle);
                app.SpecAxesMorph.Visible = "off";
                set(app.SpecAxesRef, "XColor", [0 0 0]);
            end
            start(app.MyTimer);
            app.FlushonButton.Enable = "off";
            app.FlushoffButton.Enable = "on";
            app.ViewcontrolButtonGroup.Enable = "off";
            app.WaveAxesMorph.Visible = "off";
            app.SpecAxesMorph.Visible = "off";
        end

        % Button pushed function: FlushoffButton
        function FlushoffButtonPushed(app, event)
            if isfield(app.CommonData, "mhwHandle")
                delete(app.CommonData.mhwHandle);
                app.WaveAxesMorph.Visible = "off";
                set(app.WaveAxesRef, "XColor", [0 0 0]);
            end
            if isfield(app.CommonData, "mhsHandle")
                delete(app.CommonData.mhsHandle);
                app.SpecAxesMorph.Visible = "off";
                set(app.WaveAxesRef, "XColor", [0 0 0]);
            end
            stop(app.MyTimer);
            app.FlushonButton.Enable = "on";
            app.FlushoffButton.Enable = "off";
            app.ViewcontrolButtonGroup.Enable = "on";
            app.WaveAxesMorph.Visible = "off";
            app.SpecAxesMorph.Visible = "off";
            app.CommonData.currentAlpha = 0.5;
            app.AlphaSlider.Value = 0.5;
            app.VariableButton.Value = 1;
            changingValue = app.AlphaSlider.Value;
            set(app.CommonData.refWaveHandle,"Color",[0 0 0 1-changingValue]);
            set(app.CommonData.targetWaveRHandle,"Color",[0 0.7 0 changingValue]);
            set(app.CommonData.tgtSgramHandle,"AlphaData",changingValue);
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            switch get(app.MyTimer, "Running")
                case 'on'
                    stop(app.MyTimer);
            end
            app.WaveAxesMorph.Visible = "off";
            app.SpecAxesMorph.Visible = "off";
            if isfield(app.CommonData, "mhwHandle")
                delete(app.CommonData.mhwHandle);
                set(app.WaveAxesRef, "XColor", [0 0 0]);
            end
            if isfield(app.CommonData, "mhsHandle")
                delete(app.CommonData.mhsHandle);
                set(app.SpecAxesRef, "XColor", [0 0 0]);
            end
            if isfield(app.CommonData, "currentId")
                app.CommonData = rmfield(app.CommonData, "currentId");
            end
            app.CommonData.timeCount = 0;
            app.CommonData.pen = "up";
            app.CommonData.anchorCount = 0;
            app.CommonData.totalTFanchors = 0;
            app.CommonData.differenceHistory = zeros(100,1) - 20;
            app.CommonData.ScoreHandle = ...
                plot(app.SCOREAxes, app.CommonData.differenceHistory,"LineWidth",2);
            grid(app.SCOREAxes, "on");
            axis(app.SCOREAxes, [1 100 -40 -16])
            cla(app.SpecAxesRef);
            cla(app.WaveAxesRef);
            set(app.SpecAxesRef,"YLim",[0 6]);
            set(app.SpecAxesMorph,"YLim",[0 6]);
            app.kHzButton_5.Value = 1;
            app.AlphaSlider.Value = 0.5;
            app.READonCannonicalAxButton.Enable = "on";
            app.READonNonlinearAxButton.Enable = "off";
            app.RESETButton.Enable = "off";
            app.SynthesisButton.Enable = "off";
            app.SAVEEDITButton.Enable = "off";
            app.FlushonButton.Enable = "off";
            app.RestoreEDITButton.Enable = "on";
            app.PLAYCxButton.Enable = "off";
            app.PLAYNLxButton.Enable = "off";
            app.SAVEmObjectButton.Enable = "off";
        end

        % Value changed function: mRateSlider
        function mRateSliderValueChanged(app, event)
            mRate = app.mRateSlider.Value;
            app.mRate_indicator.Text = num2str(mRate,3);
            app.CommonData.mRate = mRate;
            morphingAndSynthesize(app);
        end

        % Button down function: SpecAxesRef
        function SpecAxesRefButtonDown(app, event)
            if 1 == 2
            if app.CommonData.anchorCount > 0 && (get(app.UIFigure, "Pointer") == "cross" ...
                    || get(app.UIFigure, "Pointer") == "circle")
                currentSpecLoc = get(app.SpecAxesRef, "CurrentPoint");
                switch get(app.UIFigure, "Pointer")
                    case "cross"
                        nId = app.CommonData.anchorCount;
                        [~, idx] = min(abs(currentSpecLoc(1,1) - app.CommonData.handleLocation(1:nId)));
                        nfC = sum(app.CommonData.ftAnchor(:, idx) > 0);
                        nfC = nfC + 1;
                        app.CommonData.ftAnchor(nfC, idx) = currentSpecLoc(1,2);
                        xloc = get(app.CommonData.timeHandleSpec(idx),"Value");
                        set(app.CommonData.anchBase.handleSet(idx).freqHandle(nfC), "Visible", "on", ...
                            "xdata",xloc, "ydata", currentSpecLoc(1,2));
                        set(app.UIFigure, "Pointer","top");
                end
            end
            end
        end

        % Value changing function: VTLSlider
        function VTLSliderValueChanging(app, event)
            changingValue = event.Value;
            app.CommonData.vtl_ratio = changingValue;
            updateSgramDisplay(app);
        end

        % Value changing function: mRateSlider
        function mRateSliderValueChanging(app, event)
            mRate = event.Value;
            app.mRate_indicator.Text = num2str(mRate,3);
        end

        % Button pushed function: RestoreEDITButton
        function RestoreEDITButtonPushed(app, event)
            [paramFile, paramPath] = uigetfile({'*.mat'},"Select morphing parameter file:");
            tmp = load([paramPath '/' paramFile]);
            app.CommonData.tmp = load([paramPath '/' tmp.morphingStr.referenceFileName]);
            app.CommonData.tmp.paramFile = tmp.morphingStr.referenceFileName;
            setupReferenceData(app);
            app.CommonData.tmp = load([paramPath '/' tmp.morphingStr.targetFileName]);
            app.CommonData.tmp.paramFile = tmp.morphingStr.targetFileName;
            setupTargetData(app);
            app.RestoreEDITButton.Enable = "off";
            nId = length(tmp.morphingStr.anchStr.tAnchRef);
            app.CommonData.anchorCount = nId;
            if isfield(tmp.morphingStr, "vtl_ratio")
                app.CommonData.vtl_ratio = tmp.morphingStr.vtl_ratio;
            else
                app.CommonData.vtl_ratio = 1;
            end
            app.VTLSlider.Value = app.CommonData.vtl_ratio;
            app.CommonData.currentId = 0;
            app.CommonData.handleLocation(1:nId) = tmp.morphingStr.anchStr.tAnchRef;
            if isfield(tmp.morphingStr.anchStr, "referenceLocation")
                app.CommonData.referenceLocation(1:nId) = tmp.morphingStr.anchStr.referenceLocation;
            else
                app.CommonData.referenceLocation(1:nId) = tmp.morphingStr.anchStr.tAnchTgt;
            end
            app.CommonData.tAnchTgt(1:nId) = tmp.morphingStr.anchStr.tAnchTgt;
            app.CommonData.ftAnchor = tmp.morphingStr.anchStr.tfAnchor;
            app.CommonData.ftAnchorTgt = tmp.morphingStr.anchStr.ftAnchorTgt;
            %updateWaveDisplay(app);
            %app.CommonData.axisOnTarget = app.CommonData.lastTimeOnTarget;
            app.CommonData.currentAlpha = 0.5;
            for id = 1:nId
                app.CommonData.currentTFidx = id;
                set(app.CommonData.timeHandleWave(id),"color",[1 1 1] * 0.2,"visible","on", ...
                    "Value", app.CommonData.handleLocation(id),"userdata", id);
                set(app.CommonData.timeHandleSpec(id),"color",[1 1 1],"linewidth",1, ...
                    "visible","on", "Value", app.CommonData.handleLocation(id), "userdata", id, ...
                    "HitTest", "on");
                nfC = sum(app.CommonData.ftAnchor(:, id) > 0);
                if nfC > 0
                    for jj = 1:nfC
                        set(app.CommonData.anchBase.handleSet(id).freqHandle(jj), ...
                            "xdata",app.CommonData.handleLocation(id), ...
                            "ydata", app.CommonData.ftAnchor(jj,id), ...
                            "Visible", "on");
                        app.CommonData.totalTFanchors = app.CommonData.totalTFanchors + 1;
                        nTFcount = app.CommonData.totalTFanchors;
                        ftAnchorV = app.CommonData.ftAnchor(nfC, id);
                        ftAnchorTgtV = app.CommonData.ftAnchorTgt(nfC, id);
                        app.CommonData.TFimeAnchorHistory(nTFcount,:) = ...
                            [id, jj, ftAnchorV,  ftAnchorTgtV, app.CommonData.vtl_ratio];
                        app.CommonData.FTinitialize = 0;
                        updateSgramDisplay(app);
                        app.CommonData.FTinitialize = 0;
                    end
                end
            end
            updateSgramDisplay(app);
            updateWaveDisplay(app);
            if app.CommonData.totalTFanchors > 0
                app.UndoButton_2.Enable = "on";
                app.ClearButton_2.Enable = "on";
            end
            if nId > 0
                app.UndoButton.Enable = "on";
                app.ClearButton.Enable = "on";
            end
        end

        % Selection changed function: ViewcontrolButtonGroup
        function ViewcontrolButtonGroupSelectionChanged(app, event)
            selectedButton = app.ViewcontrolButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Cannonical'
                    app.AlphaSlider.Value = 0;
                case 'Variable'
                    app.AlphaSlider.Value = 0.5;
                case 'NL-TFaxis'
                    app.AlphaSlider.Value = 1;
            end
            app.CommonData.currentAlpha = app.AlphaSlider.Value;
            changingValue = app.CommonData.currentAlpha;%app.AlphaSlider.Value;
            if isvalid(app.CommonData.targetWaveRHandle)
                set(app.CommonData.refWaveHandle,"Color",[0 0 0 1-changingValue]);
                set(app.CommonData.targetWaveRHandle,"Color",[0 0.7 0 changingValue]);
                set(app.CommonData.tgtSgramHandle,"AlphaData",changingValue);
            end
        end

        % Button pushed function: UndoButton_2
        function UndoButton_2Pushed(app, event)
            if app.CommonData.anchorCount > 0
                idx = app.CommonData.anchorCount;
                app.CommonData.timeHandleSpec(idx).Visible = "off";
                app.CommonData.timeHandleWave(idx).Visible = "off";
                app.CommonData.referenceLocation(idx) = 0;
                app.CommonData.handleLocation(idx) = 0;
                app.CommonData.anchorCount = ...
                    app.CommonData.anchorCount - 1;
                updateSgramDisplay(app);
                updateWaveDisplay(app);
                if app.CommonData.anchorCount <= 0
                    app.UndoButton_2.Enable = "off";
                    app.ClearButton_2.Enable = "off";
                end
            end
        end

        % Button pushed function: ClearButton_2
        function ClearButton_2Pushed(app, event)
            while app.CommonData.anchorCount > 0
                idx = app.CommonData.anchorCount;
                app.CommonData.timeHandleSpec(idx).Visible = "off";
                app.CommonData.timeHandleWave(idx).Visible = "off";
                app.CommonData.referenceLocation(idx) = 0;
                app.CommonData.handleLocation(idx) = 0;
                app.CommonData.anchorCount = ...
                    app.CommonData.anchorCount - 1;
                updateSgramDisplay(app);
                updateWaveDisplay(app);
                if app.CommonData.anchorCount <= 0
                    app.ClearButton_2.Enable = "off";
                    app.UndoButton_2.Enable = "off";
                end
            end
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            if app.CommonData.totalTFanchors > 0
                nTFcound = app.CommonData.totalTFanchors;
                idx = app.CommonData.TFimeAnchorHistory(nTFcound,1);
                nfC = app.CommonData.TFimeAnchorHistory(nTFcound,2);
                app.CommonData.currentTFidx = idx;
                app.CommonData.anchBase.handleSet(idx).freqHandle(nfC).Visible = "off";
                app.CommonData.ftAnchor(nfC,idx) = 0;
                app.CommonData.ftAnchorTgt(nfC,idx) = 0;
                app.CommonData.vtl_ratio = app.CommonData.TFimeAnchorHistory(nTFcound,5);
                updateSgramDisplay(app);
                app.CommonData.totalTFanchors = app.CommonData.totalTFanchors - 1;
                if app.CommonData.totalTFanchors <= 0
                    app.UndoButton.Enable = "off";
                    app.ClearButton.Enable = "off";
                end
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            while app.CommonData.totalTFanchors > 0
                nTFcound = app.CommonData.totalTFanchors;
                idx = app.CommonData.TFimeAnchorHistory(nTFcound,1);
                nfC = app.CommonData.TFimeAnchorHistory(nTFcound,2);
                app.CommonData.currentTFidx = idx;
                app.CommonData.anchBase.handleSet(idx).freqHandle(nfC).Visible = "off";
                app.CommonData.ftAnchor(nfC,idx) = 0;
                app.CommonData.ftAnchorTgt(nfC,idx) = 0;
                app.CommonData.vtl_ratio = app.CommonData.TFimeAnchorHistory(nTFcound,5);
                updateSgramDisplay(app);
                app.CommonData.totalTFanchors = app.CommonData.totalTFanchors - 1;
                if app.CommonData.totalTFanchors <= 0
                    app.UndoButton.Enable = "off";
                    app.ClearButton.Enable = "off";
                end
            end
        end

        % Button pushed function: DebugButton
        function DebugButtonPushed(app, event)
            keyboard;
        end

        % Selection changed function: FreqlimitButtonGroup
        function FreqlimitButtonGroupSelectionChanged(app, event)
            selectedButton = app.FreqlimitButtonGroup.SelectedObject;
            fs = app.CommonData.samplingFrequency;
            switch selectedButton.Text
                case '1kHz'
                    set(app.SpecAxesRef,"YLim",[0 1]);
                    set(app.SpecAxesMorph,"YLim",[0 1]);
                    set(app.WaveAxesRef,"YTick",[0 1],"YTickLabel",{'0.0','1.0'})
                case '2kHz'
                    set(app.SpecAxesRef,"YLim",[0 2]);
                    set(app.SpecAxesMorph,"YLim",[0 2]);
                    set(app.WaveAxesRef,"YTick",[0 1],"YTickLabel",{'0.0','1.0'})
                case '4kHz'
                    set(app.SpecAxesRef,"YLim",[0 4]);
                    set(app.SpecAxesMorph,"YLim",[0 4]);
                    set(app.WaveAxesRef,"YTick",[0 1],"YTickLabel",{'0.0','1.0'})
                case '6kHz'
                    set(app.SpecAxesRef,"YLim",[0 6]);
                    set(app.SpecAxesMorph,"YLim",[0 6]);
                    set(app.WaveAxesRef,"YTick",[0 1],"YTickLabel",{'0','1'})
                case '8kHz'
                    set(app.SpecAxesRef,"YLim",[0 8]);
                    set(app.SpecAxesMorph,"YLim",[0 8]);
                    set(app.WaveAxesRef,"YTick",[0 1],"YTickLabel",{'0','1'})
                case 'Full'
                    set(app.SpecAxesRef,"YLim",[0 fs/2/1000]);
                    set(app.SpecAxesMorph,"YLim",[0 fs/2/1000]);
                    set(app.WaveAxesRef,"YTick",[0 1],"YTickLabel",{'0','1'})
            end
            drawnow
        end

        % Value changing function: TxmagSlider
        function TxmagSliderValueChanging(app, event)
            changingValue = event.Value;
            width = app.CommonData.originalTxRef(end) - app.CommonData.originalTxRef(1);
            currentXlim = [0 width / changingValue] + width*app.leftposSlider.Value/100;
            set(app.WaveAxesRef,"XLim",currentXlim);
            set(app.CommonData.refTextHandle, "position", [currentXlim(1) 0.8 0]);
            set(app.CommonData.tgtTextHandle, "position", [currentXlim(1) -0.8 0]);
            drawnow limitrate
        end

        % Value changing function: leftposSlider
        function leftposSliderValueChanging(app, event)
            changingValue = event.Value;
            width = app.CommonData.originalTxRef(end) - app.CommonData.originalTxRef(1);
            currentXlim = [0 width / app.TxmagSlider.Value] + width*changingValue/100;
            set(app.WaveAxesRef,"XLim",currentXlim);
            set(app.CommonData.refTextHandle, "position", [currentXlim(1) 0.8 0]);
            set(app.CommonData.tgtTextHandle, "position", [currentXlim(1) -0.8 0]);
            drawnow limitrate
        end

        % Button pushed function: PLAYCxButton
        function PLAYCxButtonPushed(app, event)
            fs = app.CommonData.samplingFrequency;
            currentXlimInSample = get(app.WaveAxesRef,"XLim") * fs;
            stp = round(max(1,currentXlimInSample(1)));
            xRef = app.CommonData.xRef;
            edp = round(min(length(xRef),currentXlimInSample(end)));
            soundsc(xRef(stp:edp), fs);
        end

        % Button pushed function: PLAYNLxButton
        function PLAYNLxButtonPushed(app, event)
            fs = app.CommonData.samplingFrequency;
            currentXlimOnRefTime = get(app.WaveAxesRef,"XLim");
            [~, idxSt] = min(abs(currentXlimOnRefTime(1) - app.CommonData.originalTxRef));
            [~, idxEnd] = min(abs(currentXlimOnRefTime(2) - app.CommonData.originalTxRef));
            currentXlimInSample = app.CommonData.targetTimeOnRefAxis([idxSt, idxEnd]) * fs;
            stp = round(max(1,currentXlimInSample(1)));
            xTarget = app.CommonData.xTarget;
            edp = round(min(length(xTarget),currentXlimInSample(end)));
            soundsc(xTarget(stp:edp), fs);
        end

        % Button pushed function: SAVEmObjectButton
        function SAVEmObjectButtonPushed(app, event)
            morphingStr = makeMorphingStr(app);
            outFileNameCannonical = "mObj_" + app.CommonData.referenceFileName;
            outFileNameNLTFaxis = "mObj_" + app.CommonData.targetFileName;
            if isfield(app.CommonData,'outFilePath')
                oldPath = cd(app.CommonData.outFilePath);
            end
            [fileName,filePath] = uiputfile('','save Cannonical',outFileNameCannonical);
            if isequal(filePath, 0) || isequal(fileName, 0)
                if isfield(app.CommonData,'outFilePath')
                    cd(oldPath);
                    disp('save cancelled!');
                end
            else
                if isfield(app.CommonData,'outFilePath')
                    cd(oldPath);
                end
                worldParameter = morphingStr.worldPRef;
                timeAnchor = morphingStr.timeAnchorReference;
                timeFreqAnchor = morphingStr.timeFreqAnchorReference;
                app.CommonData.outFileName = string(fileName);
                app.CommonData.outFilePath = string(filePath);
                app.CommonData.fileFullPath = app.CommonData.outFilePath;
                save(app.CommonData.outFilePath + fileName,...
                    "worldParameter","timeAnchor","timeFreqAnchor");
            end
            switch app.CommonData.nltfAxis
                case "new"
            if isfield(app.CommonData,'outFilePath')
                oldPath = cd(app.CommonData.outFilePath);
            end
            [fileName,filePath] = uiputfile('','save NLTFaxis',outFileNameNLTFaxis);
            if isequal(filePath, 0) || isequal(fileName, 0)
                if isfield(app.CommonData,'outFilePath')
                    cd(oldPath);
                    disp('save cancelled!');
                end
            else
                if isfield(app.CommonData,'outFilePath')
                    cd(oldPath);
                end
                worldParameter = morphingStr.worldPTgt;
                timeAnchor = morphingStr.timeAnchorTarget;
                timeFreqAnchor = morphingStr.timeFreqAnchorTarget;
                app.CommonData.outFileName = string(fileName);
                app.CommonData.outFilePath = string(filePath);
                app.CommonData.fileFullPath = app.CommonData.outFilePath;
                save(app.CommonData.outFilePath + fileName,...
                    "worldParameter","timeAnchor","timeFreqAnchor");
            end
                case "given"
                    disp("File: " + app.CommonData.targetFileName + " was used as the reference.")
            end
            app.SAVEmObjectButton.Enable = "off";
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {604, 604};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {286, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SCOREAxes = uiaxes(app.LeftPanel);
            title(app.SCOREAxes, 'Difference')
            xlabel(app.SCOREAxes, 'event axis')
            ylabel(app.SCOREAxes, 'difference (dB)')
            zlabel(app.SCOREAxes, 'Z')
            app.MorphingalignerLabel = uilabel(app.LeftPanel);
            app.MorphingalignerLabel.Text = 'Morphing aligner';
            app.READonCannonicalAxButton = uibutton(app.LeftPanel, 'push');
            app.READonCannonicalAxButton.ButtonPushedFcn = createCallbackFcn(app, @READonCannonicalAxButtonPushed, true);
            app.READonCannonicalAxButton.Text = 'READ on Cannonical Ax';
            app.READonNonlinearAxButton = uibutton(app.LeftPanel, 'push');
            app.READonNonlinearAxButton.ButtonPushedFcn = createCallbackFcn(app, @READonNonlinearAxButtonPushed, true);
            app.READonNonlinearAxButton.Text = 'READ on Nonlinear Ax';
            app.SAVEEDITButton = uibutton(app.LeftPanel, 'push');
            app.SAVEEDITButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEEDITButtonPushed, true);
            app.SAVEEDITButton.Enable = 'off';
            app.SAVEEDITButton.Text = 'SAVE EDIT';
            app.mRateSliderLabel = uilabel(app.LeftPanel);
            app.mRateSliderLabel.Text = 'mRate';
            app.mRateSlider = uislider(app.LeftPanel);
            app.mRateSlider.Limits = [0 1];
            app.mRateSlider.ValueChangedFcn = createCallbackFcn(app, @mRateSliderValueChanged, true);
            app.mRateSlider.ValueChangingFcn = createCallbackFcn(app, @mRateSliderValueChanging, true);
            app.mRateSlider.Value = 0.5;
            app.SynthesisButton = uibutton(app.LeftPanel, 'push');
            app.SynthesisButton.ButtonPushedFcn = createCallbackFcn(app, @SynthesisButtonPushed, true);
            app.SynthesisButton.Enable = 'off';
            app.SynthesisButton.Text = 'Synthesis';
            app.RESETButton = uibutton(app.LeftPanel, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Enable = 'off';
            app.RESETButton.Text = 'RESET';
            app.VTLLabel = uilabel(app.LeftPanel);
            app.VTLLabel.Enable = 'off';
            app.VTLLabel.Visible = 'off';
            app.VTLLabel.Text = 'VTL';
            app.VTLSlider = uislider(app.LeftPanel);
            app.VTLSlider.Limits = [0.5 1.5];
            app.VTLSlider.ValueChangingFcn = createCallbackFcn(app, @VTLSliderValueChanging, true);
            app.VTLSlider.Enable = 'off';
            app.VTLSlider.Visible = 'off';
            app.VTLSlider.Value = 1;
            app.VTL_indicator = uilabel(app.LeftPanel);
            app.VTL_indicator.Visible = 'off';
            app.VTL_indicator.Text = '1';
            app.mRate_indicator = uilabel(app.LeftPanel);
            app.mRate_indicator.Text = '0.5';
            app.RestoreEDITButton = uibutton(app.LeftPanel, 'push');
            app.RestoreEDITButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreEDITButtonPushed, true);
            app.RestoreEDITButton.Text = 'Restore EDIT';
            app.mRate_indicator_2 = uilabel(app.LeftPanel);
            app.mRate_indicator_2.Text = 'CANN';
            app.mRate_indicator_3 = uilabel(app.LeftPanel);
            app.mRate_indicator_3.Text = 'NLTF';
            app.PLAYCxButton = uibutton(app.LeftPanel, 'push');
            app.PLAYCxButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYCxButtonPushed, true);
            app.PLAYCxButton.Enable = 'off';
            app.PLAYCxButton.Text = 'PLAY Cx';
            app.PLAYNLxButton = uibutton(app.LeftPanel, 'push');
            app.PLAYNLxButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYNLxButtonPushed, true);
            app.PLAYNLxButton.Enable = 'off';
            app.PLAYNLxButton.Text = 'PLAY NLx';
            app.SAVEmObjectButton = uibutton(app.LeftPanel, 'push');
            app.SAVEmObjectButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEmObjectButtonPushed, true);
            app.SAVEmObjectButton.Enable = 'off';
            app.SAVEmObjectButton.Text = 'SAVE m-Object';
            app.RightPanel = uipanel(app.GridLayout);
            app.SpecAxesRef = uiaxes(app.RightPanel);
            ylabel(app.SpecAxesRef, 'frequency (kHz)')
            zlabel(app.SpecAxesRef, 'Z')
            app.SpecAxesRef.ButtonDownFcn = createCallbackFcn(app, @SpecAxesRefButtonDown, true);
            app.WaveAxesRef = uiaxes(app.RightPanel);
            xlabel(app.WaveAxesRef, 'time (s)')
            ylabel(app.WaveAxesRef, 'level (rel)')
            zlabel(app.WaveAxesRef, 'Z')
            app.WaveAxesRef.ALim = [0 1];
            app.WaveAxesRef.YTick = [-1 0 1];
            app.WaveAxesRef.YTickLabel = {'-1'; '0'; '1'};
            app.WaveAxesRef.ButtonDownFcn = createCallbackFcn(app, @WaveAxesRefButtonDown, true);
            app.SpecAxesMorph = uiaxes(app.RightPanel);
            ylabel(app.SpecAxesMorph, 'frequency (kHz)')
            zlabel(app.SpecAxesMorph, 'Z')
            app.SpecAxesMorph.Visible = 'off';
            app.WaveAxesMorph = uiaxes(app.RightPanel);
            xlabel(app.WaveAxesMorph, 'time (s)')
            ylabel(app.WaveAxesMorph, 'level (rel)')
            zlabel(app.WaveAxesMorph, 'Z')
            app.WaveAxesMorph.ALim = [0 1];
            app.WaveAxesMorph.YTick = [-1 0 1];
            app.WaveAxesMorph.YTickLabel = {'-1'; '0'; '1'};
            app.WaveAxesMorph.Visible = 'off';
            app.AlphaSlider = uislider(app.RightPanel);
            app.AlphaSlider.Limits = [0 1];
            app.AlphaSlider.ValueChangingFcn = createCallbackFcn(app, @AlphaSliderValueChanging, true);
            app.AlphaSlider.Value = 0.5;
            app.FlushonButton = uibutton(app.RightPanel, 'push');
            app.FlushonButton.ButtonPushedFcn = createCallbackFcn(app, @FlushonButtonPushed, true);
            app.FlushonButton.Enable = 'off';
            app.FlushonButton.Text = 'Flush on';
            app.FlushoffButton = uibutton(app.RightPanel, 'push');
            app.FlushoffButton.ButtonPushedFcn = createCallbackFcn(app, @FlushoffButtonPushed, true);
            app.FlushoffButton.Enable = 'off';
            app.FlushoffButton.Text = 'Flush off';
            app.rateSliderLabel = uilabel(app.RightPanel);
            app.rateSliderLabel.Text = 'rate';
            app.rateSlider = uislider(app.RightPanel);
            app.rateSlider.Limits = [0.2 2];
            app.rateSlider.Value = 0.5;
            app.ViewcontrolButtonGroup = uibuttongroup(app.RightPanel);
            app.ViewcontrolButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ViewcontrolButtonGroupSelectionChanged, true);
            app.ViewcontrolButtonGroup.Title = 'View control';
            app.CannonicalButton = uiradiobutton(app.ViewcontrolButtonGroup);
            app.CannonicalButton.Text = 'Cannonical';
            app.VariableButton = uiradiobutton(app.ViewcontrolButtonGroup);
            app.VariableButton.Text = 'Variable';
            app.VariableButton.Value = true;
            app.NLTFaxisButton = uiradiobutton(app.ViewcontrolButtonGroup);
            app.NLTFaxisButton.Text = 'NL-TFaxis';
            app.DebugButton = uibutton(app.RightPanel, 'push');
            app.DebugButton.ButtonPushedFcn = createCallbackFcn(app, @DebugButtonPushed, true);
            app.DebugButton.Text = 'Debug';
            app.mRate_indicator_4 = uilabel(app.RightPanel);
            app.mRate_indicator_4.Text = ' CANN';
            app.mRate_indicator_5 = uilabel(app.RightPanel);
            app.mRate_indicator_5.Text = 'NLTF';
            app.TFanchorPanel = uipanel(app.RightPanel);
            app.TFanchorPanel.Title = 'TF anchor';
            app.UndoButton = uibutton(app.TFanchorPanel, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = 'Undo';
            app.ClearButton = uibutton(app.TFanchorPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.TimeanchorPanel = uipanel(app.RightPanel);
            app.TimeanchorPanel.Title = 'Time anchor';
            app.UndoButton_2 = uibutton(app.TimeanchorPanel, 'push');
            app.UndoButton_2.ButtonPushedFcn = createCallbackFcn(app, @UndoButton_2Pushed, true);
            app.UndoButton_2.Text = 'Undo';
            app.ClearButton_2 = uibutton(app.TimeanchorPanel, 'push');
            app.ClearButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_2Pushed, true);
            app.ClearButton_2.Text = 'Clear';
            app.FreqlimitButtonGroup = uibuttongroup(app.RightPanel);
            app.FreqlimitButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FreqlimitButtonGroupSelectionChanged, true);
            app.FreqlimitButtonGroup.Title = 'Freq. limit';
            app.FullButton = uiradiobutton(app.FreqlimitButtonGroup);
            app.FullButton.Text = 'Full';
            app.kHzButton_4 = uiradiobutton(app.FreqlimitButtonGroup);
            app.kHzButton_4.Text = '8kHz';
            app.kHzButton_3 = uiradiobutton(app.FreqlimitButtonGroup);
            app.kHzButton_3.Text = '4kHz';
            app.kHzButton_2 = uiradiobutton(app.FreqlimitButtonGroup);
            app.kHzButton_2.Text = '2kHz';
            app.kHzButton = uiradiobutton(app.FreqlimitButtonGroup);
            app.kHzButton.Text = '1kHz';
            app.kHzButton_5 = uiradiobutton(app.FreqlimitButtonGroup);
            app.kHzButton_5.Text = '6kHz';
            app.kHzButton_5.Value = true;
            app.TxmagSliderLabel = uilabel(app.RightPanel);
            app.TxmagSliderLabel.Text = 'Tx-mag';
            app.TxmagSlider = uislider(app.RightPanel);
            app.TxmagSlider.Limits = [1 5];
            app.TxmagSlider.ValueChangingFcn = createCallbackFcn(app, @TxmagSliderValueChanging, true);
            app.TxmagSlider.Value = 1;
            app.leftposSliderLabel = uilabel(app.RightPanel);
            app.leftposSliderLabel.Text = 'left pos(%)';
            app.leftposSlider = uislider(app.RightPanel);
            app.leftposSlider.ValueChangingFcn = createCallbackFcn(app, @leftposSliderValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = morphingAligner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end