classdef morphingSoundGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GENERATEButton                  matlab.ui.control.Button
        CHECKButton                     matlab.ui.control.Button
        stepsDropDown                   matlab.ui.control.DropDown
        stepsDropDownLabel              matlab.ui.control.Label
        continuumprefixEditField        matlab.ui.control.EditField
        continuumprefixEditFieldLabel   matlab.ui.control.Label
        FileNameRLabel                  matlab.ui.control.Label
        FileNameLLabel                  matlab.ui.control.Label
        RECALLBButton                   matlab.ui.control.Button
        ASSIGNBButton                   matlab.ui.control.Button
        RECALLAButton                   matlab.ui.control.Button
        ASSIGNAButton                   matlab.ui.control.Button
        SAVEButton                      matlab.ui.control.Button
        SYNTHESISButton                 matlab.ui.control.Button
        EditFieldApR                    matlab.ui.control.NumericEditField
        EditFieldFoR                    matlab.ui.control.NumericEditField
        EditFieldSplR                   matlab.ui.control.NumericEditField
        EditFieldTxR                    matlab.ui.control.NumericEditField
        EditFieldFxR                    matlab.ui.control.NumericEditField
        EditFieldApL                    matlab.ui.control.NumericEditField
        EditFieldFoL                    matlab.ui.control.NumericEditField
        EditFieldSplL                   matlab.ui.control.NumericEditField
        EditFieldTxL                    matlab.ui.control.NumericEditField
        EditFieldFxL                    matlab.ui.control.NumericEditField
        EditFieldTotalWR                matlab.ui.control.NumericEditField
        EditFieldTotalWL                matlab.ui.control.NumericEditField
        aperiodicitymorphingrateSlider  matlab.ui.control.Slider
        aperiodicitymorphingrateSliderLabel  matlab.ui.control.Label
        fundamentalfrequencymorphingrateSlider  matlab.ui.control.Slider
        fundamentalfrequencymorphingrateSliderLabel  matlab.ui.control.Label
        spectrumlevelmorphingrateSlider  matlab.ui.control.Slider
        spectrumlevelmorphingrateSliderLabel  matlab.ui.control.Label
        timeaxismorphingrateSlider      matlab.ui.control.Slider
        timeaxismorphingrateSliderLabel  matlab.ui.control.Label
        frequencyaxismorphingrateSlider  matlab.ui.control.Slider
        frequencyaxismorphingrateSliderLabel  matlab.ui.control.Label
        wholemorphingrateSlider         matlab.ui.control.Slider
        wholemorphingrateSliderLabel    matlab.ui.control.Label
        MorphingratesettingButtonGroup  matlab.ui.container.ButtonGroup
        individualButton                matlab.ui.control.RadioButton
        connectedButton                 matlab.ui.control.RadioButton
        READRButton                     matlab.ui.control.Button
        READLButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        CommonData % Shared data
    end
    
    methods (Access = private)
        
        function results = initializePanel(app)
            results = 1;
            app.EditFieldTotalWL.Value = 0.5;
            app.EditFieldFxL.Value = 0.5;
            app.EditFieldTxL.Value = 0.5;
            app.EditFieldSplL.Value = 0.5;
            app.EditFieldFoL.Value = 0.5;
            app.EditFieldApL.Value = 0.5;
            app.EditFieldFxL.Enable = "off";
            app.EditFieldTxL.Enable = "off";
            app.EditFieldSplL.Enable = "off";
            app.EditFieldFoL.Enable = "off";
            app.EditFieldApL.Enable = "off";
            app.EditFieldTotalWR.Value = 0.5;
            app.EditFieldFxR.Value = 0.5;
            app.EditFieldTxR.Value = 0.5;
            app.EditFieldSplR.Value = 0.5;
            app.EditFieldFoR.Value = 0.5;
            app.EditFieldApR.Value = 0.5;
            app.EditFieldFxR.Enable = "off";
            app.EditFieldTxR.Enable = "off";
            app.EditFieldSplR.Enable = "off";
            app.EditFieldFoR.Enable = "off";
            app.EditFieldApR.Enable = "off";
            app.stepsDropDown.Editable = "off";
            app.frequencyaxismorphingrateSlider.Enable = "off";
            app.timeaxismorphingrateSlider.Enable = "off";
            app.fundamentalfrequencymorphingrateSlider.Enable = "off";
            app.spectrumlevelmorphingrateSlider.Enable = "off";
            app.aperiodicitymorphingrateSlider.Enable = "off";
            app.wholemorphingrateSlider.Enable = "off";
            app.SYNTHESISButton.Enable = "off";
            app.SAVEButton.Enable = "off";
            app.ASSIGNAButton.Enable = "off";
            app.ASSIGNBButton.Enable = "off";
            app.RECALLAButton.Enable = "off";
            app.RECALLBButton.Enable = "off";
            app.MorphingratesettingButtonGroup.Enable = "off";
            app.CommonData.originalSignalL = [];
            app.CommonData.originalSignalR = [];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            initializePanel(app)
            value = app.continuumprefixEditField.Value;
            app.CommonData.prefixName = value;
            app.CommonData.mRate = 0.5;
        end

        % Button pushed function: READLButton
        function READLButtonPushed(app, event)
            [paramFile, paramPath] = uigetfile({'*.mat'},"Select parameter file:");
            if isequal(paramFile,0)
                disp("cancelled")
                return;
            end
            tmp = load([paramPath '/' paramFile]);
            app.CommonData.fileNameL = paramFile;
            app.FileNameLLabel.Text = paramFile;
            app.CommonData.worldParamsL = tmp.worldParameter;
            app.CommonData.timeAnchorL = tmp.timeAnchor;
            app.CommonData.timeFreqAnchorL = tmp.timeFreqAnchor;
            app.CommonData.originalSignalL = tmp.worldParameter.signal;
            if ~isempty(app.CommonData.originalSignalR)
                app.SYNTHESISButton.Enable = "on";
                app.wholemorphingrateSlider.Enable = "on";
                app.MorphingratesettingButtonGroup.Enable = "on";
            end
        end

        % Button pushed function: SYNTHESISButton
        function SYNTHESISButtonPushed(app, event)
            worldPRef = app.CommonData.worldParamsL;
            worldPTgt = app.CommonData.worldParamsR;
            anchStr = struct;
            anchStr.timeAnchorReference = app.CommonData.timeAnchorL;
            anchStr.timeAnchorTarget = app.CommonData.timeAnchorR;
            anchStr.timeFreqAnchorReference = app.CommonData.timeFreqAnchorL;
            anchStr.timeFreqAnchorTarget = app.CommonData.timeFreqAnchorR;
            if app.connectedButton.Value == 1
                mRate = app.EditFieldTotalWR.Value;
            else
                mRate = struct;
                mRate.tx = app.EditFieldTxR.Value;
                mRate.fx = app.EditFieldFxR.Value;
                mRate.fo = app.EditFieldFoR.Value;
                mRate.sl = app.EditFieldSplR.Value;
                mRate.ap = app.EditFieldApR.Value;
            end
            app.CommonData.mRate = mRate;
            vtl_rtio = 1;
            morphStr = wordTV2WmorphingEngineRev(worldPRef, worldPTgt, anchStr, ...
                mRate, vtl_rtio, 0);
            xSynth = Synthesis(morphStr.source_parameter, ...
                morphStr.spectrum_parameter);
            soundsc(xSynth,worldPRef.samplingFrequency);
            app.CommonData.synthesizedSignal = xSynth/max(abs(xSynth))*0.8;
            app.SAVEButton.Enable = "on";
            app.ASSIGNAButton.Enable = "on";
            app.ASSIGNBButton.Enable = "on";
        end

        % Button pushed function: READRButton
        function READRButtonPushed(app, event)
            [paramFile, paramPath] = uigetfile({'*.mat'},"Select parameter file:");
            if isequal(paramFile,0)
                disp("cancelled")
                return;
            end
            tmp = load([paramPath '/' paramFile]);
            app.CommonData.fileNameR = paramFile;
            app.FileNameRLabel.Text = paramFile;
            app.CommonData.worldParamsR = tmp.worldParameter;
            app.CommonData.timeAnchorR = tmp.timeAnchor;
            app.CommonData.timeFreqAnchorR = tmp.timeFreqAnchor;
            app.CommonData.originalSignalR = tmp.worldParameter.signal;
            if ~isempty(app.CommonData.originalSignalL)
                app.SYNTHESISButton.Enable = "on";
                app.wholemorphingrateSlider.Enable = "on";
                app.MorphingratesettingButtonGroup.Enable = "on";
            end
        end

        % Value changed function: continuumprefixEditField
        function continuumprefixEditFieldValueChanged(app, event)
            value = app.continuumprefixEditField.Value;
            app.CommonData.prefixName = value;
        end

        % Value changed function: EditFieldTotalWL
        function EditFieldTotalWLValueChanged(app, event)
            value = app.EditFieldTotalWL.Value;
            app.EditFieldTotalWR.Value = 1-value;
            app.wholemorphingrateSlider.Value = 1-value;
            SYNTHESISButtonPushed(app,event);
        end

        % Value changed function: EditFieldTotalWR
        function EditFieldTotalWRValueChanged(app, event)
            value = app.EditFieldTotalWR.Value;
            app.EditFieldTotalWL.Value = 1-value;
            app.wholemorphingrateSlider.Value = value;
            SYNTHESISButtonPushed(app,event);
        end

        % Value changing function: wholemorphingrateSlider
        function wholemorphingrateSliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditFieldTotalWR.Value = str2double(num2str(changingValue,2));
            app.EditFieldTotalWL.Value = str2double(num2str(1-changingValue,2));
            app.ASSIGNAButton.Enable = "off";
            app.ASSIGNBButton.Enable = "off";
        end

        % Value changed function: wholemorphingrateSlider
        function wholemorphingrateSliderValueChanged(app, event)
            %value = app.wholemorphingrateSlider.Value;
            SYNTHESISButtonPushed(app,event);
        end

        % Selection changed function: MorphingratesettingButtonGroup
        function MorphingratesettingButtonGroupSelectionChanged(app, event)
            selectedButton = app.MorphingratesettingButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'individual'
                    app.EditFieldTotalWL.Enable = "off";
                    app.EditFieldTotalWR.Enable = "off";
                    app.EditFieldFxL.Enable = "on";
                    app.EditFieldTxL.Enable = "on";
                    app.EditFieldSplL.Enable = "on";
                    app.EditFieldFoL.Enable = "on";
                    app.EditFieldApL.Enable = "on";
                    app.EditFieldFxR.Enable = "on";
                    app.EditFieldTxR.Enable = "on";
                    app.EditFieldSplR.Enable = "on";
                    app.EditFieldFoR.Enable = "on";
                    app.EditFieldApR.Enable = "on";
                    app.wholemorphingrateSlider.Enable = "off";
                    app.frequencyaxismorphingrateSlider.Enable = "on";
                    app.timeaxismorphingrateSlider.Enable = "on";
                    app.fundamentalfrequencymorphingrateSlider.Enable = "on";
                    app.spectrumlevelmorphingrateSlider.Enable = "on";
                    app.aperiodicitymorphingrateSlider.Enable = "on";
                case 'connected'
                    app.EditFieldTotalWL.Enable = "on";
                    app.EditFieldTotalWR.Enable = "on";
                    app.EditFieldFxL.Enable = "off";
                    app.EditFieldTxL.Enable = "off";
                    app.EditFieldSplL.Enable = "off";
                    app.EditFieldFoL.Enable = "off";
                    app.EditFieldApL.Enable = "off";
                    app.EditFieldFxR.Enable = "off";
                    app.EditFieldTxR.Enable = "off";
                    app.EditFieldSplR.Enable = "off";
                    app.EditFieldFoR.Enable = "off";
                    app.EditFieldApR.Enable = "off";
                    app.wholemorphingrateSlider.Enable = "on";
                    app.frequencyaxismorphingrateSlider.Enable = "off";
                    app.timeaxismorphingrateSlider.Enable = "off";
                    app.fundamentalfrequencymorphingrateSlider.Enable = "off";
                    app.spectrumlevelmorphingrateSlider.Enable = "off";
                    app.aperiodicitymorphingrateSlider.Enable = "off";
            end
            app.ASSIGNAButton.Enable = "off";
            app.ASSIGNBButton.Enable = "off";
        end

        % Value changing function: frequencyaxismorphingrateSlider
        function frequencyaxismorphingrateSliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditFieldFxR.Value = str2double(num2str(changingValue,2));
            app.EditFieldFxL.Value = str2double(num2str(1-changingValue,2));
            app.ASSIGNAButton.Enable = "off";
            app.ASSIGNBButton.Enable = "off";
        end

        % Value changing function: timeaxismorphingrateSlider
        function timeaxismorphingrateSliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditFieldTxR.Value = str2double(num2str(changingValue,2));
            app.EditFieldTxL.Value = str2double(num2str(1-changingValue,2));
            app.ASSIGNAButton.Enable = "off";
            app.ASSIGNBButton.Enable = "off";
        end

        % Value changing function: spectrumlevelmorphingrateSlider
        function spectrumlevelmorphingrateSliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditFieldSplR.Value = str2double(num2str(changingValue,2));
            app.EditFieldSplL.Value = str2double(num2str(1-changingValue,2));
            app.ASSIGNAButton.Enable = "off";
            app.ASSIGNBButton.Enable = "off";
        end

        % Value changing function: fundamentalfrequencymorphingrateSlider
        function fundamentalfrequencymorphingrateSliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditFieldFoR.Value = str2double(num2str(changingValue,2));
            app.EditFieldFoL.Value = str2double(num2str(1-changingValue,2));
            app.ASSIGNAButton.Enable = "off";
            app.ASSIGNBButton.Enable = "off";
        end

        % Value changing function: aperiodicitymorphingrateSlider
        function aperiodicitymorphingrateSliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditFieldApR.Value = str2double(num2str(changingValue,2));
            app.EditFieldApL.Value = str2double(num2str(1-changingValue,2));
            app.ASSIGNAButton.Enable = "off";
            app.ASSIGNBButton.Enable = "off";
        end

        % Value changed function: EditFieldFxL
        function EditFieldFxLValueChanged(app, event)
            value = app.EditFieldFxL.Value;
            app.EditFieldFxR.Value = 1-value;
            app.frequencyaxismorphingrateSlider.Value = 1-value;
            app.ASSIGNAButton.Enable = "off";
            app.ASSIGNBButton.Enable = "off";
        end

        % Value changed function: EditFieldTxL
        function EditFieldTxLValueChanged(app, event)
            value = app.EditFieldTxL.Value;
            app.EditFieldTxR.Value = 1-value;
            app.timeaxismorphingrateSlider.Value = 1-value;
            app.ASSIGNAButton.Enable = "off";
            app.ASSIGNBButton.Enable = "off";
        end

        % Value changed function: EditFieldSplL
        function EditFieldSplLValueChanged(app, event)
            value = app.EditFieldSplL.Value;
            app.EditFieldSplR.Value = 1-value;
            app.spectrumlevelmorphingrateSlider.Value = 1-value;
            app.ASSIGNAButton.Enable = "off";
            app.ASSIGNBButton.Enable = "off";
        end

        % Value changed function: EditFieldFoL
        function EditFieldFoLValueChanged(app, event)
            value = app.EditFieldFoL.Value;
            app.EditFieldFoR.Value = 1-value;
            app.fundamentalfrequencymorphingrateSlider.Value = 1-value;
            app.ASSIGNAButton.Enable = "off";
            app.ASSIGNBButton.Enable = "off";
        end

        % Value changed function: EditFieldApL
        function EditFieldApLValueChanged(app, event)
            value = app.EditFieldApL.Value;
            app.EditFieldApR.Value = 1-value;
            app.aperiodicitymorphingrateSlider.Value = 1-value;
            app.ASSIGNAButton.Enable = "off";
            app.ASSIGNBButton.Enable = "off";
        end

        % Value changed function: EditFieldFxR
        function EditFieldFxRValueChanged(app, event)
            value = app.EditFieldFxR.Value;
            app.EditFieldFxL.Value = 1-value;
            app.frequencyaxismorphingrateSlider.Value = value;
            app.ASSIGNAButton.Enable = "off";
            app.ASSIGNBButton.Enable = "off";
        end

        % Value changed function: EditFieldTxR
        function EditFieldTxRValueChanged(app, event)
            value = app.EditFieldTxR.Value;
            app.EditFieldTxL.Value = 1-value;
            app.timeaxismorphingrateSlider.Value = value;
            app.ASSIGNAButton.Enable = "off";
            app.ASSIGNBButton.Enable = "off";
        end

        % Value changed function: EditFieldSplR
        function EditFieldSplRValueChanged(app, event)
            value = app.EditFieldSplR.Value;
            app.EditFieldSplL.Value = 1-value;
            app.spectrumlevelmorphingrateSlider.Value = value;
            app.ASSIGNAButton.Enable = "off";
            app.ASSIGNBButton.Enable = "off";
        end

        % Value changed function: EditFieldFoR
        function EditFieldFoRValueChanged(app, event)
            value = app.EditFieldFoR.Value;
            app.EditFieldFoL.Value = 1-value;
            app.fundamentalfrequencymorphingrateSlider.Value = value;
            app.ASSIGNAButton.Enable = "off";
            app.ASSIGNBButton.Enable = "off";
        end

        % Value changed function: EditFieldApR
        function EditFieldApRValueChanged(app, event)
            value = app.EditFieldApR.Value;
            app.EditFieldApL.Value = 1-value;
            app.aperiodicitymorphingrateSlider.Value = value;
            app.ASSIGNAButton.Enable = "off";
            app.ASSIGNBButton.Enable = "off";
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            outFileName = ['morphT' datestr(now,30) '.wav'];
            [fileName,filePath] = uiputfile('*.wav','save parameter',outFileName);
            if isequal(filePath, 0) || isequal(fileName, 0)
                disp("cancelled")
                return;
            end
            fs = app.CommonData.worldParamsL.samplingFrequency;
            app.CommonData.outFilePath = filePath;
            app.CommonData.outSynthFileName = fileName;
            synthModDataFileName = string(fileName(1:end-4)) + ".mat";
            audiowrite(string(app.CommonData.outFilePath) ...
                + string(app.CommonData.outSynthFileName),...
                app.CommonData.synthesizedSignal, fs,"BitsPerSample", 24, ...
                "Comment", synthModDataFileName);
            morphingCondStr = struct;
            morphingCondStr.creationDate = datestr(now); 
            morphingCondStr.paramFile1 = app.CommonData.fileNameL;
            morphingCondStr.paramFile2 = app.CommonData.fileNameR;
            morphingCondStr.mRate = app.CommonData.mRate;
            save(app.CommonData.outFilePath+synthModDataFileName,'morphingCondStr');
        end

        % Button pushed function: ASSIGNAButton
        function ASSIGNAButtonPushed(app, event)
            app.CommonData.AssignA.connected = app.connectedButton.Value;
            app.CommonData.AssignA.mRate = app.CommonData.mRate;
            app.RECALLAButton.Enable = "on";
        end

        % Button pushed function: ASSIGNBButton
        function ASSIGNBButtonPushed(app, event)
            app.CommonData.AssignB.connected = app.connectedButton.Value;
            app.CommonData.AssignB.mRate = app.CommonData.mRate;
            app.RECALLBButton.Enable = "on";
        end

        % Button pushed function: RECALLAButton
        function RECALLAButtonPushed(app, event)
            app.connectedButton.Value = app.CommonData.AssignA.connected;
            if isstruct(app.CommonData.AssignA.mRate)
                mRate = app.CommonData.AssignA.mRate;
                    app.EditFieldTotalWL.Enable = "off";
                    app.EditFieldTotalWR.Enable = "off";
                    app.EditFieldFxL.Enable = "on";app.EditFieldFxL.Value = 1-mRate.fx;
                    app.EditFieldTxL.Enable = "on";app.EditFieldFxR.Value = mRate.fx;
                    app.EditFieldSplL.Enable = "on";app.EditFieldTxL.Value = 1-mRate.tx;
                    app.EditFieldFoL.Enable = "on";app.EditFieldTxR.Value = mRate.tx;
                    app.EditFieldApL.Enable = "on";app.EditFieldSplL.Value = 1-mRate.sl;
                    app.EditFieldFxR.Enable = "on";app.EditFieldSplR.Value = mRate.sl;
                    app.EditFieldTxR.Enable = "on";app.EditFieldFoL.Value = 1-mRate.fo;
                    app.EditFieldSplR.Enable = "on";app.EditFieldFoR.Value = mRate.fo;
                    app.EditFieldFoR.Enable = "on";app.EditFieldApL.Value = 1-mRate.ap;
                    app.EditFieldApR.Enable = "on";app.EditFieldApR.Value = mRate.ap;
                    app.wholemorphingrateSlider.Enable = "off";
                    app.frequencyaxismorphingrateSlider.Enable = "on";
                    app.frequencyaxismorphingrateSlider.Value = mRate.fx;
                    app.timeaxismorphingrateSlider.Enable = "on";
                    app.timeaxismorphingrateSlider.Value = mRate.tx;
                    app.fundamentalfrequencymorphingrateSlider.Enable = "on";
                    app.fundamentalfrequencymorphingrateSlider.Value = mRate.fo;
                    app.spectrumlevelmorphingrateSlider.Enable = "on";
                    app.spectrumlevelmorphingrateSlider.Value = mRate.sl;
                    app.aperiodicitymorphingrateSlider.Enable = "on";
                    app.aperiodicitymorphingrateSlider.Value = mRate.ap;
            else
                    app.EditFieldTotalWL.Enable = "on";
                    app.EditFieldTotalWR.Enable = "on";
                    app.EditFieldFxL.Enable = "off";
                    app.EditFieldTxL.Enable = "off";
                    app.EditFieldSplL.Enable = "off";
                    app.EditFieldFoL.Enable = "off";
                    app.EditFieldApL.Enable = "off";
                    app.EditFieldFxR.Enable = "off";
                    app.EditFieldTxR.Enable = "off";
                    app.EditFieldSplR.Enable = "off";
                    app.EditFieldFoR.Enable = "off";
                    app.EditFieldApR.Enable = "off";
                    app.wholemorphingrateSlider.Enable = "on";
                    app.frequencyaxismorphingrateSlider.Enable = "off";
                    app.timeaxismorphingrateSlider.Enable = "off";
                    app.fundamentalfrequencymorphingrateSlider.Enable = "off";
                    app.spectrumlevelmorphingrateSlider.Enable = "off";
                    app.aperiodicitymorphingrateSlider.Enable = "off";
                    app.wholemorphingrateSlider.Value = app.CommonData.AssignA.mRate;
                    app.EditFieldTotalWR.Value = app.CommonData.AssignA.mRate;
                    app.EditFieldTotalWL.Value = 1-app.CommonData.AssignA.mRate;
            end
        end

        % Button pushed function: RECALLBButton
        function RECALLBButtonPushed(app, event)
            app.connectedButton.Value = app.CommonData.AssignB.connected;
            if isstruct(app.CommonData.AssignB.mRate)
                mRate = app.CommonData.AssignB.mRate;
                    app.EditFieldTotalWL.Enable = "off";
                    app.EditFieldTotalWR.Enable = "off";
                    app.EditFieldFxL.Enable = "on";app.EditFieldFxL.Value = 1-mRate.fx;
                    app.EditFieldTxL.Enable = "on";app.EditFieldFxR.Value = mRate.fx;
                    app.EditFieldSplL.Enable = "on";app.EditFieldTxL.Value = 1-mRate.tx;
                    app.EditFieldFoL.Enable = "on";app.EditFieldTxR.Value = mRate.tx;
                    app.EditFieldApL.Enable = "on";app.EditFieldSplL.Value = 1-mRate.sl;
                    app.EditFieldFxR.Enable = "on";app.EditFieldSplR.Value = mRate.sl;
                    app.EditFieldTxR.Enable = "on";app.EditFieldFoL.Value = 1-mRate.fo;
                    app.EditFieldSplR.Enable = "on";app.EditFieldFoR.Value = mRate.fo;
                    app.EditFieldFoR.Enable = "on";app.EditFieldApL.Value = 1-mRate.ap;
                    app.EditFieldApR.Enable = "on";app.EditFieldApR.Value = mRate.ap;
                    app.wholemorphingrateSlider.Enable = "off";
                    app.frequencyaxismorphingrateSlider.Enable = "on";
                    app.frequencyaxismorphingrateSlider.Value = mRate.fx;
                    app.timeaxismorphingrateSlider.Enable = "on";
                    app.timeaxismorphingrateSlider.Value = mRate.tx;
                    app.fundamentalfrequencymorphingrateSlider.Enable = "on";
                    app.fundamentalfrequencymorphingrateSlider.Value = mRate.fo;
                    app.spectrumlevelmorphingrateSlider.Enable = "on";
                    app.spectrumlevelmorphingrateSlider.Value = mRate.sl;
                    app.aperiodicitymorphingrateSlider.Enable = "on";
                    app.aperiodicitymorphingrateSlider.Value = mRate.ap;
            else
                    app.EditFieldTotalWL.Enable = "on";
                    app.EditFieldTotalWR.Enable = "on";
                    app.EditFieldFxL.Enable = "off";
                    app.EditFieldTxL.Enable = "off";
                    app.EditFieldSplL.Enable = "off";
                    app.EditFieldFoL.Enable = "off";
                    app.EditFieldApL.Enable = "off";
                    app.EditFieldFxR.Enable = "off";
                    app.EditFieldTxR.Enable = "off";
                    app.EditFieldSplR.Enable = "off";
                    app.EditFieldFoR.Enable = "off";
                    app.EditFieldApR.Enable = "off";
                    app.wholemorphingrateSlider.Enable = "on";
                    app.frequencyaxismorphingrateSlider.Enable = "off";
                    app.timeaxismorphingrateSlider.Enable = "off";
                    app.fundamentalfrequencymorphingrateSlider.Enable = "off";
                    app.spectrumlevelmorphingrateSlider.Enable = "off";
                    app.aperiodicitymorphingrateSlider.Enable = "off";
                    app.wholemorphingrateSlider.Value = app.CommonData.AssignB.mRate;
                    app.EditFieldTotalWR.Value = app.CommonData.AssignB.mRate;
                    app.EditFieldTotalWL.Value = 1-app.CommonData.AssignB.mRate;
            end
        end

        % Button pushed function: CHECKButton
        function CHECKButtonPushed(app, event)
            fs = app.CommonData.worldParamsL.samplingFrequency;
            if isstruct(app.CommonData.AssignA.mRate)
                m1Rate.fx = app.CommonData.AssignA.mRate.fx;
                m2ate.fx = app.CommonData.AssignB.mRate.fx;
                m1Rate.tx = app.CommonData.AssignA.mRate.tx;
                m2ate.tx = app.CommonData.AssignB.mRate.tx;
                m1Rate.sl = app.CommonData.AssignA.mRate.sl;
                m2ate.sl = app.CommonData.AssignB.mRate.sl;
                m1Rate.fo = app.CommonData.AssignA.mRate.fo;
                m2ate.fo = app.CommonData.AssignB.mRate.fo;
                m1Rate.ap = app.CommonData.AssignA.mRate.ap;
                m2ate.ap = app.CommonData.AssignB.mRate.ap;
                nItems = str2double(app.stepsDropDown.Value);
                for ii = 0:nItems-1
                    mRate.fx = ii * (m2ate.fx-m1Rate.fx)/(nItems-1)+m1Rate.fx;
                    mRate.tx = ii * (m2ate.tx-m1Rate.tx)/(nItems-1)+m1Rate.tx;
                    mRate.sl = ii * (m2ate.sl-m1Rate.sl)/(nItems-1)+m1Rate.sl;
                    mRate.fo = ii * (m2ate.fo-m1Rate.fo)/(nItems-1)+m1Rate.fo;
                    mRate.ap = ii * (m2ate.ap-m1Rate.ap)/(nItems-1)+m1Rate.ap;
                    app.EditFieldTotalWL.Enable = "off";
                    app.EditFieldTotalWR.Enable = "off";
                    app.EditFieldFxL.Enable = "on";app.EditFieldFxL.Value = 1-mRate.fx;
                    app.EditFieldTxL.Enable = "on";app.EditFieldFxR.Value = mRate.fx;
                    app.EditFieldSplL.Enable = "on";app.EditFieldTxL.Value = 1-mRate.tx;
                    app.EditFieldFoL.Enable = "on";app.EditFieldTxR.Value = mRate.tx;
                    app.EditFieldApL.Enable = "on";app.EditFieldSplL.Value = 1-mRate.sl;
                    app.EditFieldFxR.Enable = "on";app.EditFieldSplR.Value = mRate.sl;
                    app.EditFieldTxR.Enable = "on";app.EditFieldFoL.Value = 1-mRate.fo;
                    app.EditFieldSplR.Enable = "on";app.EditFieldFoR.Value = mRate.fo;
                    app.EditFieldFoR.Enable = "on";app.EditFieldApL.Value = 1-mRate.fo;
                    app.EditFieldApR.Enable = "on";app.EditFieldApR.Value = mRate.ap;
                    app.wholemorphingrateSlider.Enable = "off";
                    app.frequencyaxismorphingrateSlider.Enable = "on";
                    app.frequencyaxismorphingrateSlider.Value = mRate.fx;
                    app.timeaxismorphingrateSlider.Enable = "on";
                    app.timeaxismorphingrateSlider.Value = mRate.tx;
                    app.fundamentalfrequencymorphingrateSlider.Enable = "on";
                    app.fundamentalfrequencymorphingrateSlider.Value = mRate.fo;
                    app.spectrumlevelmorphingrateSlider.Enable = "on";
                    app.spectrumlevelmorphingrateSlider.Value = mRate.sl;
                    app.aperiodicitymorphingrateSlider.Enable = "on";
                    app.aperiodicitymorphingrateSlider.Value = mRate.ap;
                    SYNTHESISButtonPushed(app,event);
                    pause(length(app.CommonData.synthesizedSignal)/fs+0.5);
                end
            else
                m1 = app.CommonData.AssignA.mRate;
                m2 = app.CommonData.AssignB.mRate;
                nItems = str2double(app.stepsDropDown.Value);
                for ii = 0:nItems-1
                    tmpMrate = ii * (m2-m1)/(nItems-1)+m1;
                    app.EditFieldTotalWL.Enable = "on";
                    app.EditFieldTotalWR.Enable = "on";
                    app.EditFieldFxL.Enable = "off";
                    app.EditFieldTxL.Enable = "off";
                    app.EditFieldSplL.Enable = "off";
                    app.EditFieldFoL.Enable = "off";
                    app.EditFieldApL.Enable = "off";
                    app.EditFieldFxR.Enable = "off";
                    app.EditFieldTxR.Enable = "off";
                    app.EditFieldSplR.Enable = "off";
                    app.EditFieldFoR.Enable = "off";
                    app.EditFieldApR.Enable = "off";
                    app.wholemorphingrateSlider.Enable = "on";
                    app.frequencyaxismorphingrateSlider.Enable = "off";
                    app.timeaxismorphingrateSlider.Enable = "off";
                    app.fundamentalfrequencymorphingrateSlider.Enable = "off";
                    app.spectrumlevelmorphingrateSlider.Enable = "off";
                    app.aperiodicitymorphingrateSlider.Enable = "off";
                    app.wholemorphingrateSlider.Value = tmpMrate;
                    app.EditFieldTotalWR.Value = tmpMrate;
                    app.EditFieldTotalWL.Value = 1-tmpMrate;
                    SYNTHESISButtonPushed(app,event);
                    pause(length(app.CommonData.synthesizedSignal)/fs+0.5);
                end
            end
        end

        % Button pushed function: GENERATEButton
        function GENERATEButtonPushed(app, event)
            prefix = app.continuumprefixEditField.Value;
            outFileName = [prefix datestr(now,30) '.wav'];
            [fileName,filePath] = uiputfile('*.wav','save parameter',outFileName);
            if isequal(filePath, 0) || isequal(fileName, 0)
                disp("cancelled")
                return;
            end
            fs = app.CommonData.worldParamsL.samplingFrequency;
            app.CommonData.outFilePath = filePath;
            app.CommonData.outSynthFileName = fileName;
            synthModDataFileName = string(fileName(1:end-4)) + ".mat";
            morphingCondStr = struct;
            morphingCondStr.creationDate = datestr(now); 
            morphingCondStr.paramFile1 = app.CommonData.fileNameL;
            morphingCondStr.paramFile2 = app.CommonData.fileNameR;
            morphingCondStr.mRateA = app.CommonData.AssignA.mRate;
            morphingCondStr.mRateB = app.CommonData.AssignB.mRate;
            save(app.CommonData.outFilePath+synthModDataFileName,'morphingCondStr');
            if isstruct(app.CommonData.AssignA.mRate)
                m1Rate.fx = app.CommonData.AssignA.mRate.fx;
                m2ate.fx = app.CommonData.AssignB.mRate.fx;
                m1Rate.tx = app.CommonData.AssignA.mRate.tx;
                m2ate.tx = app.CommonData.AssignB.mRate.tx;
                m1Rate.sl = app.CommonData.AssignA.mRate.sl;
                m2ate.sl = app.CommonData.AssignB.mRate.sl;
                m1Rate.fo = app.CommonData.AssignA.mRate.fo;
                m2ate.fo = app.CommonData.AssignB.mRate.fo;
                m1Rate.ap = app.CommonData.AssignA.mRate.ap;
                m2ate.ap = app.CommonData.AssignB.mRate.ap;
                nItems = str2double(app.stepsDropDown.Value);
                for ii = 0:nItems-1
                    outFileNameC = [outFileName(1:end-4) num2str(ii) '.wav'];
                    mRate.fx = ii * (m2ate.fx-m1Rate.fx)/(nItems-1)+m1Rate.fx;
                    mRate.tx = ii * (m2ate.tx-m1Rate.tx)/(nItems-1)+m1Rate.tx;
                    mRate.sl = ii * (m2ate.sl-m1Rate.sl)/(nItems-1)+m1Rate.sl;
                    mRate.fo = ii * (m2ate.fo-m1Rate.fo)/(nItems-1)+m1Rate.fo;
                    mRate.ap = ii * (m2ate.ap-m1Rate.ap)/(nItems-1)+m1Rate.ap;
                    app.EditFieldTotalWL.Enable = "off";
                    app.EditFieldTotalWR.Enable = "off";
                    app.EditFieldFxL.Enable = "on";app.EditFieldFxL.Value = 1-mRate.fx;
                    app.EditFieldTxL.Enable = "on";app.EditFieldFxR.Value = mRate.fx;
                    app.EditFieldSplL.Enable = "on";app.EditFieldTxL.Value = 1-mRate.tx;
                    app.EditFieldFoL.Enable = "on";app.EditFieldTxR.Value = mRate.tx;
                    app.EditFieldApL.Enable = "on";app.EditFieldSplL.Value = 1-mRate.sl;
                    app.EditFieldFxR.Enable = "on";app.EditFieldSplR.Value = mRate.sl;
                    app.EditFieldTxR.Enable = "on";app.EditFieldFoL.Value = 1-mRate.fo;
                    app.EditFieldSplR.Enable = "on";app.EditFieldFoR.Value = mRate.fo;
                    app.EditFieldFoR.Enable = "on";app.EditFieldApL.Value = 1-mRate.fo;
                    app.EditFieldApR.Enable = "on";app.EditFieldApR.Value = mRate.ap;
                    app.wholemorphingrateSlider.Enable = "off";
                    app.frequencyaxismorphingrateSlider.Enable = "on";
                    app.frequencyaxismorphingrateSlider.Value = mRate.fx;
                    app.timeaxismorphingrateSlider.Enable = "on";
                    app.timeaxismorphingrateSlider.Value = mRate.tx;
                    app.fundamentalfrequencymorphingrateSlider.Enable = "on";
                    app.fundamentalfrequencymorphingrateSlider.Value = mRate.fo;
                    app.spectrumlevelmorphingrateSlider.Enable = "on";
                    app.spectrumlevelmorphingrateSlider.Value = mRate.sl;
                    app.aperiodicitymorphingrateSlider.Enable = "on";
                    app.aperiodicitymorphingrateSlider.Value = mRate.ap;
                    SYNTHESISButtonPushed(app,event);
                    pause(length(app.CommonData.synthesizedSignal)/fs+0.5);
                    audiowrite(string(app.CommonData.outFilePath) ...
                + string(outFileNameC),...
                app.CommonData.synthesizedSignal, fs,"BitsPerSample", 24, ...
                "Comment", synthModDataFileName);
                end
            else
                m1 = app.CommonData.AssignA.mRate;
                m2 = app.CommonData.AssignB.mRate;
                nItems = str2double(app.stepsDropDown.Value);
                for ii = 0:nItems-1
                    outFileNameC = [outFileName(1:end-4) num2str(ii) '.wav'];
                    tmpMrate = ii * (m2-m1)/(nItems-1)+m1;
                    app.EditFieldTotalWL.Enable = "on";
                    app.EditFieldTotalWR.Enable = "on";
                    app.EditFieldFxL.Enable = "off";
                    app.EditFieldTxL.Enable = "off";
                    app.EditFieldSplL.Enable = "off";
                    app.EditFieldFoL.Enable = "off";
                    app.EditFieldApL.Enable = "off";
                    app.EditFieldFxR.Enable = "off";
                    app.EditFieldTxR.Enable = "off";
                    app.EditFieldSplR.Enable = "off";
                    app.EditFieldFoR.Enable = "off";
                    app.EditFieldApR.Enable = "off";
                    app.wholemorphingrateSlider.Enable = "on";
                    app.frequencyaxismorphingrateSlider.Enable = "off";
                    app.timeaxismorphingrateSlider.Enable = "off";
                    app.fundamentalfrequencymorphingrateSlider.Enable = "off";
                    app.spectrumlevelmorphingrateSlider.Enable = "off";
                    app.aperiodicitymorphingrateSlider.Enable = "off";
                    app.wholemorphingrateSlider.Value = tmpMrate;
                    app.EditFieldTotalWR.Value = tmpMrate;
                    app.EditFieldTotalWL.Value = 1-tmpMrate;
                    SYNTHESISButtonPushed(app,event);
                    pause(length(app.CommonData.synthesizedSignal)/fs+0.5);
                    audiowrite(string(app.CommonData.outFilePath) ...
                + string(outFileNameC),...
                app.CommonData.synthesizedSignal, fs,"BitsPerSample", 24, ...
                "Comment", synthModDataFileName);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.READLButton = uibutton(app.UIFigure, 'push');
            app.READLButton.ButtonPushedFcn = createCallbackFcn(app, @READLButtonPushed, true);
            app.READLButton.Text = 'READ L';
            app.READRButton = uibutton(app.UIFigure, 'push');
            app.READRButton.ButtonPushedFcn = createCallbackFcn(app, @READRButtonPushed, true);
            app.READRButton.Text = 'READ R';
            app.MorphingratesettingButtonGroup = uibuttongroup(app.UIFigure);
            app.MorphingratesettingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MorphingratesettingButtonGroupSelectionChanged, true);
            app.MorphingratesettingButtonGroup.Title = 'Morphing rate setting';
            app.connectedButton = uiradiobutton(app.MorphingratesettingButtonGroup);
            app.connectedButton.Text = 'connected';
            app.connectedButton.Value = true;
            app.individualButton = uiradiobutton(app.MorphingratesettingButtonGroup);
            app.individualButton.Text = 'individual';
            app.wholemorphingrateSliderLabel = uilabel(app.UIFigure);
            app.wholemorphingrateSliderLabel.Text = 'whole morphing rate';
            app.wholemorphingrateSlider = uislider(app.UIFigure);
            app.wholemorphingrateSlider.Limits = [0 1];
            app.wholemorphingrateSlider.MajorTicks = [0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.wholemorphingrateSlider.ValueChangedFcn = createCallbackFcn(app, @wholemorphingrateSliderValueChanged, true);
            app.wholemorphingrateSlider.ValueChangingFcn = createCallbackFcn(app, @wholemorphingrateSliderValueChanging, true);
            app.wholemorphingrateSlider.Value = 0.5;
            app.frequencyaxismorphingrateSliderLabel = uilabel(app.UIFigure);
            app.frequencyaxismorphingrateSliderLabel.Text = 'frequency axis morphing rate';
            app.frequencyaxismorphingrateSlider = uislider(app.UIFigure);
            app.frequencyaxismorphingrateSlider.Limits = [0 1];
            app.frequencyaxismorphingrateSlider.MajorTicks = [0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.frequencyaxismorphingrateSlider.ValueChangingFcn = createCallbackFcn(app, @frequencyaxismorphingrateSliderValueChanging, true);
            app.frequencyaxismorphingrateSlider.Value = 0.5;
            app.timeaxismorphingrateSliderLabel = uilabel(app.UIFigure);
            app.timeaxismorphingrateSliderLabel.Text = 'time axis morphing rate';
            app.timeaxismorphingrateSlider = uislider(app.UIFigure);
            app.timeaxismorphingrateSlider.Limits = [0 1];
            app.timeaxismorphingrateSlider.MajorTicks = [0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.timeaxismorphingrateSlider.ValueChangingFcn = createCallbackFcn(app, @timeaxismorphingrateSliderValueChanging, true);
            app.timeaxismorphingrateSlider.Value = 0.5;
            app.spectrumlevelmorphingrateSliderLabel = uilabel(app.UIFigure);
            app.spectrumlevelmorphingrateSliderLabel.Text = 'spectrum level morphing rate';
            app.spectrumlevelmorphingrateSlider = uislider(app.UIFigure);
            app.spectrumlevelmorphingrateSlider.Limits = [0 1];
            app.spectrumlevelmorphingrateSlider.MajorTicks = [0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.spectrumlevelmorphingrateSlider.ValueChangingFcn = createCallbackFcn(app, @spectrumlevelmorphingrateSliderValueChanging, true);
            app.spectrumlevelmorphingrateSlider.Value = 0.5;
            app.fundamentalfrequencymorphingrateSliderLabel = uilabel(app.UIFigure);
            app.fundamentalfrequencymorphingrateSliderLabel.Text = 'fundamental frequency morphing rate';
            app.fundamentalfrequencymorphingrateSlider = uislider(app.UIFigure);
            app.fundamentalfrequencymorphingrateSlider.Limits = [0 1];
            app.fundamentalfrequencymorphingrateSlider.MajorTicks = [0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.fundamentalfrequencymorphingrateSlider.ValueChangingFcn = createCallbackFcn(app, @fundamentalfrequencymorphingrateSliderValueChanging, true);
            app.fundamentalfrequencymorphingrateSlider.Value = 0.5;
            app.aperiodicitymorphingrateSliderLabel = uilabel(app.UIFigure);
            app.aperiodicitymorphingrateSliderLabel.Text = 'aperiodicity morphing rate';
            app.aperiodicitymorphingrateSlider = uislider(app.UIFigure);
            app.aperiodicitymorphingrateSlider.Limits = [0 1];
            app.aperiodicitymorphingrateSlider.MajorTicks = [0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.aperiodicitymorphingrateSlider.ValueChangingFcn = createCallbackFcn(app, @aperiodicitymorphingrateSliderValueChanging, true);
            app.aperiodicitymorphingrateSlider.Value = 0.5;
            app.EditFieldTotalWL = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldTotalWL.ValueChangedFcn = createCallbackFcn(app, @EditFieldTotalWLValueChanged, true);
            app.EditFieldTotalWR = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldTotalWR.ValueChangedFcn = createCallbackFcn(app, @EditFieldTotalWRValueChanged, true);
            app.EditFieldFxL = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldFxL.ValueChangedFcn = createCallbackFcn(app, @EditFieldFxLValueChanged, true);
            app.EditFieldTxL = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldTxL.ValueChangedFcn = createCallbackFcn(app, @EditFieldTxLValueChanged, true);
            app.EditFieldSplL = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldSplL.ValueChangedFcn = createCallbackFcn(app, @EditFieldSplLValueChanged, true);
            app.EditFieldFoL = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldFoL.ValueChangedFcn = createCallbackFcn(app, @EditFieldFoLValueChanged, true);
            app.EditFieldApL = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldApL.ValueChangedFcn = createCallbackFcn(app, @EditFieldApLValueChanged, true);
            app.EditFieldFxR = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldFxR.ValueChangedFcn = createCallbackFcn(app, @EditFieldFxRValueChanged, true);
            app.EditFieldTxR = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldTxR.ValueChangedFcn = createCallbackFcn(app, @EditFieldTxRValueChanged, true);
            app.EditFieldSplR = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldSplR.ValueChangedFcn = createCallbackFcn(app, @EditFieldSplRValueChanged, true);
            app.EditFieldFoR = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldFoR.ValueChangedFcn = createCallbackFcn(app, @EditFieldFoRValueChanged, true);
            app.EditFieldApR = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldApR.ValueChangedFcn = createCallbackFcn(app, @EditFieldApRValueChanged, true);
            app.SYNTHESISButton = uibutton(app.UIFigure, 'push');
            app.SYNTHESISButton.ButtonPushedFcn = createCallbackFcn(app, @SYNTHESISButtonPushed, true);
            app.SYNTHESISButton.Text = 'SYNTHESIS';
            app.SAVEButton = uibutton(app.UIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.ASSIGNAButton = uibutton(app.UIFigure, 'push');
            app.ASSIGNAButton.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNAButtonPushed, true);
            app.ASSIGNAButton.Text = 'ASSIGN A';
            app.RECALLAButton = uibutton(app.UIFigure, 'push');
            app.RECALLAButton.ButtonPushedFcn = createCallbackFcn(app, @RECALLAButtonPushed, true);
            app.RECALLAButton.Text = 'RECALL A';
            app.ASSIGNBButton = uibutton(app.UIFigure, 'push');
            app.ASSIGNBButton.ButtonPushedFcn = createCallbackFcn(app, @ASSIGNBButtonPushed, true);
            app.ASSIGNBButton.Text = 'ASSIGN B';
            app.RECALLBButton = uibutton(app.UIFigure, 'push');
            app.RECALLBButton.ButtonPushedFcn = createCallbackFcn(app, @RECALLBButtonPushed, true);
            app.RECALLBButton.Text = 'RECALL B';
            app.FileNameLLabel = uilabel(app.UIFigure);
            app.FileNameLLabel.Text = 'FileNameL';
            app.FileNameRLabel = uilabel(app.UIFigure);
            app.FileNameRLabel.Text = 'FileNameR';
            app.continuumprefixEditFieldLabel = uilabel(app.UIFigure);
            app.continuumprefixEditFieldLabel.Text = 'continuum prefix';
            app.continuumprefixEditField = uieditfield(app.UIFigure, 'text');
            app.continuumprefixEditField.ValueChangedFcn = createCallbackFcn(app, @continuumprefixEditFieldValueChanged, true);
            app.continuumprefixEditField.Value = 'cntnm';
            app.stepsDropDownLabel = uilabel(app.UIFigure);
            app.stepsDropDownLabel.Text = 'steps';
            app.stepsDropDown = uidropdown(app.UIFigure);
            app.stepsDropDown.Items = {'3', '5', '7', '9', '11'};
            app.stepsDropDown.Value = '11';
            app.CHECKButton = uibutton(app.UIFigure, 'push');
            app.CHECKButton.ButtonPushedFcn = createCallbackFcn(app, @CHECKButtonPushed, true);
            app.CHECKButton.Text = 'CHECK';
            app.GENERATEButton = uibutton(app.UIFigure, 'push');
            app.GENERATEButton.ButtonPushedFcn = createCallbackFcn(app, @GENERATEButtonPushed, true);
            app.GENERATEButton.Text = 'GENERATE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = morphingSoundGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end