classdef threeItemMorpherGeneralUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GreenLabel                      matlab.ui.control.Label
        BlueLabel                       matlab.ui.control.Label
        RedLabel                        matlab.ui.control.Label
        SAVEButton                      matlab.ui.control.Button
        ViewDropDown                    matlab.ui.control.DropDown
        ViewDropDownLabel               matlab.ui.control.Label
        GENERATEButton                  matlab.ui.control.Button
        LangDropDown                    matlab.ui.control.DropDown
        Label                           matlab.ui.control.Label
        MorphingsettingpanelPanel       matlab.ui.container.Panel
        AttributePanel                  matlab.ui.container.Panel
        slLamp                          matlab.ui.control.Lamp
        slLampLabel                     matlab.ui.control.Label
        apLamp                          matlab.ui.control.Lamp
        apLampLabel                     matlab.ui.control.Label
        foLamp                          matlab.ui.control.Lamp
        foLampLabel                     matlab.ui.control.Label
        fxLamp                          matlab.ui.control.Lamp
        fxLampLabel                     matlab.ui.control.Label
        txLamp                          matlab.ui.control.Lamp
        txLampLabel                     matlab.ui.control.Label
        NumberofweightpatternsDropDown  matlab.ui.control.DropDown
        NumberofweightpatternsDropDownLabel  matlab.ui.control.Label
        WeightPatternDisplayButtonGroup  matlab.ui.container.ButtonGroup
        Pattern5Button                  matlab.ui.control.RadioButton
        Pattern4Button                  matlab.ui.control.RadioButton
        Pattern3Button                  matlab.ui.control.RadioButton
        Pattern2Button                  matlab.ui.control.RadioButton
        Pattern1Button                  matlab.ui.control.RadioButton
        AperiodicityButtonGroup         matlab.ui.container.ButtonGroup
        ap5Button                       matlab.ui.control.RadioButton
        ap4Button                       matlab.ui.control.RadioButton
        ap3Button                       matlab.ui.control.RadioButton
        ap2Button                       matlab.ui.control.RadioButton
        ap1Button                       matlab.ui.control.RadioButton
        SpectrumLevelButtonGroup        matlab.ui.container.ButtonGroup
        sl5Button                       matlab.ui.control.RadioButton
        sl4Button                       matlab.ui.control.RadioButton
        sl3Button                       matlab.ui.control.RadioButton
        sl2Button                       matlab.ui.control.RadioButton
        sl1Button                       matlab.ui.control.RadioButton
        FundamentalFrequencyButtonGroup  matlab.ui.container.ButtonGroup
        fo5Button                       matlab.ui.control.RadioButton
        fo4Button                       matlab.ui.control.RadioButton
        fo3Button                       matlab.ui.control.RadioButton
        fo2Button                       matlab.ui.control.RadioButton
        fo1Button                       matlab.ui.control.RadioButton
        FrequencyAxisButtonGroup        matlab.ui.container.ButtonGroup
        fx5Button                       matlab.ui.control.RadioButton
        fx4Button                       matlab.ui.control.RadioButton
        fx3Button                       matlab.ui.control.RadioButton
        fx2Button                       matlab.ui.control.RadioButton
        fx1Button                       matlab.ui.control.RadioButton
        TimeAxisButtonGroup             matlab.ui.container.ButtonGroup
        tx5Button                       matlab.ui.control.RadioButton
        tx4Button                       matlab.ui.control.RadioButton
        tx3Button                       matlab.ui.control.RadioButton
        tx2Button                       matlab.ui.control.RadioButton
        tx1Button                       matlab.ui.control.RadioButton
        MessageTextArea                 matlab.ui.control.TextArea
        MessageTextAreaLabel            matlab.ui.control.Label
        RESETButton                     matlab.ui.control.Button
        LoadGreenFileButton             matlab.ui.control.Button
        LoadBlueFileButton              matlab.ui.control.Button
        LoadRedFileButton               matlab.ui.control.Button
        UIspecAxes                      matlab.ui.control.UIAxes
        UIfoAxes                        matlab.ui.control.UIAxes
        UIWeightAx                      matlab.ui.control.UIAxes
        UIControl3D                     matlab.ui.control.UIAxes
    end


    % Copyright 2024 Hideki Kawahara
    %
    % Licensed under the Apache License, Version 2.0 (the "License");
    % you may not use this file except in compliance with the License.
    % You may obtain a copy of the License at
    %
    %    http://www.apache.org/licenses/LICENSE-2.0
    %
    % Unless required by applicable law or agreed to in writing, software
    % distributed under the License is distributed on an "AS IS" BASIS,
    % WITHOUT WARRANTIES OR CONDITIONS OF ANY KIND, either express or implied.
    % See the License for the specific language governing permissions and
    % limitations under the License.

    properties (Access = private)
        CommonData % Shared data
    end

    methods (Access = private)

        function results = initializeGraphics(app)
            app.CommonData.redyFor2ItemMorphing = 0;
            app.CommonData.redyFor3ItemMorphing = 0;
            app.CommonData.dataXmax = 0.1;
            app.CommonData.cursor = "off";
            app.RESETButton.Visible = "off";
            app.LoadRedFileButton.Visible = "off";
            app.LoadGreenFileButton.Visible = "off";
            app.LoadBlueFileButton.Visible = "off";
            app.UIControl3D.Visible = "off";
            app.MessageTextArea.Value = ...
                {'『---』と表示されているメニューで', ...
                '使用する言語を選択して下さい', ...
                ' ', ...
                'Select Language using [---] menu'};
            while string(app.LangDropDown.Value) == "---"
                pause(0.2);
            end
            app.LangDropDown.Enable = "off";
            app.RESETButton.Visible = "on";
            app.LoadRedFileButton.Visible = "on";
            app.LoadGreenFileButton.Visible = "on";
            app.LoadBlueFileButton.Visible = "on";
            app.RedLabel.Visible = "on";
            app.BlueLabel.Visible = "on";
            app.GreenLabel.Visible = "on";
            app.UIControl3D.Visible = "on";
            design3Dcursor(app);
            weightDisplay(app);
            foDisplay(app);
            sgramDisplay(app);
            setWeightingPattern(app);
            app.CommonData.redWeight.Visible = "off";
            app.CommonData.blueWeight.Visible = "off";
            app.CommonData.greenWeight.Visible = "off";
            app.CommonData.patternUpdate = "off";
            app.CommonData.reset = "off";
            switch app.LangDropDown.Value
                case {'JPN','---'}
                    app.MessageTextArea.Value = ...
                        {'モーフィング用の音声情報が入っているmatファイルを', ...
                        '右下の三色のボタンを使って読み込んで下さい', ...
                        '情報が入力された色の球が不透明になります', ...
                        '  ','モーフィング用の情報が入っていないときは読み込まれません'};
                case 'EN'
                    app.MessageTextArea.Value = ...
                        {'Load .mat files consisting of morhping data', ...
                        'using colored buttons on the bottom right.', ...
                        'Data filled sphere turns opaque', ...
                        '  ','Imcompatible data is ignored and not filled'};
            end
            app.CommonData.playOn = 0;
            results = 1;
        end

        function results = design3Dcursor(app)
            %axes(app.UIControl3D);
            axis(app.UIControl3D,[-2 2 -2 2 -2 2]);
            [az,al] = view(app.UIControl3D);
            [XX,YY] = meshgrid(-2:0.01:2,-2:0.01:2);
            ndiv = size(XX,1);
            np=21;
            [sx,sy,sz] = sphere(np);
            app.CommonData.sphereBase = {sx,sy,sz};
            app.CommonData.cursorRad = 0.2;
            curorRad = app.CommonData.cursorRad;
            app.CommonData.cursorBase = ...
                {curorRad*sx,curorRad*sy,curorRad*sz};
            cursorBase = app.CommonData.cursorBase;
            scolor = ones(np,np,3);
            app.CommonData.curcorCenter = [0 0];
            %cursorCenter = app.CommonData.curcorCenter;
            %---- define a half-transparent cursor for weight manipulation
            app.CommonData.cursorHandle = ...
                surface(app.UIControl3D,...
                cursorBase{1},cursorBase{2},cursorBase{3}+2, ...
                scolor,"FaceLighting","gouraud","FaceAlpha",0.7,"AmbientStrength",0.6);
            shading(app.UIControl3D,"flat");
            light(app.UIControl3D,'Position',[1/3 1 0.55]);
            hold(app.UIControl3D,"on");
            %---- define three reference items, red, blue, and green
            redColor = scolor*0+0.2;
            redColor(:,:,1) = 1;
            greenColor = scolor*0+0.2;
            greenColor(:,:,2) = 1;
            blueColor = scolor*0+0.2;
            blueColor(:,:,3) = 1;
            redCenter = [0 1];
            greenCenter = [cos(7/6*pi) sin(7/6*pi)];
            blueCenter = [cos(11/6*pi) sin(11/6*pi)];
            app.CommonData.redCenter = redCenter;
            app.CommonData.greenCenter = greenCenter;
            app.CommonData.blueCenter = blueCenter;
            app.CommonData.redItem = ...
                surface(app.UIControl3D,...
                curorRad*sx+redCenter(1),curorRad*sy+redCenter(2),curorRad*sz+1, ...
                redColor,"FaceLighting","gouraud","AmbientStrength",0.8,"FaceAlpha",0.3);
            app.CommonData.greenItem = ...
                surface(app.UIControl3D,...
                curorRad*sx+greenCenter(1),curorRad*sy+greenCenter(2),curorRad*sz+1, ...
                greenColor,"FaceLighting","gouraud","AmbientStrength",0.8,"FaceAlpha",0.3);
            app.CommonData.blueItem = ...
                surface(app.UIControl3D,...
                curorRad*sx+blueCenter(1),curorRad*sy+blueCenter(2),curorRad*sz+1, ...
                blueColor,"FaceLighting","gouraud","AmbientStrength",0.8,"FaceAlpha",0.3);
            shading(app.UIControl3D,"flat");
            %---- draw backgground image
            r = sqrt(XX.^2+YY.^2);
            %backImgBase = 1-0.7 ./(1+exp(10*(r-1.3)));
            backImg = zeros(ndiv,ndiv,3);
            backImg(:,:,1) = 1-0.7 ./(1+exp(10*(r-1.3)));
            backImg(:,:,2) = 1-0.3 ./(1+exp(10*(r-1.3)));
            backImg(:,:,3) = 1-0.7 ./(1+exp(10*(r-1.3)));
            image(app.UIControl3D,-2:0.01:2,-2:0.01:2,backImg)
            %---- draw triangular edges connecting three colored spheres
            plot(app.UIControl3D,[redCenter(1) greenCenter(1)],...
                [redCenter(2) greenCenter(2)],"k","LineWidth",3);
            plot(app.UIControl3D,[blueCenter(1) greenCenter(1)],...
                [blueCenter(2) greenCenter(2)],"k","LineWidth",3);
            plot(app.UIControl3D,[blueCenter(1) redCenter(1)],...
                [blueCenter(2) redCenter(2)],"k","LineWidth",3);
            %---- define three lines connecting the cursor and colored spheres
            app.CommonData.stemRed = plot(app.UIControl3D,[redCenter(1) 0],...
                [redCenter(2) 0],"k","LineWidth",1);
            app.CommonData.stemGreen = plot(app.UIControl3D,[greenCenter(1) 0],...
                [greenCenter(2) 0],"k","LineWidth",1);
            app.CommonData.stemBlue = plot(app.UIControl3D,[blueCenter(1) 0],...
                [blueCenter(2) 0],"k","LineWidth",1);
            %plot3(app.UIControl3D,sx,sy,sz)
            grid(app.UIControl3D,"off");
            %axis(app.UIControl3D,"off");
            axis(app.UIControl3D,"equal");
            axis(app.UIControl3D,"image");
            axis(app.UIControl3D,1.6*[-1 1 -1 1 -1 1]);
            view(app.UIControl3D,az,al);
            hold(app.UIControl3D,"off");
            axes(app.UIControl3D);
            app.CommonData.cursor = "off";
            %------- initialize cursor default to control sphere
            app.CommonData.cursorLocation = struct;
            CurrentPoint = get(app.UIControl3D,'CurrentPoint');
            CurrentPoint = CurrentPoint*0;
            for ii = 1:5
            app.CommonData.cursorLocation(ii).currentLocation = ...
                CurrentPoint;
            end
            app.CommonData.patternWeight = zeros(5,3);
            for ii = 1:5
                app.CommonData.patternWeight(ii,:) = [1 1 1]/3;
            end
            app.CommonData.selectedPattern = 1;
            results = 1;
        end


        function results = position2weight(app)
            xt = [app.CommonData.redCenter(1),app.CommonData.blueCenter(1),...
                app.CommonData.greenCenter(1)];
            yt = [app.CommonData.redCenter(2),app.CommonData.blueCenter(2),...
                app.CommonData.greenCenter(2)];
            x = app.CommonData.curcorCenter(1);
            y = app.CommonData.curcorCenter(2);
            L = det([1 xt(1) yt(1);1 xt(2) yt(2);1 xt(3) yt(3)]);
            L1 = det([1 x y;1 xt(2) yt(2);1 xt(3) yt(3)]);
            L2 = det([1 xt(1) yt(1);1 x y;1 xt(3) yt(3)]);
            L3 = det([1 xt(1) yt(1);1 xt(2) yt(2);1 x y]);
            app.CommonData.weight = [L1, L2, L3]/L;
            if app.CommonData.redyFor2ItemMorphing == 1
                if ~isfield(app.CommonData,"redObject")
                    app.CommonData.weight(1) = 0.0001;
                    app.CommonData.weight = ...
                        app.CommonData.weight/sum(app.CommonData.weight);
                elseif ~isfield(app.CommonData,"blueObject")
                    app.CommonData.weight(2) = 0.0001;
                    app.CommonData.weight = ...
                        app.CommonData.weight/sum(app.CommonData.weight);
                elseif ~isfield(app.CommonData,"greenObject")
                    app.CommonData.weight(3) = 0.0001;
                    app.CommonData.weight = ...
                        app.CommonData.weight/sum(app.CommonData.weight);
                end
            end
            results = 1;
        end

        function results = weightDisplay(app)
            app.CommonData.redWeight = bar(app.UIWeightAx,3,1/3,"red","Horizontal","on");
            hold(app.UIWeightAx,"on");
            app.CommonData.blueWeight = bar(app.UIWeightAx,2,1/3,"blue","Horizontal","on");
            app.CommonData.greenWeight = bar(app.UIWeightAx,1,1/3,"green","Horizontal","on");
            xlabel(app.UIWeightAx,"weight")
            set(app.UIWeightAx,"YTick",[1 2 3],"YTickLabel",{"R";"B";"G"})
            hold(app.UIWeightAx,"off");
            results = 1;
        end

        function results = checkObjectVaridity(app)
            tmp = app.CommonData.tmpObject;
            if isstruct(tmp)
                if isfield(tmp,"timeAnchor") && isfield(tmp,"timeFreqAnchor") ...
                        && isfield(tmp,"worldParameter")
                    app.CommonData.objectIsValid = "YES";
                end
            end
            results = 1;
        end

        function results = checkIntegrity(app)
            app.CommonData.rbgButtonStatus = zeros(1,3);
            app.CommonData.redyFor3ItemMorphing = 0;
            app.CommonData.redyFor2ItemMorphing = 0;
            objectCount = 0;
            app.CommonData.orderedObject = struct;
            if isfield(app.CommonData,"redObject")
                objectCount = objectCount+1;
                app.CommonData.orderedObject(objectCount).object = ...
                    app.CommonData.redObject;
                app.CommonData.orderedObject(objectCount).identity = "RED";
                app.CommonData.rbgButtonStatus(1) = 1;
            end
            if isfield(app.CommonData,"blueObject")
                objectCount = objectCount+1;
                app.CommonData.orderedObject(objectCount).object = ...
                    app.CommonData.blueObject;
                app.CommonData.orderedObject(objectCount).identity = "BLUE";
                app.CommonData.rbgButtonStatus(2) = 1;
            end
            if isfield(app.CommonData,"greenObject")
                objectCount = objectCount+1;
                app.CommonData.orderedObject(objectCount).object = ...
                    app.CommonData.greenObject;
                app.CommonData.orderedObject(objectCount).identity = "Green";
                app.CommonData.rbgButtonStatus(3) = 1;
            end
            integrityOk = 0;
            for ii = 1:objectCount
                refObj = app.CommonData.orderedObject(ii).object;
                for jj = ii+1:objectCount
                    tgtObj = app.CommonData.orderedObject(jj).object;
                    integrityOk = integrityOk ...
                        + all(size(refObj.timeAnchor) == size(tgtObj.timeAnchor)) ...
                        + all(size(refObj.timeFreqAnchor) == size(tgtObj.timeFreqAnchor));
                end
            end
            if 2 == integrityOk && objectCount == 2
                app.CommonData.redyFor2ItemMorphing = 1;
                morphing2ItemIsReady(app);
                setWeightingPattern(app);
            elseif 6 == integrityOk && objectCount == 3
                app.CommonData.redyFor3ItemMorphing = 1;
                app.CommonData.rbgButtonStatus = [1,1,1];
                morphing3ItemIsReady(app);
                setWeightingPattern(app);
            end
            results = 1;
        end

        function results = morphing3ItemIsReady(app)
            switch app.LangDropDown.Value
                case 'JPN'
                    app.MessageTextArea.Value = ...
                        {'3事例間のモーフィングの準備ができました', ...
                        '上の半透明の球をドラッグして操作できます', ...
                        '左の『RESET』ボタンで球を原点に戻せます', ...
                        '『Display』のメニューを切替えると高度な設定ができます', ...
                        };
                case 'EN'
                    app.MessageTextArea.Value = ...
                        {'Three-instances morphing is ready', ...
                        'Drag the translucent sphere controller', ...
                        '[RESET] button returns the sphere to the origin', ...
                        'Changeing[Display] menu enables advanced setting', ...
                        };
            end
            app.UIWeightAx.Visible = "on";
            app.ViewDropDown.Visible = "on";
            app.MorphingsettingpanelPanel.Visible = "on";
            app.CommonData.redWeight.Visible = "on";
            app.CommonData.blueWeight.Visible = "on";
            app.CommonData.greenWeight.Visible = "on";
            %app.CommonData.currentWeight = [1;1;1]/3;
            makeMorphBaseStructure(app);
            RESETButtonPushed(app,[]);
            setDataDispaly(app);
            app.CommonData.playOn = 0;
            %---- make morphBase structure
            GENERATEButtonPushed(app, []);
            results = 1;
        end

        function results = morphing2ItemIsReady(app)
            app.MessageTextArea.Value = ...
                {'2事例間のモーフィングの準備ができました', ...
                '上の半透明の球をドラッグして操作できます', ...
                '左の『RESET』ボタンで球を原点に戻せます', ...
                ' ', ...
                'もう一つファイルを読み込めば', ...
                '3事例間のモーフィングの準備ができます', ...
                '『Display』のメニューを切替えると高度な設定ができます', ...
                };
            switch app.LangDropDown.Value
                case 'JPN'
                    app.MessageTextArea.Value = ...
                        {'2事例間のモーフィングの準備ができました', ...
                        '上の半透明の球をドラッグして操作できます', ...
                        '左の『RESET』ボタンで球を原点に戻せます', ...
                        ' ', ...
                        'もう一つファイルを読み込めば', ...
                        '3事例間のモーフィングの準備ができます', ...
                        '『Display』のメニューを切替えると高度な設定ができます', ...
                        };
                case 'EN'
                    app.MessageTextArea.Value = ...
                        {'Two-instances morphing is ready', ...
                        'Drag the translucent sphere controller', ...
                        '[RESET] button returns the sphere to the origin', ...
                        ' ', ...
                        'Loading one more data enables three-instances morphing', ...
                        'Changeing[Display] menu enables advanced setting', ...
                        };
            end
            app.UIWeightAx.Visible = "on";
            app.ViewDropDown.Visible = "on";
            app.CommonData.redWeight.Visible = "on";
            app.CommonData.blueWeight.Visible = "on";
            app.CommonData.greenWeight.Visible = "on";
            app.MorphingsettingpanelPanel.Visible = "on";
            app.GENERATEButton.Visible = "on";
            %app.CommonData.currentWeight = [1;1;1]/3;
            makeMorphBaseStructure(app);
            RESETButtonPushed(app,[]);
            setDataDispaly(app);
            app.CommonData.playOn = 0;
            RESETButtonPushed(app,[]);
            updateWeightData(app);
            GENERATEButtonPushed(app, []);
            results = 1;
        end


        function results = foDisplay(app)
            tx = 0:0.005:1;
            foRed = tx*0+30;
            foBlue = tx*0+30;
            foGreen = tx*0+30;
            foMorph = tx*0+30;
            app.CommonData.foRedHandle = ...
                semilogy(app.UIfoAxes,tx,foRed,"r","LineWidth",2);
            hold(app.UIfoAxes,"on");
            app.CommonData.foBlueHandle = ...
                semilogy(app.UIfoAxes,tx,foBlue,"LineWidth",2, ...
                "Color",[0.2 0.2 1]);
            app.CommonData.foGreenHandle = ...
                semilogy(app.UIfoAxes,tx,foGreen,"g","LineWidth",2);
            app.CommonData.foMorphHandle = ...
                semilogy(app.UIfoAxes,tx,foMorph,"k","LineWidth",2);
            axis(app.UIfoAxes,[0 tx(end) 50 800]);
            grid(app.UIfoAxes,"on");
            set(app.UIfoAxes,"YTick",[60 80 100 200 300 400 500 600 700 800 900 1000], ...
                "YTickLabel", {".06";".08";"0.1";"0.2";"0.3";"0.4";"0.5";"0.6";" "; ...
                "0.8";" ";"1.0"})
            hold(app.UIfoAxes,"off");
            app.CommonData.foRedHandle.Visible = "off";
            app.CommonData.foBlueHandle.Visible = "off";
            app.CommonData.foGreenHandle.Visible = "off";
            app.CommonData.foMorphHandle.Visible = "off";
            drawnow
            results = 1;
        end

        function results = sgramDisplay(app)
            fs = 44100;
            tx = 0:0.005:1;
            spgdB = rand(2048,length(tx));
            app.CommonData.sgramHandle = ...
                imagesc(app.UIspecAxes,[0 tx(end)],[0 fs/2]/1000, spgdB);
            axis(app.UIspecAxes,'xy');
            axis(app.UIspecAxes,[0 tx(end) 0 6]);
            set(app.UIspecAxes,"YTick",0:9,"YTickLabel", ...
                {"0.0";"1.0";"2.0";"3.0";"4.0";"5.0";"6.0";"7.0";"8.0";"9.0"});
            app.CommonData.sgramHandle.Visible = "off";
            drawnow
            results = 1;
        end

        function results = setDataDispaly(app)
            if app.ViewDropDown.Value == app.ViewDropDown.Items{1}
                app.UIfoAxes.Visible = "on";
                app.MorphingsettingpanelPanel.Visible = "off";
                maxTx = 0;
                app.CommonData.dataXmax = 0.1;
                if isfield(app.CommonData,"redObject")
                    worldParameter = app.CommonData.redObject.worldParameter;
                    tx = worldParameter.source_parameter.temporal_positions;
                    fo = worldParameter.source_parameter.f0;
                    app.CommonData.foRedHandle.XData = tx;
                    app.CommonData.foRedHandle.YData = fo;
                    app.CommonData.foRedHandle.Visible = "on";
                    if maxTx < tx(end)
                        maxTx = tx(end);
                    end
                end
                if isfield(app.CommonData,"blueObject")
                    worldParameter = app.CommonData.blueObject.worldParameter;
                    tx = worldParameter.source_parameter.temporal_positions;
                    fo = worldParameter.source_parameter.f0;
                    app.CommonData.foBlueHandle.XData = tx;
                    app.CommonData.foBlueHandle.YData = fo;
                    app.CommonData.foBlueHandle.Visible = "on";
                    if maxTx < tx(end)
                        maxTx = tx(end);
                    end
                end
                if isfield(app.CommonData,"greenObject")
                    worldParameter = app.CommonData.greenObject.worldParameter;
                    tx = worldParameter.source_parameter.temporal_positions;
                    fo = worldParameter.source_parameter.f0;
                    app.CommonData.foGreenHandle.XData = tx;
                    app.CommonData.foGreenHandle.YData = fo;
                    app.CommonData.foGreenHandle.Visible = "on";
                    if maxTx < tx(end)
                        maxTx = tx(end);
                    end
                end
                set(app.UIfoAxes,"XLim",[0 maxTx]);
                app.CommonData.dataXmax = maxTx;
            end
            results = 1;
        end

        function results = updateDataDispaly(app)
            if app.ViewDropDown.Value == app.ViewDropDown.Items{1}
                xlimit = app.CommonData.dataXmax;
                app.UIfoAxes.Visible = "on";
                app.MorphingsettingpanelPanel.Visible = "off";
                worldParameter = app.CommonData.morphStr;
                tx = worldParameter.source_parameter.temporal_positions;
                fo = worldParameter.source_parameter.f0;
                app.CommonData.foMorphHandle.XData = tx;
                app.CommonData.foMorphHandle.YData = fo;%.*vuv;
                app.CommonData.foMorphHandle.Visible = "on";
                if xlimit < tx(end)
                    xlimit = tx(end);
                end
                set(app.UIfoAxes,"XLim",[0 xlimit]);
                app.UIspecAxes.Visible = "on";
                spgdB = 10*log10(worldParameter.spectrum_parameter.spectrogram);
                spgdB = max(-70,spgdB - max(spgdB(:)));
                app.CommonData.sgramHandle.CData = spgdB;
                app.CommonData.sgramHandle.XData = [0 xlimit];
                app.CommonData.sgramHandle.Visible = "on";
                set(app.UIspecAxes,"XLim",[0 xlimit]);
                %drawnow limitrate
                %disp("updated")
            end
            results = 1;
        end

        function results = makeMorphBaseStructure(app)
            objCount = 0;
            morphdBase = struct;
            filledObject = zeros(3,1);
            if isfield(app.CommonData,"redObject")
                objCount = objCount+1;
                filledObject(1) = 1;
                tmp.worldParameter = ...
                    app.CommonData.redObject.worldParameter;
                tmp.timeAnchor = ...
                    app.CommonData.redObject.timeAnchor;
                tmp.timeFreqAnchor = ...
                    app.CommonData.redObject.timeFreqAnchor;
                tmp.fullpath = app.CommonData.redObject.fullpath;
                morphdBase.morphStr(objCount) = tmp;
            end
            if isfield(app.CommonData,"blueObject")
                objCount = objCount+1;
                filledObject(2) = 1;
                tmp.worldParameter = ...
                    app.CommonData.blueObject.worldParameter;
                tmp.timeAnchor = ...
                    app.CommonData.blueObject.timeAnchor;
                tmp.timeFreqAnchor = ...
                    app.CommonData.blueObject.timeFreqAnchor;
                tmp.fullpath = app.CommonData.blueObject.fullpath;
                morphdBase.morphStr(objCount) = tmp;
            end
            if isfield(app.CommonData,"greenObject")
                objCount = objCount+1;
                filledObject(3) = 1;
                tmp.worldParameter = ...
                    app.CommonData.greenObject.worldParameter;
                tmp.timeAnchor = ...
                    app.CommonData.greenObject.timeAnchor;
                tmp.timeFreqAnchor = ...
                    app.CommonData.greenObject.timeFreqAnchor;
                tmp.fullpath = app.CommonData.greenObject.fullpath;
                morphdBase.morphStr(objCount) = tmp;
            end
            app.CommonData.morphdBase = morphdBase;
            app.CommonData.filledObject = filledObject;
            results = 1;
        end
        
        function results = checkWeightPattern(app)
            numberOfPatterns = str2double(app.NumberofweightpatternsDropDown.Value);
            app.CommonData.numberOfPatterns = numberOfPatterns;
            checkEnablingPattern(app);
            switch numberOfPatterns
                case 1
                    app.tx1Button.Value = 1;
                    app.fx1Button.Value = 1;
                    app.fo1Button.Value = 1;
                    app.sl1Button.Value = 1;
                    app.ap1Button.Value = 1;
                    initializeWeightPattern(app);
                case 2
                    app.tx1Button.Value = 1;
                    app.fx1Button.Value = 1;
                    app.fo1Button.Value = 1;
                    app.sl1Button.Value = 1;
                    app.ap1Button.Value = 0;
                    %
                    app.tx2Button.Value = 0;
                    app.fx2Button.Value = 0;
                    app.fo2Button.Value = 0;
                    app.sl2Button.Value = 0;
                    app.ap2Button.Value = 1;
                    initializeWeightPattern(app);
                case 3
                    app.tx1Button.Value = 1;
                    app.fx1Button.Value = 1;
                    app.fo1Button.Value = 1;
                    app.sl1Button.Value = 0;
                    app.ap1Button.Value = 0;
                    %
                    app.tx2Button.Value = 0;
                    app.fx2Button.Value = 0;
                    app.fo2Button.Value = 0;
                    app.sl2Button.Value = 1;
                    app.ap2Button.Value = 0;
                    %
                    app.tx3Button.Value = 0;
                    app.fx3Button.Value = 0;
                    app.fo3Button.Value = 0;
                    app.sl3Button.Value = 0;
                    app.ap3Button.Value = 1;
                    %
                    app.tx2Button.Enable = "on";
                    app.fx2Button.Enable = "on";
                    app.fo2Button.Enable = "on";
                    app.sl2Button.Enable = "on";
                    app.ap2Button.Enable = "on";
                    initializeWeightPattern(app);
                case 4
                    app.tx1Button.Value = 1;
                    app.fx1Button.Value = 1;
                    app.fo1Button.Value = 0;
                    app.sl1Button.Value = 0;
                    app.ap1Button.Value = 0;
                    %
                    app.tx2Button.Value = 0;
                    app.fx2Button.Value = 0;
                    app.fo2Button.Value = 1;
                    app.sl2Button.Value = 0;
                    app.ap2Button.Value = 0;
                    %
                    app.tx3Button.Value = 0;
                    app.fx3Button.Value = 0;
                    app.fo3Button.Value = 0;
                    app.sl3Button.Value = 1;
                    app.ap3Button.Value = 0;
                    %
                    app.tx4Button.Value = 0;
                    app.fx4Button.Value = 0;
                    app.fo4Button.Value = 0;
                    app.sl4Button.Value = 0;
                    app.ap4Button.Value = 1;
                    initializeWeightPattern(app);
                case 5
                    app.tx1Button.Value = 1;
                    app.fx1Button.Value = 0;
                    app.fo1Button.Value = 0;
                    app.sl1Button.Value = 0;
                    app.ap1Button.Value = 0;
                    %
                    app.tx2Button.Value = 0;
                    app.fx2Button.Value = 1;
                    app.fo2Button.Value = 0;
                    app.sl2Button.Value = 0;
                    app.ap2Button.Value = 0;
                    %
                    app.tx3Button.Value = 0;
                    app.fx3Button.Value = 0;
                    app.fo3Button.Value = 1;
                    app.sl3Button.Value = 0;
                    app.ap3Button.Value = 0;
                    %
                    app.tx4Button.Value = 0;
                    app.fx4Button.Value = 0;
                    app.fo4Button.Value = 0;
                    app.sl4Button.Value = 1;
                    app.ap4Button.Value = 0;
                    %
                    app.tx5Button.Value = 0;
                    app.fx5Button.Value = 0;
                    app.fo5Button.Value = 0;
                    app.sl5Button.Value = 0;
                    app.ap5Button.Value = 1;
                    initializeWeightPattern(app);
            end
            results = 1;
        end
        
        function results = setWeightingPattern(app)
            app.CommonData.weightAssignment = ones(5,1);
            app.CommonData.numberOfPatterns = 1;
            app.CommonData.currentPattern = 1;
            % column, attribute,sphere,pattern
            results = 1;
        end
        
        function results = initializeWeightPattern(app)
            resetWeight = app.CommonData.rbgButtonStatus ...
                /sum(app.CommonData.rbgButtonStatus);
            app.CommonData.weightAssignment = ones(5,1);
            app.CommonData.patternWeight = zeros(5,3);
            for ii = 1:5
                app.CommonData.weightAssignment(ii) = 1;
                app.CommonData.patternWeight(ii,:) = resetWeight;
            end
            switch app.CommonData.numberOfPatterns
                case 1
                    % do nothing
                case 2
                    app.CommonData.weightAssignment(1:4) = 1;
                    app.CommonData.weightAssignment(5) = 2;
                case 3
                    app.CommonData.weightAssignment(1:3) = 1;
                    app.CommonData.weightAssignment(4) = 2;
                    app.CommonData.weightAssignment(5) = 3;
                case 4
                    app.CommonData.weightTensor(1:2) = 1;
                    for ii = 3:5
                        app.CommonData.weightAssignment(ii) = ii-1;
                    end
                case 5
                    for ii = 1:5
                        app.CommonData.weightAssignment(ii) = ii;
                    end
            end
            results = 1;
        end
        
        function results = checkEnablingPattern(app)
            numberOfPatterns = str2double(app.NumberofweightpatternsDropDown.Value);
            app.CommonData.numberOfPatterns = numberOfPatterns;
            switch numberOfPatterns
                case 1
                    app.tx2Button.Enable = "off";
                    app.fx2Button.Enable = "off";
                    app.fo2Button.Enable = "off";
                    app.sl2Button.Enable = "off";
                    app.ap2Button.Enable = "off";
                    %
                    app.tx3Button.Enable = "off";
                    app.fx3Button.Enable = "off";
                    app.fo3Button.Enable = "off";
                    app.sl3Button.Enable = "off";
                    app.ap3Button.Enable = "off";
                    %
                    app.tx4Button.Enable = "off";
                    app.fx4Button.Enable = "off";
                    app.fo4Button.Enable = "off";
                    app.sl4Button.Enable = "off";
                    app.ap4Button.Enable = "off";
                    %
                    app.tx5Button.Enable = "off";
                    app.fx5Button.Enable = "off";
                    app.fo5Button.Enable = "off";
                    app.sl5Button.Enable = "off";
                    app.ap5Button.Enable = "off";
                    %
                    app.Pattern2Button.Enable = "off";
                    app.Pattern3Button.Enable = "off";
                    app.Pattern4Button.Enable = "off";
                    app.Pattern5Button.Enable = "off";
                case 2
                    app.tx2Button.Enable = "on";
                    app.fx2Button.Enable = "on";
                    app.fo2Button.Enable = "on";
                    app.sl2Button.Enable = "on";
                    app.ap2Button.Enable = "on";
                    %
                    app.tx3Button.Enable = "off";
                    app.fx3Button.Enable = "off";
                    app.fo3Button.Enable = "off";
                    app.sl3Button.Enable = "off";
                    app.ap3Button.Enable = "off";
                    %
                    app.tx4Button.Enable = "off";
                    app.fx4Button.Enable = "off";
                    app.fo4Button.Enable = "off";
                    app.sl4Button.Enable = "off";
                    app.ap4Button.Enable = "off";
                    %
                    app.tx5Button.Enable = "off";
                    app.fx5Button.Enable = "off";
                    app.fo5Button.Enable = "off";
                    app.sl5Button.Enable = "off";
                    app.ap5Button.Enable = "off";
                    %
                    app.Pattern2Button.Enable = "on";
                    app.Pattern3Button.Enable = "off";
                    app.Pattern4Button.Enable = "off";
                    app.Pattern5Button.Enable = "off";
                case 3
                    app.tx2Button.Enable = "on";
                    app.fx2Button.Enable = "on";
                    app.fo2Button.Enable = "on";
                    app.sl2Button.Enable = "on";
                    app.ap2Button.Enable = "on";
                    %
                    app.tx3Button.Enable = "on";
                    app.fx3Button.Enable = "on";
                    app.fo3Button.Enable = "on";
                    app.sl3Button.Enable = "on";
                    app.ap3Button.Enable = "on";
                    %
                    app.tx4Button.Enable = "off";
                    app.fx4Button.Enable = "off";
                    app.fo4Button.Enable = "off";
                    app.sl4Button.Enable = "off";
                    app.ap4Button.Enable = "off";
                    %
                    app.tx5Button.Enable = "off";
                    app.fx5Button.Enable = "off";
                    app.fo5Button.Enable = "off";
                    app.sl5Button.Enable = "off";
                    app.ap5Button.Enable = "off";
                    %
                    app.Pattern2Button.Enable = "on";
                    app.Pattern3Button.Enable = "on";
                    app.Pattern4Button.Enable = "off";
                    app.Pattern5Button.Enable = "off";
                case 4
                    app.tx2Button.Enable = "on";
                    app.fx2Button.Enable = "on";
                    app.fo2Button.Enable = "on";
                    app.sl2Button.Enable = "on";
                    app.ap2Button.Enable = "on";
                    %
                    app.tx3Button.Enable = "on";
                    app.fx3Button.Enable = "on";
                    app.fo3Button.Enable = "on";
                    app.sl3Button.Enable = "on";
                    app.ap3Button.Enable = "on";
                    %
                    app.tx4Button.Enable = "on";
                    app.fx4Button.Enable = "on";
                    app.fo4Button.Enable = "on";
                    app.sl4Button.Enable = "on";
                    app.ap4Button.Enable = "on";
                    %
                    app.tx5Button.Enable = "off";
                    app.fx5Button.Enable = "off";
                    app.fo5Button.Enable = "off";
                    app.sl5Button.Enable = "off";
                    app.ap5Button.Enable = "off";
                    %
                    app.Pattern2Button.Enable = "on";
                    app.Pattern3Button.Enable = "on";
                    app.Pattern4Button.Enable = "on";
                    app.Pattern5Button.Enable = "off";
                    initializeWeightPattern(app);
                case 5
                    app.tx2Button.Enable = "on";
                    app.fx2Button.Enable = "on";
                    app.fo2Button.Enable = "on";
                    app.sl2Button.Enable = "on";
                    app.ap2Button.Enable = "on";
                    %
                    app.tx3Button.Enable = "on";
                    app.fx3Button.Enable = "on";
                    app.fo3Button.Enable = "on";
                    app.sl3Button.Enable = "on";
                    app.ap3Button.Enable = "on";
                    %
                    app.tx4Button.Enable = "on";
                    app.fx4Button.Enable = "on";
                    app.fo4Button.Enable = "on";
                    app.sl4Button.Enable = "on";
                    app.ap4Button.Enable = "on";
                    %
                    app.tx5Button.Enable = "on";
                    app.fx5Button.Enable = "on";
                    app.fo5Button.Enable = "on";
                    app.sl5Button.Enable = "on";
                    app.ap5Button.Enable = "on";
                    %
                    app.Pattern2Button.Enable = "on";
                    app.Pattern3Button.Enable = "on";
                    app.Pattern4Button.Enable = "on";
                    app.Pattern5Button.Enable = "on";
            end
            results = 1;
        end
        
        function results = updateWeightData(app)
            selectedPattern = 1;
            if app.Pattern1Button.Value == 1
            elseif app.Pattern2Button.Value == 1
                selectedPattern = 2;
            elseif app.Pattern3Button.Value == 1
                selectedPattern = 3;
            elseif app.Pattern4Button.Value == 1
                selectedPattern = 4;
            elseif app.Pattern5Button.Value == 1
                selectedPattern = 5;
            end
            app.CommonData.patternWeight(selectedPattern,:) = ...
                app.CommonData.weight;
            app.CommonData.cursorLocation(selectedPattern).currentLocation = ...
                get(app.UIControl3D,'CurrentPoint');
            app.CommonData.selectedPattern = selectedPattern;
            results = 1;
        end
        
        function results = updateAttributePanel(app)
            selectedPattern = app.CommonData.selectedPattern;
            weightAssignment = app.CommonData.weightAssignment;
            currentOnAttribute = ones(5,1);
            for ii = 1:5
                if weightAssignment(ii) ~= selectedPattern
                    currentOnAttribute(ii) = 0;
                end
            end
            if currentOnAttribute(2) == 0
                app.fxLamp.Color = [1 1 1];
            else
                app.fxLamp.Color = [0 1 0];
            end
            if currentOnAttribute(3) == 0
                app.foLamp.Color = [1 1 1];
            else
                app.foLamp.Color = [0 1 0];
            end
            if currentOnAttribute(1) == 0
                app.txLamp.Color = [1 1 1];
            else
                app.txLamp.Color = [0 1 0];
            end
            if currentOnAttribute(4) == 0
                app.slLamp.Color = [1 1 1];
            else
                app.slLamp.Color = [0 1 0];
            end
            if currentOnAttribute(5) == 0
                app.apLamp.Color = [1 1 1];
            else
                app.apLamp.Color = [0 1 0];
            end
            results = 1;
        end
        
        function results = messageforWeightSetting(app)
            switch app.LangDropDown.Value
                case 'JPN'
                    app.MessageTextArea.Value = ...
                        {'音響的属性を選択的にモーフィングできます', ...
                        '選択方法の種類を5種類まで設定できます', ...
                        '『Number of weight patterns』で個数を決めて下さい', ...
                        '『Weight pattern display』で選んだ属性が操作できます', ...
                        ' ', ...
                        '『Setting』メニューで元の表示に戻ります', ...
                        };
                case 'EN'
                    app.MessageTextArea.Value = ...
                        {'You can select acoustic attribute to manipulate', ...
                        'Up to 5 manupulation patterns, you can define', ...
                        '[Number of weight patterns] set the number', ...
                        '[Weight pattern display] selects attributes to manipulate', ...
                        ' ', ...
                        'Changing [Setting] resumes attributes display', ...
                        };
            end
            results = 1;
        end
        
        function results = messageforAttributeDiaplay(app)
            switch app.LangDropDown.Value
                case 'JPN'
                    app.MessageTextArea.Value = ...
                        {'音響的属性を表示しています', ...
                        '上の半透明の球をドラッグして操作できます', ...
                        '左の『RESET』ボタンで球を原点に戻せます', ...
                        '『Display』のメニューを切替えると高度な設定ができます', ...
                        };
                case 'EN'
                    app.MessageTextArea.Value = ...
                        {'Acoustic diaplay mode:', ...
                        'Drag the translucent sphere controller', ...
                        '[RESET] button returns the sphere to the origin', ...
                        'Changeing[Display] menu enables advanced setting', ...
                        };
            end
            results = 1;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            initializeGraphics(app);
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            set(app.UIFigure, "Pointer", 'arrow')
            if app.CommonData.cursor == "on"
                app.CommonData.playOn = 1;
                updateWeightData(app);
                GENERATEButtonPushed(app, []);
                app.CommonData.cursor = "off";
            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            if app.CommonData.cursor == "on"
                currentPoint = get(app.UIControl3D,'CurrentPoint');
                displayMode = app.ViewDropDown.Value;
                if app.CommonData.reset == "on"
                    app.CommonData.reset = "off";
                    app.CommonData.cursor = "off";
                    currentPoint = currentPoint*0;
                elseif app.CommonData.patternUpdate == "on" && isempty(event)
                    selectedPattern = app.CommonData.selectedPattern;
                    currentPoint = ...
                        app.CommonData.cursorLocation(selectedPattern).currentLocation;
                    app.CommonData.patternUpdate = "off";
                    app.CommonData.cursor = "off";
                end
                app.CommonData.playOn = 0;
                %disp(currentPoint(1,1:2));
                cursorBase = app.CommonData.cursorBase;
                sx = cursorBase{1}+currentPoint(1,1);
                sy = cursorBase{2}+currentPoint(1,2);
                set(app.CommonData.cursorHandle,"XData",sx,"YData",sy);
                app.CommonData.stemRed.XData(2) = currentPoint(1,1);
                app.CommonData.stemRed.YData(2) = currentPoint(1,2);
                app.CommonData.stemGreen.XData(2) = currentPoint(1,1);
                app.CommonData.stemGreen.YData(2) = currentPoint(1,2);
                app.CommonData.stemBlue.XData(2) = currentPoint(1,1);
                app.CommonData.stemBlue.YData(2) = currentPoint(1,2);
                app.CommonData.curcorCenter = currentPoint(1,1:2);
                position2weight(app);
                app.CommonData.redWeight.YData = app.CommonData.weight(1);
                app.CommonData.blueWeight.YData = app.CommonData.weight(2);
                app.CommonData.greenWeight.YData = app.CommonData.weight(3);
                sph = app.CommonData.sphereBase;
                baseRad = app.CommonData.cursorRad;
                w = (abs(app.CommonData.weight)*3).^(1/3)*baseRad;
                %-------
                redCenter = app.CommonData.redCenter;
                app.CommonData.redItem.XData = sph{1}*w(1)+redCenter(1);
                app.CommonData.redItem.YData = sph{2}*w(1)+redCenter(2);
                ZData = sph{3}*w(1);
                if app.CommonData.weight(1) < 0;ZData(7:end,:)= -ZData(7:end,:);end
                app.CommonData.redItem.ZData = ZData+1;
                %-------
                blueCenter = app.CommonData.blueCenter;
                app.CommonData.blueItem.XData = sph{1}*w(2)+blueCenter(1);
                app.CommonData.blueItem.YData = sph{2}*w(2)+blueCenter(2);
                ZData = sph{3}*w(2);
                if app.CommonData.weight(2) < 0;ZData(7:end,:)= -ZData(7:end,:);end
                app.CommonData.blueItem.ZData = ZData+1;
                %-------
                greenCenter = app.CommonData.greenCenter;
                app.CommonData.greenItem.XData = sph{1}*w(3)+greenCenter(1);
                app.CommonData.greenItem.YData = sph{2}*w(3)+greenCenter(2);
                ZData = sph{3}*w(3);
                if app.CommonData.weight(3) < 0;ZData(7:end,:)= -ZData(7:end,:);end
                app.CommonData.greenItem.ZData = ZData+1;
                GENERATEButtonPushed(app, []);
                if displayMode == app.ViewDropDown.Items{1}
                    %app.CommonData.currentWeight = w(:);
                    updateDataDispaly(app);
                end
                %-------
                drawnow limitrate
            end
        end

        % Callback function: UIFigure, UIFigure
        function UIFigureButtonDown(app, event)
            if app.CommonData.redyFor2ItemMorphing || ...
                    app.CommonData.redyFor3ItemMorphing
                currentPoint = get(app.UIControl3D,'CurrentPoint');
                d = app.CommonData.curcorCenter - currentPoint(1,1:2);
                d = sqrt(sum(d.^2));
                if d < 0.2
                    set(app.UIFigure, "Pointer", 'hand')
                    app.CommonData.cursor = "on";
                    app.SAVEButton.Visible = "off";
                end
                %app.CommonData.currentWeight = [1;0;0];
                app.CommonData.playOn = 0;
            end
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.CommonData.reset = "on";
            app.CommonData.cursor = "on";
            UIFigureWindowButtonMotion(app, event);
            app.CommonData.cursor = "off";
        end

        % Button pushed function: LoadRedFileButton
        function LoadRedFileButtonPushed(app, event)
            successInd = 1;
            try
                [paramFile, paramPath] = ...
                    uigetfile({'*.mat'},"Select parameter file for RED:");
                if isnumeric(paramFile) && isnumeric(paramPath)
                    if paramFile*paramPath == 0;successInd = 0;end
                end
            catch
                successInd = 0;
            end
            if successInd
                if isfield(app.CommonData,"tmpObject")
                    app.CommonData = rmfield(app.CommonData,"tmpObject");
                end
                tmpObject = load([paramPath  paramFile]);
                %disp([paramPath '----' paramFile])
                tmpObject.fullpath = [paramPath paramFile];
                app.CommonData.tmpObject = tmpObject;
                app.CommonData.objectIsValid = "NO";
                checkObjectVaridity(app);
                if app.CommonData.objectIsValid == "YES"
                    app.CommonData.redObject = tmpObject;
                    app.CommonData.redItem.AmbientStrength = 0.6;
                    app.CommonData.redItem.FaceAlpha = 1;
                    app.RedLabel.Text = paramFile(1:end-4);
                    app.CommonData.fs = ...
                        app.CommonData.tmpObject.worldParameter.samplingFrequency;
                    checkIntegrity(app);
                end
            end
        end

        % Button pushed function: LoadBlueFileButton
        function LoadBlueFileButtonPushed(app, event)
            successInd = 1;
            try
                [paramFile, paramPath] = ...
                    uigetfile({'*.mat'},"Select parameter file for BLUE:");
                if isnumeric(paramFile) && isnumeric(paramPath)
                    if paramFile*paramPath == 0;successInd = 0;end
                end
            catch
                successInd = 0;
            end
            if successInd
                if isfield(app.CommonData,"tmpObject")
                    app.CommonData = rmfield(app.CommonData,"tmpObject");
                end
                tmpObject = load([paramPath  paramFile]);
                %disp([paramPath '----' paramFile])
                tmpObject.fullpath = [paramPath paramFile];
                app.CommonData.tmpObject = tmpObject;
                app.CommonData.objectIsValid = "NO";
                checkObjectVaridity(app);
                if app.CommonData.objectIsValid == "YES"
                    app.CommonData.blueObject = tmpObject;
                    app.CommonData.blueItem.AmbientStrength = 0.6;
                    app.CommonData.blueItem.FaceAlpha = 1;
                    app.BlueLabel.Text = paramFile(1:end-4);
                    checkIntegrity(app);
                end
            end
        end

        % Button pushed function: LoadGreenFileButton
        function LoadGreenFileButtonPushed(app, event)
            successInd = 1;
            try
                [paramFile, paramPath] = ...
                    uigetfile({'*.mat'},"Select parameter file for GREEN:");
                if isnumeric(paramFile) && isnumeric(paramPath)
                    if paramFile*paramPath == 0;successInd = 0;end
                end
            catch
                successInd = 0;
            end
            if successInd
                if isfield(app.CommonData,"tmpObject")
                    app.CommonData = rmfield(app.CommonData,"tmpObject");
                end
                tmpObject = load([paramPath  paramFile]);
                %disp([paramPath '----' paramFile])
                tmpObject.fullpath = [paramPath paramFile];
                app.CommonData.tmpObject = tmpObject;
                app.CommonData.objectIsValid = "NO";
                checkObjectVaridity(app);
                if app.CommonData.objectIsValid == "YES"
                    app.CommonData.greenObject = tmpObject;
                    app.CommonData.greenItem.AmbientStrength = 0.6;
                    app.CommonData.greenItem.FaceAlpha = 1;
                    app.GreenLabel.Text = paramFile(1:end-4);
                    checkIntegrity(app);
                end
            end
        end

        % Button pushed function: GENERATEButton
        function GENERATEButtonPushed(app, event)
            if app.CommonData.redyFor2ItemMorphing == 1 ...
                    || app.CommonData.redyFor3ItemMorphing == 1
                morphdBase = app.CommonData.morphdBase;
                filledObject = app.CommonData.filledObject;
                %nObj = sum(filledObject);
                selectedPattern = app.CommonData.selectedPattern;
                weightAssignment = app.CommonData.weightAssignment;
                currentOnAttribute = ones(5,1);
                for ii = 1:5
                    if weightAssignment(ii) ~= selectedPattern
                        currentOnAttribute(ii) = 0;
                    end
                end
                %currentWeight = app.CommonData.currentWeight;
                weightStr = struct;
                selector = (1:3)';
                selector = selector(filledObject==1);
                currentWeight = app.CommonData.weight(:);
                weight = app.CommonData.patternWeight;
                if isempty(event)
                    weightAssignment = app.CommonData.weightAssignment;
                    if currentOnAttribute(1)
                        weightStr.tx = currentWeight(selector);
                    else
                        weightStr.tx = weight(weightAssignment(1),selector)';
                    end
                    if currentOnAttribute(2)
                        weightStr.fx = currentWeight(selector);
                    else
                        weightStr.fx = weight(weightAssignment(2),selector)';
                    end
                    if currentOnAttribute(3)
                        weightStr.fo = currentWeight(selector);
                    else
                        weightStr.fo = weight(weightAssignment(3),selector)';
                    end
                    if currentOnAttribute(4)
                        weightStr.sl = currentWeight(selector);
                    else
                        weightStr.sl = weight(weightAssignment(4),selector)';
                    end
                    if currentOnAttribute(5)
                        weightStr.ap = currentWeight(selector);
                    else
                        weightStr.ap = weight(weightAssignment(5),selector)';
                    end
                else
                    weightAssignment = app.CommonData.weightAssignment;
                    weightStr.tx = weight(weightAssignment(1),selector)';
                    weightStr.fx = weight(weightAssignment(2),selector)';
                    weightStr.fo = weight(weightAssignment(3),selector)';
                    weightStr.sl = weight(weightAssignment(4),selector)';
                    weightStr.ap = weight(weightAssignment(5),selector)';
                end
                morphStr = ...
                    generalizedTCmorphing(morphdBase,weightStr,0);
                app.CommonData.morphStr = morphStr;
                app.CommonData.weightStr = weightStr;
                %currentPoint = get(app.UIControl3D,'CurrentPoint');
                %disp(currentPoint(1,1:2));
                %disp("updated-1")
            end
            updateDataDispaly(app);
            if app.CommonData.playOn == 1 || ~isempty(event)
                fs = app.CommonData.fs;
                xSynth = Synthesis(morphStr.source_parameter, ...
                    morphStr.spectrum_parameter);
                soundsc(xSynth,fs);
                app.CommonData.synthesizedSignal = xSynth/max(abs(xSynth))*0.8;
                app.SAVEButton.Visible = "on";
            end
        end

        % Value changed function: ViewDropDown
        function ViewDropDownValueChanged(app, event)
            value = app.ViewDropDown.Value;
            switch value
                case app.ViewDropDown.Items{1}
                    messageforAttributeDiaplay(app);
                    app.UIfoAxes.Visible = "on";
                    app.UIspecAxes.Visible = "on";
                    app.CommonData.foRedHandle.Visible = "on";
                    app.CommonData.foBlueHandle.Visible = "on";
                    app.CommonData.foGreenHandle.Visible = "on";
                    app.CommonData.foMorphHandle.Visible = "on";
                    app.CommonData.sgramHandle.Visible = "on";
                    app.MorphingsettingpanelPanel.Visible = "off";
                case app.ViewDropDown.Items{2}
                    messageforWeightSetting(app);
                    app.UIfoAxes.Visible = "off";
                    app.UIspecAxes.Visible = "off";
                    app.CommonData.foRedHandle.Visible = "off";
                    app.CommonData.foBlueHandle.Visible = "off";
                    app.CommonData.foGreenHandle.Visible = "off";
                    app.CommonData.foMorphHandle.Visible = "off";
                    app.CommonData.sgramHandle.Visible = "off";
                    app.MorphingsettingpanelPanel.Visible = "on";
            end
            checkEnablingPattern(app);
            setDataDispaly(app);
        end

        % Value changed function: NumberofweightpatternsDropDown
        function NumberofweightpatternsDropDownValueChanged(app, event)
            value = app.NumberofweightpatternsDropDown.Value;
            if str2double(value) > 1
                app.AttributePanel.Visible = "on";
            else
                app.AttributePanel.Visible = "off";
            end
            app.Pattern1Button.Value = 1;
            for ii = 1:5
            app.CommonData.selectedPattern = ii;
            RESETButtonPushed(app,[]);
            end
            app.CommonData.selectedPattern = 1;
            updateAttributePanel(app);
            checkWeightPattern(app);
            updateAttributePanel(app);
        end

        % Selection changed function: WeightPatternDisplayButtonGroup
        function WeightPatternDisplayButtonGroupSelectionChanged(app, event)
            selectedButton = app.WeightPatternDisplayButtonGroup.SelectedObject;
            %disp(selectedButton)
            selectedPattern = 1;
            switch selectedButton.Text
                case 'Pattern-1'
                    app.CommonData.weight = ...
                        app.CommonData.patternWeight(1,:);  
                    selectedPattern = 1;
                case 'Pattern-2'
                    app.CommonData.weight = ...
                        app.CommonData.patternWeight(2,:); 
                    selectedPattern = 2;
                case 'Pattern-3'
                    app.CommonData.weight = ...
                        app.CommonData.patternWeight(3,:); 
                    selectedPattern = 3;
                case 'Pattern-4'
                    app.CommonData.weight = ...
                        app.CommonData.patternWeight(4,:); 
                    selectedPattern = 4;
                case 'Pattern-5'
                    app.CommonData.weight = ...
                        app.CommonData.patternWeight(5,:);
                    selectedPattern = 5;
            end
            %disp(selectedButton);
            %disp(selectedPattern);
            app.CommonData.patternUpdate = "on";
            app.CommonData.cursor = "on";
            app.CommonData.selectedPattern = selectedPattern;
            updateAttributePanel(app);
            UIFigureWindowButtonMotion(app,[]);
        end

        % Selection changed function: TimeAxisButtonGroup
        function TimeAxisButtonGroupSelectionChanged(app, event)
            selectedButton = app.TimeAxisButtonGroup.SelectedObject;
            disp(selectedButton)
            switch selectedButton.Text
                case 'tx-1'
                    app.CommonData.weightAssignment(1) = 1;
                case 'tx-2'
                    app.CommonData.weightAssignment(1) = 2;
                case 'tx-3'
                    app.CommonData.weightAssignment(1) = 3;
                case 'tx-4'
                    app.CommonData.weightAssignment(1) = 4;
                case 'tx-5'
                    app.CommonData.weightAssignment(1) = 5;
            end
            updateAttributePanel(app);
        end

        % Selection changed function: FrequencyAxisButtonGroup
        function FrequencyAxisButtonGroupSelectionChanged(app, event)
            selectedButton = app.FrequencyAxisButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'fx-1'
                    app.CommonData.weightAssignment(2) = 1;
                case 'fx-2'
                    app.CommonData.weightAssignment(2) = 2;
                case 'fx-3'
                    app.CommonData.weightAssignment(2) = 3;
                case 'fx-4'
                    app.CommonData.weightAssignment(2) = 4;
                case 'fx-5'
                    app.CommonData.weightAssignment(2) = 5;
            end
            updateAttributePanel(app);
        end

        % Selection changed function: FundamentalFrequencyButtonGroup
        function FundamentalFrequencyButtonGroupSelectionChanged(app, event)
            selectedButton = app.FundamentalFrequencyButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'fo-1'
                    app.CommonData.weightAssignment(3) = 1;
                case 'fo-2'
                    app.CommonData.weightAssignment(3) = 2;
                case 'fo-3'
                    app.CommonData.weightAssignment(3) = 3;
                case 'fo-4'
                    app.CommonData.weightAssignment(3) = 4;
                case 'fo-5'
                    app.CommonData.weightAssignment(3) = 5;
            end
            updateAttributePanel(app);
        end

        % Selection changed function: SpectrumLevelButtonGroup
        function SpectrumLevelButtonGroupSelectionChanged(app, event)
            selectedButton = app.SpectrumLevelButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'sl-1'
                    app.CommonData.weightAssignment(4) = 1;
                case 'sl-2'
                    app.CommonData.weightAssignment(4) = 2;
                case 'sl-3'
                    app.CommonData.weightAssignment(4) = 3;
                case 'sl-4'
                    app.CommonData.weightAssignment(4) = 4;
                case 'sl-5'
                    app.CommonData.weightAssignment(4) = 5;
            end
            updateAttributePanel(app);
        end

        % Selection changed function: AperiodicityButtonGroup
        function AperiodicityButtonGroupSelectionChanged(app, event)
            selectedButton = app.AperiodicityButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'ap-1'
                    app.CommonData.weightAssignment(5) = 1;
                case 'ap-2'
                    app.CommonData.weightAssignment(5) = 2;
                case 'ap-3'
                    app.CommonData.weightAssignment(5) = 3;
                case 'ap-4'
                    app.CommonData.weightAssignment(5) = 4;
                case 'ap-5'
                    app.CommonData.weightAssignment(5) = 5;
            end
            updateAttributePanel(app);
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            morphdBase = app.CommonData.morphdBase;
            morphStr = app.CommonData.morphStr;
            weightStr = app.CommonData.weightStr;
            fs = app.CommonData.fs;
            xSynth = Synthesis(morphStr.source_parameter, ...
                morphStr.spectrum_parameter);
            disp(pwd)
            author = "source:";
            nItems = length(morphdBase.morphStr);
            artist = string(morphdBase.morphStr(1).fullpath);
            author = author + "," ...
                + string(morphdBase.morphStr(1).worldParameter.fileName);
            for ii = 2:nItems
                artist = artist + "," ...
                    + string(morphdBase.morphStr(ii).fullpath);
                author = author + "," ...
                    + string(morphdBase.morphStr(ii).worldParameter.fileName);
            end
            comment = string([ '[' num2str(weightStr.tx(:)','%11.6f') ']' ]);
            comment = comment + "," ...
                + string([ '[' num2str(weightStr.fx(:)','%11.6f') ']' ]);
            comment = comment + "," ...
                + string([ '[' num2str(weightStr.fo(:)','%11.6f') ']' ]);
            comment = comment + "," ...
                + string([ '[' num2str(weightStr.sl(:)','%11.6f') ']' ]);
            comment = comment + "," ...
                + string([ '[' num2str(weightStr.ap(:)','%11.6f') ']' ]);
            t = datetime(datetime,'format','yyyyMMddHHmmss');
            outFileName = "morphSyn" + string(t) +".wav";
            audiowrite(outFileName,xSynth/max(abs(xSynth))*0.8,fs,"BitsPerSample",24, ...
                "Title",author,"Artist",artist,"Comment",comment);
            %save("testdata.mat","morphStr","weightStr","morphdBase");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIControl3D = uiaxes(app.UIFigure);
            xlabel(app.UIControl3D, 'X')
            ylabel(app.UIControl3D, 'Y')
            zlabel(app.UIControl3D, 'Z')
            app.UIWeightAx = uiaxes(app.UIFigure);
            app.UIWeightAx.XLim = [-1 1.5];
            app.UIWeightAx.YLim = [0 4];
            app.UIWeightAx.Visible = 'off';
            app.UIfoAxes = uiaxes(app.UIFigure);
            xlabel(app.UIfoAxes, 'time (s)')
            ylabel(app.UIfoAxes, 'fo (kHz)')
            zlabel(app.UIfoAxes, 'Z')
            app.UIfoAxes.Visible = 'off';
            app.UIspecAxes = uiaxes(app.UIFigure);
            xlabel(app.UIspecAxes, 'time (s)')
            ylabel(app.UIspecAxes, 'frequency (kHz)')
            zlabel(app.UIspecAxes, 'Z')
            app.UIspecAxes.Visible = 'off';
            app.LoadRedFileButton = uibutton(app.UIFigure, 'push');
            app.LoadRedFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRedFileButtonPushed, true);
            app.LoadRedFileButton.Text = 'Load Red File';
            app.LoadBlueFileButton = uibutton(app.UIFigure, 'push');
            app.LoadBlueFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBlueFileButtonPushed, true);
            app.LoadBlueFileButton.Text = 'Load Blue File';
            app.LoadGreenFileButton = uibutton(app.UIFigure, 'push');
            app.LoadGreenFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadGreenFileButtonPushed, true);
            app.LoadGreenFileButton.Text = 'Load Green File';
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.MessageTextAreaLabel = uilabel(app.UIFigure);
            app.MessageTextAreaLabel.Text = 'Message';
            app.MessageTextArea = uitextarea(app.UIFigure);
            app.MorphingsettingpanelPanel = uipanel(app.UIFigure);
            app.MorphingsettingpanelPanel.Title = 'Morphing setting panel';
            app.MorphingsettingpanelPanel.Visible = 'off';
            app.TimeAxisButtonGroup = uibuttongroup(app.MorphingsettingpanelPanel);
            app.TimeAxisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TimeAxisButtonGroupSelectionChanged, true);
            app.TimeAxisButtonGroup.Title = 'Time Axis';
            app.tx1Button = uiradiobutton(app.TimeAxisButtonGroup);
            app.tx1Button.Text = 'tx-1';
            app.tx1Button.Value = true;
            app.tx2Button = uiradiobutton(app.TimeAxisButtonGroup);
            app.tx2Button.Text = 'tx-2';
            app.tx3Button = uiradiobutton(app.TimeAxisButtonGroup);
            app.tx3Button.Text = 'tx-3';
            app.tx4Button = uiradiobutton(app.TimeAxisButtonGroup);
            app.tx4Button.Text = 'tx-4';
            app.tx5Button = uiradiobutton(app.TimeAxisButtonGroup);
            app.tx5Button.Text = 'tx-5';
            app.FrequencyAxisButtonGroup = uibuttongroup(app.MorphingsettingpanelPanel);
            app.FrequencyAxisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FrequencyAxisButtonGroupSelectionChanged, true);
            app.FrequencyAxisButtonGroup.Title = 'Frequency Axis';
            app.fx1Button = uiradiobutton(app.FrequencyAxisButtonGroup);
            app.fx1Button.Text = 'fx-1';
            app.fx1Button.Value = true;
            app.fx2Button = uiradiobutton(app.FrequencyAxisButtonGroup);
            app.fx2Button.Text = 'fx-2';
            app.fx3Button = uiradiobutton(app.FrequencyAxisButtonGroup);
            app.fx3Button.Text = 'fx-3';
            app.fx4Button = uiradiobutton(app.FrequencyAxisButtonGroup);
            app.fx4Button.Text = 'fx-4';
            app.fx5Button = uiradiobutton(app.FrequencyAxisButtonGroup);
            app.fx5Button.Text = 'fx-5';
            app.FundamentalFrequencyButtonGroup = uibuttongroup(app.MorphingsettingpanelPanel);
            app.FundamentalFrequencyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FundamentalFrequencyButtonGroupSelectionChanged, true);
            app.FundamentalFrequencyButtonGroup.Title = 'Fundamental Frequency';
            app.fo1Button = uiradiobutton(app.FundamentalFrequencyButtonGroup);
            app.fo1Button.Text = 'fo-1';
            app.fo1Button.Value = true;
            app.fo2Button = uiradiobutton(app.FundamentalFrequencyButtonGroup);
            app.fo2Button.Text = 'fo-2';
            app.fo3Button = uiradiobutton(app.FundamentalFrequencyButtonGroup);
            app.fo3Button.Text = 'fo-3';
            app.fo4Button = uiradiobutton(app.FundamentalFrequencyButtonGroup);
            app.fo4Button.Text = 'fo-4';
            app.fo5Button = uiradiobutton(app.FundamentalFrequencyButtonGroup);
            app.fo5Button.Text = 'fo-5';
            app.SpectrumLevelButtonGroup = uibuttongroup(app.MorphingsettingpanelPanel);
            app.SpectrumLevelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SpectrumLevelButtonGroupSelectionChanged, true);
            app.SpectrumLevelButtonGroup.Title = 'Spectrum Level';
            app.sl1Button = uiradiobutton(app.SpectrumLevelButtonGroup);
            app.sl1Button.Text = 'sl-1';
            app.sl1Button.Value = true;
            app.sl2Button = uiradiobutton(app.SpectrumLevelButtonGroup);
            app.sl2Button.Text = 'sl-2';
            app.sl3Button = uiradiobutton(app.SpectrumLevelButtonGroup);
            app.sl3Button.Text = 'sl-3';
            app.sl4Button = uiradiobutton(app.SpectrumLevelButtonGroup);
            app.sl4Button.Text = 'sl-4';
            app.sl5Button = uiradiobutton(app.SpectrumLevelButtonGroup);
            app.sl5Button.Text = 'sl-5';
            app.AperiodicityButtonGroup = uibuttongroup(app.MorphingsettingpanelPanel);
            app.AperiodicityButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AperiodicityButtonGroupSelectionChanged, true);
            app.AperiodicityButtonGroup.Title = 'Aperiodicity';
            app.ap1Button = uiradiobutton(app.AperiodicityButtonGroup);
            app.ap1Button.Text = 'ap-1';
            app.ap1Button.Value = true;
            app.ap2Button = uiradiobutton(app.AperiodicityButtonGroup);
            app.ap2Button.Text = 'ap-2';
            app.ap3Button = uiradiobutton(app.AperiodicityButtonGroup);
            app.ap3Button.Text = 'ap-3';
            app.ap4Button = uiradiobutton(app.AperiodicityButtonGroup);
            app.ap4Button.Text = 'ap-4';
            app.ap5Button = uiradiobutton(app.AperiodicityButtonGroup);
            app.ap5Button.Text = 'ap-5';
            app.WeightPatternDisplayButtonGroup = uibuttongroup(app.MorphingsettingpanelPanel);
            app.WeightPatternDisplayButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WeightPatternDisplayButtonGroupSelectionChanged, true);
            app.WeightPatternDisplayButtonGroup.Title = 'Weight Pattern Display';
            app.Pattern1Button = uiradiobutton(app.WeightPatternDisplayButtonGroup);
            app.Pattern1Button.Text = 'Pattern-1';
            app.Pattern1Button.Value = true;
            app.Pattern2Button = uiradiobutton(app.WeightPatternDisplayButtonGroup);
            app.Pattern2Button.Text = 'Pattern-2';
            app.Pattern3Button = uiradiobutton(app.WeightPatternDisplayButtonGroup);
            app.Pattern3Button.Text = 'Pattern-3';
            app.Pattern4Button = uiradiobutton(app.WeightPatternDisplayButtonGroup);
            app.Pattern4Button.Text = 'Pattern-4';
            app.Pattern5Button = uiradiobutton(app.WeightPatternDisplayButtonGroup);
            app.Pattern5Button.Text = 'Pattern-5';
            app.NumberofweightpatternsDropDownLabel = uilabel(app.MorphingsettingpanelPanel);
            app.NumberofweightpatternsDropDownLabel.Text = 'Number of  weight patterns';
            app.NumberofweightpatternsDropDown = uidropdown(app.MorphingsettingpanelPanel);
            app.NumberofweightpatternsDropDown.Items = {'1', '2', '3', '4', '5'};
            app.NumberofweightpatternsDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberofweightpatternsDropDownValueChanged, true);
            app.NumberofweightpatternsDropDown.Value = '1';
            app.AttributePanel = uipanel(app.MorphingsettingpanelPanel);
            app.AttributePanel.Title = 'Attribute';
            app.AttributePanel.Visible = 'off';
            app.txLampLabel = uilabel(app.AttributePanel);
            app.txLampLabel.Text = 'tx';
            app.txLamp = uilamp(app.AttributePanel);
            app.fxLampLabel = uilabel(app.AttributePanel);
            app.fxLampLabel.Text = 'fx';
            app.fxLamp = uilamp(app.AttributePanel);
            app.foLampLabel = uilabel(app.AttributePanel);
            app.foLampLabel.Text = 'fo';
            app.foLamp = uilamp(app.AttributePanel);
            app.apLampLabel = uilabel(app.AttributePanel);
            app.apLampLabel.Text = 'ap';
            app.apLamp = uilamp(app.AttributePanel);
            app.slLampLabel = uilabel(app.AttributePanel);
            app.slLampLabel.Text = 'sl';
            app.slLamp = uilamp(app.AttributePanel);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = ' ';
            app.LangDropDown = uidropdown(app.UIFigure);
            app.LangDropDown.Items = {'JPN', 'EN', '---'};
            app.LangDropDown.Value = '---';
            app.GENERATEButton = uibutton(app.UIFigure, 'push');
            app.GENERATEButton.ButtonPushedFcn = createCallbackFcn(app, @GENERATEButtonPushed, true);
            app.GENERATEButton.Visible = 'off';
            app.GENERATEButton.Text = 'GENERATE';
            app.ViewDropDownLabel = uilabel(app.UIFigure);
            app.ViewDropDownLabel.Visible = 'off';
            app.ViewDropDownLabel.Text = 'View';
            app.ViewDropDown = uidropdown(app.UIFigure);
            app.ViewDropDown.Items = {'Displsy', 'Setting'};
            app.ViewDropDown.ValueChangedFcn = createCallbackFcn(app, @ViewDropDownValueChanged, true);
            app.ViewDropDown.Visible = 'off';
            app.ViewDropDown.Value = 'Displsy';
            app.SAVEButton = uibutton(app.UIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Visible = 'off';
            app.SAVEButton.Text = 'SAVE';
            app.RedLabel = uilabel(app.UIFigure);
            app.RedLabel.Visible = 'off';
            app.RedLabel.Text = '---';
            app.BlueLabel = uilabel(app.UIFigure);
            app.BlueLabel.Visible = 'off';
            app.BlueLabel.Text = '---';
            app.GreenLabel = uilabel(app.UIFigure);
            app.GreenLabel.Visible = 'off';
            app.GreenLabel.Text = '---';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = threeItemMorpherGeneralUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end