classdef worldHandler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        Image_4                     matlab.ui.control.Image
        Image_3                     matlab.ui.control.Image
        Image_2                     matlab.ui.control.Image
        foSettingDialogue           matlab.ui.control.Image
        SAVElogfileCheckBox         matlab.ui.control.CheckBox
        fLDropDown                  matlab.ui.control.DropDown
        fLDropDownLabel             matlab.ui.control.Label
        fHDropDown                  matlab.ui.control.DropDown
        fHDropDownLabel             matlab.ui.control.Label
        PlayVoicedButton            matlab.ui.control.Button
        UNDOButton                  matlab.ui.control.Button
        PlayUVButton                matlab.ui.control.Button
        ChangeaudiooutdeviceButton  matlab.ui.control.Button
        RESETButton                 matlab.ui.control.Button
        VLamp                       matlab.ui.control.Lamp
        VLampLabel                  matlab.ui.control.Label
        PLamp                       matlab.ui.control.Lamp
        PLampLabel                  matlab.ui.control.Label
        YLamp                       matlab.ui.control.Lamp
        YLampLabel                  matlab.ui.control.Label
        SLamp                       matlab.ui.control.Lamp
        SLampLabel                  matlab.ui.control.Label
        ALamp                       matlab.ui.control.Lamp
        ALampLabel                  matlab.ui.control.Label
        SavesynthButton             matlab.ui.control.Button
        PlaysynthButton             matlab.ui.control.Button
        WorldHandlerLabel           matlab.ui.control.Label
        QUITButton                  matlab.ui.control.Button
        PlayoriginalButton          matlab.ui.control.Button
        SaveParameterButton         matlab.ui.control.Button
        SynthesisButton             matlab.ui.control.Button
        SpectrumButton              matlab.ui.control.Button
        AperiodicityButton          matlab.ui.control.Button
        FoextractionButton          matlab.ui.control.Button
        ReadsoundButton             matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        fileNameTextLabel           matlab.ui.control.Label
        fileFullPathTextLabel       matlab.ui.control.Label
        FileNameLabel               matlab.ui.control.Label
        fileFullPathLabel           matlab.ui.control.Label
        WaveAxesSynth               matlab.ui.control.UIAxes
        SgramAxes                   matlab.ui.control.UIAxes
        WaveAxes                    matlab.ui.control.UIAxes
        FoAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        foDialogueApp % Description
    end
    
    properties (Access = public)
        LogDataPublic % Globally accessible log data
        CommonData % Globally accessible information
    end

    methods (Access = private)

        function results = setupWaveAxesView(app)
            results = 1;
            x = app.CommonData.x;
            fs = app.CommonData.samplingFrequency;
            cla(app.WaveAxes)
            tt = (0:length(x)-1)'/fs;
            app.CommonData.timeAxis = tt;
            app.CommonData.waveHandle = plot(app.WaveAxes, tt, x, ...
                "LineWidth", 2, HitTest="off");
            app.WaveAxes.XLim = [tt(1) tt(end)];
            app.WaveAxes.YLim = max(abs(x)) * [-1 1.1];
            grid(app.WaveAxes, "on");
            xlabel(app.WaveAxes, '');
            title(app.WaveAxes, '');
            ylabel(app.WaveAxes, 'level');
            app.CommonData.waveAxisToolBar = ...
                axtoolbar(app.WaveAxes,{'pan','zoomin','zoomout','restoreview'});
            disableDefaultInteractivity(app.WaveAxes);
            switch app.CommonData.waveViewMode
                case 'initialize'
                    YlimWave = get(app.WaveAxes, 'YLim');
                    app.CommonData.dispVoiceLevel = YlimWave(2) * 0.95;
            end
            switch app.CommonData.waveViewMode
                case 'update'
                    hold(app.WaveAxes,"on")
                    continuousFo = app.CommonData.f0;
                    tFrame = app.CommonData.tFrameFo;
                    vuv = app.CommonData.viewVUV;
                    unvoiced = 1-vuv;
                    unvoiced(vuv > 0) = NaN;
                    voiced = vuv;
                    voiced(vuv <= 0) = NaN;
                    %app.CommonData.unVoicedHandle = [];
                    %unvoice = 
                    app.CommonData.unVoicedHandle = plot(app.WaveAxes, ...
                        tFrame, unvoiced*app.CommonData.dispVoiceLevel,"Color",[0 0 0], ...
                        "LineWidth", 2);
                    app.CommonData.voicedHandle = plot(app.WaveAxes, ...
                        tFrame, voiced*app.CommonData.dispVoiceLevel,"Color",[1 0 1], ...
                        "LineWidth", 3);
                    baseIdx = (1:length(tFrame))';
                    vuv = vuv(:); % make sure v-vec
                    onsetIdx = baseIdx(vuv > [0;vuv(1:end-1)]);
                    offsetIdx = baseIdx(vuv > [vuv(2:end);0]);
                    %onsetLoc = tFrame(onsetIdx);
                    %offsetLoc = tFrame(offsetIdx);
                    nSegment = length(onsetIdx);
                    app.CommonData.onsetLocations = zeros(2000,1)*NaN;
                    app.CommonData.offsetLocations = zeros(2000,1)*NaN;
                    app.CommonData.nVoicedSegments = nSegment;
                    app.CommonData.onsetLocations(1:nSegment) = onsetIdx;
                    app.CommonData.offsetLocations(1:nSegment) = offsetIdx;
                    app.CommonData.onsetdHandle = xline(app.WaveAxes, ...
                        tFrame(app.CommonData.onsetLocations(1:nSegment)), "Color",[0 1 0], "LineWidth", 0.5, HitTest="off");
                    app.CommonData.offsetdHandle = xline(app.WaveAxes, ...
                        tFrame(app.CommonData.offsetLocations(1:nSegment)), "Color",[1 0 0], "LineWidth", 0.5, HitTest="off");
                    %---- assign Segment data
                    segmentStr = struct;
                    segmentStr.onsetdHandle = app.CommonData.onsetdHandle;
                    segmentStr.offsetdHandle = app.CommonData.offsetdHandle;
                    set(app.WaveAxes, 'UserData', segmentStr);
                    %---- make continuous fo
                    continuousFo(1:onsetIdx(1)) = continuousFo(onsetIdx(1));
                    continuousFo(offsetIdx(end):end) = continuousFo(offsetIdx(end));
                    for ii = 2:nSegment
                        tmpIdx = offsetIdx(ii-1):onsetIdx(ii);
                        tmpFo = (tmpIdx - offsetIdx(ii-1))/(onsetIdx(ii) - offsetIdx(ii-1));
                        tmpFo = continuousFo(offsetIdx(ii-1)) ...
                            + (continuousFo(onsetIdx(ii)) ...
                            - continuousFo(offsetIdx(ii-1))) * tmpFo;
                        continuousFo(tmpIdx) = tmpFo;
                    end
                    app.CommonData.continuousFo = continuousFo;
                    app.CommonData.f0 = continuousFo;
                    hold(app.WaveAxes,"off")
            end
        end     
        
        function results = updateSetting(app)
            results = 1;
            worldHandlerSettingFile = "worldHandlerSetting.txt";
            checkPath = string(app.CommonData.fileFullPath) + "/" + worldHandlerSettingFile;
            fid = fopen(checkPath,"w");
            outText = ['f0_floor ' app.fLDropDown.Value];
            fprintf(fid, "%s\n", outText);
            outText = ['f0_ceil ' app.fHDropDown.Value];
            fprintf(fid, "%s\n", outText);
            fclose(fid);
        end
        
        function results = initializeGUIposition(app)
            results = 1;
            rootInfo = groot;
            screenSize = get(rootInfo, "ScreenSize");
            worldHandlerBaseFile = "worldHandlerBase.txt";
            checkPath = string(app.CommonData.appPath) + "/" + worldHandlerBaseFile;
            if exist(checkPath,"file")
                fid = fopen(checkPath,"r");
                tline = fgetl(fid);
                while tline ~= -1
                    ss = strsplit(tline);
                    if "Position" == string(ss{1})
                        if length(ss) == 5
                            xBase = str2double(ss{2});
                            yBase = str2double(ss{3});
                            width = str2double(ss{4});
                            hight = str2double(ss{5});
                        end
                    end
                    if "Extension" == string(ss{1})
                        app.CommonData.Extension = string(ss{2});
                    end
                    tline = fgetl(fid);
                end
                fclose(fid);
                if ~exist("xBase","var")
                    width = max(900, screenSize(3) - 101);
                hight = max(607, screenSize(4) - 2 - 30);
                xBase = 100;
                yBase = 1;
                end
            else
                width = max(900, screenSize(3) - 101);
                hight = max(607, screenSize(4) - 2 - 30);
                xBase = 100;
                yBase = 1;
                app.CommonData.Extension = "off";
            end
            set(app.UIFigure, "Position", [xBase yBase width hight]);
            app.CommonData.figurePosition = [xBase yBase width hight];
        end
        
        function results = quitConfirm(app)
            results = 1;
            msg = 'Save or delete the log file?';
            selection = uiconfirm(app.UIFigure,msg,'Select options', ...
                'Options',{'Save_logfile','Delete_logfile'},'Icon','question');
            switch selection
                case 'Save_logfile'
                case 'Delete_logfile'
                    commandString = "delete " + app.LogDataPublic.logFileFullpath;
                    eval(commandString);
            end
        end
        
        function results = locateSettingFile(app)
            results = 1;
            worldHandlerBaseFile = "worldHandlerBase.txt";
            app.CommonData.settingFilePath = app.CommonData.startDir;
            checkPath = string(app.CommonData.appPath) + "/" + worldHandlerBaseFile;
            if exist(checkPath,"file")
                fid = fopen(checkPath,"r");
                tline = fgetl(fid);
                while tline ~= -1
                    ss = strsplit(tline);
                    if "SettingFilePath" == string(ss{1})
                        if length(ss) == 5
                            app.CommonData.settingFilePath = ss{2};
                        end
                    end
                    tline = fgetl(fid);
                end
                fclose(fid);
            end
        end
        
        function results = saveBaseFile(app)
            results = 1;
            worldHandlerBaseFile = "worldHandlerBase.txt";
            checkPath = string(app.CommonData.appPath) + "/" + worldHandlerBaseFile;
            fid = fopen(checkPath,"w");
            outText = ['SettingFilePath ' app.CommonData.settingFilePath];
            fprintf(fid, "%s\n", outText);
            position = get(app.UIFigure, 'Position');
            outText = ['Position ' num2str(position)];
            fprintf(fid, "%s\n", outText);
            outText = "Extension " + app.CommonData.Extension;
            fprintf(fid, "%s\n", outText);
            fclose(fid);
        end
        
        function results = saveParameter(app)
            results = 1;
            filePath = app.CommonData.tmpFilePath;
            worldParameter = struct;
            worldParameter.creator = 'worldHandler.mlapp';
            worldParameter.signal = app.CommonData.x;
            worldParameter.fileFullPath = app.fileFullPathTextLabel.Text;
            worldParameter.fileName = app.fileNameTextLabel.Text;
            worldParameter.samplingFrequency = app.CommonData.samplingFrequency;
            worldParameter.span = app.CommonData.span;
            worldParameter.spectrum_parameter = app.CommonData.spectrum_parameter;
            worldParameter.source_parameter = app.CommonData.source_parameter;
            worldParameter.f0_original = app.CommonData.f0_original;
            app.CommonData.outFilePath = string(filePath);
            save(app.CommonData.outFilePath + app.CommonData.outFileName, "worldParameter");
            app.SaveParameterButton.Enable = 'off';
            app.PLamp.Visible = 'off';
        end
        
        function results = penUpInFoAxes(app)
            results = 1;
            if app.CommonData.penString.count > 0
                app.LogDataPublic.text = "Fo_pendown " + datestr(now);
                recordLog(app);
                id = app.CommonData.penString.count;
                app.CommonData.penString.TotalCount = id;
                %app.REDOButton.Enable = "off";
                app.CommonData.penStatus = 'up';
                set(app.UIFigure, "Pointer", 'arrow')
                app.UNDOButton.Enable = "on";
                sqid = app.CommonData.penString.locData(id).seqId;
                tt = get(app.CommonData.f0Handle,'xdata');
                linehandle = app.CommonData.penString.locData(id).linehandle;
                xdata = get(linehandle,'xdata');
                ydata = get(linehandle,'ydata');
                xdata = xdata(1:sqid);
                ydata = ydata(1:sqid);
                [xdataSrt, srtIdx] = sort(xdata);
                xdataSrt = xdataSrt(:) + cumsum(ones(sqid,1))/sqid/1000000000000;
                ydataSrt = ydata(srtIdx);
                stp = min(xdataSrt);
                edp = max(xdataSrt);
                y = interp1(xdataSrt,ydataSrt,tt(tt>= stp & tt < edp), ...
                    "linear","extrap");
                yDataMod = exp(y);
                lineStatus = get(app.CommonData.f0Handle, 'UserData');
                fo = lineStatus.latestFo;
                %originalFo = app.CommonData.originalFo;
                foTmp = fo;
                foTmp(tt>= stp & tt < edp) = yDataMod;
                foUpdated = foTmp;
                foTmp(app.CommonData.viewVUV <= 0) = 0;
                fo = foTmp;
                %app.DisplayData.fo = fo;
                set(app.CommonData.f0Handle, 'Ydata', log(fo));
                set(app.CommonData.originalf0Handle, 'Ydata', log(foUpdated));
                %updateFoDisplay2(app);
                app.CommonData.penString.locData(id).currentFo = foUpdated;
                lineStatus.latestFo = foUpdated;
                lineStatus.previousFo = foUpdated;
                set(app.CommonData.f0Handle, 'UserData',lineStatus);
                hold(app.FoAxes,"off");
            end
        end
        
        function results = buttonUpInWaveAxes(app)
            results = 1;
            tFrame = app.CommonData.tFrameFo;
            fs = app.CommonData.samplingFrequency;
            disp("number of onset:" + num2str(length(app.CommonData.onsetdHandle)))
            if isfield(app.CommonData, "pickedMarker")
            switch app.CommonData.pickedMarker
                case "onset"
                    set(app.CommonData.onsetdHandle(app.CommonData.markerIdx),'LineWidth',0.5);
                case "offset"
                    set(app.CommonData.offsetdHandle(app.CommonData.markerIdx),'LineWidth',0.5);
            end
            app.CommonData = rmfield(app.CommonData, "pickedMarker");
            end
            vuv = app.CommonData.viewVUV*0;
            nVoicedSegments = app.CommonData.nVoicedSegments;
            for ii = 1:nVoicedSegments
                onLoc = get(app.CommonData.onsetdHandle(ii),'Value');
                [~,onIdx] = min(abs(tFrame - onLoc));
                offLoc = get(app.CommonData.offsetdHandle(ii),'Value');
                [~,offIdx] = min(abs(tFrame - offLoc));
                vuv(onIdx) = vuv(onIdx)+1;
                vuv(offIdx) = vuv(offIdx)-1;
            end
            %vuv(app.CommonData.onsetLocations(1:nVoicedSegments)) = 1;
            %vuv(app.CommonData.offsetLocations(1:nVoicedSegments)) = -1;
            vuv = cumsum(vuv);
            vuv = double(vuv > 0);
            vuv = vuv(:);
            baseIdx = (1:length(tFrame))';
            onsetIdx = baseIdx(vuv > [0;vuv(1:end-1)]);
            offsetIdx = min(length(tFrame), baseIdx(vuv > [vuv(2:end);0]) + 1);
            vuv(offsetIdx) = 1;
            app.CommonData.viewVUV = vuv;
            nVoicedSegments = length(onsetIdx);
            app.CommonData.nVoicedSegments = nVoicedSegments;
            app.CommonData.onsetLocations = tFrame + NaN;
            %app.CommonData.onsetLocations = NaN;
            app.CommonData.offsetLocations = tFrame + NaN;
            app.CommonData.onsetLocations(1:nVoicedSegments) = onsetIdx;
            app.CommonData.offsetLocations(1:nVoicedSegments) = offsetIdx;
            unvoiced = 1-vuv;
            unvoiced(vuv > 0) = NaN;
            voiced = vuv;
            voiced(vuv <= 0) = NaN;
            set(app.CommonData.unVoicedHandle,'YData',unvoiced*app.CommonData.dispVoiceLevel);
            set(app.CommonData.voicedHandle,'YData',voiced*app.CommonData.dispVoiceLevel);
            delete(app.CommonData.onsetdHandle);
            app.CommonData.onsetdHandle = xline(app.WaveAxes, ...
                tFrame(app.CommonData.onsetLocations(1:nVoicedSegments)), ...
                "Color",[0 1 0], "LineWidth", 0.5, HitTest="off");
            delete(app.CommonData.offsetdHandle);
            app.CommonData.offsetdHandle = xline(app.WaveAxes, ...
                tFrame(app.CommonData.offsetLocations(1:nVoicedSegments)), ...
                "Color",[1 0 0], "LineWidth", 0.5, HitTest="off");
            app.CommonData.soundVUV = interp1(tFrame, vuv, ...
                (1:length(app.CommonData.x))/fs,"linear","extrap");
            app.CommonData.soundVUV = double(app.CommonData.soundVUV > 0.5);
            lineStatus = get(app.CommonData.f0Handle, 'UserData');
            fo = lineStatus.latestFo;
                %originalFo = app.CommonData.originalFo;
                fo(app.CommonData.viewVUV <= 0) = 0;
                %app.DisplayData.fo = fo;
             set(app.CommonData.f0Handle, 'Ydata', log(fo));
        end

        function results = pickMarker(app)
            results = 1;
            nVoicedSegments = app.CommonData.nVoicedSegments;
            tFrame = app.CommonData.tFrameFo;
            onsetIdx = app.CommonData.onsetLocations(1:nVoicedSegments);
            offsetIdx = app.CommonData.offsetLocations(1:nVoicedSegments);
            currentPoint = get(app.WaveAxes,'CurrentPoint');
            [minOnDist, minOnIdx] = min(abs(tFrame(onsetIdx)-currentPoint(1,1)));
            [minOffDist, minOffIdx] = min(abs(tFrame(offsetIdx)-currentPoint(1,1)));
            if min(minOnDist,minOffDist) < app.CommonData.minimumMarkerDistnce
                set(app.UIFigure, "Pointer", 'left')
                if minOnDist < minOffDist
                    set(app.CommonData.onsetdHandle(minOnIdx),'LineWidth',3);
                    app.CommonData.pickedMarker = "onset";
                    app.CommonData.markerIdx = minOnIdx;
                else
                    set(app.CommonData.offsetdHandle(minOffIdx),'LineWidth',3);
                    app.CommonData.pickedMarker = "offset";
                    app.CommonData.markerIdx = minOffIdx;
                end
            elseif min(minOnDist,minOffDist) > app.CommonData.marginDistance
                set(app.UIFigure, "Pointer", 'left')
                disp(currentPoint(1,1));
                nVoicedSegments = nVoicedSegments + 1;
                [~, ctrIndex] = min(abs(tFrame - currentPoint(1,1)));
                [~, fwrdIndex] = min(abs(tFrame - currentPoint(1,1) + app.CommonData.insertionWidth/2));
                [~, bkwrdIndex] = min(abs(tFrame - currentPoint(1,1) - app.CommonData.insertionWidth/2));
                switch double(app.CommonData.viewVUV(ctrIndex) > 0.1)
                    case 1
                        disp('voiced');
                        app.CommonData.onsetLocations(nVoicedSegments) = bkwrdIndex;
                        app.CommonData.offsetLocations(nVoicedSegments) = fwrdIndex;
                    case 0
                        disp('unvoiced');
                        app.CommonData.onsetLocations(nVoicedSegments) = fwrdIndex;
                        app.CommonData.offsetLocations(nVoicedSegments) = bkwrdIndex;
                end
                delete(app.CommonData.onsetdHandle);
                app.CommonData.onsetdHandle = xline(app.WaveAxes, ...
                    tFrame(app.CommonData.onsetLocations(1:nVoicedSegments)), ...
                    "Color",[0 1 0], "LineWidth", 0.5, HitTest="off");
                delete(app.CommonData.offsetdHandle);
                app.CommonData.offsetdHandle = xline(app.WaveAxes, ...
                    tFrame(app.CommonData.offsetLocations(1:nVoicedSegments)), ...
                    "Color",[1 0 0], "LineWidth", 0.5, HitTest="off");
                set(app.CommonData.onsetdHandle(nVoicedSegments),"LineWidth", 3);
                app.CommonData.pickedMarker = "onset";
                app.CommonData.markerIdx = nVoicedSegments;
                app.CommonData.nVoicedSegments = nVoicedSegments;
            end
        end
        
        function results = enableExtensionMode(app)
            results = 1;
            app.foSettingDialogue.Visible = "on";
            app.foSettingDialogue.Enable = "on";
            app.fLDropDown.Visible = "off";
            app.fLDropDownLabel.Visible = "off";     
            app.fHDropDown.Visible = "off";
            app.fHDropDownLabel.Visible = "off"; 
            tmpPosition = get(app.ReadsoundButton, "Position");
            tmpPosition2 = get(app.PlayoriginalButton, "Position");
            tmpPosition(3) = tmpPosition2(3);
            set(app.ReadsoundButton, "Position",tmpPosition);
            tmpPosition = get(app.FoextractionButton, "Position");
            tmpPosition(3) = tmpPosition2(3);
            set(app.FoextractionButton, "Position",tmpPosition);
        end
    end
    
    methods (Access = public)
        
        function results = recordLog(app)
            results = 1;
            position = get(app.UIFigure, 'Position');
            if isfield(app.CommonData, "figurePosition") ...
                    && sum(position ~= app.CommonData.figurePosition) > 0
                updateSetting(app);
                saveBaseFile(app);
            end
            if app.SAVElogfileCheckBox.Value == 1
                if exist(app.LogDataPublic.logFileFullpath,"file")
                    fid = fopen(app.LogDataPublic.logFileFullpath,"a");
                    fprintf(fid, "%s\n", app.LogDataPublic.text);
                    fclose(fid);
                else
                    worldHandlerLogFileName = string(pwd) + "/" ...
                        + "worldHlog" + datestr(now,30) + "d.txt";
                    app.LogDataPublic.logFileFullpath = worldHandlerLogFileName;
                    fid = fopen(app.LogDataPublic.logFileFullpath,"a");
                    app.LogDataPublic.text = "created " + datestr(now);
                    fprintf(fid, "%s\n", app.LogDataPublic.text);
                    app.LogDataPublic.text = "pwd_at_start " + app.CommonData.startDir;
                    fprintf(fid, "%s\n", app.LogDataPublic.text);
                    fclose(fid);
                end
            else
                if exist(app.LogDataPublic.logFileFullpath,"file")
                    commandString = "delete " + app.LogDataPublic.logFileFullpath;
                    eval(commandString);
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CommonData.appfullpath = mfilename("fullpath");
            [appPath, ~, ~] = fileparts(app.CommonData.appfullpath);
            app.CommonData.appPath = appPath;
            app.CommonData.startDir = pwd;
            locateSettingFile(app);
            app.CommonData.fileFullPath = pwd;
            worldHandlerLogFileName = string(pwd) + "/" ...
                + "worldHlog" + datestr(now,30) + "d.txt";
            app.LogDataPublic.logFileFullpath = worldHandlerLogFileName;
            app.LogDataPublic.text = "created " + datestr(now);
            recordLog(app);
            app.LogDataPublic.text = "pwd_at_start " + app.CommonData.startDir;
            recordLog(app);
            rootInfo = groot;
            screenSize = get(rootInfo, "ScreenSize");
            width = min(900, screenSize(3) - 101);
            hight = min(607, screenSize(4) - 2 - 30);
            set(app.UIFigure, "Position", [100 1 width hight]);
            app.CommonData.foEdit = 'off';
            app.CommonData.Extension = "off";
            fs = 44100;
            tt = (1:fs/2)'/fs;
            x = (0.2*sin(2*pi*tt*220) + 0.05*sin(2*pi*tt*277.1826) ...
                + 0.025*sin(2*pi*tt*329.6276)) .* exp(-8*tt) .*tt/tt(end);
            app.CommonData.deviceID = -1;
            app.CommonData.x = x;
            app.CommonData.samplingFrequency = fs;
            sound(x, fs);
            app.CommonData.aperiodicity = 'off';
            app.CommonData.spectrum = 'off';
            app.CommonData.synthSound = 'off';
            app.CommonData.penStatus = 'up';
            app.CommonData.initialized = 'no';
            app.CommonData.minimumMarkerDistnce = 0.015; % proximity 15ms
            app.CommonData.marginDistance = 0.04; % safeguard for insertion
            app.CommonData.insertionWidth = 0.03; % width of inserted seg.
            drawnow;
            initializeGUIposition(app);
            if app.CommonData.Extension == "on"
                enableExtensionMode(app);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {606, 606};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {261, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: ReadsoundButton
        function ReadsoundButtonPushed(app, event)
            app.LogDataPublic.text = "ReadsoundButtonPushed " + datestr(now);
            recordLog(app);
            [filename, fileFullPath] = uigetfile({'*.*'});
            if isequal(filename, 0)
                disp('file selection cancelled.');
                app.LogDataPublic.text = "cancelled " + datestr(now);
                recordLog(app);
            else
                [x, fs] = audioread([fileFullPath filename]);
                [~, nChannel] = size(x);
                if nChannel > 1
                    channels = struct;
                    for ii = 1:nChannel
                        channels(ii).name = ['channel' num2str(ii)];
                    end
                    Tchannel = struct2table(channels);
                    [id, tf] = listdlg("ListString", Tchannel.name, "SelectionMode","single", ...
                        "PromptString","Select channel","ListSize", [160, 100]);
                    if tf
                        x = x(:, id);
                    else
                        x = x(:, 1); % select default
                    end
                end
                cla(app.WaveAxes);
                if isfield(app.CommonData, 'f0_parameter')
                    app.CommonData = rmfield(app.CommonData, 'f0_parameter');
                end
                app.CommonData.settingFilePath = fileFullPath(1:end-1);
                app.LogDataPublic.text = "fileFullPath " + fileFullPath;
                recordLog(app);
                app.LogDataPublic.text = "filename " + filename;
                recordLog(app);
                app.CommonData.x = x;
                app.CommonData.soundVUV = x(:,1)*0 + 1;
                app.CommonData.samplingFrequency = fs;
                app.CommonData.fileFullPath = fileFullPath;
                app.CommonData.filename = filename;
                app.fileFullPathTextLabel.Text = fileFullPath;
                app.fileFullPathTextLabel.Visible = 'on';
                app.fileNameTextLabel.Text = filename;
                app.fileNameTextLabel.Visible = 'on';
                %---- waveform plot
                app.WaveAxes.Visible = 'on';
                app.CommonData.waveViewMode = 'initialize';
                setupWaveAxesView(app);
                app.PlayoriginalButton.Enable = 'on';
                app.FoextractionButton.Enable = 'on';
                app.AperiodicityButton.Enable = 'off';
                app.SpectrumButton.Enable = 'off';
                app.PlaysynthButton.Enable = 'off';
                app.SynthesisButton.Enable = 'off';
                app.FoAxes.Visible = 'off';
                app.WaveAxesSynth.Visible = 'off';
                app.SaveParameterButton.Enable = "off";
                app.SavesynthButton.Enable = "off";
                app.SgramAxes.Visible = 'off';
                app.PLamp.Visible = "off";
                app.VLamp.Visible = "off";
                app.CommonData.aperiodicity = 'off';
                app.CommonData.spectrum = 'off';
                app.CommonData.synthSound = 'off';
                cla(app.FoAxes);
                cla(app.WaveAxesSynth);
                cla(app.SgramAxes);
                if isfield(app.CommonData, "colorbarHandle")
                    app.CommonData.colorbarHandle.Visible = 'off';
                end
            end
        end

        % Button pushed function: QUITButton
        function QUITButtonPushed(app, event)
            app.LogDataPublic.text = "QUITButtonPushed " + datestr(now);
            recordLog(app);
            pause(0.3);
            close(app.UIFigure);
        end

        % Button pushed function: PlayoriginalButton
        function PlayoriginalButtonPushed(app, event)
            app.LogDataPublic.text = "PlayoriginalButtonPushed " + datestr(now);
            recordLog(app);
            xLim = app.WaveAxes.XLim;
            fs = app.CommonData.samplingFrequency;
            x = app.CommonData.x;
            nData = length(x);
            xLimSample = max(1, min(nData, 1+round(xLim * fs)));
            span = xLimSample(1):xLimSample(end);
            sound(x(span)/max(abs(x(span))), fs);
        end

        % Button pushed function: FoextractionButton
        function FoextractionButtonPushed(app, event)
            app.LogDataPublic.text = "FoextractionButtonPushed " + datestr(now);
            recordLog(app);
            app.SpectrumButton.Enable = 'off';
            app.PlaysynthButton.Enable = 'off';
            app.SynthesisButton.Enable = 'off';
            app.FoAxes.Visible = 'off';
            app.WaveAxesSynth.Visible = 'off';
            app.SgramAxes.Visible = 'off';
            app.CommonData.separationMargin = 0.04;
            cla(app.FoAxes)
            cla(app.WaveAxesSynth)
            cla(app.SgramAxes)
            xLim = app.WaveAxes.XLim;
            fs = app.CommonData.samplingFrequency;
            x = app.CommonData.x;
            nData = length(x);
            xLimSample = max(1, min(nData, 1+round(xLim * fs)));
            span = xLimSample(1):xLimSample(end);
            app.CommonData.span = span;
            %--------------
            option_harvest.f0_floor = str2double(app.fLDropDown.Value);
            option_harvest.f0_ceil = str2double(app.fHDropDown.Value);
            app.CommonData.option_harvest = option_harvest;
            f0_parameter = pitchWrapper(x(span), fs, option_harvest, @pitchPlginHarvest);
            %f0_parameter = pitchWrapper(x(span), fs, option_harvest, @pitchPlginXSX);
            %f0_parameter = pitchWrapper(x(span), fs, option_harvest, @pitchPlginNDF);
            %f0_parameter = pitchWrapper(x(span), fs, option_harvest, @pitchPlginPEF);
            %f0_parameter = Harvest(x(span), fs, option_harvest);
            f0_parameter.f0(1) = 0;
            f0_parameter.vuv(1) = 0;
            app.CommonData.f0_parameter = f0_parameter;
            app.CommonData.f0_original = f0_parameter.f0;
            app.FoAxes.Visible = 'on';
            app.FoAxes.LineWidth = 2;
            %[~, nTime] = size(f0_parameter.f0_candidates);
            %ttCandidate = (0:nTime-1)'/1000 + xLim(1);
            ttCandidate = f0_parameter.candidatePositions + xLim(1);
            tFrame = f0_parameter.temporal_positions + xLim(1);
            app.CommonData.soundVUV = interp1(tFrame, f0_parameter.vuv, ...
                (1:length(span))/fs,"linear","extrap");
            app.CommonData.soundVUV = double(app.CommonData.soundVUV > 0.5);
            app.CommonData.candidateHandle = ...
                plot(app.FoAxes, ttCandidate, log(f0_parameter.f0_candidates'), '.');
            set(app.CommonData.candidateHandle, 'HitTest', 'off');
            grid(app.FoAxes, "on");
            hold(app.FoAxes, "on");
            yTickLogFreq = log([10 20 30 40 50 60 70 80 90 ...
                100 200 300 400 500 600 700 800 900 1000 1500 2000]);
            yTiickLabel = {'.01', '.02', '.03', ' ', '.05', ' ', '.07', ' ', ' ', ...
                '.1', '.2', '.3', ' ', '.5', ' ', ' .7', ' ', ' ', ...
                '1', ' ', '2'};
            set(app.FoAxes, 'YTick', yTickLogFreq, 'YTickLabel', yTiickLabel);
            %if isfield(f0_parameter, "foExtractor")
            %    title(app.FoAxes, f0_parameter.foExtractor, "HorizontalAlignment","right");
            %end
            app.CommonData.f0 = f0_parameter.f0;
            app.CommonData.tFrameFo = tFrame;
            app.CommonData.originalf0Handle = ...
                plot(app.FoAxes, tFrame, log(f0_parameter.f0), ...
                'c', "LineWidth", 6);
            set(app.CommonData.originalf0Handle,'HitTest', 'off');
            lineStatus = struct;
            lineStatus.originalYdata = get(app.CommonData.originalf0Handle, 'YData');
            lineStatus.latestFo = app.CommonData.f0;
            lineStatus.previousFo = app.CommonData.f0;
            app.CommonData.initialized = 'yes';
            f0_voiced = f0_parameter.f0;
            vuv = f0_parameter.vuv;
            app.CommonData.viewVUV = vuv;
            f0_voiced(vuv < 0.5) = NaN;
            app.CommonData.f0Handle = ...
                plot(app.FoAxes, tFrame, log(f0_voiced), ...
                'k', "LineWidth", 3);
            set(app.CommonData.f0Handle, 'HitTest', 'off');
            hold(app.FoAxes, "off")
            axis(app.FoAxes, [xLim(1) xLim(end) ...
                log(option_harvest.f0_floor) log(1500)]);
            xlabel(app.FoAxes, '');
            ylabel(app.FoAxes, 'frequency (Hz)')
            if isfield(f0_parameter, "foExtractor")
                title(app.FoAxes, f0_parameter.foExtractor, ...
                    "VerticalAlignment","top","HorizontalAlignment","right", ...
                    "FontSize",18);
            end
            app.CommonData.penString = struct;
            app.CommonData.penString.count = 0;
            app.CommonData.penString.TotalCount = 0;
            app.CommonData.penString.locData = struct;
            %---- add VUV information
            app.CommonData.waveViewMode = 'update';
            setupWaveAxesView(app);
            lineStatus.latestFo = app.CommonData.continuousFo;
            lineStatus.previousFo = app.CommonData.continuousFo;
            set(app.CommonData.originalf0Handle, 'YData', ...
                log(app.CommonData.continuousFo));
            set(app.CommonData.f0Handle,'UserData', lineStatus);
            %updateVUV(app);
            %----
            app.CommonData.FoAxesToolBar = ...
                axtoolbar(app.FoAxes,{'pan','zoomin','zoomout','restoreview'});
            disableDefaultInteractivity(app.FoAxes);
            drawnow;
            app.AperiodicityButton.Enable = 'on';
            linkaxes([app.FoAxes, app.WaveAxes], 'x');
            app.CommonData.foEdit = 'off';
            app.CommonData.foEditCount = 0;
            app.CommonData.foEditData = struct;
            app.PlayVoicedButton.Enable = 'on';
            app.PlayUVButton.Enable = 'on';
            if isfield(app.CommonData, "colorbarHandle")
                app.CommonData.colorbarHandle.Visible = 'off';
            end
        end

        % Button pushed function: AperiodicityButton
        function AperiodicityButtonPushed(app, event)
            app.LogDataPublic.text = "AperiodicityButtonPushed " + datestr(now);
            recordLog(app);
            fs = app.CommonData.samplingFrequency;
            x = app.CommonData.x;
            span = app.CommonData.span;
            f0_parameter = app.CommonData.f0_parameter;
            foUpdated = exp(get(app.CommonData.originalf0Handle, 'Ydata'));
            f0_parameter.f0 = foUpdated;
            app.CommonData.f0_original = foUpdated;
            f0_parameter.f0 = foUpdated(:)' .* app.CommonData.viewVUV(:)';
            option_harvest = app.CommonData.option_harvest;
            f0_parameter.vuv = ...
                double(f0_parameter.f0 > option_harvest.f0_floor);
            app.CommonData.f0_parameter.f0 = f0_parameter.f0;
            app.CommonData.f0_parameter.vuv = f0_parameter.vuv;
            option_cheaptrick.fft_size = 4096;
            option_d4c.fft_size = option_cheaptrick.fft_size;
            source_parameter = D4C(x(span), fs, f0_parameter, option_d4c);
            app.CommonData.source_parameter = source_parameter;
            app.SpectrumButton.Enable = 'on';
            app.ALamp.Visible = 'off';
            app.SLamp.Visible = 'on';
            app.YLamp.Visible = 'on';
            app.CommonData.aperiodicity = 'on';
            app.CommonData.spectrum = 'off';
            app.CommonData.synthSound = 'off';
        end

        % Button pushed function: SynthesisButton
        function SynthesisButtonPushed(app, event)
            app.LogDataPublic.text = "SynthesisButtonPushed " + datestr(now);
            recordLog(app);
            span = app.CommonData.span;
            fs = app.CommonData.samplingFrequency;
            spectrum_parameter = app.CommonData.spectrum_parameter;
            source_parameter = app.CommonData.source_parameter;
            y = Synthesis(source_parameter, spectrum_parameter);
            app.CommonData.y = y;
            app.CommonData.tty = (0:length(y)-1)'/fs + (span(1) -1) /fs;
            app.WaveAxesSynth.Visible = 'on';
            app.CommonData.waveSynthHandle = plot(app.WaveAxesSynth, ...
                app.CommonData.tty, y, "LineWidth", 2);
            grid(app.WaveAxesSynth, "on");
            xlabel(app.WaveAxesSynth, '');
            ylabel(app.WaveAxesSynth, 'level');
            title(app.WaveAxesSynth, '');
            xLim = app.WaveAxes.XLim;
            app.CommonData.WaveAxesSynthToolBar = ...
                axtoolbar(app.WaveAxesSynth,{'pan','zoomin','zoomout','restoreview'});
            app.WaveAxesSynth.XLim = [xLim(1) xLim(2)];
            disableDefaultInteractivity(app.WaveAxesSynth);
            app.WaveAxesSynth.LineWidth = 2;
            linkaxes([app.SgramAxes, app.WaveAxes, app.FoAxes], 'off');
            linkaxes([app.SgramAxes, app.WaveAxes, app.FoAxes, app.WaveAxesSynth], 'x');
            app.PlaysynthButton.Enable = 'on';
            app.SaveParameterButton.Enable = 'on';
            app.SavesynthButton.Enable = 'on';
            app.YLamp.Visible = 'off';
            app.PLamp.Visible = 'on';
            app.VLamp.Visible = 'on';
        end

        % Button pushed function: SpectrumButton
        function SpectrumButtonPushed(app, event)
            app.LogDataPublic.text = "SpectrumButtonPushed " + datestr(now);
            recordLog(app);
            fs = app.CommonData.samplingFrequency;
            x = app.CommonData.x;
            span = app.CommonData.span;
            f0_parameter = app.CommonData.f0_parameter;
            option_cheaptrick.fft_size = 4096;
            spectrum_parameter = CheapTrick(x(span), fs, f0_parameter, option_cheaptrick);
            app.CommonData.spectrum_parameter = spectrum_parameter;
            app.SgramAxes.Visible = 'on';
            ttSgram = spectrum_parameter.temporal_positions + (span(1)-1)/fs;
            dBSgram = 10*log10(spectrum_parameter.spectrogram);
            dBSgram = dBSgram - max(dBSgram(:));
            app.CommonData.sgramHandle = imagesc(app.SgramAxes, ...
                [ttSgram(1) ttSgram(end)], [0 fs/2]/1000, ...
                max(-80, dBSgram));
            axis(app.SgramAxes, 'xy');
            title(app.SgramAxes, '');
            colormap(app.SgramAxes, "turbo");
            app.CommonData.colorbarHandle = colorbar(app.SgramAxes,"northoutside");
            xlabel(app.SgramAxes, 'time (s)');
            ylabel(app.SgramAxes, 'frequency (kHz)');
            xLim = app.WaveAxes.XLim;
            app.CommonData.SgramAxesToolBar = ...
                axtoolbar(app.SgramAxes,{'pan','zoomin','zoomout','restoreview'});
            disableDefaultInteractivity(app.SgramAxes);
            axis(app.SgramAxes, [xLim(1) xLim(end) [0 fs/2]/1000]);
            linkaxes([app.FoAxes, app.WaveAxes], 'off');
            linkaxes([app.SgramAxes, app.WaveAxes, app.FoAxes], 'x');
            app.SynthesisButton.Enable = 'on';
            app.SLamp.Visible = 'off';
            app.YLamp.Visible = 'on';
            app.SaveParameterButton.Enable = 'on';
            app.CommonData.spectrum = 'on';
            app.CommonData.synthSound = 'off';
        end

        % Button pushed function: PlaysynthButton
        function PlaysynthButtonPushed(app, event)
            app.LogDataPublic.text = "PlaysynthButtonPushed " + datestr(now);
            recordLog(app);
            xLim = app.WaveAxes.XLim;
            tty = app.CommonData.tty;
            fs = app.CommonData.samplingFrequency;
            y = app.CommonData.y;
            yTrim = y(tty > xLim(1) & tty < xLim(2));
            if ~isempty(yTrim)
                sound(yTrim/max(abs(yTrim)), fs);
            end
        end

        % Button down function: FoAxes
        function FoAxesButtonDown(app, event)
            app.LogDataPublic.text = "FoAxesButtonDown " + datestr(now);
            recordLog(app);
            if isfield(app.CommonData, 'f0_parameter')
                app.CommonData.penStatus = 'down';
                set(app.UIFigure, "Pointer", 'crosshair')
                currentPoint = get(app.FoAxes,'CurrentPoint');
                if app.CommonData.penString.count < app.CommonData.penString.TotalCount
                    while app.CommonData.penString.count < app.CommonData.penString.TotalCount
                        UNDOButtonPushed(app, event);
                        app.CommonData.penString.TotalCount = ...
                            app.CommonData.penString.TotalCount - 1;
                        pause(0.1);
                    end
                end
                app.RESETButton.Enable = 'on';
                app.CommonData.penString.count = app.CommonData.penString.count + 1;
                app.CommonData.penString.TotalCount = app.CommonData.penString.TotalCount + 1;
                xlocation = zeros(1000,1) * NaN;
                ylocation = zeros(1000,1) * NaN;
                lineStatus = get(app.CommonData.f0Handle, 'UserData');
                fo = lineStatus.latestFo;
                id = app.CommonData.penString.count;
                ylocation(1) = currentPoint(1,2);
                xlocation(1) = currentPoint(1,1);
                hold(app.FoAxes,"on");
                app.CommonData.penString.locData(id).seqId = 1;
                app.CommonData.penString.locData(id).linehandle = ...
                    plot(app.FoAxes,xlocation,ylocation,'m-','LineWidth',1);
                app.CommonData.penString.locData(id).latestFo = fo;
            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            cLocFo = get(app.FoAxes,'CurrentPoint');
            cLocWave = get(app.WaveAxes,'CurrentPoint');
            xlimFo = get(app.FoAxes,'xlim');
            %ylimFo = get(app.FoAxes,'ylim');
            ylimWave = get(app.WaveAxes,'ylim');
            switch app.CommonData.penStatus
                case 'down'
                    id = app.CommonData.penString.count;
                    if id > 0
                        currentPoint = get(app.FoAxes,'CurrentPoint');
                        seqId = app.CommonData.penString.locData(id).seqId + 1;
                        app.CommonData.penString.locData(id).seqId = seqId;
                        xlocation = get(app.CommonData.penString.locData(id).linehandle,'xdata');
                        ylocation = get(app.CommonData.penString.locData(id).linehandle,'ydata');
                        xlocation(seqId) = currentPoint(1,1);
                        ylocation(seqId) = currentPoint(1,2);
                        set(app.CommonData.penString.locData(id).linehandle,'xdata',xlocation);
                        set(app.CommonData.penString.locData(id).linehandle,'ydata',ylocation);
                        drawnow;
                    end
            end
            if string(app.CommonData.initialized) == "yes" ...
                    && (ylimWave(1)-cLocWave(1,2))*(ylimWave(2)-cLocWave(1,2)) <0 ...
                    && (xlimFo(1)-cLocFo(1,1))*(xlimFo(2)-cLocFo(1,1)) <0
                %buttonUpInWaveAxes(app);
                %handle =
                if isfield(app.CommonData, "pickedMarker")
            switch app.CommonData.pickedMarker
                case "onset"
                    set(app.CommonData.onsetdHandle(app.CommonData.markerIdx),'Value',cLocWave(1,1));
                case "offset"
                    set(app.CommonData.offsetdHandle(app.CommonData.markerIdx),'Value',cLocWave(1,1));
            end
                end
            end
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            position = get(app.UIFigure, 'Position');
            if isfield(app.CommonData, "figurePosition") ...
                    && sum(position ~= app.CommonData.figurePosition) > 0
                updateSetting(app);
                saveBaseFile(app);
            end
            cLocFo = get(app.FoAxes,'CurrentPoint');
            cLocWave = get(app.WaveAxes,'CurrentPoint');
            xlimFo = get(app.FoAxes,'xlim');
            ylimFo = get(app.FoAxes,'ylim');
            ylimWave = get(app.WaveAxes,'ylim');
            if ~isfield(app.CommonData, 'f0_parameter') ...
                    ||(xlimFo(1)-cLocFo(1,1))*(xlimFo(2)-cLocFo(1,1)) >0 ...
                    || ((ylimFo(1)-cLocFo(1,2))*(ylimFo(2)-cLocFo(1,2)) >0 ...
                    && (ylimWave(1)-cLocWave(1,2))*(ylimWave(2)-cLocWave(1,2)) >0)
            else
                app.LogDataPublic.text = "UIFigureWindowButtonUp " + datestr(now);
                recordLog(app);
                if string(app.CommonData.initialized) == "yes" ...
                        && string(app.CommonData.penStatus) == "down"
                    penUpInFoAxes(app);
                end
                if string(app.CommonData.initialized) == "yes" ...
                        && (ylimWave(1)-cLocWave(1,2))*(ylimWave(2)-cLocWave(1,2)) <0 ...
                        && (xlimFo(1)-cLocFo(1,1))*(xlimFo(2)-cLocFo(1,1)) <0
                    buttonUpInWaveAxes(app);
                end
                app.AperiodicityButton.Enable = 'on';
                app.SpectrumButton.Enable = 'off';
                app.SynthesisButton.Enable = 'off';
                app.PlaysynthButton.Enable = 'off';
                app.SaveParameterButton.Enable = 'off';
                app.SavesynthButton.Enable = 'off';
                app.VLamp.Visible = 'on';
                app.PLamp.Visible = 'on';
                app.YLamp.Visible = 'on';
                app.SLamp.Visible = 'on';
                app.ALamp.Visible = 'on';
                set(app.UIFigure, "Pointer", 'arrow')
            end
        end

        % Button pushed function: UNDOButton
        function UNDOButtonPushed(app, event)
            app.LogDataPublic.text = "UNDOButtonPushed " + datestr(now);
            recordLog(app);
            id = app.CommonData.penString.count;
            if id > 0
                app.CommonData.penString.locData(id).linehandle.Visible = "off";
                fo = app.CommonData.penString.locData(id).latestFo;
                lineStatus = get(app.CommonData.f0Handle, 'UserData');
                lineStatus.latestFo = fo;
                set(app.CommonData.f0Handle, 'UserData',lineStatus);
                displayfo = fo;
                displayfo(app.CommonData.viewVUV <= 0) = 0;
                set(app.CommonData.f0Handle, 'ydata',log(displayfo));
                set(app.CommonData.originalf0Handle, 'ydata',log(fo));
                app.CommonData.penString.count = id - 1;
                if app.CommonData.penString.count == 0
                    app.UNDOButton.Enable = "off";
                    app.RESETButton.Enable = "off";
                end
            else
                app.UNDOButton.Enable = "off";
                app.RESETButton.Enable = "off";
            end
        end

        % Button pushed function: SaveParameterButton
        function SaveParameterButtonPushed(app, event)
            app.LogDataPublic.text = "SaveParameterButtonPushed " + datestr(now);
            recordLog(app);
            outFileName = "worldPrm" + datestr(now,30) + "d.mat";
            worldParameter = struct;
            worldParameter.creator = 'worldHandler.mlapp';
            worldParameter.signal = app.CommonData.x;
            worldParameter.fileFullPath = app.fileFullPathTextLabel.Text;
            worldParameter.fileName = app.fileNameTextLabel.Text;
            worldParameter.samplingFrequency = app.CommonData.samplingFrequency;
            worldParameter.span = app.CommonData.span;
            worldParameter.spectrum_parameter = app.CommonData.spectrum_parameter;
            worldParameter.source_parameter = app.CommonData.source_parameter;
            worldParameter.f0_original = app.CommonData.f0_original;
            if isfield(app.CommonData,'outFilePath')
                oldPath = cd(app.CommonData.outFilePath);
            end
            [fileName,filePath] = uiputfile('','save parameter',outFileName);
            if isequal(filePath, 0) || isequal(fileName, 0)
                if isfield(app.CommonData,'outFilePath')
                    cd(oldPath);
                    disp('save cancelled!')
                end
            else
                if isfield(app.CommonData,'outFilePath')
                    cd(oldPath);
                end
                app.CommonData.outFileName = string(fileName);
                app.CommonData.outFilePath = string(filePath);
                save(app.CommonData.outFilePath + app.CommonData.outFileName, "worldParameter");
                app.SaveParameterButton.Enable = 'off';
                app.PLamp.Visible = 'off';
                app.SavesynthButton.Enable = 'on';
            end
        end

        % Button pushed function: SavesynthButton
        function SavesynthButtonPushed(app, event)
            app.LogDataPublic.text = "SavesynthButtonPushed " + datestr(now);
            recordLog(app);
            outSynthFileName = "worldSynthMod" + datestr(now,30) + "d.wav";
            fs = app.CommonData.samplingFrequency;
            if isfield(app.CommonData,'outFilePath')
                oldPath = cd(app.CommonData.outFilePath);
            end
            [fileName,filePath] = ...
                uiputfile('*.wav','Synth. filename definition',outSynthFileName);
            if isequal(filePath, 0) || isequal(fileName, 0)
                if isfield(app.CommonData,'outFilePath')
                    cd(oldPath);
                    disp('save cancelled!');
                    app.LogDataPublic.text = "cancelled " + datestr(now);
                    recordLog(app);
                end
            else
                app.CommonData.outSynthFileName = string(fileName);
                app.CommonData.outFilePath = string(filePath);
                app.CommonData.outFileName = string([fileName(1:end-4) '.mat']);
                app.CommonData.tmpFilePath = filePath;
                saveParameter(app);
                audiowrite(app.CommonData.outFilePath+app.CommonData.outSynthFileName,...
                    app.CommonData.y, fs,"BitsPerSample", 24, ...
                    "Comment", app.CommonData.outFileName);
                app.SavesynthButton.Enable = 'off';
                app.VLamp.Visible = 'off';
                app.PLamp.Visible = 'off';
                app.CommonData.synthSound = 'on';
            end
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.LogDataPublic.text = "RESETButtonPushed " + datestr(now);
            recordLog(app);
            while app.CommonData.penString.count > 0
                UNDOButtonPushed(app,event);
                pause(0.1);
            end
            app.RESETButton.Enable = 'off';
        end

        % Button pushed function: ChangeaudiooutdeviceButton
        function ChangeaudiooutdeviceButtonPushed(app, event)
            fs = app.CommonData.samplingFrequency;
            tt = (1:fs/2)'/fs;
            x = (0.2*sin(2*pi*tt*220) + 0.05*sin(2*pi*tt*277.1826) ...
                + 0.025*sin(2*pi*tt*329.6276)) .* exp(-8*tt) .*tt/tt(end);
            info = audiodevinfo;
            T = struct2table(info.output);
            [id, tf] = listdlg("ListString", T.Name, "SelectionMode","single", ...
                "PromptString","Select audio output","ListSize",[250, 150]);
            if tf
                app.CommonData.deviceID = info.output(id).ID;
                app.CommonData.player = audioplayer(x, fs, 24, app.CommonData.deviceID);
                play(app.CommonData.player)
            else
                disp('Audio output devide selection is cancelled.')
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            %key = event.Key;
            UIFigureWindowButtonUp(app, event);
        end

        % Button down function: WaveAxes
        function WaveAxesButtonDown(app, event)
            app.LogDataPublic.text = "WaveAxesButtonDown " + datestr(now);
            recordLog(app);
            if isfield(app.CommonData, 'f0_parameter')
                pickMarker(app);
            end
        end

        % Button pushed function: PlayVoicedButton
        function PlayVoicedButtonPushed(app, event)
            app.LogDataPublic.text = "PlayVoicedButtonPushed " + datestr(now);
            recordLog(app);
            xLim = app.WaveAxes.XLim;
            fs = app.CommonData.samplingFrequency;
            x = app.CommonData.x(:) .* app.CommonData.soundVUV(:);
            nData = length(x);
            xLimSample = max(1, min(nData, 1+round(xLim * fs)));
            span = xLimSample(1):xLimSample(end);
            sound(x(span)/max(abs(x(span))), fs);
        end

        % Button pushed function: PlayUVButton
        function PlayUVButtonPushed(app, event)
            app.LogDataPublic.text = "PlayUVButtonPushed " + datestr(now);
            recordLog(app);
            xLim = app.WaveAxes.XLim;
            fs = app.CommonData.samplingFrequency;
            x = app.CommonData.x(:) .* (1-app.CommonData.soundVUV(:));
            nData = length(x);
            xLimSample = max(1, min(nData, 1+round(xLim * fs)));
            span = xLimSample(1):xLimSample(end);
            sound(x(span)/max(abs(x(span))), fs);
        end

        % Value changed function: SAVElogfileCheckBox
        function SAVElogfileCheckBoxValueChanged(app, event)
            value = app.SAVElogfileCheckBox.Value;
            app.LogDataPublic.text = "SAVElogfileCheckBoxValueChanged " + datestr(now);
            recordLog(app);
            app.LogDataPublic.text = "Value= " + num2str(value);
            recordLog(app);
        end

        % Image clicked function: foSettingDialogue
        function foSettingDialogueClicked(app, event)
            app.foDialogueApp = foSetting(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.LogDataPublic.text = "UIFigureCloseRequest " + datestr(now);
            recordLog(app);
            pause(0.4);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ReadsoundButton = uibutton(app.LeftPanel, 'push');
            app.ReadsoundButton.ButtonPushedFcn = createCallbackFcn(app, @ReadsoundButtonPushed, true);
            app.ReadsoundButton.Text = 'Read sound';
            app.FoextractionButton = uibutton(app.LeftPanel, 'push');
            app.FoextractionButton.ButtonPushedFcn = createCallbackFcn(app, @FoextractionButtonPushed, true);
            app.FoextractionButton.Enable = 'off';
            app.FoextractionButton.Text = 'Fo extraction';
            app.AperiodicityButton = uibutton(app.LeftPanel, 'push');
            app.AperiodicityButton.ButtonPushedFcn = createCallbackFcn(app, @AperiodicityButtonPushed, true);
            app.AperiodicityButton.Enable = 'off';
            app.AperiodicityButton.Text = 'Aperiodicity';
            app.SpectrumButton = uibutton(app.LeftPanel, 'push');
            app.SpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrumButtonPushed, true);
            app.SpectrumButton.Enable = 'off';
            app.SpectrumButton.Text = 'Spectrum';
            app.SynthesisButton = uibutton(app.LeftPanel, 'push');
            app.SynthesisButton.ButtonPushedFcn = createCallbackFcn(app, @SynthesisButtonPushed, true);
            app.SynthesisButton.Enable = 'off';
            app.SynthesisButton.Text = 'Synthesis';
            app.SaveParameterButton = uibutton(app.LeftPanel, 'push');
            app.SaveParameterButton.ButtonPushedFcn = createCallbackFcn(app, @SaveParameterButtonPushed, true);
            app.SaveParameterButton.Enable = 'off';
            app.SaveParameterButton.Text = 'Save Parameter';
            app.PlayoriginalButton = uibutton(app.LeftPanel, 'push');
            app.PlayoriginalButton.ButtonPushedFcn = createCallbackFcn(app, @PlayoriginalButtonPushed, true);
            app.PlayoriginalButton.Enable = 'off';
            app.PlayoriginalButton.Text = 'Play original';
            app.QUITButton = uibutton(app.LeftPanel, 'push');
            app.QUITButton.ButtonPushedFcn = createCallbackFcn(app, @QUITButtonPushed, true);
            app.QUITButton.Text = 'QUIT';
            app.WorldHandlerLabel = uilabel(app.LeftPanel);
            app.WorldHandlerLabel.Text = 'World Handler';
            app.PlaysynthButton = uibutton(app.LeftPanel, 'push');
            app.PlaysynthButton.ButtonPushedFcn = createCallbackFcn(app, @PlaysynthButtonPushed, true);
            app.PlaysynthButton.Enable = 'off';
            app.PlaysynthButton.Text = 'Play synth.';
            app.SavesynthButton = uibutton(app.LeftPanel, 'push');
            app.SavesynthButton.ButtonPushedFcn = createCallbackFcn(app, @SavesynthButtonPushed, true);
            app.SavesynthButton.Enable = 'off';
            app.SavesynthButton.Text = 'Save synth.';
            app.ALampLabel = uilabel(app.LeftPanel);
            app.ALampLabel.Visible = 'off';
            app.ALampLabel.Text = 'A';
            app.ALamp = uilamp(app.LeftPanel);
            app.ALamp.Visible = 'off';
            app.SLampLabel = uilabel(app.LeftPanel);
            app.SLampLabel.Visible = 'off';
            app.SLampLabel.Text = 'S';
            app.SLamp = uilamp(app.LeftPanel);
            app.SLamp.Visible = 'off';
            app.YLampLabel = uilabel(app.LeftPanel);
            app.YLampLabel.Visible = 'off';
            app.YLampLabel.Text = 'Y';
            app.YLamp = uilamp(app.LeftPanel);
            app.YLamp.Visible = 'off';
            app.PLampLabel = uilabel(app.LeftPanel);
            app.PLampLabel.Visible = 'off';
            app.PLampLabel.Text = 'P';
            app.PLamp = uilamp(app.LeftPanel);
            app.PLamp.Visible = 'off';
            app.VLampLabel = uilabel(app.LeftPanel);
            app.VLampLabel.Visible = 'off';
            app.VLampLabel.Text = 'V';
            app.VLamp = uilamp(app.LeftPanel);
            app.VLamp.Visible = 'off';
            app.RESETButton = uibutton(app.LeftPanel, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Enable = 'off';
            app.RESETButton.Text = 'RESET';
            app.ChangeaudiooutdeviceButton = uibutton(app.LeftPanel, 'push');
            app.ChangeaudiooutdeviceButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeaudiooutdeviceButtonPushed, true);
            app.ChangeaudiooutdeviceButton.Enable = 'off';
            app.ChangeaudiooutdeviceButton.Visible = 'off';
            app.ChangeaudiooutdeviceButton.Text = 'Change audio out device';
            app.PlayUVButton = uibutton(app.LeftPanel, 'push');
            app.PlayUVButton.ButtonPushedFcn = createCallbackFcn(app, @PlayUVButtonPushed, true);
            app.PlayUVButton.Enable = 'off';
            app.PlayUVButton.Text = 'Play UV';
            app.UNDOButton = uibutton(app.LeftPanel, 'push');
            app.UNDOButton.ButtonPushedFcn = createCallbackFcn(app, @UNDOButtonPushed, true);
            app.UNDOButton.Enable = 'off';
            app.UNDOButton.Text = 'UNDO';
            app.PlayVoicedButton = uibutton(app.LeftPanel, 'push');
            app.PlayVoicedButton.ButtonPushedFcn = createCallbackFcn(app, @PlayVoicedButtonPushed, true);
            app.PlayVoicedButton.Enable = 'off';
            app.PlayVoicedButton.Text = 'Play Voiced';
            app.fHDropDownLabel = uilabel(app.LeftPanel);
            app.fHDropDownLabel.Text = 'fH';
            app.fHDropDown = uidropdown(app.LeftPanel);
            app.fHDropDown.Items = {'400', '800', '1200'};
            app.fHDropDown.Value = '800';
            app.fLDropDownLabel = uilabel(app.LeftPanel);
            app.fLDropDownLabel.Text = 'fL';
            app.fLDropDown = uidropdown(app.LeftPanel);
            app.fLDropDown.Items = {'40', '100', '200'};
            app.fLDropDown.Value = '40';
            app.SAVElogfileCheckBox = uicheckbox(app.LeftPanel);
            app.SAVElogfileCheckBox.ValueChangedFcn = createCallbackFcn(app, @SAVElogfileCheckBoxValueChanged, true);
            app.SAVElogfileCheckBox.Text = 'SAVE logfile';
            app.foSettingDialogue = uiimage(app.LeftPanel);
            app.foSettingDialogue.ImageClickedFcn = createCallbackFcn(app, @foSettingDialogueClicked, true);
            app.foSettingDialogue.Enable = 'off';
            app.foSettingDialogue.Visible = 'off';
            app.foSettingDialogue.ImageSource = fullfile(pathToMLAPP, 'setting.jpg');
            app.Image_2 = uiimage(app.LeftPanel);
            app.Image_2.Enable = 'off';
            app.Image_2.Visible = 'off';
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'setting.jpg');
            app.Image_3 = uiimage(app.LeftPanel);
            app.Image_3.Enable = 'off';
            app.Image_3.Visible = 'off';
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'setting.jpg');
            app.Image_4 = uiimage(app.LeftPanel);
            app.Image_4.Enable = 'off';
            app.Image_4.Visible = 'off';
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'setting.jpg');
            app.RightPanel = uipanel(app.GridLayout);
            app.FoAxes = uiaxes(app.RightPanel);
            title(app.FoAxes, 'Title')
            xlabel(app.FoAxes, 'X')
            ylabel(app.FoAxes, 'Y')
            zlabel(app.FoAxes, 'Z')
            app.FoAxes.ButtonDownFcn = createCallbackFcn(app, @FoAxesButtonDown, true);
            app.FoAxes.Visible = 'off';
            app.WaveAxes = uiaxes(app.RightPanel);
            title(app.WaveAxes, 'Title')
            xlabel(app.WaveAxes, 'X')
            ylabel(app.WaveAxes, 'Y')
            zlabel(app.WaveAxes, 'Z')
            app.WaveAxes.ButtonDownFcn = createCallbackFcn(app, @WaveAxesButtonDown, true);
            app.WaveAxes.Visible = 'off';
            app.SgramAxes = uiaxes(app.RightPanel);
            title(app.SgramAxes, 'Title')
            xlabel(app.SgramAxes, 'X')
            ylabel(app.SgramAxes, 'Y')
            zlabel(app.SgramAxes, 'Z')
            app.SgramAxes.Visible = 'off';
            app.WaveAxesSynth = uiaxes(app.RightPanel);
            title(app.WaveAxesSynth, 'Title')
            xlabel(app.WaveAxesSynth, 'X')
            ylabel(app.WaveAxesSynth, 'Y')
            zlabel(app.WaveAxesSynth, 'Z')
            app.WaveAxesSynth.Visible = 'off';
            app.fileFullPathLabel = uilabel(app.RightPanel);
            app.fileFullPathLabel.Text = 'fileFullPath:';
            app.FileNameLabel = uilabel(app.RightPanel);
            app.FileNameLabel.Text = 'FileName:';
            app.fileFullPathTextLabel = uilabel(app.RightPanel);
            app.fileFullPathTextLabel.Visible = 'off';
            app.fileFullPathTextLabel.Text = 'fileFullPathText';
            app.fileNameTextLabel = uilabel(app.RightPanel);
            app.fileNameTextLabel.Visible = 'off';
            app.fileNameTextLabel.Text = 'fileNameText';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = worldHandler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end