classdef worldManipulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        DebugButton                 matlab.ui.control.Button
        SAVEParameterButton         matlab.ui.control.Button
        RESTOREEDITButton           matlab.ui.control.Button
        vaxismanipulatorPanel       matlab.ui.container.Panel
        VEditField                  matlab.ui.control.NumericEditField
        VEditFieldLabel             matlab.ui.control.Label
        BEditField                  matlab.ui.control.NumericEditField
        BEditFieldLabel             matlab.ui.control.Label
        REditField                  matlab.ui.control.NumericEditField
        REditFieldLabel             matlab.ui.control.Label
        Reset_VTLButton             matlab.ui.control.Button
        VTLSlider                   matlab.ui.control.Slider
        VTLLabel                    matlab.ui.control.Label
        ResetbaseButton             matlab.ui.control.Button
        baseSlider                  matlab.ui.control.Slider
        baseSliderLabel             matlab.ui.control.Label
        ResetdynamicsButton         matlab.ui.control.Button
        rangeSlider                 matlab.ui.control.Slider
        rangeSliderLabel            matlab.ui.control.Label
        ChangeaudiooutdeviceButton  matlab.ui.control.Button
        SAVESoundButton             matlab.ui.control.Button
        WORLDManipulatorLabel       matlab.ui.control.Label
        PLAYOriginalButton          matlab.ui.control.Button
        SAVEEDITButton              matlab.ui.control.Button
        PLAYSynthesizedButton       matlab.ui.control.Button
        SYNTHESISButton             matlab.ui.control.Button
        QUITButton                  matlab.ui.control.Button
        READParameterButton         matlab.ui.control.Button
        SAVElogfileCheckBox         matlab.ui.control.CheckBox
        RightPanel                  matlab.ui.container.Panel
        TmagEditField               matlab.ui.control.NumericEditField
        TmagEditFieldLabel          matlab.ui.control.Label
        UNDOButton_2                matlab.ui.control.Button
        ColormapButtonGroup         matlab.ui.container.ButtonGroup
        grayButton                  matlab.ui.control.RadioButton
        turboButton                 matlab.ui.control.RadioButton
        peruraButton                matlab.ui.control.RadioButton
        FreqlimitButtonGroup        matlab.ui.container.ButtonGroup
        kHzButton_5                 matlab.ui.control.RadioButton
        kHzButton_4                 matlab.ui.control.RadioButton
        kHzButton_3                 matlab.ui.control.RadioButton
        kHzButton_2                 matlab.ui.control.RadioButton
        kHzButton                   matlab.ui.control.RadioButton
        FullButton                  matlab.ui.control.RadioButton
        REDOButton                  matlab.ui.control.Button
        UNDOButton                  matlab.ui.control.Button
        Sgram_scaleButtonGroup      matlab.ui.container.ButtonGroup
        ERB_NButton                 matlab.ui.control.RadioButton
        logButton_2                 matlab.ui.control.RadioButton
        linearButton                matlab.ui.control.RadioButton
        Reset_TmagButton            matlab.ui.control.Button
        TmagSlider                  matlab.ui.control.Slider
        TmagSliderLabel             matlab.ui.control.Label
        Reset_foButton              matlab.ui.control.Button
        Shift_scaleButtonGroup      matlab.ui.container.ButtonGroup
        mel2Button                  matlab.ui.control.RadioButton
        melButton                   matlab.ui.control.RadioButton
        logButton                   matlab.ui.control.RadioButton
        fileLabel                   matlab.ui.control.Label
        pathLabel                   matlab.ui.control.Label
        SgramAxis2                  matlab.ui.control.UIAxes
        powerViewAxis               matlab.ui.control.UIAxes
        FoAxis                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        CommonData % Common data and parameters
        DisplayData % Data for fo display update
        worldParameterMod % modified signal and WORLD parameter used
        LogData % Data for logging
    end

    methods (Access = private)


        function results = initializeGraphics(app)
            %--- Spectrogram
            app.SgramAxis2.Visible = 'on';
            sgram = app.CommonData.worldParams.spectrum_parameter.spectrogram;
            tt = app.CommonData.worldParams.spectrum_parameter.temporal_positions;
            fs = app.CommonData.samplingFrequency;
            app.CommonData.currentSpectrogram = sgram;
            linear2logSgram(app);
            linear2erbSgram(app);
            sgramDb = 10*log10(sgram);
            sgramDb = max(-80, sgramDb - max(sgramDb(:)));
            app.CommonData.linearSgramDb = sgramDb;
            sgramHandle = imagesc(app.SgramAxis2,[tt(1) tt(end)], [0 fs/2]/1000, sgramDb);
            axis(app.SgramAxis2, 'xy');
            ylabel(app.SgramAxis2, 'frequency (kHz)')
            colormap(app.SgramAxis2, "turbo");
            app.CommonData.linearyTickLoc = get(app.SgramAxis2, 'YTick');
            app.CommonData.linearyTickLabel = get(app.SgramAxis2, 'YTickLabel');
            app.linearButton.Value = 1;
            app.CommonData.sgramHandle = sgramHandle;
            app.CommonData.sgraphToolBar = axtoolbar(app.SgramAxis2,{'pan','zoomin','zoomout'});
            axis(app.SgramAxis2, [tt(1) tt(end) [0 fs/2]/1000])
            app.CommonData.linearYlim = get(app.SgramAxis2, 'YLim');
            app.CommonData.linearSgramViewYlim = app.CommonData.linearYlim;
            disableDefaultInteractivity(app.SgramAxis2);
            %--- fundamental frequency
            app.FoAxis.Visible = 'on';
            source_parameter = app.CommonData.worldParams.source_parameter;
            fo = source_parameter.f0(:);
            app.CommonData.originalFo = fo;
            app.CommonData.meanFo = mean(fo(fo>0));
            txfo = source_parameter.temporal_positions(:);
            yTickFreq = [10 20 30 40 50 60 70 80 90 ...
                100 200 300 400 500 600 700 800 900 1000 1500 2000];
            yTiickLabel = {'.01', '.02', '.03', ' ', '.05', ' ', '.07', ' ', ' ', ...
                '.1', '.2', '.3', ' ', '.5', ' ', ' .7', ' ', ' ', ...
                '1', ' ', '2'};
            selectedButton = app.Shift_scaleButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'log'
                    foHandle = plot(app.FoAxis, txfo, log(fo), 'LineWidth', 3);
                    app.FoAxis.Tag = 'log';
                    axis(app.FoAxis, [txfo(1) txfo(end) log([40 1000])])
                    app.CommonData.foHandle = foHandle;
                    set(app.FoAxis, 'ytick', log(yTickFreq), 'ytickLabel', yTiickLabel);
                case 'mel'
                    foHandle = plot(app.FoAxis, txfo, hz2mel(fo), 'LineWidth', 3);
                    app.FoAxis.Tag = 'mel';
                    axis(app.FoAxis, [txfo(1) txfo(end) hz2mel([40 1000])])
                    app.CommonData.foHandle = foHandle;
                    set(app.FoAxis, 'ytick', hz2mel(yTickFreq), 'ytickLabel', yTiickLabel);
            end
            app.CommonData.yTickFreq = yTickFreq;
            app.CommonData.yTiickLabel = yTiickLabel;
            ylabel(app.FoAxis, 'frequency (kHz)');
            app.CommonData.foToolBar = axtoolbar(app.FoAxis,{'pan','zoomin','zoomout','restoreview'});
            lineStatus = struct;
            lineStatus.originalYdata = get(app.CommonData.foHandle, 'YData');
            lineStatus.latestFo = fo;
            lineStatus.previousFo = fo;
            lineStatus.scale = 'log';
            lineStatus.replayHandle = app.PLAYSynthesizedButton;
            lineStatus.axisToolbar = app.CommonData.foToolBar;
            set(app.CommonData.foHandle,'UserData', lineStatus);
            set(app.UIFigure,'KeyPressFcn',@lineButtonUpFcn);
            disableDefaultInteractivity(app.FoAxis);
            app.CommonData.previousTslider = 1;
            grid(app.FoAxis, 'on')
            app.CommonData.penString = struct;
            app.CommonData.penString.count = 0;
            app.CommonData.penString.TotalCount = 0;
            app.CommonData.penString.locData = struct;
            %--- waveform monitor
            app.powerViewAxis.Visible = 'on';
            fx = (0:size(sgram,1)-1)/size(sgram,1)*fs/2;
            x = app.CommonData.x;
            vuv = app.CommonData.worldParams.source_parameter.vuv;
            app.CommonData.vuv = vuv;
            tx = app.CommonData.txSignal;
            sgram(1,:) = sgram(2,:);
            highPower = sum(sgram(fx>2000,:));
            lowPower = sum(sgram(fx<=2000,:));
            maxLvl = max([10*log10(lowPower(:)); 10*log10(highPower(:))]);
            dispX = x/max(abs(x))*30 + maxLvl -35;
            app.CommonData.waveHandle = plot(app.powerViewAxis, tx, dispX, ...
                "Color", [0.7 0.7 0.7], "LineWidth", 3,"HitTest","off");
            hold(app.powerViewAxis,"on");
            app.CommonData.unvoicedHandle = plot(app.powerViewAxis, ...
                tt, (maxLvl+4) + tt*0, "LineWidth",2 , "Color", [0 0 0],"Visible","off");
            app.CommonData.voicedHandle = plot(app.powerViewAxis, ...
                tt, (maxLvl+4) * (vuv * 1000 - 999), "LineWidth",3 , "Color", [0 1 0], ...
                "Visible","off");
            app.CommonData.pwHandle1 = plot(app.powerViewAxis, ...
                tt, 10*log10(lowPower),"LineWidth",2,"Color",[0 0.4470 0.7410], ...
                "Visible","on","HitTest","off");
            app.CommonData.pwHandle2 = plot(app.powerViewAxis, ...
                tt, 10*log10(highPower),"LineWidth",2,"Color",[0.8500 0.3250  0.0980], ...
                "Visible","on","HitTest","off");
            set(app.powerViewAxis, 'XTickLabel', '');
            ylabel(app.powerViewAxis, 'level (dB)')
            grid(app.powerViewAxis,"on");
            hold(app.powerViewAxis,"off");
            axis(app.powerViewAxis, [tt(1) tt(end) maxLvl + [-70 5]]);
            app.CommonData.pwYlim = maxLvl + [-70 5];
            pwDy = diff(app.CommonData.pwYlim)/4;
            for jj = 0:3
                yline(app.powerViewAxis, jj*pwDy+app.CommonData.pwYlim(1), ...
                    "Color",[0 0.6 0],"HitTest","off", ...
                    "LabelVerticalAlignment","top","LabelHorizontalAlignment","right", ...
                    "Label", num2str(jj-1),"FontSize",15)
            end
            app.CommonData.magText = text(app.powerViewAxis, 0, 3.5*pwDy+app.CommonData.pwYlim(1), ...
                ' 1.0','FontSize',24, 'Visible','off');
            disableDefaultInteractivity(app.powerViewAxis);
            app.CommonData.waveToolHandle = axtoolbar(app.powerViewAxis,{'pan','restoreview'});
            %app.powerViewAxis.HitTest = "off";
            %--- time axis segment handles
            app.CommonData.nTimeHandle = 100;
            tmpDeltaT = tx(end)/(app.CommonData.nTimeHandle + 1);
            xHandlePos = (1:app.CommonData.nTimeHandle) * tmpDeltaT;
            app.CommonData.foViewHandle = xline(app.FoAxis, xHandlePos, "Color",[0 0.6 0], ...
                "LineWidth", 1, "Visible","off","HitTest","off");
            app.CommonData.sgramViewHandle = xline(app.SgramAxis2, xHandlePos, "Color",[1 1 1], ...
                "LineStyle","-" , "LineWidth", 0.5, "Visible","off","HitTest","off");
            app.CommonData.powerViewHandle = xline(app.powerViewAxis, xHandlePos, "Color",[0 0.5 0], ...
                "LineStyle","-" , "LineWidth", 2, "Visible","off","HitTest","off");
            powerViewYlim = get(app.powerViewAxis, "YLim");
            midPoint = diff(powerViewYlim)/4*2 + powerViewYlim(1);
            hold(app.powerViewAxis,"on");
            for ii = 1:app.CommonData.nTimeHandle+1
                app.CommonData.segmentHandle(ii) = plot(app.powerViewAxis, ...
                    [0 tx(end)],[midPoint midPoint], "Color",[0 0.5 0], ...
                    "Visible","off","HitTest","off", "LineWidth", 2);
            end
            hold(app.powerViewAxis,"off");
            app.CommonData.segmentId = 0;
            app.CommonData.handleId = 0;
            app.CommonData.handleTotal = 0;
            %--- GUI parts setting
            linkaxes([app.SgramAxis2  app.powerViewAxis app.FoAxis],'x')
            app.SYNTHESISButton.Enable = 'on';
            app.Reset_foButton.Enable = 'on';
            app.CommonData.initialized = 'yes';
            results = 'ok';
        end

        function results = linear2logSgram(app)
            sgram = app.CommonData.currentSpectrogram;
            fs = app.CommonData.samplingFrequency;
            fx = (0:size(sgram,1)-1)'/size(sgram,1)*fs/2;
            fx(1) = fx(2)/2;
            logFqLimit = log([100 fs/2]);
            logFx = (1:length(fx))/length(fx)*diff(logFqLimit) + logFqLimit(1);
            yTkckFqLog = log([100 200 300 500 700 1000 2000 3000 5000 7000 10000 20000]);
            yTickLabel = {'.1', '.2', '.3', '.5', '.7', '1', '2', '3', '5', '7', '10', '20'};
            yTickLoc = interp1(logFx,logFx,yTkckFqLog,"linear","extrap");
            sgramLogFx = interp1(log(fx),sgram,logFx,'linear','extrap');
            sgramDb = 10*log10(sgramLogFx);
            sgramDb = max(-80, sgramDb - max(sgramDb(:)));
            app.CommonData.logSgramDb = sgramDb;
            app.CommonData.logyTickLoc = yTickLoc;
            app.CommonData.logyTickLabel = yTickLabel;
            app.CommonData.logYlim = logFqLimit;
            results = 1;
        end

        function results = linear2erbSgram(app)
            sgram = app.CommonData.currentSpectrogram;
            fs = app.CommonData.samplingFrequency;
            fx = (0:size(sgram,1)-1)/size(sgram,1)*fs/2;
            fx(1) = fx(2)/2;
            app.CommonData.hz = [100 fs/2];
            erbFqLimit = myhz2erb(app);
            erbFx = (1:length(fx))/length(fx)*diff(erbFqLimit) + erbFqLimit(1);
            app.CommonData.hz = [100 300 500 700 1000 2000 4000 8000 10000 20000];
            yTkckFqErb = myhz2erb(app);
            yTickLabel = {'.1', '.3', '.5', '.7', '1', '2', '4', '8', '10', '20'};
            yTickLoc = interp1(erbFx,erbFx,yTkckFqErb,"linear","extrap");
            app.CommonData.hz = fx;
            sgramErbFx = interp1(myhz2erb(app),sgram,erbFx,'linear','extrap');
            sgramDb = 10*log10(sgramErbFx);
            sgramDb = max(-80, sgramDb - max(sgramDb(:)));
            app.CommonData.erbSgramDb = sgramDb;
            app.CommonData.erbyTickLoc = yTickLoc;
            app.CommonData.erbyTickLabel = yTickLabel;
            app.CommonData.erbYlim = erbFqLimit;
            results = 1;
        end


        function results = updateFoDisplay2(app)
            results = 1;
            fo = app.DisplayData.fo;
            baseValue = app.DisplayData.baseValue;
            magValue = app.DisplayData.magValue;
            selectedButton = app.Shift_scaleButtonGroup.SelectedObject;
            meanFo = app.CommonData.meanFo;
            switch selectedButton.Text
                case 'log'
                    modLogFo = (log(fo) - log(meanFo)) * magValue ...
                        + log(meanFo) + log(baseValue);
                    modLogFo(fo <= 0) = NaN;
                    set(app.CommonData.foHandle, 'YData', modLogFo);
                case 'mel'
                    app.CommonData.hz = fo;
                    v_hz2mel_fo = myhz2mel(app);
                    app.CommonData.hz = meanFo;
                    v_hz2mel_meanFo = myhz2mel(app);
                    app.CommonData.hz = meanFo*baseValue;
                    v_hz2mel_meanFo_baseValue = myhz2mel(app);
                    app.CommonData.mel = magValue * (v_hz2mel_fo - v_hz2mel_meanFo) ...
                        + v_hz2mel_meanFo_baseValue;
                    app.CommonData.hz = mymel2hz(app);
                    modMelFo = myhz2mel(app);
                    modMelFo(fo<=0) = NaN;
                    set(app.CommonData.foHandle, 'YData', modMelFo);
            end
        end
        
        function modificationStr = makeModificationStr(app)
            modificationStr = struct;
            modificationStr.creator = 'worldManipulator';
            modificationStr.parameterFile = app.fileLabel.Text;
            modificationStr.parameterPath = app.pathLabel.Text;
            source_parameter = app.CommonData.worldParams.source_parameter;
            yData = get(app.CommonData.foHandle, 'YData');
            lineStatus = get(app.CommonData.foHandle, 'UserData');
            switch lineStatus.scale
                case 'log'
                    tmp = exp(yData(:));
                case 'mel'
                    tmpY = yData;
                    tmpY(isnan(yData)) = 10;
                    tmp = mel2hz(tmpY(:));
                    tmp(isnan(yData)) = NaN;
            end
            tmp(isnan(tmp)) = 0;
            source_parameter.f0 = tmp;
            modificationStr.modifiedFo = source_parameter.f0;
            modificationStr.latestFo = lineStatus.latestFo;
            modificationStr.timeAxisMagnification = app.TmagSlider.Value;
            modificationStr.VTLMagnification = app.VTLSlider.Value;
            modificationStr.dynamicRangeModification = app.rangeSlider.Value;
            modificationStr.baseModification = app.baseSlider.Value;
        end
        
        function results = removePenRecord(app)
            results = 1;
            id = app.CommonData.penString.Totalcount;
            if id > 0
                delete(app.CommonData.penString.locData(id).linehandle)
                fo = app.CommonData.penString.locData(id).latestFo;
                lineStatus = get(app.CommonData.foHandle, 'UserData');
                lineStatus.latestFo = fo;
                set(app.CommonData.foHandle, 'UserData',lineStatus);
                app.DisplayData.fo = fo;
                app.DisplayData.baseValue = app.baseSlider.Value;
                app.DisplayData.magValue = app.rangeSlider.Value;
                updateFoDisplay2(app);
                app.CommonData.penString.Totalcount = id - 1;
                if app.CommonData.penString.Totalcount == 0
                    app.UNDOButton.Enable = "off";
                end
            else
                app.UNDOButton.Enable = "off";
            end
        end
        
        function erb = myhz2erb(app)
            hz = app.CommonData.hz;
            aa = 1000*log(10)/24.7/4.37;
            erb = log10(hz*0.00437+1)/aa;
        end
        
        function mel = myhz2mel(app)
            hz = app.CommonData.hz;
            mel = 2595 * log10(1+hz/700);
        end
        
        function hz = mymel2hz(app)
            mel = app.CommonData.mel;
            hz = 700*(10 .^(mel/2595) -1);
        end
        
        function results = recordLog(app)
            results = 1;
            position = get(app.UIFigure, 'Position');
            if isfield(app.CommonData, "figurePosition") ...
                    && sum(position ~= app.CommonData.figurePosition) > 0
                saveBaseFile(app);
            end
            if app.SAVElogfileCheckBox.Value == 1
                if exist(app.LogData.logFileFullpath,"file")
                    fid = fopen(app.LogData.logFileFullpath,"a");
                    fprintf(fid, "%s\n", app.LogData.text);
                    fclose(fid);
                else
                    tStr = string(datetime(datetime,'format','yyyyMMddHHmmss'));
                    worldManipulatorLogFileName = string(pwd) + "/" ...
                        + "worldMlog" + tStr + "d.txt";
                    app.LogData.logFileFullpath = worldManipulatorLogFileName;
                    fid = fopen(app.LogData.logFileFullpath,"a");
                    app.LogData.text = "created " + string(datetime(datetime));
                    fprintf(fid, "%s\n", app.LogData.text);
                    app.LogData.text = "pwd_at_start " + app.CommonData.startDir;
                    fprintf(fid, "%s\n", app.LogData.text);
                    fclose(fid);
                end
            else
                if exist(app.LogData.logFileFullpath,"file")
                    commandString = "delete " + app.LogData.logFileFullpath;
                    eval(commandString);
                end
            end

        end
        
        function results = initializeGUIposition(app)
            results = 1;
            rootInfo = groot;
            screenSize = get(rootInfo, "ScreenSize");
            worldManipulatorBaseFile = "worldManipulatorBase.txt";
            checkPath = string(app.CommonData.appPath) + "/" + worldManipulatorBaseFile;
            if exist(checkPath,"file")
                fid = fopen(checkPath,"r");
                tline = fgetl(fid);
                while tline ~= -1
                    ss = strsplit(tline);
                    if "Position" == string(ss{1})
                        if length(ss) == 5
                            xBase = str2double(ss{2});
                            yBase = str2double(ss{3});
                            width = str2double(ss{4});
                            hight = str2double(ss{5});
                        end
                    end
                    tline = fgetl(fid);
                end
                fclose(fid);
                if ~exist("xBase","var")
                width = max(1131, screenSize(3) - 101);
                hight = max(660, screenSize(4) - 2 - 30);
                xBase = 100;
                yBase = 1;
                end
            else
                width = max(1131, screenSize(3) - 101);
                hight = max(660, screenSize(4) - 2 - 30);
                xBase = 100;
                yBase = 1;
            end
            set(app.UIFigure, "Position", [xBase yBase width hight]);
            app.CommonData.figurePosition = [xBase yBase width hight];
        end
        
        function results = saveBaseFile(app)
            results = 1;
            worldManipulatorBaseFile = "worldManipulatorBase.txt";
            checkPath = string(app.CommonData.appPath) + "/" + worldManipulatorBaseFile;
            fid = fopen(checkPath,"w");
            position = get(app.UIFigure, 'Position');
            outText = ['Position ' num2str(position)];
            fprintf(fid, "%s\n", outText);
            fclose(fid);

        end
        
        function results = assignSegmentMagHandle(app)
            results = 1;
            if app.CommonData.handleTotal > 0
                tt = app.CommonData.worldParams.spectrum_parameter.temporal_positions;
                nHandle = app.CommonData.handleTotal;
                handleLocations = zeros(nHandle,1);
                for ii = 1:nHandle
                    handleLocations(ii) = app.CommonData.foViewHandle(ii).Value;
                end
                [sortedLocs,~] = sort(handleLocations);
                onLocs = [0 sortedLocs(:)'];
                offLocs = [sortedLocs(:)' tt(end)];
                for ii = 1:nHandle+1
                    set(app.CommonData.segmentHandle(ii), "XData", [onLocs(ii) offLocs(ii)], ...
                        "Visible", "on");
                end
            end
        end
        
        function results = displayMagLevel(app)
            results = 1;
            segId = app.CommonData.segmentId;
            pwYlim = app.powerViewAxis.YLim;
            currentPoint = get(app.powerViewAxis, 'CurrentPoint');
            if segId > 0
                magLevel = (currentPoint(1,2)-pwYlim(1))/(diff(pwYlim)/4)-1;
                app.CommonData.magText.String = [' ' num2str(magLevel,2)];
            end
        end
        
        function results = nolinearTimeAxisWarping(app)
            results = 1;
                source_parameter = app.CommonData.synth_source_parameter;
                spectrum_parameter = app.CommonData.synth_spectrum_parameter;
                original_temporal_positions = source_parameter.temporal_positions;
                modified_temporal_positions = original_temporal_positions*0;
                aligned_temporal_idx = modified_temporal_positions;
                dTx = 0.005; % default 5 ms
                %timeMagValue = app.baseSlider.Value;
                nHandles = app.CommonData.handleTotal;
                nSegment = nHandles+1;
                timeMagList = zeros(nSegment,1);
                pwYlim = app.powerViewAxis.YLim;
                for ii = 1:nSegment
                    yData = app.CommonData.segmentHandle(ii).YData;
                    timeMagList(ii) = (yData(1)-pwYlim(1))/(diff(pwYlim)/4)-1;
                end
                timeMagList(timeMagList==0) = 0.000001; % safeguard
                nFrames = length(original_temporal_positions);
                handleLoc = zeros(nHandles,1);
                for ii = 1:nHandles
                    handleLoc(ii) = app.CommonData.foViewHandle(ii).Value;
                end
                handleLoc = sort(handleLoc);
                segmentLocs = zeros(nSegment,2);
                [~,idxTime] = min(abs(original_temporal_positions - handleLoc(1))); 
                segmentLocs(1,:) = [1 idxTime];
                for segId = 2:nSegment
                    stIdx = segmentLocs(segId-1,2) + 1;
                    if segId < nSegment
                    [~,idxTime] = min(abs(original_temporal_positions - handleLoc(segId)));
                    else
                        idxTime = length(original_temporal_positions);
                    end
                    segmentLocs(segId, :) = [stIdx, idxTime];
                end
                currentTime = 0;
                for ii = 2:nFrames
                    segId = 1;
                    for jj = 1:nSegment
                        if (segmentLocs(jj, 1) - ii)*(segmentLocs(jj, 2) - ii)<=0
                            segId = jj;
                            break;
                        end
                    end
                    currentTime = currentTime ...
                        + (original_temporal_positions(ii) - original_temporal_positions(ii-1)) ...
                        * abs(timeMagList(segId));
                    modified_temporal_positions(ii) = currentTime;
                end
                for segId = 1:nSegment
                    stp = segmentLocs(segId, 1);
                    edp = segmentLocs(segId, 2);
                    if timeMagList(segId) > 0
                        aligned_temporal_idx(stp:edp) = stp:edp;
                    else
                        aligned_temporal_idx(stp:edp) = edp:-1:stp;
                    end
                end
                temporal_positions = (0:dTx:modified_temporal_positions(end));
                fo = source_parameter.f0;
                spectrogramFix = spectrum_parameter.spectrogram(:,aligned_temporal_idx);
                aperiodicityFix = source_parameter.aperiodicity(:,aligned_temporal_idx);
                foFix = fo(aligned_temporal_idx);
                vuvFix = source_parameter.vuv(aligned_temporal_idx);
                fo = interp1(modified_temporal_positions,foFix, ...
                    temporal_positions,"linear","extrap");
                vuv = interp1(modified_temporal_positions,vuvFix, ...
                    temporal_positions,"linear","extrap");
                spectrogram = interp1(modified_temporal_positions,spectrogramFix', ...
                    temporal_positions,"linear","extrap")';
                aperiodicity = interp1(modified_temporal_positions,aperiodicityFix', ...
                    temporal_positions,"linear","extrap")';
                %sgramFix
                source_parameter.temporal_positions = temporal_positions(:)';
                source_parameter.f0 = fo(:)';
                vuv = double(vuv>0.3);
                source_parameter.vuv = vuv(:)';
                source_parameter.aperiodicity = aperiodicity;
                spectrum_parameter.temporal_positions = temporal_positions(:)';
                spectrum_parameter.spectrogram = spectrogram;
                app.CommonData.synth_source_parameter = source_parameter;
                app.CommonData.synth_spectrum_parameter = spectrum_parameter;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CommonData.appfullpath = mfilename("fullpath");
            [appPath, ~, ~] = fileparts(app.CommonData.appfullpath);
            app.CommonData.appPath = appPath;
            app.CommonData.startDir = pwd;
            app.CommonData.fileFullPath = pwd;
            worldManipulatorLogFileName = string(pwd) + "/" ...
                + "worldMlog" + string(datetime(datetime,'format','yyyyMMddHHmmss')) + "d.txt";
            app.LogData.logFileFullpath = worldManipulatorLogFileName;
            app.LogData.text = "created " + string(datetime(datetime));
            recordLog(app);
            app.LogData.text = "pwd_at_start " + app.CommonData.startDir;
            recordLog(app);
            rootInfo = groot;
            screenSize = get(rootInfo, "ScreenSize");
            width = min(1131, screenSize(3) - 21);
            hight = min(660, screenSize(4) - 2 - 30);
            set(app.UIFigure, "Position", [20 1 width hight]);

            app.CommonData.penStatus = 'up';
            app.CommonData.initialized = 'no';
            fs = 44100;
            tt = (1:fs/2)'/fs;
            x = (0.2*sin(2*pi*tt*220) + 0.05*sin(2*pi*tt*277.1826) ...
                + 0.025*sin(2*pi*tt*329.6276)) .* exp(-8*tt) .*tt/tt(end);
            app.CommonData.deviceID = -1;
            app.CommonData.x = x;
            app.CommonData.samplingFrequency = fs;
            sound(x, fs);
            app.SAVEEDITButton.Enable = 'off';
            app.SAVESoundButton.Enable = 'off';
            drawnow
            initializeGUIposition(app);
            if 1 == 2
            width = max(1131, screenSize(3) - 100);
            hight = max(660, screenSize(4) - 2 - 30);
            set(app.UIFigure, "Position", [20 1 width hight]);
            app.CommonData.figurePosition = [20 1 width hight];
            drawnow
            end
        end

        % Button pushed function: QUITButton
        function QUITButtonPushed(app, event)
            app.LogData.text = "QUITButtonPushed " + string(datetime(datetime));
            recordLog(app);
            close(app.UIFigure);
        end

        % Button pushed function: READParameterButton
        function READParameterButtonPushed(app, event)
            app.LogData.text = "READParameterButtonPushed " + string(datetime(datetime));
            recordLog(app);
            [paramFile, paramPath] = uigetfile({'*.mat'},"Select parameter file:");
            tmp = load([paramPath '/' paramFile]);
            if isfield(tmp, "worldParameter")
            app.fileLabel.Text = paramFile;
            app.pathLabel.Text = paramPath;
            app.CommonData.worldParams = tmp.worldParameter;
            app.CommonData.samplingFrequency = tmp.worldParameter.samplingFrequency;
            if isfield(tmp, "timeAnchor")
                app.CommonData.timeAnchor = tmp.timeAnchor;
                app.CommonData.timeFreqAnchor = tmp.timeFreqAnchor;
            end
            app.CommonData.x = tmp.worldParameter.signal;
            x = app.CommonData.x;
            fs = tmp.worldParameter.samplingFrequency;
            app.CommonData.txSignal = (1:length(x))'/fs;
            initializeGraphics(app);
            app.PLAYOriginalButton.Enable = 'on';
            app.PLAYSynthesizedButton.Enable = 'off';
            app.SAVEEDITButton.Enable = 'off';
            app.SAVESoundButton.Enable = 'off';
            else
                messageTxt = {'This file is not a WORLD parameter file!', ...
                    'Use RESTORE EDIT button, if it is an EDIT file.'};
                uialert(app.UIFigure, messageTxt, 'Invalid file');
            end
        end

        % Button pushed function: ChangeaudiooutdeviceButton
        function ChangeaudiooutdeviceButtonPushed(app, event)
            app.LogData.text = "ChangeaudiooutdeviceButtonPushed " + string(datetime(datetime));
            recordLog(app);
            fs = app.CommonData.samplingFrequency;
            tt = (1:fs/2)'/fs;
            x = (0.2*sin(2*pi*tt*220) + 0.05*sin(2*pi*tt*277.1826) ...
                + 0.025*sin(2*pi*tt*329.6276)) .* exp(-8*tt) .*tt/tt(end);
            info = audiodevinfo;
            T = struct2table(info.output);
            [id, tf] = listdlg("ListString", T.Name, "SelectionMode","single", ...
                "PromptString","Select audio output","ListSize",[250, 150]);
            if tf
                app.CommonData.deviceID = info.output(id).ID;
                sound(x, fs);
            else
                disp('Audio output devide selection is cancelled.')
            end
        end

        % Button pushed function: PLAYOriginalButton
        function PLAYOriginalButtonPushed(app, event)
            app.LogData.text = "PLAYOriginalButtonPushed " + string(datetime(datetime));
            recordLog(app);
            xLim = app.SgramAxis2.XLim;
            fs = app.CommonData.samplingFrequency;
            x = app.CommonData.x;
            nData = length(x);
            switch app.TmagSlider.Value
                case 1
                    xLimSample = max(1, min(nData, 1+round(xLim * fs)));
                otherwise
                    xLimSample = max(1, min(nData, 1+round(xLim * fs / app.TmagSlider.Value)));
            end
            span = xLimSample(1):xLimSample(end);
            sound(x(span)/max(abs(x(span)))*0.7, fs);
        end

        % Selection changed function: Shift_scaleButtonGroup
        function Shift_scaleButtonGroupSelectionChanged(app, event)
            app.LogData.text = "Shift_scaleButtonGroupSelectionChanged " + string(datetime(datetime));
            recordLog(app);
            selectedButton = app.Shift_scaleButtonGroup.SelectedObject;
            baseValue = app.baseSlider.Value;
            magValue = app.rangeSlider.Value;
            lineStatus = get(app.CommonData.foHandle, 'UserData');
            fo = lineStatus.latestFo;
            lineStatus.previousFo = fo;
            app.FoAxis.Tag = selectedButton.Text;
            meanFo = app.CommonData.meanFo;
            app.LogData.text = "selectedButton " + selectedButton.Text;
            recordLog(app);
            switch selectedButton.Text
                case 'log'
                    modLogFo = (log(fo) - log(meanFo)) * magValue ...
                        + log(meanFo) + log(baseValue);
                    modLogFo(fo <= 0) = NaN;
                    set(app.CommonData.foHandle, 'YData', modLogFo);
                    set(app.FoAxis, 'YLim', log([40 1000]))
                    set(app.FoAxis, 'ytick', log(app.CommonData.yTickFreq));
                    lineStatus.scale = 'log';
                case 'mel'
                    app.CommonData.hz = fo;
                    v_hz2mel_fo = myhz2mel(app);
                    app.CommonData.hz = meanFo;
                    v_hz2mel_meanFo = myhz2mel(app);
                    app.CommonData.hz = meanFo*baseValue;
                    v_hz2mel_meanFo_baseValue = myhz2mel(app);
                    app.CommonData.mel = magValue * (v_hz2mel_fo - v_hz2mel_meanFo) ...
                        + v_hz2mel_meanFo_baseValue;
                    app.CommonData.hz = mymel2hz(app);
                    modMelFo = myhz2mel(app);
                    modMelFo(fo<=0) = NaN;
                    set(app.CommonData.foHandle, 'YData', modMelFo);
                    app.CommonData.hz = [40 1000];
                    set(app.FoAxis, 'YLim', myhz2mel(app))
                    app.CommonData.hz = app.CommonData.yTickFreq;
                    set(app.FoAxis, 'ytick', myhz2mel(app));
                    lineStatus.scale = 'mel';
            end
            set(app.CommonData.foHandle, 'UserData',lineStatus);
        end

        % Button pushed function: SYNTHESISButton
        function SYNTHESISButtonPushed(app, event)
            app.LogData.text = "SYNTHESISButtonPushed " + string(datetime(datetime));
            recordLog(app);
            xLim = app.SgramAxis2.XLim;
            fs = app.CommonData.samplingFrequency;
            source_parameter = app.CommonData.worldParams.source_parameter;
            yData = get(app.CommonData.foHandle, 'YData');
            lineStatus = get(app.CommonData.foHandle, 'UserData');
            switch lineStatus.scale
                case 'log'
                    tmp = exp(yData(:));
                case 'mel'
                    tmpY = yData;
                    tmpY(isnan(yData)) = 10;
                    app.CommonData.mel = tmpY(:);
                    tmp = mymel2hz(app);
                    tmp(isnan(yData)) = NaN;
            end
            tmp(isnan(tmp)) = 0;
            source_parameter.f0 = tmp;
            spectrum_parameter = app.CommonData.worldParams.spectrum_parameter;
            switch app.TmagSlider.Value
                case 1
                otherwise
                    spectrum_parameter.temporal_positions = ...
                        spectrum_parameter.temporal_positions * app.TmagSlider.Value;
                    source_parameter.temporal_positions = ...
                        source_parameter.temporal_positions * app.TmagSlider.Value;
            end
            switch app.VTLSlider.Value
                case 1
                otherwise
                    value = app.VTLSlider.Value;
                    sgram = app.CommonData.worldParams.spectrum_parameter.spectrogram;
                    apGram = app.CommonData.worldParams.source_parameter.aperiodicity;
                    fx = (0:size(sgram,1))/size(sgram,1)*fs/2;
                    sgramDisp = interp1(fx, [sgram;sgram(end,:)], fx*value, "linear","extrap");
                    spectrum_parameter.spectrogram = sgramDisp(1:end-1,:);
                    apGramDisp = interp1(fx, [apGram;apGram(end,:)], fx*value, "linear","extrap");
                    source_parameter.aperiodicity = apGramDisp(1:end-1,:);
            end
            if app.CommonData.handleTotal > 0
                app.CommonData.synth_source_parameter = source_parameter;
                app.CommonData.synth_spectrum_parameter = spectrum_parameter;
                nolinearTimeAxisWarping(app);
                source_parameter = app.CommonData.synth_source_parameter;
                spectrum_parameter = app.CommonData.synth_spectrum_parameter;
            end
            xSynth = Synthesis(source_parameter, spectrum_parameter);
            nData = length(xSynth);
            xLimSample = max(1, min(nData, 1+round(xLim * fs)));
            app.CommonData.synthesizedSignal = xSynth;
            %------ make structure for save parameter
            app.worldParameterMod.creator = 'worldManipulator.mlapp';
            app.worldParameterMod.signal = xSynth;
            app.worldParameterMod.fileFullPath = ' ';
            app.worldParameterMod.fileName = ' ';
            app.worldParameterMod.samplingFrequency = fs;
            app.worldParameterMod.span = xLimSample(1):xLimSample(end);
            app.worldParameterMod.spectrum_parameter = spectrum_parameter;
            app.worldParameterMod.source_parameter = source_parameter;
            app.worldParameterMod.f0_original = source_parameter.f0(:)';
            %------ end
            app.UIFigure.Tag = '';
            app.PLAYSynthesizedButton.Enable = 'on';
            app.SAVEEDITButton.Enable = 'on';
            app.SAVESoundButton.Enable = 'on';
            if app.CommonData.handleTotal >0
                sound(xSynth/max(abs(xSynth))*0.7,fs);
            else
                xTrim = xSynth(xLimSample(1):xLimSample(end));
                sound(xTrim/max(abs(xTrim))*0.7, fs);
            end
        end

        % Button pushed function: Reset_foButton
        function Reset_foButtonPushed(app, event)
            app.LogData.text = "Reset_foButtonPushed " + string(datetime(datetime));
            recordLog(app);
            fo = app.CommonData.worldParams.source_parameter.f0(:);
            selectedButton = app.Shift_scaleButtonGroup.SelectedObject;
            lineStatus = get(app.CommonData.foHandle, 'UserData');
            while app.CommonData.penString.count > 0
                UNDOButtonPushed(app,event);
                pause(0:1);
            end
            app.CommonData.penString.TotalCount = 0;
            switch selectedButton.Text
                case 'log'
                    set(app.CommonData.foHandle, 'YData', log(fo));
                    lineStatus.scale = 'log';
                case 'mel'
                    app.CommonData.hz = fo;
                    tmp = myhz2mel(app);
                    tmp(fo <= 0) = NaN;
                    set(app.CommonData.foHandle, 'YData', tmp);
                    lineStatus.scale = 'mel';
            end
            lineStatus.latestFo = fo;
            lineStatus.previousFo = fo;
            set(app.CommonData.foHandle, 'UserData',lineStatus);
            app.PLAYSynthesizedButton.Enable = 'off';
            app.rangeSlider.Value = 1;
            app.baseSlider.Value = 1;
            app.ResetbaseButton.Enable = "off";
            app.ResetdynamicsButton.Enable = "off";
            app.REDOButton.Enable = "off";
            drawnow;
            app.SAVEEDITButton.Enable = 'off';
            app.SAVESoundButton.Enable = 'off';
        end

        % Value changing function: TmagSlider
        function TmagSliderValueChanging(app, event)
            changingValue = event.Value;
            if isfield(app.CommonDat,"previousTslider") % safeguard
                prevoiurValue = app.CommonData.previousTslider;
            else
                prevoiurValue = 1;
                app.CommonData.previousTslider = prevoiurValue;
            end
            xLimOnOriginal = get(app.FoAxis,'Xlim')/prevoiurValue;
            source_parameter = app.CommonData.worldParams.source_parameter;
            txfo = source_parameter.temporal_positions(:);
            tx = app.CommonData.txSignal;
            tt = app.CommonData.worldParams.spectrum_parameter.temporal_positions;
            set(app.FoAxis,'Xlim', xLimOnOriginal * changingValue);
            set(app.CommonData.foHandle, 'XData', txfo * changingValue);
            set(app.CommonData.pwHandle1, 'XData', tt * changingValue);
            set(app.CommonData.pwHandle2, 'XData', tt * changingValue);
            set(app.CommonData.unvoicedHandle, 'XData', tt * changingValue);
            set(app.CommonData.voicedHandle, 'XData', tt * changingValue);
            set(app.CommonData.waveHandle, 'XData', tx * changingValue);
            set(app.CommonData.sgramHandle, 'XData', [txfo(1) txfo(end)] * changingValue);
            app.CommonData.previousTslider = changingValue;
            app.TmagEditField.Value = changingValue;
            app.Reset_TmagButton.Enable = 'on';
            app.PLAYSynthesizedButton.Enable = 'off';
            drawnow limitrate
            app.SAVEEDITButton.Enable = 'off';
            app.SAVESoundButton.Enable = 'off';
        end

        % Button pushed function: Reset_TmagButton
        function Reset_TmagButtonPushed(app, event)
            app.LogData.text = "Reset_TmagButtonPushed " + string(datetime(datetime));
            recordLog(app);
            source_parameter = app.CommonData.worldParams.source_parameter;
            txfo = source_parameter.temporal_positions(:);
            tt = app.CommonData.worldParams.spectrum_parameter.temporal_positions;
            tx = app.CommonData.txSignal;
            set(app.FoAxis,'Xlim', [txfo(1) txfo(end)]);
            set(app.CommonData.foHandle, 'XData', txfo);
            set(app.CommonData.sgramHandle, 'XData', [txfo(1) txfo(end)]);
            set(app.CommonData.pwHandle1, 'XData', tt);
            set(app.CommonData.pwHandle2, 'XData', tt);
            set(app.CommonData.unvoicedHandle, 'XData', tt);
            set(app.CommonData.voicedHandle, 'XData', tt);
            set(app.CommonData.waveHandle, 'XData', tx);
            app.TmagSlider.Value = 1;
            app.CommonData.previousTslider = 1;
            app.Reset_TmagButton.Enable = 'off';
            app.PLAYSynthesizedButton.Enable = 'off';
            drawnow
            app.SAVEEDITButton.Enable = 'off';
            app.SAVESoundButton.Enable = 'off';
        end

        % Button pushed function: PLAYSynthesizedButton
        function PLAYSynthesizedButtonPushed(app, event)
            app.LogData.text = "PLAYSynthesizedButtonPushed " + string(datetime(datetime));
            recordLog(app);
            xLim = app.SgramAxis2.XLim;
            fs = app.CommonData.samplingFrequency;
            x = app.CommonData.synthesizedSignal;
            nData = length(x);
            xLimSample = max(1, min(nData, 1+round(xLim * fs)));
            span = xLimSample(1):xLimSample(end);
            sound(x(span)/max(abs(x(span)))*0.7, fs);
            if app.CommonData.handleTotal >0
                sound(x/max(abs(x))*0.7,fs);
            else
            sound(x(span)/max(abs(x(span)))*0.7, fs);
            end
        end

        % Value changed function: VTLSlider
        function VTLSliderValueChanged(app, event)
            app.LogData.text = "VTLSliderValueChanged " + string(datetime(datetime));
            recordLog(app);
            value = app.VTLSlider.Value;
            app.VEditField.Value = value;
            fs = app.CommonData.samplingFrequency;
            sgram = app.CommonData.worldParams.spectrum_parameter.spectrogram;
            fx = (0:size(sgram,1))/size(sgram,1)*fs/2;
            sgramDisp = interp1(fx, [sgram;sgram(end,:)], fx*value, "linear","extrap");
            app.CommonData.currentSpectrogram = sgramDisp(1:end-1,:);
            linear2logSgram(app);
            linear2erbSgram(app);
            sgramDb = 10*log10(sgramDisp(1:end-1,:));
            sgramDb = max(-80, sgramDb - max(sgramDb(:)));
            app.CommonData.linearSgramDb = sgramDb;
            Sgram_scaleButtonGroupSelectionChanged(app, event);
            app.PLAYSynthesizedButton.Enable = 'off';
            app.Reset_VTLButton.Enable = 'on';
            app.SAVEEDITButton.Enable = 'off';
            app.SAVESoundButton.Enable = 'off';
        end

        % Button pushed function: Reset_VTLButton
        function Reset_VTLButtonPushed(app, event)
            app.LogData.text = "Reset_VTLButtonPushed " + string(datetime(datetime));
            recordLog(app);
            sgram = app.CommonData.worldParams.spectrum_parameter.spectrogram;
            app.CommonData.currentSpectrogram = sgram;
            app.VEditField.Value = 1;
            linear2logSgram(app);
            linear2erbSgram(app);
            sgramDb = 10*log10(sgram);
            sgramDb = max(-80, sgramDb - max(sgramDb(:)));
            app.CommonData.linearSgramDb = sgramDb;
            Sgram_scaleButtonGroupSelectionChanged(app, event);
            app.VTLSlider.Value = 1;
            app.Reset_VTLButton.Enable = 'off';
            app.PLAYSynthesizedButton.Enable = 'off';
            app.SAVEEDITButton.Enable = 'off';
            app.SAVESoundButton.Enable = 'off';
        end

        % Selection changed function: Sgram_scaleButtonGroup
        function Sgram_scaleButtonGroupSelectionChanged(app, event)
            app.LogData.text = "Sgram_scaleButtonGroupSelectionChanged " + string(datetime(datetime));
            recordLog(app);
            selectedButton = app.Sgram_scaleButtonGroup.SelectedObject;
            app.LogData.text = "selectedButton " + selectedButton.Text;
            recordLog(app);
            switch selectedButton.Text
                case 'ERB_N'
                    set(app.CommonData.sgramHandle, 'CData', app.CommonData.erbSgramDb);
                    set(app.CommonData.sgramHandle, 'YData', app.CommonData.erbYlim);
                    set(app.SgramAxis2, 'YTick', app.CommonData.erbyTickLoc);
                    set(app.SgramAxis2, 'YTickLabel', app.CommonData.erbyTickLabel);
                    set(app.SgramAxis2, 'YLim', app.CommonData.erbYlim);
                    app.FreqlimitButtonGroup.Enable = "off";
                case 'log'
                    set(app.CommonData.sgramHandle, 'CData', app.CommonData.logSgramDb);
                    set(app.CommonData.sgramHandle, 'YData', app.CommonData.logYlim)
                    set(app.SgramAxis2, 'YTick', app.CommonData.logyTickLoc);
                    set(app.SgramAxis2, 'YTickLabel', app.CommonData.logyTickLabel);
                    set(app.SgramAxis2, 'YLim', app.CommonData.logYlim);
                    app.FreqlimitButtonGroup.Enable = "off";
                case 'linear'
                    set(app.CommonData.sgramHandle, 'CData', app.CommonData.linearSgramDb);
                    set(app.CommonData.sgramHandle, 'YData', app.CommonData.linearYlim)
                    set(app.SgramAxis2, 'YTick', app.CommonData.linearyTickLoc);
                    set(app.SgramAxis2, 'YTickLabel', app.CommonData.linearyTickLabel);
                    set(app.SgramAxis2, 'YLim', app.CommonData.linearSgramViewYlim);
                    app.FreqlimitButtonGroup.Enable = "on";
                    %app.FullButton.Value = 1;
            end
            drawnow
        end

        % Button pushed function: SAVEEDITButton
        function SAVEEDITButtonPushed(app, event)
            app.LogData.text = "SAVEEDITButtonPushed " + string(datetime(datetime));
            recordLog(app);
            modificationStr = makeModificationStr(app);
            outFileName = "worldPrmEdit" + string(datetime(datetime,'format','yyyyMMddHHmmss')) + "d.mat";
            if isfield(app.CommonData,'outFilePath')
                oldPath = cd(app.CommonData.outFilePath);
            end
            [fileName,filePath] = uiputfile('','save parameter',outFileName);
            if isequal(filePath, 0) || isequal(fileName, 0)
                if isfield(app.CommonData,'outFilePath')
                    cd(oldPath);
                    disp('save cancelled!');
                    app.LogData.text = "SAVEEDIT_cancelled " + string(datetime(datetime));
                    recordLog(app);
                end
            else
                if isfield(app.CommonData,'outFilePath')
                    cd(oldPath);
                end
                app.CommonData.outFileName = string(fileName);
                app.CommonData.outFilePath = string(filePath);
                save(app.CommonData.outFilePath + app.CommonData.outFileName, "modificationStr");
                app.SAVEEDITButton.Enable = 'off';
                app.LogData.text = "SAVEEDIT_saved " ...
                    + app.CommonData.outFilePath + app.CommonData.outFileName;
                recordLog(app);
            end
        end

        % Button pushed function: SAVESoundButton
        function SAVESoundButtonPushed(app, event)
            app.LogData.text = "SAVESoundButtonPushed " + string(datetime(datetime));
            recordLog(app);
            dateStrCommon = string(datetime(datetime,'format','yyyyMMddHHmmss'));
            outSynthFileName = "worldSynthMod" + dateStrCommon + "d.wav";
            fs = app.CommonData.samplingFrequency;
            if isfield(app.CommonData,'outFilePath')
                oldPath = cd(app.CommonData.outFilePath);
            end
            [fileName,filePath] = uiputfile('*.wav','save parameter',outSynthFileName);
            if isequal(filePath, 0) || isequal(fileName, 0)
                if isfield(app.CommonData,'outFilePath')
                    cd(oldPath);
                    disp('save cancelled!');
                    app.LogData.text = "SAVESound_cancelled " + string(datetime(datetime));
                    recordLog(app);
                end
            else
                if isfield(app.CommonData,'outFilePath')
                    cd(oldPath);
                end
                app.CommonData.outFilePath = filePath;
                app.CommonData.outSynthFileName = fileName;
                synthModDataFileName = string(fileName(1:end-4)) + ".mat";
                x = app.CommonData.synthesizedSignal;
                x = x / max(abs(x)) * 0.8;
                audiowrite(string(app.CommonData.outFilePath) ...
                    + string(app.CommonData.outSynthFileName),...
                    x, fs,"BitsPerSample", 24, ...
                    "Comment", synthModDataFileName);
                modificationStr = makeModificationStr(app);
                save(app.CommonData.outFilePath+synthModDataFileName,'modificationStr');
                if isfield(app.CommonData, "timeAnchor") && app.TmagSlider.Value == 1
                    augParamFileName = string(fileName(1:end-4)) + "AugPrm.mat";
                    worldParameter = app.CommonData.worldParams;
                    worldParameter.signal = x;
                    worldParameter.span = app.worldParameterMod.span;
                    worldParameter.spectrum_parameter = app.worldParameterMod.spectrum_parameter;
                    worldParameter.source_parameter = app.worldParameterMod.source_parameter;
                    worldParameter.f0_original = app.worldParameterMod.source_parameter.f0;
                    timeAnchor = app.CommonData.timeAnchor;
                    timeFreqAnchor = app.CommonData.timeFreqAnchor / app.VTLSlider.Value;
                    save(app.CommonData.outFilePath+augParamFileName,'worldParameter', ...
                        'timeAnchor','timeFreqAnchor');
                end
                app.SAVESoundButton.Enable = 'off';
                app.SAVEParameterButton.Enable = 'on';
                app.LogData.text = "SAVESound_saved " ...
                    + string(app.CommonData.outFilePath) ...
                    + string(app.CommonData.outSynthFileName);
                recordLog(app);
            end
        end

        % Value changing function: rangeSlider
        function rangeSliderValueChanging(app, event)
            magValue = event.Value;
            app.REditField.Value = magValue;
            baseValue = app.baseSlider.Value;
            lineStatus = get(app.CommonData.foHandle, 'UserData');
            fo = lineStatus.latestFo;
            app.DisplayData.fo = fo;
            app.DisplayData.baseValue = baseValue;
            app.DisplayData.magValue = magValue;
            updateFoDisplay2(app);
            app.ResetdynamicsButton.Enable = 'on';
            app.PLAYSynthesizedButton.Enable = "off";
        end

        % Button pushed function: ResetdynamicsButton
        function ResetdynamicsButtonPushed(app, event)
            app.LogData.text = "ResetdynamicsButtonPushed " + string(datetime(datetime));
            recordLog(app);
            selectedButton = app.Shift_scaleButtonGroup.SelectedObject;
            lineStatus = get(app.CommonData.foHandle, 'UserData');
            fo = lineStatus.previousFo;
            switch selectedButton.Text
                case 'log'
                    set(app.CommonData.foHandle, 'YData', log(fo));
                case 'mel'
                    app.CommonData.hz = fo;
                    tmp = myhz2mel(app);
                    %tmp = hz2mel(fo);
                    tmp(fo <= 0) = NaN;
                    set(app.CommonData.foHandle, 'YData', tmp);
            end
            app.REditField.Value = 1;
            app.ResetdynamicsButton.Enable = 'off';
            app.PLAYSynthesizedButton.Enable = "off";
            app.rangeSlider.Value = 1;
        end

        % Button pushed function: RESTOREEDITButton
        function RESTOREEDITButtonPushed(app, event)
            app.LogData.text = "RESTOREEDITButtonPushed " + string(datetime(datetime));
            recordLog(app);
            [paramFile, paramPath] = uigetfile({'*.mat'},"Select EDIT file:");
            tmpEdit = load([paramPath '/' paramFile]);
            app.fileLabel.Text = tmpEdit.modificationStr.parameterFile;
            app.pathLabel.Text = tmpEdit.modificationStr.parameterPath;
            tmp = load([tmpEdit.modificationStr.parameterPath '/' tmpEdit.modificationStr.parameterFile]);
            app.CommonData.worldParams = tmp.worldParameter;
            app.CommonData.samplingFrequency = tmp.worldParameter.samplingFrequency;
            app.CommonData.x = tmp.worldParameter.signal;
            x = app.CommonData.x;
            fs = tmp.worldParameter.samplingFrequency;
            app.CommonData.txSignal = (1:length(x))'/fs;
            initializeGraphics(app);
            modificationStr = tmpEdit.modificationStr;
            app.TmagSlider.Value = modificationStr.timeAxisMagnification;
            if app.TmagSlider.Value ~= 1
                app.Reset_TmagButton.Enable = "on";
            end
            app.VTLSlider.Value = modificationStr.VTLMagnification;
            app.VEditField.Value = app.VTLSlider.Value;
            if app.VTLSlider.Value ~= 1
                app.Reset_VTLButton.Enable = "on";
            end
            app.rangeSlider.Value = modificationStr.dynamicRangeModification;
            app.REditField.Value = app.rangeSlider.Value;
            if app.rangeSlider.Value ~= 1
                app.ResetdynamicsButton.Enable = "on";
            end
            app.baseSlider.Value = modificationStr.baseModification;
            app.BEditField.Value = app.baseSlider.Value;
            if app.baseSlider.Value ~= 1
                app.ResetbaseButton.Enable = "on";
            end
            selectedButton = app.Shift_scaleButtonGroup.SelectedObject;
            fo = modificationStr.latestFo;
            baseValue = app.baseSlider.Value;
            magValue = app.rangeSlider.Value;
            meanFo = app.CommonData.meanFo;
            switch selectedButton.Text
                case 'log'
                    modLogFo = (log(fo) - log(meanFo)) * magValue ...
                        + log(meanFo) + log(baseValue);
                    modLogFo(fo <= 0) = NaN;
                    set(app.CommonData.foHandle, 'YData', modLogFo);
                case 'mel'
                    app.CommonData.hz = fo;
                    v_hz2mel_fo = myhz2mel(app);
                    app.CommonData.hz = meanFo;
                    v_hz2mel_meanFo = myhz2mel(app);
                    app.CommonData.hz = meanFo*baseValue;
                    v_hz2mel_meanFo_baseValue = myhz2mel(app);
                    app.CommonData.mel = magValue * (v_hz2mel_fo - v_hz2mel_meanFo) ...
                        + v_hz2mel_meanFo_baseValue;
                    app.CommonData.hz = mymel2hz(app);
                    modMelFo = myhz2mel(app);
                    modMelFo(fo<=0) = NaN;
                    set(app.CommonData.foHandle, 'YData', modMelFo);
            end
            lineStatus = get(app.CommonData.foHandle, 'UserData');
            lineStatus.latestFo = fo;
            lineStatus.previousFo = fo;
            set(app.CommonData.foHandle, 'UserData',lineStatus);
            app.PLAYOriginalButton.Enable = 'on';
            app.PLAYSynthesizedButton.Enable = 'off';
            app.SAVEEDITButton.Enable = 'off';
            app.SAVESoundButton.Enable = 'off';
        end

        % Button down function: FoAxis
        function FoAxisButtonDown(app, event)
            app.LogData.text = "FoAxisButtonDown " + string(datetime(datetime));
            recordLog(app);
            app.CommonData.penStatus = 'down';
            set(app.UIFigure, "Pointer", 'crosshair')
            currentPoint = get(app.FoAxis,'CurrentPoint');
            if app.CommonData.penString.count < app.CommonData.penString.TotalCount
                while app.CommonData.penString.count < app.CommonData.penString.TotalCount
                    UNDOButtonPushed(app, event);
                    app.CommonData.penString.TotalCount = ...
                        app.CommonData.penString.TotalCount - 1;
                    pause(0.1);
                end
            end
            app.CommonData.penString.count = app.CommonData.penString.count + 1;
            app.CommonData.penString.TotalCount = app.CommonData.penString.TotalCount + 1;
            xlocation = zeros(1000,1) * NaN;
            ylocation = zeros(1000,1) * NaN;
            lineStatus = get(app.CommonData.foHandle, 'UserData');
            fo = lineStatus.latestFo;
            id = app.CommonData.penString.count;
            ylocation(1) = currentPoint(1,2);
            xlocation(1) = currentPoint(1,1);
            hold(app.FoAxis,"on");
            app.CommonData.penString.locData(id).seqId = 1;
            app.CommonData.penString.locData(id).linehandle = ...
                plot(app.FoAxis,xlocation,ylocation,'g-','LineWidth',1);
            app.CommonData.penString.locData(id).latestFo = fo;
            app.PLAYSynthesizedButton.Enable = "off";
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            position = get(app.UIFigure, 'Position');
            if isfield(app.CommonData, "figurePosition") ...
                    && sum(position ~= app.CommonData.figurePosition) > 0
                saveBaseFile(app);
                app.LogData.text = "GUI_position_changed " + string(datetime(datetime));
                recordLog(app);
            end
            if string(app.CommonData.initialized) == "yes" ...
                    && (string(app.CommonData.penStatus) == "tcursordown" ...
                    || string(app.CommonData.penStatus) == "tcursorpick")
                id = app.CommonData.handleId;
                set(app.CommonData.foViewHandle(id), "linewidth" ,1);
                set(app.CommonData.sgramViewHandle(id), "linewidth" ,1);
                assignSegmentMagHandle(app);
                set(app.UIFigure, "Pointer", 'arrow')
                app.CommonData.penStatus = 'up';
                return;
            end
            if string(app.CommonData.initialized) == "yes" ...
                    && string(app.CommonData.penStatus) == "tmugAdjust"
                segId = app.CommonData.segmentId;
                set(app.CommonData.segmentHandle(segId), "color", [0 0.6 0], ...
                    "linewidth", 2);
                set(app.UIFigure, "Pointer", 'arrow')
                app.CommonData.penStatus = 'up';
                return;
            end
            if string(app.CommonData.initialized) == "yes" ...
                    && string(app.CommonData.penStatus) == "down"
                if app.CommonData.penString.count > 0
                    app.LogData.text = "Fo_pendown " + string(datetime(datetime));
                    recordLog(app);
                    id = app.CommonData.penString.count;
                    app.CommonData.penString.TotalCount = id;
                    app.REDOButton.Enable = "off";
                    app.CommonData.penStatus = 'up';
                    set(app.UIFigure, "Pointer", 'arrow')
                    app.UNDOButton.Enable = "on";
                    sqid = app.CommonData.penString.locData(id).seqId;
                    tt = get(app.CommonData.foHandle,'xdata');
                    linehandle = app.CommonData.penString.locData(id).linehandle;
                    xdata = get(linehandle,'xdata');
                    ydata = get(linehandle,'ydata');
                    xdata = xdata(1:sqid);
                    ydata = ydata(1:sqid);
                    [xdataSrt, srtIdx] = sort(xdata);
                    xdataSrt = xdataSrt(:) + cumsum(ones(sqid,1))/sqid/1000000000000;
                    ydataSrt = ydata(srtIdx);
                    stp = min(xdataSrt);
                    edp = max(xdataSrt);
                    y = interp1(xdataSrt,ydataSrt,tt(tt>= stp & tt < edp), ...
                        "linear","extrap");
                    selectedButton = app.Shift_scaleButtonGroup.SelectedObject;
                    % ---- inverse function check!
                    a = app.rangeSlider.Value;
                    b = app.baseSlider.Value;
                    xb = app.CommonData.meanFo;
                    if a == 0
                        a = 0.01;
                    end
                    switch selectedButton.Text
                        case 'log'
                            yDataMod = exp((y-log(b*xb))/a+log(xb));
                        case 'mel'
                            app.CommonData.hz = b*xb;
                            v_hz2mel_b_xb = myhz2mel(app);
                            app.CommonData.hz = xb;
                            v_hz2mel_xb = myhz2mel(app);
                            app.CommonData.mel = (y-v_hz2mel_b_xb)/a+v_hz2mel_xb;
                            yDataMod = mymel2hz(app);
                    end
                    % ---------
                    lineStatus = get(app.CommonData.foHandle, 'UserData');
                    fo = lineStatus.latestFo;
                    originalFo = app.CommonData.originalFo;
                    foTmp = fo;
                    foTmp(tt>= stp & tt < edp) = yDataMod;
                    foTmp(originalFo <= 0) = 0;
                    fo = foTmp;
                    baseValue = app.baseSlider.Value;
                    magValue = app.rangeSlider.Value;
                    app.DisplayData.fo = fo;
                    app.DisplayData.baseValue = baseValue;
                    app.DisplayData.magValue = magValue;
                    updateFoDisplay2(app);
                    app.CommonData.penString.locData(id).currentFo = fo;
                    lineStatus.latestFo = fo;
                    lineStatus.previousFo = fo;
                    set(app.CommonData.foHandle, 'UserData',lineStatus);
                    hold(app.FoAxis,"off");
                end
            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            switch app.CommonData.penStatus
                case 'down'
                    id = app.CommonData.penString.count;
                    if id > 0
                    currentPoint = get(app.FoAxis,'CurrentPoint');
                    seqId = app.CommonData.penString.locData(id).seqId + 1;
                    app.CommonData.penString.locData(id).seqId = seqId;
                    xlocation = get(app.CommonData.penString.locData(id).linehandle,'xdata');
                    ylocation = get(app.CommonData.penString.locData(id).linehandle,'ydata');
                    xlocation(seqId) = currentPoint(1,1);
                    ylocation(seqId) = currentPoint(1,2);
                    set(app.CommonData.penString.locData(id).linehandle,'xdata',xlocation);
                    set(app.CommonData.penString.locData(id).linehandle,'ydata',ylocation);
                    drawnow;
                    end
                case 'tcursordown'
                    id = app.CommonData.handleId;
                    if id > 0
                    currentPoint = get(app.powerViewAxis,'CurrentPoint');
                    set(app.CommonData.foViewHandle(id), "value",currentPoint(1,1), "linewidth" ,3);
                    set(app.CommonData.sgramViewHandle(id), "value",currentPoint(1,1), "linewidth" ,3);
                    set(app.CommonData.powerViewHandle(id), "value",currentPoint(1,1), "linewidth" ,3);
                    drawnow
                    end
                case 'tmugAdjust'
                    segId = app.CommonData.segmentId;
                    if segId > 0
                        currentPoint = get(app.powerViewAxis,'CurrentPoint');
                        app.CommonData.segmentHandle(segId).YData = currentPoint(1,2)*[1 1];
                        displayMagLevel(app);
                    end
                case 'up'
                    if string(app.CommonData.initialized) == "yes"
                    currentPoint = get(app.powerViewAxis,'CurrentPoint');
                    pwYlim = get(app.powerViewAxis,'YLim');
                    tFrame = app.CommonData.worldParams.spectrum_parameter.temporal_positions;
                    nHandle = app.CommonData.handleTotal;
                    if (pwYlim(1)-currentPoint(1,2))*(pwYlim(2)-currentPoint(1,2)) < 0 && nHandle > 0
                        handleLocs = zeros(nHandle,1);
                        for ii = 1:nHandle
                            handleLocs(ii) = app.CommonData.powerViewHandle(ii).Value;
                        end
                        handleLocs = sort(handleLocs);
                        segments = zeros(nHandle+1,3);
                        for ii = 1:nHandle+1
                            yLevel = app.CommonData.segmentHandle(ii).YData;
                            if ii > 1
                                if ii <= nHandle
                                segments(ii,:) = [handleLocs(ii-1) handleLocs(ii) yLevel(1)];
                                else
                                    segments(ii,:) = [handleLocs(ii-1) tFrame(end) yLevel(1)];
                                end
                            else
                                segments(ii,:) = [0 handleLocs(ii) yLevel(1)];
                            end
                        end
                        [minDist, idx] = min(abs(currentPoint(1,1) - handleLocs));
                        if minDist < 0.005
                            set(app.UIFigure, "Pointer", 'hand')
                            app.CommonData.handleId = idx;
                        else
                            minLevel = 100;
                            segId = 1;
                            for ii = 1:nHandle+1
                                if (segments(ii,1) - currentPoint(1,1))*(segments(ii,2) - currentPoint(1,1)) < 0 ...
                                        && abs(segments(ii,3) - currentPoint(1,2)) < minLevel
                                    minLevel = abs(segments(ii,3) - currentPoint(1,2));
                                    segId = ii;
                                end
                            end
                            if minLevel < diff(pwYlim)/50
                                set(app.UIFigure, "Pointer", 'fleur')
                                displayMagLevel(app);
                                app.CommonData.magText.Visible = "on";
                                app.CommonData.segmentId = segId;
                            else
                                set(app.UIFigure, "Pointer", 'arrow')
                                app.CommonData.magText.Visible = "off";
                            end
                        end
                    end
                    end
            end
        end

        % Value changing function: baseSlider
        function baseSliderValueChanging(app, event)
            baseValue = event.Value;
            app.BEditField.Value = baseValue;
            magValue = app.rangeSlider.Value;
            lineStatus = get(app.CommonData.foHandle, 'UserData');
            fo = lineStatus.latestFo;
            app.DisplayData.fo = fo;
            app.DisplayData.baseValue = baseValue;
            app.DisplayData.magValue = magValue;
            updateFoDisplay2(app);
            app.ResetbaseButton.Enable = 'on';
            app.PLAYSynthesizedButton.Enable = "off";
        end

        % Button pushed function: ResetbaseButton
        function ResetbaseButtonPushed(app, event)
            app.LogData.text = "ResetbaseButtonPushed " + string(datetime(datetime));
            recordLog(app);
            selectedButton = app.Shift_scaleButtonGroup.SelectedObject;
            lineStatus = get(app.CommonData.foHandle, 'UserData');
            fo = lineStatus.previousFo;
            switch selectedButton.Text
                case 'log'
                    set(app.CommonData.foHandle, 'YData', log(fo));
                case 'mel'
                    app.CommonData.hz = fo;
                    tmp = myhz2mel(app);
                    %tmp = hz2mel(fo);
                    tmp(fo <= 0) = NaN;
                    set(app.CommonData.foHandle, 'YData', tmp);
            end
            app.BEditField.Value = 1;
            app.ResetbaseButton.Enable = 'off';
            app.PLAYSynthesizedButton.Enable = "off";
            app.baseSlider.Value = 1;
        end

        % Button pushed function: UNDOButton
        function UNDOButtonPushed(app, event)
            app.LogData.text = "UNDOButtonPushed " + string(datetime(datetime));
            recordLog(app);
            id = app.CommonData.penString.count;
            if id > 0
                app.CommonData.penString.locData(id).linehandle.Visible = "off";
                fo = app.CommonData.penString.locData(id).latestFo;
                lineStatus = get(app.CommonData.foHandle, 'UserData');
                lineStatus.latestFo = fo;
                set(app.CommonData.foHandle, 'UserData',lineStatus);
                app.DisplayData.fo = fo;
                app.DisplayData.baseValue = app.baseSlider.Value;
                app.DisplayData.magValue = app.rangeSlider.Value;
                updateFoDisplay2(app);
                app.CommonData.penString.count = id - 1;
                if app.CommonData.penString.count == 0
                    app.UNDOButton.Enable = "off";
                end
            else
                app.UNDOButton.Enable = "off";
            end
            if app.CommonData.penString.count < app.CommonData.penString.TotalCount
                app.REDOButton.Enable = "on";
            end
            app.PLAYSynthesizedButton.Enable = "off";
        end

        % Button pushed function: SAVEParameterButton
        function SAVEParameterButtonPushed(app, event)
            app.LogData.text = "SAVEParameterButtonPushed " + string(datetime(datetime));
            recordLog(app);
            modificationStr = makeModificationStr(app);
            outFileName = "worldParameterMod" + string(datetime(datetime,'format','yyyyMMddHHmmss')) + "d.mat";
            if isfield(app.CommonData,'outFilePath')
                oldPath = cd(app.CommonData.outFilePath);
            end
            [fileName,filePath] = uiputfile('','save parameter',outFileName);
            if isequal(filePath, 0) || isequal(fileName, 0)
                if isfield(app.CommonData,'outFilePath')
                    cd(oldPath);
                    disp('save cancelled!');
                end
            else
                if isfield(app.CommonData,'outFilePath')
                    cd(oldPath);
                end
                %app.CommonData.outFileName = string(fileName);
                app.CommonData.outFilePath = string(filePath);
                app.worldParameterMod.fileFullPath = app.CommonData.outFilePath;
                app.worldParameterMod.fileName = app.CommonData.outSynthFileName;
                worldParameter = app.worldParameterMod;
                save(app.CommonData.outFilePath + fileName,...
                    "modificationStr","worldParameter");
                app.SAVEParameterButton.Enable = "off";
            end
        end

        % Button pushed function: REDOButton
        function REDOButtonPushed(app, event)
            app.LogData.text = "REDOButtonPushed " + string(datetime(datetime));
            recordLog(app);
            id = app.CommonData.penString.count;
            if id < app.CommonData.penString.TotalCount
                app.CommonData.penString.count = id + 1;
                id = id + 1;
                app.CommonData.penString.locData(id).linehandle.Visible = "on";
                fo = app.CommonData.penString.locData(id).currentFo;
                lineStatus = get(app.CommonData.foHandle, 'UserData');
                lineStatus.latestFo = fo;
                set(app.CommonData.foHandle, 'UserData',lineStatus);
                app.DisplayData.fo = fo;
                app.DisplayData.baseValue = app.baseSlider.Value;
                app.DisplayData.magValue = app.rangeSlider.Value;
                updateFoDisplay2(app);
                if app.CommonData.penString.count == app.CommonData.penString.TotalCount
                    app.REDOButton.Enable = "off";
                end
            else
                app.REDOButton.Enable = "off";
            end
            if app.CommonData.penString.count < app.CommonData.penString.TotalCount
                app.UNDOButton.Enable = "on";
            end
            app.PLAYSynthesizedButton.Enable = "off";
        end

        % Value changed function: SAVElogfileCheckBox
        function SAVElogfileCheckBoxValueChanged(app, event)
            value = app.SAVElogfileCheckBox.Value;
            app.LogData.text = "SAVElogfileCheckBoxValueChanged " + string(datetime(datetime));
            recordLog(app);
            app.LogData.text = "Value= " + num2str(value);
            recordLog(app);
        end

        % Value changed function: TmagSlider
        function TmagSliderValueChanged(app, event)
            value = app.TmagSlider.Value;
            app.LogData.text = "TmagSliderValueChanged " + string(datetime(datetime));
            recordLog(app);
            app.LogData.text = "Value " + num2str(value);
            recordLog(app);
        end

        % Value changed function: baseSlider
        function baseSliderValueChanged(app, event)
            value = app.baseSlider.Value;
            app.LogData.text = "baseSliderValueChanged " + string(datetime(datetime));
            recordLog(app);
            app.LogData.text = "Value " + num2str(value);
            recordLog(app);
        end

        % Value changed function: rangeSlider
        function rangeSliderValueChanged(app, event)
            value = app.rangeSlider.Value;
            app.LogData.text = "rangeSliderValueChanged " + string(datetime(datetime));
            recordLog(app);
            app.LogData.text = "Value " + num2str(value);
            recordLog(app);
        end

        % Selection changed function: ColormapButtonGroup
        function ColormapButtonGroupSelectionChanged(app, event)
            selectedButton = app.ColormapButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'perura'
                    colormap(app.SgramAxis2,"parula");
                case 'turbo'
                    colormap(app.SgramAxis2,"turbo");
                case 'gray'
                    cmap = colormap(app.SgramAxis2,"gray");
                    colormap(app.SgramAxis2,1-cmap);
            end
        end

        % Selection changed function: FreqlimitButtonGroup
        function FreqlimitButtonGroupSelectionChanged(app, event)
            selectedButton = app.FreqlimitButtonGroup.SelectedObject;
            fs = app.CommonData.samplingFrequency/1000;
            switch selectedButton.Text
                case 'Full'
                    set(app.SgramAxis2, "YLim", [0 fs/2]);
                case '8kHz'
                    set(app.SgramAxis2, "YLim", [0 8]);
                case '6kHz'
                    set(app.SgramAxis2, "YLim", [0 6]);
                case '4kHz'
                    set(app.SgramAxis2, "YLim", [0 4]);
                case '2kHz'
                    set(app.SgramAxis2, "YLim", [0 2]);
                case '1kHz'
                    set(app.SgramAxis2, "YLim", [0 1]);
            end
            app.CommonData.linearSgramViewYlim = get(app.SgramAxis2, "YLim");
        end

        % Button pushed function: DebugButton
        function DebugButtonPushed(app, event)
            %keyboard
        end

        % Button down function: powerViewAxis
        function powerViewAxisButtonDown(app, event)
            currentPoint = get(app.powerViewAxis,"CurrentPoint");
            %disp(currentPoint)
            switch get(app.UIFigure, "Pointer")
                case 'fleur'
                    app.CommonData.penStatus = 'tmugAdjust';
                    set(app.UIFigure, "Pointer", 'top')
                    segId = app.CommonData.segmentId;
                    set(app.CommonData.segmentHandle(segId), "color", [1 0.8 0], ...
                        "linewidth",4);
                    return;
                case 'arrow'
                    app.CommonData.handleTotal = app.CommonData.handleTotal + 1;
                    app.CommonData.handleId = app.CommonData.handleTotal;
                case 'hand'
            end
            app.CommonData.penStatus = 'tcursordown';
            set(app.UIFigure, "Pointer", 'left')
            id = app.CommonData.handleId;
            set(app.CommonData.foViewHandle(id), "visible","on","value",currentPoint(1,1));
            set(app.CommonData.sgramViewHandle(id), "visible","on","value",currentPoint(1,1));
            set(app.CommonData.powerViewHandle(id), "visible","on","value",currentPoint(1,1));
            drawnow
            app.UNDOButton_2.Enable = "on";
        end

        % Button pushed function: UNDOButton_2
        function UNDOButton_2Pushed(app, event)
            if app.CommonData.handleTotal > 0
                id = app.CommonData.handleTotal;
                set(app.CommonData.foViewHandle(id), "visible","off");
                set(app.CommonData.sgramViewHandle(id), "visible","off");
                set(app.CommonData.powerViewHandle(id), "visible","off");
                set(app.CommonData.segmentHandle(id+1), "visible","off");
                app.CommonData.handleTotal = app.CommonData.handleTotal - 1;
                app.CommonData.handleId = app.CommonData.handleTotal;
                assignSegmentMagHandle(app);
            end
            if app.CommonData.handleTotal == 0
                set(app.CommonData.segmentHandle(1), "visible","off");
                app.UNDOButton_2.Enable = "off";
            end
            drawnow;
        end

        % Value changed function: REditField
        function REditFieldValueChanged(app, event)
            value = app.REditField.Value;
            app.rangeSlider.Value = value;
            baseValue = app.baseSlider.Value;
            %magValue = app.DisplayData.magValue;
            %magValue = app.TmagSlider.Value;
            lineStatus = get(app.CommonData.foHandle, 'UserData');
            fo = lineStatus.latestFo;
            app.DisplayData.fo = fo;
            app.DisplayData.baseValue = baseValue;
            %app.DisplayData.rangeValue = value;
            app.DisplayData.magValue = value;
            updateFoDisplay2(app);
            app.ResetdynamicsButton.Enable = 'on';
            app.PLAYSynthesizedButton.Enable = "off";
        end

        % Value changed function: BEditField
        function BEditFieldValueChanged(app, event)
            value = app.BEditField.Value;
            app.baseSlider.Value = value;
            rangeValue = app.rangeSlider.Value;
            %magValue = app.DisplayData.magValue;
            %magValue = app.TmagSlider.Value;
            lineStatus = get(app.CommonData.foHandle, 'UserData');
            fo = lineStatus.latestFo;
            app.DisplayData.fo = fo;
            app.DisplayData.baseValue = value;
            %app.DisplayData.rangeValue = rangeValue;
            app.DisplayData.magValue = rangeValue;
            updateFoDisplay2(app);
            app.ResetbaseButton.Enable = 'on';
            app.PLAYSynthesizedButton.Enable = "off";
        end

        % Value changed function: VEditField
        function VEditFieldValueChanged(app, event)
            value = app.VEditField.Value;
            app.VTLSlider.Value = value;
            %app.VEditField.Value = value;
            fs = app.CommonData.samplingFrequency;
            sgram = app.CommonData.worldParams.spectrum_parameter.spectrogram;
            fx = (0:size(sgram,1))/size(sgram,1)*fs/2;
            sgramDisp = interp1(fx, [sgram;sgram(end,:)], fx*value, "linear","extrap");
            app.CommonData.currentSpectrogram = sgramDisp(1:end-1,:);
            linear2logSgram(app);
            linear2erbSgram(app);
            sgramDb = 10*log10(sgramDisp(1:end-1,:));
            sgramDb = max(-80, sgramDb - max(sgramDb(:)));
            app.CommonData.linearSgramDb = sgramDb;
            Sgram_scaleButtonGroupSelectionChanged(app, event);
            app.PLAYSynthesizedButton.Enable = 'off';
            app.Reset_VTLButton.Enable = 'on';
            app.SAVEEDITButton.Enable = 'off';
            app.SAVESoundButton.Enable = 'off';
        end

        % Value changing function: VTLSlider
        function VTLSliderValueChanging(app, event)
            changingValue = event.Value;
            app.VEditField.Value = changingValue;
        end

        % Value changed function: TmagEditField
        function TmagEditFieldValueChanged(app, event)
            value = app.TmagEditField.Value;
            app.TmagSlider.Value = value;
            changingValue = value;
            if isfield(app.CommonDat,"previousTslider") % safeguard
                prevoiurValue = app.CommonData.previousTslider;
            else
                prevoiurValue = 1;
                app.CommonData.previousTslider = prevoiurValue;
            end
            xLimOnOriginal = get(app.FoAxis,'Xlim')/prevoiurValue;
            source_parameter = app.CommonData.worldParams.source_parameter;
            txfo = source_parameter.temporal_positions(:);
            tx = app.CommonData.txSignal;
            tt = app.CommonData.worldParams.spectrum_parameter.temporal_positions;
            set(app.FoAxis,'Xlim', xLimOnOriginal * changingValue);
            set(app.CommonData.foHandle, 'XData', txfo * changingValue);
            set(app.CommonData.pwHandle1, 'XData', tt * changingValue);
            set(app.CommonData.pwHandle2, 'XData', tt * changingValue);
            set(app.CommonData.unvoicedHandle, 'XData', tt * changingValue);
            set(app.CommonData.voicedHandle, 'XData', tt * changingValue);
            set(app.CommonData.waveHandle, 'XData', tx * changingValue);
            set(app.CommonData.sgramHandle, 'XData', [txfo(1) txfo(end)] * changingValue);
            app.CommonData.previousTslider = changingValue;
            app.Reset_TmagButton.Enable = 'on';
            app.PLAYSynthesizedButton.Enable = 'off';
            drawnow limitrate
            app.SAVEEDITButton.Enable = 'off';
            app.SAVESoundButton.Enable = 'off';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {660, 660};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {263, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SAVElogfileCheckBox = uicheckbox(app.LeftPanel);
            app.SAVElogfileCheckBox.ValueChangedFcn = createCallbackFcn(app, @SAVElogfileCheckBoxValueChanged, true);
            app.SAVElogfileCheckBox.Text = 'SAVE logfile';
            app.READParameterButton = uibutton(app.LeftPanel, 'push');
            app.READParameterButton.ButtonPushedFcn = createCallbackFcn(app, @READParameterButtonPushed, true);
            app.READParameterButton.Text = 'READ Parameter';
            app.QUITButton = uibutton(app.LeftPanel, 'push');
            app.QUITButton.ButtonPushedFcn = createCallbackFcn(app, @QUITButtonPushed, true);
            app.QUITButton.Enable = 'off';
            app.QUITButton.Visible = 'off';
            app.QUITButton.Text = 'QUIT';
            app.SYNTHESISButton = uibutton(app.LeftPanel, 'push');
            app.SYNTHESISButton.ButtonPushedFcn = createCallbackFcn(app, @SYNTHESISButtonPushed, true);
            app.SYNTHESISButton.Enable = 'off';
            app.SYNTHESISButton.Text = 'SYNTHESIS';
            app.PLAYSynthesizedButton = uibutton(app.LeftPanel, 'push');
            app.PLAYSynthesizedButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYSynthesizedButtonPushed, true);
            app.PLAYSynthesizedButton.Enable = 'off';
            app.PLAYSynthesizedButton.Text = ' PLAY Synthesized';
            app.SAVEEDITButton = uibutton(app.LeftPanel, 'push');
            app.SAVEEDITButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEEDITButtonPushed, true);
            app.SAVEEDITButton.Enable = 'off';
            app.SAVEEDITButton.Text = 'SAVE EDIT';
            app.PLAYOriginalButton = uibutton(app.LeftPanel, 'push');
            app.PLAYOriginalButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYOriginalButtonPushed, true);
            app.PLAYOriginalButton.Enable = 'off';
            app.PLAYOriginalButton.Text = 'PLAY Original';
            app.WORLDManipulatorLabel = uilabel(app.LeftPanel);
            app.WORLDManipulatorLabel.Text = ' WORLD Manipulator';
            app.SAVESoundButton = uibutton(app.LeftPanel, 'push');
            app.SAVESoundButton.ButtonPushedFcn = createCallbackFcn(app, @SAVESoundButtonPushed, true);
            app.SAVESoundButton.Enable = 'off';
            app.SAVESoundButton.Text = 'SAVE Sound';
            app.ChangeaudiooutdeviceButton = uibutton(app.LeftPanel, 'push');
            app.ChangeaudiooutdeviceButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeaudiooutdeviceButtonPushed, true);
            app.ChangeaudiooutdeviceButton.Enable = 'off';
            app.ChangeaudiooutdeviceButton.Visible = 'off';
            app.ChangeaudiooutdeviceButton.Text = 'Change audio out device';
            app.vaxismanipulatorPanel = uipanel(app.LeftPanel);
            app.vaxismanipulatorPanel.Title = 'v-axis manipulator';
            app.rangeSliderLabel = uilabel(app.vaxismanipulatorPanel);
            app.rangeSliderLabel.Text = 'range';
            app.rangeSlider = uislider(app.vaxismanipulatorPanel);
            app.rangeSlider.Limits = [-1 2];
            app.rangeSlider.ValueChangedFcn = createCallbackFcn(app, @rangeSliderValueChanged, true);
            app.rangeSlider.ValueChangingFcn = createCallbackFcn(app, @rangeSliderValueChanging, true);
            app.rangeSlider.Value = 1;
            app.ResetdynamicsButton = uibutton(app.vaxismanipulatorPanel, 'push');
            app.ResetdynamicsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetdynamicsButtonPushed, true);
            app.ResetdynamicsButton.Enable = 'off';
            app.ResetdynamicsButton.Text = 'Reset dynamics';
            app.baseSliderLabel = uilabel(app.vaxismanipulatorPanel);
            app.baseSliderLabel.Text = 'base';
            app.baseSlider = uislider(app.vaxismanipulatorPanel);
            app.baseSlider.Limits = [0.4 3];
            app.baseSlider.MajorTicks = [0.4 0.5 1 1.5 2 2.5 3 5];
            app.baseSlider.ValueChangedFcn = createCallbackFcn(app, @baseSliderValueChanged, true);
            app.baseSlider.ValueChangingFcn = createCallbackFcn(app, @baseSliderValueChanging, true);
            app.baseSlider.Value = 1;
            app.ResetbaseButton = uibutton(app.vaxismanipulatorPanel, 'push');
            app.ResetbaseButton.ButtonPushedFcn = createCallbackFcn(app, @ResetbaseButtonPushed, true);
            app.ResetbaseButton.Enable = 'off';
            app.ResetbaseButton.Text = 'Reset base';
            app.VTLLabel = uilabel(app.vaxismanipulatorPanel);
            app.VTLLabel.Text = 'VTL';
            app.VTLSlider = uislider(app.vaxismanipulatorPanel);
            app.VTLSlider.Limits = [0.4 2];
            app.VTLSlider.ValueChangedFcn = createCallbackFcn(app, @VTLSliderValueChanged, true);
            app.VTLSlider.ValueChangingFcn = createCallbackFcn(app, @VTLSliderValueChanging, true);
            app.VTLSlider.Value = 1;
            app.Reset_VTLButton = uibutton(app.vaxismanipulatorPanel, 'push');
            app.Reset_VTLButton.ButtonPushedFcn = createCallbackFcn(app, @Reset_VTLButtonPushed, true);
            app.Reset_VTLButton.Enable = 'off';
            app.Reset_VTLButton.Text = 'Reset_VTL';
            app.REditFieldLabel = uilabel(app.vaxismanipulatorPanel);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.vaxismanipulatorPanel, 'numeric');
            app.REditField.ValueChangedFcn = createCallbackFcn(app, @REditFieldValueChanged, true);
            app.REditField.Value = 1;
            app.BEditFieldLabel = uilabel(app.vaxismanipulatorPanel);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.vaxismanipulatorPanel, 'numeric');
            app.BEditField.ValueChangedFcn = createCallbackFcn(app, @BEditFieldValueChanged, true);
            app.BEditField.Value = 1;
            app.VEditFieldLabel = uilabel(app.vaxismanipulatorPanel);
            app.VEditFieldLabel.Text = 'V';
            app.VEditField = uieditfield(app.vaxismanipulatorPanel, 'numeric');
            app.VEditField.ValueChangedFcn = createCallbackFcn(app, @VEditFieldValueChanged, true);
            app.VEditField.Value = 1;
            app.RESTOREEDITButton = uibutton(app.LeftPanel, 'push');
            app.RESTOREEDITButton.ButtonPushedFcn = createCallbackFcn(app, @RESTOREEDITButtonPushed, true);
            app.RESTOREEDITButton.Text = 'RESTORE EDIT';
            app.SAVEParameterButton = uibutton(app.LeftPanel, 'push');
            app.SAVEParameterButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEParameterButtonPushed, true);
            app.SAVEParameterButton.Enable = 'off';
            app.SAVEParameterButton.Text = 'SAVE Parameter';
            app.DebugButton = uibutton(app.LeftPanel, 'push');
            app.DebugButton.ButtonPushedFcn = createCallbackFcn(app, @DebugButtonPushed, true);
            app.DebugButton.Text = 'Debug';
            app.RightPanel = uipanel(app.GridLayout);
            app.FoAxis = uiaxes(app.RightPanel);
            xlabel(app.FoAxis, 'time (s)')
            ylabel(app.FoAxis, 'Y')
            zlabel(app.FoAxis, 'Z')
            app.FoAxis.ButtonDownFcn = createCallbackFcn(app, @FoAxisButtonDown, true);
            app.powerViewAxis = uiaxes(app.RightPanel);
            ylabel(app.powerViewAxis, 'Y')
            zlabel(app.powerViewAxis, 'Z')
            app.powerViewAxis.ButtonDownFcn = createCallbackFcn(app, @powerViewAxisButtonDown, true);
            app.SgramAxis2 = uiaxes(app.RightPanel);
            xlabel(app.SgramAxis2, 'time (s)')
            ylabel(app.SgramAxis2, 'Y')
            zlabel(app.SgramAxis2, 'Z')
            app.pathLabel = uilabel(app.RightPanel);
            app.fileLabel = uilabel(app.RightPanel);
            app.Shift_scaleButtonGroup = uibuttongroup(app.RightPanel);
            app.Shift_scaleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Shift_scaleButtonGroupSelectionChanged, true);
            app.Shift_scaleButtonGroup.Title = 'Shift_scale';
            app.logButton = uiradiobutton(app.Shift_scaleButtonGroup);
            app.logButton.Text = 'log';
            app.logButton.Value = true;
            app.melButton = uiradiobutton(app.Shift_scaleButtonGroup);
            app.melButton.Text = 'mel';
            app.mel2Button = uiradiobutton(app.Shift_scaleButtonGroup);
            app.mel2Button.Enable = 'off';
            app.mel2Button.Visible = 'off';
            app.mel2Button.Text = 'mel2';
            app.Reset_foButton = uibutton(app.RightPanel, 'push');
            app.Reset_foButton.ButtonPushedFcn = createCallbackFcn(app, @Reset_foButtonPushed, true);
            app.Reset_foButton.Enable = 'off';
            app.Reset_foButton.Text = 'Reset_fo';
            app.TmagSliderLabel = uilabel(app.RightPanel);
            app.TmagSliderLabel.Text = 'Tmag';
            app.TmagSlider = uislider(app.RightPanel);
            app.TmagSlider.Limits = [0.2 4];
            app.TmagSlider.MajorTicks = [0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2 2.2 2.4 2.6 2.8 3 3.2 3.4 3.6 3.8 4];
            app.TmagSlider.ValueChangedFcn = createCallbackFcn(app, @TmagSliderValueChanged, true);
            app.TmagSlider.ValueChangingFcn = createCallbackFcn(app, @TmagSliderValueChanging, true);
            app.TmagSlider.Value = 1;
            app.Reset_TmagButton = uibutton(app.RightPanel, 'push');
            app.Reset_TmagButton.ButtonPushedFcn = createCallbackFcn(app, @Reset_TmagButtonPushed, true);
            app.Reset_TmagButton.Enable = 'off';
            app.Reset_TmagButton.Text = 'Reset_Tmag';
            app.Sgram_scaleButtonGroup = uibuttongroup(app.RightPanel);
            app.Sgram_scaleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Sgram_scaleButtonGroupSelectionChanged, true);
            app.Sgram_scaleButtonGroup.Title = 'Sgram_scale';
            app.linearButton = uiradiobutton(app.Sgram_scaleButtonGroup);
            app.linearButton.Text = 'linear';
            app.linearButton.Value = true;
            app.logButton_2 = uiradiobutton(app.Sgram_scaleButtonGroup);
            app.logButton_2.Text = 'log';
            app.ERB_NButton = uiradiobutton(app.Sgram_scaleButtonGroup);
            app.ERB_NButton.Text = 'ERB_N';
            app.UNDOButton = uibutton(app.RightPanel, 'push');
            app.UNDOButton.ButtonPushedFcn = createCallbackFcn(app, @UNDOButtonPushed, true);
            app.UNDOButton.Enable = 'off';
            app.UNDOButton.Text = 'UNDO';
            app.REDOButton = uibutton(app.RightPanel, 'push');
            app.REDOButton.ButtonPushedFcn = createCallbackFcn(app, @REDOButtonPushed, true);
            app.REDOButton.Enable = 'off';
            app.REDOButton.Text = 'REDO';
            app.FreqlimitButtonGroup = uibuttongroup(app.RightPanel);
            app.FreqlimitButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FreqlimitButtonGroupSelectionChanged, true);
            app.FreqlimitButtonGroup.Title = 'Freq. limit';
            app.FullButton = uiradiobutton(app.FreqlimitButtonGroup);
            app.FullButton.Text = 'Full';
            app.FullButton.Value = true;
            app.kHzButton = uiradiobutton(app.FreqlimitButtonGroup);
            app.kHzButton.Text = '8kHz';
            app.kHzButton_2 = uiradiobutton(app.FreqlimitButtonGroup);
            app.kHzButton_2.Text = '4kHz';
            app.kHzButton_3 = uiradiobutton(app.FreqlimitButtonGroup);
            app.kHzButton_3.Text = '2kHz';
            app.kHzButton_4 = uiradiobutton(app.FreqlimitButtonGroup);
            app.kHzButton_4.Text = '1kHz';
            app.kHzButton_5 = uiradiobutton(app.FreqlimitButtonGroup);
            app.kHzButton_5.Text = '6kHz';
            app.ColormapButtonGroup = uibuttongroup(app.RightPanel);
            app.ColormapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColormapButtonGroupSelectionChanged, true);
            app.ColormapButtonGroup.Title = 'Color map';
            app.peruraButton = uiradiobutton(app.ColormapButtonGroup);
            app.peruraButton.Text = 'perura';
            app.turboButton = uiradiobutton(app.ColormapButtonGroup);
            app.turboButton.Text = 'turbo';
            app.turboButton.Value = true;
            app.grayButton = uiradiobutton(app.ColormapButtonGroup);
            app.grayButton.Text = 'gray';
            app.UNDOButton_2 = uibutton(app.RightPanel, 'push');
            app.UNDOButton_2.ButtonPushedFcn = createCallbackFcn(app, @UNDOButton_2Pushed, true);
            app.UNDOButton_2.Enable = 'off';
            app.UNDOButton_2.Text = 'UNDO';
            app.TmagEditFieldLabel = uilabel(app.RightPanel);
            app.TmagEditFieldLabel.Text = 'Tmag';
            app.TmagEditField = uieditfield(app.RightPanel, 'numeric');
            app.TmagEditField.Limits = [0.2 4];
            app.TmagEditField.ValueDisplayFormat = '%6.3g';
            app.TmagEditField.ValueChangedFcn = createCallbackFcn(app, @TmagEditFieldValueChanged, true);
            app.TmagEditField.Value = 0.2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = worldManipulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end