classdef worldManipulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        ChangeaudiooutdeviceButton  matlab.ui.control.Button
        RESETButton                 matlab.ui.control.Button
        UNDOButton                  matlab.ui.control.Button
        SAVESoundButton             matlab.ui.control.Button
        WORLDManipulatorLabel       matlab.ui.control.Label
        PLAYOriginalButton          matlab.ui.control.Button
        SAVEParameterButton         matlab.ui.control.Button
        REPLAYButton                matlab.ui.control.Button
        SYNTHESISButton             matlab.ui.control.Button
        QUITButton                  matlab.ui.control.Button
        READParameterButton         matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        Sgram_scaleButtonGroup      matlab.ui.container.ButtonGroup
        ERB_NButton                 matlab.ui.control.RadioButton
        logButton_2                 matlab.ui.control.RadioButton
        linearButton                matlab.ui.control.RadioButton
        Reset_VTLButton             matlab.ui.control.Button
        Reset_TmagButton            matlab.ui.control.Button
        VTLSlider                   matlab.ui.control.Slider
        VTLLabel                    matlab.ui.control.Label
        TmagSlider                  matlab.ui.control.Slider
        TmagSliderLabel             matlab.ui.control.Label
        Reset_foButton              matlab.ui.control.Button
        Shift_scaleButtonGroup      matlab.ui.container.ButtonGroup
        mel2Button                  matlab.ui.control.RadioButton
        melButton                   matlab.ui.control.RadioButton
        logButton                   matlab.ui.control.RadioButton
        fileLabel                   matlab.ui.control.Label
        pathLabel                   matlab.ui.control.Label
        SgramAxis2                  matlab.ui.control.UIAxes
        powerViewAxis               matlab.ui.control.UIAxes
        FoAxis                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        CommonData % Common data and parameters
        %SgramAxis2 % graphic axis for sgram
    end

    methods (Access = private)


        function results = initializeGraphics(app)
            %--- Spectrogram
            %app.SgramAxis.Visible = 'off';
            %app.SgramAxis2 = uiaxes(app.RightPanel,"Position", [149,330,710,337]);
            app.SgramAxis2.Visible = 'on';
            sgram = app.CommonData.worldParams.spectrum_parameter.spectrogram;
            tt = app.CommonData.worldParams.spectrum_parameter.temporal_positions;
            fs = app.CommonData.samplingFrequency;
            app.CommonData.currentSpectrogram = sgram;
            linear2logSgram(app);
            linear2erbSgram(app);
            sgramDb = 10*log10(sgram);
            sgramDb = max(-80, sgramDb - max(sgramDb(:)));
            app.CommonData.linearSgramDb = sgramDb;
            sgramHandle = imagesc(app.SgramAxis2,[tt(1) tt(end)], [0 fs/2]/1000, sgramDb);
            axis(app.SgramAxis2, 'xy');
            %set(app.SgramAxis2, 'xlim', [tt(1) tt(end)], ...
            %    'ylim', [0 fs/2]/1000);
            ylabel(app.SgramAxis2, 'frequency (kHz)')
            colormap(app.SgramAxis2, "turbo");
            app.CommonData.linearyTickLoc = get(app.SgramAxis2, 'YTick');
            app.CommonData.linearyTickLabel = get(app.SgramAxis2, 'YTickLabel');
            app.linearButton.Value = 1;
            app.CommonData.sgramHandle = sgramHandle;
            app.CommonData.sgraphToolBar = axtoolbar(app.SgramAxis2,{'pan','zoomin','zoomout'});
            axis(app.SgramAxis2, [tt(1) tt(end) [0 fs/2]/1000])
            app.CommonData.linearYlim = get(app.SgramAxis2, 'YLim');
            disableDefaultInteractivity(app.SgramAxis2);
            %set(app.CommonData.sgraphToolBar.Children(4), 'ButtonDownFcn',@resetSgramView, ...
            %    'UserData', app);
            %--- fundamental frequency
            app.FoAxis.Visible = 'on';
            source_parameter = app.CommonData.worldParams.source_parameter;
            fo = source_parameter.f0(:);
            txfo = source_parameter.temporal_positions(:);
            yTickFreq = [10 20 30 40 50 60 70 80 90 ...
                100 200 300 400 500 600 700 800 900 1000 1500 2000];
            yTiickLabel = {'.01', '.02', '.03', ' ', '.05', ' ', '.07', ' ', ' ', ...
                '.1', '.2', '.3', ' ', '.5', ' ', ' .7', ' ', ' ', ...
                '1', ' ', '2'};
            selectedButton = app.Shift_scaleButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'log'
                    foHandle = plot(app.FoAxis, txfo, log(fo), 'LineWidth', 0.5);
                    app.FoAxis.Tag = 'log';
                    axis(app.FoAxis, [txfo(1) txfo(end) log([40 1000])])
                    app.CommonData.foHandle = foHandle;
                    set(app.FoAxis, 'ytick', log(yTickFreq), 'ytickLabel', yTiickLabel);
                case 'mel'
                    foHandle = plot(app.FoAxis, txfo, hz2mel(fo), 'LineWidth', 0.5);
                    app.FoAxis.Tag = 'mel';
                    axis(app.FoAxis, [txfo(1) txfo(end) hz2mel([40 1000])])
                    app.CommonData.foHandle = foHandle;
                    set(app.FoAxis, 'ytick', hz2mel(yTickFreq), 'ytickLabel', yTiickLabel);
            end
            app.CommonData.yTickFreq = yTickFreq;
            app.CommonData.yTiickLabel = yTiickLabel;
            ylabel(app.FoAxis, 'frequency (x 100 Hz)');
            app.CommonData.foToolBar = axtoolbar(app.FoAxis,{'pan','zoomin','zoomout','restoreview'});
            %app.CommonData.foToolBar.Visible = 'off';
            set(app.CommonData.foHandle, 'ButtonDownFcn', @lineCallback)
            lineStatus = struct;
            lineStatus.originalYdata = get(app.CommonData.foHandle, 'YData');
            lineStatus.latestFo = fo;
            lineStatus.scale = 'log';
            lineStatus.replayHandle = app.REPLAYButton;
            lineStatus.axisToolbar = app.CommonData.foToolBar;
            set(app.CommonData.foHandle,'UserData', lineStatus);
            set(app.UIFigure,'KeyPressFcn',@lineButtonUpFcn);
            disableDefaultInteractivity(app.FoAxis);
            app.CommonData.previousTslider = 1;
            grid(app.FoAxis, 'on')
            %set(app.FoAxis, 'yscale','log')

            %app.CommonData.foHandle = foHandle;
            %--- waveform monitor
            app.powerViewAxis.Visible = 'on';
            fx = (0:size(sgram,1)-1)/size(sgram,1)*fs/2;
            x = app.CommonData.x;
            vuv = app.CommonData.worldParams.source_parameter.vuv;
            app.CommonData.vuv = vuv;
            tx = (1:length(x))'/fs;
            sgram(1,:) = sgram(2,:);
            highPower = sum(sgram(fx>2000,:));
            lowPower = sum(sgram(fx<=2000,:));
            maxLvl = max([10*log10(lowPower(:)); 10*log10(highPower(:))]);
            dispX = x/max(abs(x))*30 + maxLvl -35;
            app.CommonData.waveHandle = plot(app.powerViewAxis, tx, dispX, ...
                "Color", [0.7 0.7 0.7], "LineWidth", 3);
            hold(app.powerViewAxis,"on");
            app.CommonData.unvoicedHandle = plot(app.powerViewAxis, ...
                tt, (maxLvl+4) + tt*0, "LineWidth",2 , "Color", [0 0 0]);
            app.CommonData.unvoicedHandle = plot(app.powerViewAxis, ...
                tt, (maxLvl+4) * (vuv * 1000 - 999), "LineWidth",3 , "Color", [0 1 0]);
            app.CommonData.pwHandle1 = plot(app.powerViewAxis, ...
                tt, 10*log10(lowPower),"LineWidth",2,"Color",[0 0.4470 0.7410]);
            app.CommonData.pwHandle2 = plot(app.powerViewAxis, ...
                tt, 10*log10(highPower),"LineWidth",2,"Color",[0.8500 0.3250  0.0980]);
            set(app.powerViewAxis, 'XTickLabel', '');
            ylabel(app.powerViewAxis, 'level (dB)')
            grid(app.powerViewAxis,"on");
            hold(app.powerViewAxis,"off");
            axis(app.powerViewAxis, [tt(1) tt(end) maxLvl + [-70 5]]);
            disableDefaultInteractivity(app.powerViewAxis);
            app.CommonData.waveHandle = axtoolbar(app.powerViewAxis,{'pan','restoreview'});
            %app.powerViewAxis.Interactions = rulerPanInteraction('Dimensions','x');
            %app.CommonData.waveHandle.Visible = 'off';
            %--- GUI parts setting
            linkaxes([app.SgramAxis2  app.powerViewAxis app.FoAxis],'x')
            app.SYNTHESISButton.Enable = 'on';
            app.Reset_foButton.Enable = 'on';
            %set(app.FoAxis, 'yscale','log');
            results = 'ok';
        end

        function results = linear2logSgram(app)
            %sgram = app.CommonData.worldParams.spectrum_parameter.spectrogram;
            sgram = app.CommonData.currentSpectrogram;
            %tt = app.CommonData.worldParams.spectrum_parameter.temporal_positions;
            fs = app.CommonData.samplingFrequency;
            fx = (0:size(sgram,1)-1)'/size(sgram,1)*fs/2;
            fx(1) = fx(2)/2;
            logFqLimit = log([100 fs/2]);
            logFx = (1:length(fx))/length(fx)*diff(logFqLimit) + logFqLimit(1);
            yTkckFqLog = log([100 200 300 500 700 1000 2000 3000 5000 7000 10000 20000]);
            yTickLabel = {'.1', '.2', '.3', '.5', '.7', '1', '2', '3', '5', '7', '10', '20'};
            yTickLoc = interp1(logFx,logFx,yTkckFqLog,"linear","extrap");
            sgramLogFx = interp1(log(fx),sgram,logFx,'linear','extrap');
            sgramDb = 10*log10(sgramLogFx);
            sgramDb = max(-80, sgramDb - max(sgramDb(:)));
            app.CommonData.logSgramDb = sgramDb;
            app.CommonData.logyTickLoc = yTickLoc;
            app.CommonData.logyTickLabel = yTickLabel;
            app.CommonData.logYlim = logFqLimit;
            results = 1;
        end

        function results = linear2erbSgram(app)
            %sgram = app.CommonData.worldParams.spectrum_parameter.spectrogram;
            sgram = app.CommonData.currentSpectrogram;
            %tt = app.CommonData.worldParams.spectrum_parameter.temporal_positions;
            fs = app.CommonData.samplingFrequency;
            fx = (0:size(sgram,1)-1)/size(sgram,1)*fs/2;
            fx(1) = fx(2)/2;
            erbFqLimit = hz2erb([100 fs/2]);
            erbFx = (1:length(fx))/length(fx)*diff(erbFqLimit) + erbFqLimit(1);
            yTkckFqErb = hz2erb([100 300 500 700 1000 2000 4000 8000 10000 20000]);
            yTickLabel = {'.1', '.3', '.5', '.7', '1', '2', '4', '8', '10', '20'};
            yTickLoc = interp1(erbFx,erbFx,yTkckFqErb,"linear","extrap");
            sgramErbFx = interp1(hz2erb(fx),sgram,erbFx,'linear','extrap');
            sgramDb = 10*log10(sgramErbFx);
            sgramDb = max(-80, sgramDb - max(sgramDb(:)));
            app.CommonData.erbSgramDb = sgramDb;
            app.CommonData.erbyTickLoc = yTickLoc;
            app.CommonData.erbyTickLabel = yTickLabel;
            app.CommonData.erbYlim = erbFqLimit;
            results = 1;
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            rootInfo = groot;
            screenSize = get(rootInfo, "ScreenSize");
            width = min(1095, screenSize(3) - 21);
            hight = min(856, screenSize(4) - 21 - 30);
            set(app.UIFigure, "Position", [20 20 width hight]);
            fs = 44100;
            tt = (1:fs/2)'/fs;
            x = 0.2*sin(2*pi*tt*440);
            app.CommonData.deviceID = -1;
            app.CommonData.x = x;
            app.CommonData.samplingFrequency = fs;
            app.CommonData.player = audioplayer(x, fs, 24, app.CommonData.deviceID);
            play(app.CommonData.player);
            app.SAVEParameterButton.Enable = 'off';
            app.SAVESoundButton.Enable = 'off';
        end

        % Button pushed function: QUITButton
        function QUITButtonPushed(app, event)
            close(app.UIFigure);
        end

        % Button pushed function: READParameterButton
        function READParameterButtonPushed(app, event)
            [paramFile, paramPath] = uigetfile({'*.mat'},"Select parameter file:");
            tmp = load([paramPath '/' paramFile]);
            app.fileLabel.Text = paramFile;
            app.pathLabel.Text = paramPath;
            app.CommonData.worldParams = tmp.worldParameter;
            app.CommonData.samplingFrequency = tmp.worldParameter.samplingFrequency;
            app.CommonData.x = tmp.worldParameter.signal;
            x = app.CommonData.x;
            fs = tmp.worldParameter.samplingFrequency;
            app.CommonData.player = audioplayer(x, fs, 24, app.CommonData.deviceID);
            initializeGraphics(app);
            app.PLAYOriginalButton.Enable = 'on';
            app.REPLAYButton.Enable = 'off';
            app.SAVEParameterButton.Enable = 'off';
            app.SAVESoundButton.Enable = 'off';
        end

        % Button pushed function: ChangeaudiooutdeviceButton
        function ChangeaudiooutdeviceButtonPushed(app, event)
            fs = app.CommonData.samplingFrequency;
            tt = (1:fs/2)'/fs;
            x = 0.2*sin(2*pi*tt*440);
            info = audiodevinfo;
            T = struct2table(info.output);
            [id, tf] = listdlg("ListString", T.Name, "SelectionMode","single", ...
                "PromptString","Select audio output","ListSize",[250, 150]);
            if tf
                app.CommonData.deviceID = info.output(id).ID;
                app.CommonData.player = audioplayer(x, fs, 24, app.CommonData.deviceID);
                play(app.CommonData.player)
            else
                disp('Audio output devide selection is cancelled.')
            end
        end

        % Button pushed function: PLAYOriginalButton
        function PLAYOriginalButtonPushed(app, event)
            xLim = app.SgramAxis2.XLim;
            fs = app.CommonData.samplingFrequency;
            x = app.CommonData.x;
            nData = length(x);
            switch app.TmagSlider.Value
                case 1
                    xLimSample = max(1, min(nData, 1+round(xLim * fs)));
                otherwise
                    xLimSample = max(1, min(nData, 1+round(xLim * fs / app.TmagSlider.Value)));
            end
            span = xLimSample(1):xLimSample(end);
            app.CommonData.audioPlayer = ...
                audioplayer(x(span)/max(abs(x(span))), fs, 24,...
                app.CommonData.deviceID);
            play(app.CommonData.audioPlayer);
        end

        % Selection changed function: Shift_scaleButtonGroup
        function Shift_scaleButtonGroupSelectionChanged(app, event)
            selectedButton = app.Shift_scaleButtonGroup.SelectedObject;
            lineStatus = get(app.CommonData.foHandle, 'UserData');
            fo = lineStatus.latestFo;
            app.FoAxis.Tag = selectedButton.Text;
            switch selectedButton.Text
                case 'log'
                    set(app.CommonData.foHandle, 'YData', log(fo));
                    set(app.FoAxis, 'YLim', log([40 1000]))
                    set(app.FoAxis, 'ytick', log(app.CommonData.yTickFreq));
                    lineStatus.scale = 'log';
                case 'mel'
                    set(app.CommonData.foHandle, 'YData', hz2mel(fo));
                    set(app.FoAxis, 'YLim', hz2mel([40 1000]))
                    set(app.FoAxis, 'ytick', hz2mel(app.CommonData.yTickFreq));
                    lineStatus.scale = 'mel';
            end
            set(app.CommonData.foHandle, 'UserData',lineStatus);
        end

        % Button pushed function: SYNTHESISButton
        function SYNTHESISButtonPushed(app, event)
            %span = app.CommonData.span;
            xLim = app.SgramAxis2.XLim;
            fs = app.CommonData.samplingFrequency;
            source_parameter = app.CommonData.worldParams.source_parameter;
            yData = get(app.CommonData.foHandle, 'YData');
            lineStatus = get(app.CommonData.foHandle, 'UserData');
            switch lineStatus.scale
                case 'log'
                    tmp = exp(yData(:));
                    tmp(isnan(tmp)) = 0;
            source_parameter.f0 = tmp;
                case 'mel'
                    source_parameter.f0 = mel2hz(yData(:));
            end
            spectrum_parameter = app.CommonData.worldParams.spectrum_parameter;
            %source_parameter = app.CommonData.source_parameter;
            switch app.TmagSlider.Value
                case 1
                otherwise
                    spectrum_parameter.temporal_positions = ...
                        spectrum_parameter.temporal_positions * app.TmagSlider.Value;
                    source_parameter.temporal_positions = ...
                        source_parameter.temporal_positions * app.TmagSlider.Value;
            end
            switch app.VTLSlider.Value
                case 1
                otherwise
                    value = app.VTLSlider.Value;
                    sgram = app.CommonData.worldParams.spectrum_parameter.spectrogram;
                    apGram = app.CommonData.worldParams.source_parameter.aperiodicity;
                    fx = (0:size(sgram,1))/size(sgram,1)*fs/2;
                    sgramDisp = interp1(fx, [sgram;sgram(end,:)], fx*value, "linear","extrap");
                    spectrum_parameter.spectrogram = sgramDisp(1:end-1,:);
                    apGramDisp = interp1(fx, [apGram;apGram(end,:)], fx*value, "linear","extrap");
                    source_parameter.aperiodicity = apGramDisp(1:end-1,:);
            end
            xSynth = Synthesis(source_parameter, spectrum_parameter);
            nData = length(xSynth);
            xLimSample = max(1, min(nData, 1+round(xLim * fs)));
            app.CommonData.player = audioplayer(xSynth(xLimSample(1):xLimSample(end)), ...
                fs, 24, app.CommonData.deviceID);
            app.CommonData.synthesizedSignal = xSynth;
            app.UIFigure.Tag = '';
            app.REPLAYButton.Enable = 'on';
            app.SAVEParameterButton.Enable = 'on';
            app.SAVESoundButton.Enable = 'off';
            play(app.CommonData.player);
        end

        % Button pushed function: Reset_foButton
        function Reset_foButtonPushed(app, event)
            fo = app.CommonData.worldParams.source_parameter.f0(:);
            selectedButton = app.Shift_scaleButtonGroup.SelectedObject;
            lineStatus = get(app.CommonData.foHandle, 'UserData');
            switch selectedButton.Text
                case 'log'
            set(app.CommonData.foHandle, 'YData', log(fo));
            lineStatus.scale = 'log';
                case 'mel'
                    set(app.CommonData.foHandle, 'YData', hz2mel(fo));
                    lineStatus.scale = 'mel';
            end
            lineStatus.latestFo = fo;
            set(app.CommonData.foHandle, 'UserData',lineStatus);
            app.REPLAYButton.Enable = 'off';
            drawnow;
            app.SAVEParameterButton.Enable = 'off';
            app.SAVESoundButton.Enable = 'off';
        end

        % Value changing function: TmagSlider
        function TmagSliderValueChanging(app, event)
            changingValue = event.Value;
            prevoiurValue = app.CommonData.previousTslider;
            xLimOnOriginal = get(app.FoAxis,'Xlim')/prevoiurValue;
            source_parameter = app.CommonData.worldParams.source_parameter;
            txfo = source_parameter.temporal_positions(:);
            tt = app.CommonData.worldParams.spectrum_parameter.temporal_positions;
            set(app.FoAxis,'Xlim', xLimOnOriginal * changingValue);
            set(app.CommonData.foHandle, 'XData', txfo * changingValue);
            set(app.CommonData.pwHandle1, 'XData', tt * changingValue);
            set(app.CommonData.pwHandle2, 'XData', tt * changingValue);
            set(app.CommonData.sgramHandle, 'XData', [txfo(1) txfo(end)] * changingValue);
            app.CommonData.previousTslider = changingValue;
            app.Reset_TmagButton.Enable = 'on';
            app.REPLAYButton.Enable = 'off';
            drawnow limitrate
            app.SAVEParameterButton.Enable = 'off';
            app.SAVESoundButton.Enable = 'off';
        end

        % Button pushed function: Reset_TmagButton
        function Reset_TmagButtonPushed(app, event)
            source_parameter = app.CommonData.worldParams.source_parameter;
            txfo = source_parameter.temporal_positions(:);
            tt = app.CommonData.worldParams.spectrum_parameter.temporal_positions;
            set(app.FoAxis,'Xlim', [txfo(1) txfo(end)]);
            set(app.CommonData.foHandle, 'XData', txfo);
            set(app.CommonData.sgramHandle, 'XData', [txfo(1) txfo(end)]);
            set(app.CommonData.pwHandle1, 'XData', tt);
            set(app.CommonData.pwHandle2, 'XData', tt);
            app.TmagSlider.Value = 1;
            app.CommonData.previousTslider = 1;
            app.Reset_TmagButton.Enable = 'off';
            app.REPLAYButton.Enable = 'off';
            drawnow
            app.SAVEParameterButton.Enable = 'off';
            app.SAVESoundButton.Enable = 'off';
        end

        % Button pushed function: REPLAYButton
        function REPLAYButtonPushed(app, event)
            xLim = app.SgramAxis2.XLim;
            fs = app.CommonData.samplingFrequency;
            x = app.CommonData.synthesizedSignal;
            nData = length(x);
            xLimSample = max(1, min(nData, 1+round(xLim * fs)));
            span = xLimSample(1):xLimSample(end);
            app.CommonData.audioPlayer = ...
                audioplayer(x(span)/max(abs(x(span))), fs, 24,...
                app.CommonData.deviceID);
            play(app.CommonData.audioPlayer);
        end

        % Value changed function: VTLSlider
        function VTLSliderValueChanged(app, event)
            value = app.VTLSlider.Value;
            fs = app.CommonData.samplingFrequency;
            sgram = app.CommonData.worldParams.spectrum_parameter.spectrogram;
            fx = (0:size(sgram,1))/size(sgram,1)*fs/2;
            sgramDisp = interp1(fx, [sgram;sgram(end,:)], fx*value, "linear","extrap");
            app.CommonData.currentSpectrogram = sgramDisp(1:end-1,:);
            linear2logSgram(app);
            linear2erbSgram(app);
            sgramDb = 10*log10(sgramDisp(1:end-1,:));
            sgramDb = max(-80, sgramDb - max(sgramDb(:)));
            app.CommonData.linearSgramDb = sgramDb;
            %set(app.CommonData.sgramHandle, 'CData', sgramDb);
            Sgram_scaleButtonGroupSelectionChanged(app, event);
            app.REPLAYButton.Enable = 'off';
            app.Reset_VTLButton.Enable = 'on';
            app.SAVEParameterButton.Enable = 'off';
            app.SAVESoundButton.Enable = 'off';
        end

        % Button pushed function: Reset_VTLButton
        function Reset_VTLButtonPushed(app, event)
            sgram = app.CommonData.worldParams.spectrum_parameter.spectrogram;
            app.CommonData.currentSpectrogram = sgram;
            linear2logSgram(app);
            linear2erbSgram(app);
            sgramDb = 10*log10(sgram);
            sgramDb = max(-80, sgramDb - max(sgramDb(:)));
            app.CommonData.linearSgramDb = sgramDb;
            %set(app.CommonData.sgramHandle, 'CData', sgramDb);
            Sgram_scaleButtonGroupSelectionChanged(app, event);
            app.VTLSlider.Value = 1;
            app.Reset_VTLButton.Enable = 'off';
            app.REPLAYButton.Enable = 'off';
            app.SAVEParameterButton.Enable = 'off';
            app.SAVESoundButton.Enable = 'off';
        end

        % Selection changed function: Sgram_scaleButtonGroup
        function Sgram_scaleButtonGroupSelectionChanged(app, event)
            selectedButton = app.Sgram_scaleButtonGroup.SelectedObject;
            %tt = app.CommonData.worldParams.spectrum_parameter.temporal_positions;
            %fs = app.CommonData.samplingFrequency;
            switch selectedButton.Text
                case 'ERB_N'
                    set(app.CommonData.sgramHandle, 'CData', app.CommonData.erbSgramDb);
                    set(app.CommonData.sgramHandle, 'YData', app.CommonData.erbYlim);
                    set(app.SgramAxis2, 'YTick', app.CommonData.erbyTickLoc);
                    set(app.SgramAxis2, 'YTickLabel', app.CommonData.erbyTickLabel);
                    set(app.SgramAxis2, 'YLim', app.CommonData.erbYlim);
                case 'log'
                    set(app.CommonData.sgramHandle, 'CData', app.CommonData.logSgramDb);
                    set(app.CommonData.sgramHandle, 'YData', app.CommonData.logYlim)
                    set(app.SgramAxis2, 'YTick', app.CommonData.logyTickLoc);
                    set(app.SgramAxis2, 'YTickLabel', app.CommonData.logyTickLabel);
                    set(app.SgramAxis2, 'YLim', app.CommonData.logYlim);
                case 'linear'
                    set(app.CommonData.sgramHandle, 'CData', app.CommonData.linearSgramDb);
                    set(app.CommonData.sgramHandle, 'YData', app.CommonData.linearYlim)
                    set(app.SgramAxis2, 'YTick', app.CommonData.linearyTickLoc);
                    set(app.SgramAxis2, 'YTickLabel', app.CommonData.linearyTickLabel);
                    set(app.SgramAxis2, 'YLim', app.CommonData.linearYlim);
            end
            drawnow
        end

        % Button pushed function: SAVEParameterButton
        function SAVEParameterButtonPushed(app, event)
            modificationStr = structure;
            modificationStr.parameterFile = app.fileLabel.Text;
            modificationStr.parameterPath = app.pathLabel.Text;
            fs = app.CommonData.samplingFrequency;
            source_parameter = app.CommonData.worldParams.source_parameter;
            yData = get(app.CommonData.foHandle, 'YData');
            lineStatus = get(app.CommonData.foHandle, 'UserData');
            switch lineStatus.scale
                case 'log'
                    tmp = exp(yData(:));
                    tmp(isnan(tmp)) = 0;
            source_parameter.f0 = tmp;
                case 'mel'
                    source_parameter.f0 = mel2hz(yData(:));
            end
            modificationStr.modifiedFo = source_parameter.f0;
            spectrum_parameter = app.CommonData.worldParams.spectrum_parameter;
            %source_parameter = app.CommonData.source_parameter;
            modificationStr.timeAxisMagnification = app.TmagSlider.Value;
            modificationStr.VTLMagnification = app.VTLSlider.Value;
            switch app.TmagSlider.Value
                case 1
                otherwise
                    spectrum_parameter.temporal_positions = ...
                        spectrum_parameter.temporal_positions * app.TmagSlider.Value;
                    source_parameter.temporal_positions = ...
                        source_parameter.temporal_positions * app.TmagSlider.Value;
            end
            switch app.VTLSlider.Value
                case 1
                otherwise
                    value = app.VTLSlider.Value;
                    sgram = app.CommonData.worldParams.spectrum_parameter.spectrogram;
                    apGram = app.CommonData.worldParams.source_parameter.aperiodicity;
                    fx = (0:size(sgram,1))/size(sgram,1)*fs/2;
                    sgramDisp = interp1(fx, [sgram;sgram(end,:)], fx*value, "linear","extrap");
                    spectrum_parameter.spectrogram = sgramDisp(1:end-1,:);
                    apGramDisp = interp1(fx, [apGram;apGram(end,:)], fx*value, "linear","extrap");
                    source_parameter.aperiodicity = apGramDisp(1:end-1,:);
            end
            outFileName = "worldPrmMod" + datestr(now,30) + "d.mat";
            if isfield(app.CommonData,'outFilePath')
                oldPath = cd(app.CommonData.outFilePath);
            end
            [fileName,filePath] = uiputfile('','save parameter',outFileName);
            if isfield(app.CommonData,'outFilePath')
                cd(oldPath);
            end
            app.CommonData.outFileName = string(fileName);
            app.CommonData.outFilePath = string(filePath);
            %disp(fileName)a
            save(app.CommonData.outFilePath + app.CommonData.outFileName, "modificationStr");
            app.SAVEParameterButton.Enable = 'off';
            app.SAVESoundButton.Enable = 'on';
        end

        % Button pushed function: SAVESoundButton
        function SAVESoundButtonPushed(app, event)
            outSynthFileName = "worldSynthMod" + datestr(now,30) + "d.wav";
            fs = app.CommonData.samplingFrequency;
            dlgletter = 'synth file name definition';
            prompt = 'Enter file name';
            dims = [1 45];
            answer = inputdlg(prompt, dlgletter, dims, outSynthFileName);
            app.CommonData.outSynthFileName = answer{1};
            audiowrite(app.CommonData.outFilePath+app.CommonData.outSynthFileName,...
                app.CommonData.synthesizedSignal, fs,"BitsPerSample", 24, ...
                "Comment", app.CommonData.outFileName);
            app.SAVESoundButton.Enable = 'off';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {856, 856};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {227, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.READParameterButton = uibutton(app.LeftPanel, 'push');
            app.READParameterButton.ButtonPushedFcn = createCallbackFcn(app, @READParameterButtonPushed, true);
            app.READParameterButton.Text = 'READ Parameter';
            app.QUITButton = uibutton(app.LeftPanel, 'push');
            app.QUITButton.ButtonPushedFcn = createCallbackFcn(app, @QUITButtonPushed, true);
            app.QUITButton.Text = 'QUIT';
            app.SYNTHESISButton = uibutton(app.LeftPanel, 'push');
            app.SYNTHESISButton.ButtonPushedFcn = createCallbackFcn(app, @SYNTHESISButtonPushed, true);
            app.SYNTHESISButton.Enable = 'off';
            app.SYNTHESISButton.Text = 'SYNTHESIS';
            app.REPLAYButton = uibutton(app.LeftPanel, 'push');
            app.REPLAYButton.ButtonPushedFcn = createCallbackFcn(app, @REPLAYButtonPushed, true);
            app.REPLAYButton.Enable = 'off';
            app.REPLAYButton.Text = ' REPLAY';
            app.SAVEParameterButton = uibutton(app.LeftPanel, 'push');
            app.SAVEParameterButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEParameterButtonPushed, true);
            app.SAVEParameterButton.Enable = 'off';
            app.SAVEParameterButton.Text = 'SAVE Parameter';
            app.PLAYOriginalButton = uibutton(app.LeftPanel, 'push');
            app.PLAYOriginalButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYOriginalButtonPushed, true);
            app.PLAYOriginalButton.Enable = 'off';
            app.PLAYOriginalButton.Text = 'PLAY Original';
            app.WORLDManipulatorLabel = uilabel(app.LeftPanel);
            app.WORLDManipulatorLabel.Text = ' WORLD Manipulator';
            app.SAVESoundButton = uibutton(app.LeftPanel, 'push');
            app.SAVESoundButton.ButtonPushedFcn = createCallbackFcn(app, @SAVESoundButtonPushed, true);
            app.SAVESoundButton.Enable = 'off';
            app.SAVESoundButton.Text = 'SAVE Sound';
            app.UNDOButton = uibutton(app.LeftPanel, 'push');
            app.UNDOButton.Enable = 'off';
            app.UNDOButton.Text = 'UNDO';
            app.RESETButton = uibutton(app.LeftPanel, 'push');
            app.RESETButton.Enable = 'off';
            app.RESETButton.Text = 'RESET';
            app.ChangeaudiooutdeviceButton = uibutton(app.LeftPanel, 'push');
            app.ChangeaudiooutdeviceButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeaudiooutdeviceButtonPushed, true);
            app.ChangeaudiooutdeviceButton.Text = 'Change audio out device';
            app.RightPanel = uipanel(app.GridLayout);
            app.FoAxis = uiaxes(app.RightPanel);
            xlabel(app.FoAxis, 'time (s)')
            ylabel(app.FoAxis, 'Y')
            zlabel(app.FoAxis, 'Z')
            app.powerViewAxis = uiaxes(app.RightPanel);
            ylabel(app.powerViewAxis, 'Y')
            zlabel(app.powerViewAxis, 'Z')
            app.SgramAxis2 = uiaxes(app.RightPanel);
            xlabel(app.SgramAxis2, 'time (s)')
            ylabel(app.SgramAxis2, 'Y')
            zlabel(app.SgramAxis2, 'Z')
            app.pathLabel = uilabel(app.RightPanel);
            app.fileLabel = uilabel(app.RightPanel);
            app.Shift_scaleButtonGroup = uibuttongroup(app.RightPanel);
            app.Shift_scaleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Shift_scaleButtonGroupSelectionChanged, true);
            app.Shift_scaleButtonGroup.Title = 'Shift_scale';
            app.logButton = uiradiobutton(app.Shift_scaleButtonGroup);
            app.logButton.Text = 'log';
            app.logButton.Value = true;
            app.melButton = uiradiobutton(app.Shift_scaleButtonGroup);
            app.melButton.Text = 'mel';
            app.mel2Button = uiradiobutton(app.Shift_scaleButtonGroup);
            app.mel2Button.Enable = 'off';
            app.mel2Button.Visible = 'off';
            app.mel2Button.Text = 'mel2';
            app.Reset_foButton = uibutton(app.RightPanel, 'push');
            app.Reset_foButton.ButtonPushedFcn = createCallbackFcn(app, @Reset_foButtonPushed, true);
            app.Reset_foButton.Enable = 'off';
            app.Reset_foButton.Text = 'Reset_fo';
            app.TmagSliderLabel = uilabel(app.RightPanel);
            app.TmagSliderLabel.Text = 'Tmag';
            app.TmagSlider = uislider(app.RightPanel);
            app.TmagSlider.Limits = [0.2 4];
            app.TmagSlider.MajorTicks = [0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2 2.2 2.4 2.6 2.8 3 3.2 3.4 3.6 3.8 4];
            app.TmagSlider.ValueChangingFcn = createCallbackFcn(app, @TmagSliderValueChanging, true);
            app.TmagSlider.Value = 1;
            app.VTLLabel = uilabel(app.RightPanel);
            app.VTLLabel.Text = 'VTL';
            app.VTLSlider = uislider(app.RightPanel);
            app.VTLSlider.Limits = [0.4 2];
            app.VTLSlider.ValueChangedFcn = createCallbackFcn(app, @VTLSliderValueChanged, true);
            app.VTLSlider.Value = 1;
            app.Reset_TmagButton = uibutton(app.RightPanel, 'push');
            app.Reset_TmagButton.ButtonPushedFcn = createCallbackFcn(app, @Reset_TmagButtonPushed, true);
            app.Reset_TmagButton.Enable = 'off';
            app.Reset_TmagButton.Text = 'Reset_Tmag';
            app.Reset_VTLButton = uibutton(app.RightPanel, 'push');
            app.Reset_VTLButton.ButtonPushedFcn = createCallbackFcn(app, @Reset_VTLButtonPushed, true);
            app.Reset_VTLButton.Enable = 'off';
            app.Reset_VTLButton.Text = 'Reset_VTL';
            app.Sgram_scaleButtonGroup = uibuttongroup(app.RightPanel);
            app.Sgram_scaleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Sgram_scaleButtonGroupSelectionChanged, true);
            app.Sgram_scaleButtonGroup.Title = 'Sgram_scale';
            app.linearButton = uiradiobutton(app.Sgram_scaleButtonGroup);
            app.linearButton.Text = 'linear';
            app.linearButton.Value = true;
            app.logButton_2 = uiradiobutton(app.Sgram_scaleButtonGroup);
            app.logButton_2.Text = 'log';
            app.ERB_NButton = uiradiobutton(app.Sgram_scaleButtonGroup);
            app.ERB_NButton.Text = 'ERB_N';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = worldManipulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end